classdef synui_2018_11_29 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewButton                   matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        DialogueLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        IsoIntensityTab                 matlab.ui.container.Tab
        IsoStimulusPanel                matlab.ui.container.Panel
        IsoStimHiTimeLabel              matlab.ui.control.Label
        IsoStimLoTimeLabel              matlab.ui.control.Label
        SynapseValuesLabel              matlab.ui.control.Label
        IsoStimNPulsesLabel             matlab.ui.control.Label
        IsoGateLoTimeLabel              matlab.ui.control.Label
        IsoGateNPulsesLabel             matlab.ui.control.Label
        IsoLevelLabel                   matlab.ui.control.Label
        StimHiTimemsLabel               matlab.ui.control.Label
        IsoStimHiTimeEdit               matlab.ui.control.NumericEditField
        StimLoTimemsLabel               matlab.ui.control.Label
        IsoStimLoTimeEdit               matlab.ui.control.NumericEditField
        StimNPulsesLabel                matlab.ui.control.Label
        IsoStimNPulsesEdit              matlab.ui.control.NumericEditField
        GateLoTimemsLabel_2             matlab.ui.control.Label
        IsoGateLoTimeEdit               matlab.ui.control.NumericEditField
        GateNPulsesLabel                matlab.ui.control.Label
        IsoGateNPulsesEdit              matlab.ui.control.NumericEditField
        LeveldBEditFieldLabel           matlab.ui.control.Label
        IsoLevelEdit                    matlab.ui.control.NumericEditField
        IsoGetButton                    matlab.ui.control.Button
        IsoSetButton                    matlab.ui.control.Button
        IsoStimAxes                     matlab.ui.control.UIAxes
        ExperimentNameEditFieldLabel    matlab.ui.control.Label
        ExpEdit1                        matlab.ui.control.EditField
        ExpButton1                      matlab.ui.control.Button
        LDSNoiseTab                     matlab.ui.container.Tab
        NBNStimulusPanel                matlab.ui.container.Panel
        NBNTotLDSPulsesLabel            matlab.ui.control.Label
        NBNLDSOnsetDelayLabel           matlab.ui.control.Label
        SynapseValuesLabel_2            matlab.ui.control.Label
        NBNLDSDurationLabel             matlab.ui.control.Label
        NBNLDSTimeBtwnPulsesLabel       matlab.ui.control.Label
        NBNLevelLabel                   matlab.ui.control.Label
        NBNHPCFLabel                    matlab.ui.control.Label
        TotLDSPulsesLabel               matlab.ui.control.Label
        NBNTotLDSPulsesEdit             matlab.ui.control.NumericEditField
        LDSOnsetDelaysecLabel           matlab.ui.control.Label
        NBNLDSOnsetDelayEdit            matlab.ui.control.NumericEditField
        LDSDurationsecLabel             matlab.ui.control.Label
        NBNLDSDurationEdit              matlab.ui.control.NumericEditField
        LDSTimeBtwnPulsessecLabel       matlab.ui.control.Label
        NBNLDSTimeBtwnPulsesEdit        matlab.ui.control.NumericEditField
        LeveldBLabel                    matlab.ui.control.Label
        NBNLevelEdit                    matlab.ui.control.NumericEditField
        HipassHzLabel                   matlab.ui.control.Label
        NBNHPCFEdit                     matlab.ui.control.NumericEditField
        LopassHzEditFieldLabel          matlab.ui.control.Label
        NBNLPCFEdit                     matlab.ui.control.NumericEditField
        ModulationEditFieldLabel        matlab.ui.control.Label
        NBNModPercentageEdit            matlab.ui.control.NumericEditField
        ModulationFreqHzEditFieldLabel  matlab.ui.control.Label
        NBNModFrequencyEdit             matlab.ui.control.NumericEditField
        SineExponentEditFieldLabel      matlab.ui.control.Label
        NBNSineWaveExponentEdit         matlab.ui.control.NumericEditField
        NBNLPCFLabel                    matlab.ui.control.Label
        NBNModPercentageLabel           matlab.ui.control.Label
        NBNModFrequencyLabel            matlab.ui.control.Label
        NBNSineWaveExponentLabel        matlab.ui.control.Label
        NBNGetButton                    matlab.ui.control.Button
        NBNSetButton                    matlab.ui.control.Button
        NBNStimAxes                     matlab.ui.control.UIAxes
        ExperimentNameEditFieldLabel_2  matlab.ui.control.Label
        ExpEdit2                        matlab.ui.control.EditField
        ExpButton2                      matlab.ui.control.Button
        LDSToneTab                      matlab.ui.container.Tab
        TONEStimulusPanel               matlab.ui.container.Panel
        TONETotLDSPulsesLabel           matlab.ui.control.Label
        TONELDSOnsetDelayLabel          matlab.ui.control.Label
        TONESynapseValuesLabel          matlab.ui.control.Label
        TONELDSDurationLabel            matlab.ui.control.Label
        TONELDSTimeBtwnPulsesLabel      matlab.ui.control.Label
        TONELevelLabel                  matlab.ui.control.Label
        TONEToneFreqLabel               matlab.ui.control.Label
        TONEModPercentageLabel          matlab.ui.control.Label
        TONEModFrequencyLabel           matlab.ui.control.Label
        TONESineWaveExponentLabel       matlab.ui.control.Label
        TotLDSPulsesLabel_2             matlab.ui.control.Label
        TONETotLDSPulsesEdit            matlab.ui.control.NumericEditField
        LDSOnsetDelaysecLabel_2         matlab.ui.control.Label
        TONELDSOnsetDelayEdit           matlab.ui.control.NumericEditField
        LDSDurationsecLabel_2           matlab.ui.control.Label
        TONELDSDurationEdit             matlab.ui.control.NumericEditField
        LDSTimeBtwnPulsessecLabel_2     matlab.ui.control.Label
        TONELDSTimeBtwnPulsesEdit       matlab.ui.control.NumericEditField
        LeveldBLabel_2                  matlab.ui.control.Label
        TONELevelEdit                   matlab.ui.control.NumericEditField
        FrequencyHzLabel                matlab.ui.control.Label
        TONEToneFreqEdit                matlab.ui.control.NumericEditField
        ModulationEditFieldLabel_2      matlab.ui.control.Label
        TONEModPercentageEdit           matlab.ui.control.NumericEditField
        ModulationFreqHzEditFieldLabel_2  matlab.ui.control.Label
        TONEModFrequencyEdit            matlab.ui.control.NumericEditField
        SineExponentEditFieldLabel_2    matlab.ui.control.Label
        TONESineWaveExponentEdit        matlab.ui.control.NumericEditField
        TONEGetButton                   matlab.ui.control.Button
        TONESetButton                   matlab.ui.control.Button
        TONEStimAxes                    matlab.ui.control.UIAxes
        ExperimentNameEditFieldLabel_3  matlab.ui.control.Label
        ExpEdit3                        matlab.ui.control.EditField
        ExpButton3                      matlab.ui.control.Button
        ReadTankTab                     matlab.ui.container.Tab
        SelectTankButton                matlab.ui.control.Button
        LoadTankButton                  matlab.ui.control.Button
        TankEditFieldLabel              matlab.ui.control.Label
        TankEditField                   matlab.ui.control.EditField
        ISILabel                        matlab.ui.control.Label
        CurrentModeLabel                matlab.ui.control.Label
        IdleButton                      matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        FiringRateTab                   matlab.ui.container.Tab
        rateAxes                        matlab.ui.control.UIAxes
        BinsizemsLabel_2                matlab.ui.control.Label
        BinEdit                         matlab.ui.control.NumericEditField
        HistorysecLabel                 matlab.ui.control.Label
        RangeEdit                       matlab.ui.control.NumericEditField
        ratePopupCheck                  matlab.ui.control.CheckBox
        FreqTuningTab                   matlab.ui.container.Tab
        OffsetEdit                      matlab.ui.control.NumericEditField
        EvokedratewindowmsLabel_4       matlab.ui.control.Label
        OnsetEdit                       matlab.ui.control.NumericEditField
        FTCAxes                         matlab.ui.control.UIAxes
        FTCPopupCheck                   matlab.ui.control.CheckBox
        PSTHTab                         matlab.ui.container.Tab
        PSTH1Axes                       matlab.ui.control.UIAxes
        PSTHPopupCheck                  matlab.ui.control.CheckBox
        BinsizemsLabel                  matlab.ui.control.Label
        PSTHBinEdit                     matlab.ui.control.NumericEditField
        PSTH2Axes                       matlab.ui.control.UIAxes
        PSTH1WindowsecEditFieldLabel    matlab.ui.control.Label
        PSTH1StartEdit                  matlab.ui.control.NumericEditField
        PSTH1EndEdit                    matlab.ui.control.NumericEditField
        Referencet0DropDownLabel        matlab.ui.control.Label
        PSTH1RefDrop                    matlab.ui.control.DropDown
        Referencet0DropDown_2Label      matlab.ui.control.Label
        PSTH2RefDrop                    matlab.ui.control.DropDown
        PSTH2EndEdit                    matlab.ui.control.NumericEditField
        PSTH2WindowsecEditFieldLabel    matlab.ui.control.Label
        PSTH2StartEdit                  matlab.ui.control.NumericEditField
        PollingperiodsecEditFieldLabel  matlab.ui.control.Label
        UpdateEdit                      matlab.ui.control.NumericEditField
        ExpDurationRadio                matlab.ui.container.ButtonGroup
        InfiniteButton                  matlab.ui.control.RadioButton
        SecondsButton                   matlab.ui.control.RadioButton
        PulsesButton                    matlab.ui.control.RadioButton
        ExpDurationEdit                 matlab.ui.control.NumericEditField
        ChannelsPanel                   matlab.ui.container.Panel
        CheckBox_1                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_21                     matlab.ui.control.CheckBox
        CheckBox_22                     matlab.ui.control.CheckBox
        CheckBox_23                     matlab.ui.control.CheckBox
        CheckBox_24                     matlab.ui.control.CheckBox
        CheckBox_25                     matlab.ui.control.CheckBox
        CheckBox_26                     matlab.ui.control.CheckBox
        CheckBox_27                     matlab.ui.control.CheckBox
        CheckBox_28                     matlab.ui.control.CheckBox
        CheckBox_29                     matlab.ui.control.CheckBox
        CheckBox_30                     matlab.ui.control.CheckBox
        CheckBox_31                     matlab.ui.control.CheckBox
        CheckBox_32                     matlab.ui.control.CheckBox
        Chan116Button                   matlab.ui.control.StateButton
        Chan1732Button                  matlab.ui.control.StateButton
    end


    properties (Access = private)
        syn = [];           % links to Synapse
        experiment = [];    % 1 Iso Intensity, 2 LDS Noise, 3 LDS Tone
        pars = [];          % Parameters
        gizmos = [];        % Gizmos
    end

    methods (Access = private)
%Updates the textbox that displays the Synapse Mode
        function updatemode(app) 
            switch app.syn.getMode
                case 0
                    app.CurrentModeLabel.Text = 'Idle';
                case 1
                    app.CurrentModeLabel.Text = 'Standby';
                case 2
                    app.CurrentModeLabel.Text = 'Preview';
                case 3
                    app.CurrentModeLabel.Text = 'Recording';
            end
        end
    
%Reads parameters stored in synapse        
        function updatepars(app) 
            if app.syn.getMode == 0
                app.syn.setMode(1);
            end
            app.IsoStimulusPanel.Visible = 0;
            app.NBNStimulusPanel.Visible = 0;
            app.TONEStimulusPanel.Visible = 0;

            switch app.experiment
                case 1
                    app.pars = {'StimHiTime'; 'StimLoTime'; 'StimNPulses'; 'GateLoTime'; 'GateNPulses'; 'Level'};
                    app.gizmos = {'Stimulus'; 'Stimulus'; 'Stimulus'; 'Stimulus'; 'Stimulus'; 'Intensity'};
                    prefix = 'Iso';
                case 2
                    app.pars = {'TotLDSPulses'; 'LDSOnsetDelay'; 'LDSDuration'; 'LDSTimeBtwnPulses'; 'Level'; 'HPCF'; 'LPCF'; ...
                        'ModPercentage'; 'ModFrequency'; 'SineWaveExponent'};
                    app.gizmos = {'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; ...
                        'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'};
                    prefix = 'NBN';
                case 3
                    app.pars = {'TotLDSPulses'; 'LDSOnsetDelay'; 'LDSDuration'; 'LDSTimeBtwnPulses'; 'Level'; 'ToneFreq'; ...
                        'ModPercentage'; 'ModFrequency'; 'SineWaveExponent'};
                    app.gizmos = {'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; ...
                        'LDS_tones'; 'LDS_tones'};
                    prefix = 'TONE';
            end
            for par = 1:numel(app.pars)
                mvarname = [prefix char(app.pars(par)) 'Label'];
                app.(mvarname).Text = num2str(app.syn.getParameterValue(char(app.gizmos(par)), char(app.pars(par))));
            end
            switch app.experiment
                case 1
                    hitime = str2double(app.IsoStimHiTimeLabel.Text);
                    lotime = str2double(app.IsoStimLoTimeLabel.Text);
                    period = hitime + lotime;
                    stimons = 0:period:period*(str2double(app.IsoStimNPulsesLabel.Text)-1);
                    stimoffs = stimons + hitime;
                    trainperiod =  stimoffs(end)+ lotime + str2double(app.IsoGateLoTimeLabel.Text);
                    train = reshape([stimons; stimoffs], 1, numel([stimons; stimoffs]));
                    timemat = repmat(train, str2double(app.IsoGateNPulsesLabel.Text), 1) + (0:trainperiod:trainperiod*(str2double(app.IsoGateNPulsesLabel.Text)-1))';
                    timevec = reshape(timemat', 1, numel(timemat));
                    timevec2 = reshape([timevec; timevec], 1, numel(timevec)*2);
                    yval = repmat([0 1 1 0], 1, numel(timevec2)/4);
                    plot(app.IsoStimAxes, timevec2/1000, yval, 'k-')
                case 2
                    period = str2double(app.NBNLDSDurationLabel.Text) + str2double(app.NBNLDSTimeBtwnPulsesLabel.Text);
                    del = str2double(app.NBNLDSOnsetDelayLabel.Text);
                    stimons = del:period:(del + period*(str2double(app.NBNTotLDSPulsesLabel.Text))-1);
                    stimoffs = stimons + str2double(app.NBNLDSDurationLabel.Text);
                    timevec = reshape([stimons; stimoffs], 1, numel([stimons; stimoffs]));
                    timevec2 = reshape([timevec; timevec], 1, numel(timevec)*2);
                    yval = repmat([0 1 1 0], 1, numel(timevec2)/4);
                    plot(app.NBNStimAxes, timevec2/60, yval, 'k-')
                case 3
                    period = str2double(app.NBNLDSDurationLabel.Text) + str2double(app.NBNLDSTimeBtwnPulsesLabel.Text);
                    del = str2double(app.NBNLDSOnsetDelayLabel.Text);
                    stimons = del:period:(del + period*(str2double(app.NBNTotLDSPulsesLabel.Text))-1);
                    stimoffs = stimons + str2double(app.NBNLDSDurationLabel.Text);
                    timevec = reshape([stimons; stimoffs], 1, numel([stimons; stimoffs]));
                    timevec2 = reshape([timevec; timevec], 1, numel(timevec)*2);
                    yval = repmat([0 1 1 0], 1, numel(timevec2)/4);
                    plot(app.NBNStimAxes, timevec2/60, yval, 'k-')
            end
            stimpanel = [prefix 'StimulusPanel'];
            app.(stimpanel).Visible = 1;
        end
        
%Streams data from synapse to MATLAB during preview and recording
        function livestreamapp(app, mode) 
        app.DialogueLabel.Text = 'Beginning Experiment';
        updatepars(app);
        chanlist = updatechans(app);
        nchans = numel(chanlist);
        spikevar = 'eSpk';
        wavevar = 'Mult';
        stimvar = 'Freq';
        offvar = 'StOn';
        
        app.DialogueLabel.Text = 'Connecting to SynapseLive';
        namefield = ['ExpEdit' num2str(app.experiment)];
        exp = SynapseLive('MODE', mode, 'EXPERIMENT', app.(namefield).Value);
        updaterate = app.UpdateEdit.Value;
        binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
            
        if updaterate<2*binwindow; pause(2*binwindow); end
        
        spets = [];
        channels = [];
        stimons = [];
        stimoffs = [];
        frqs = [];
        currtime = [];
        parmat = [];
        app.rateAxes.Visible = 1;
        app.FTCAxes.Visible = 1;
        app.DialogueLabel.Text = 'Streaming Data';
        loopc = 1;
        loopcon = 1;
            while loopcon
                pause(updaterate)

                if app.syn.getMode() < 2 %stops if synapse is set to idle or standby
                    break
                end
                new = exp.update;
                try 
                if isempty(new)
                    break
                end
                
            %read UI data  
                updaterate = app.UpdateEdit.Value;
                binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
                ratewin = app.RangeEdit.Value;
                analwin = [app.OnsetEdit.Value app.OffsetEdit.Value]./1000; %ftc analysis window;
                chanlist = updatechans(app);

            %read continuously updated data
                currtime = [currtime; app.syn.getParameterValue('ReadTime', 'Time')];
                currpars = NaN(size(app.pars));
                for par = 1:numel(app.pars)
                    currpars(par) = app.syn.getParameterValue(char(app.gizmos(par)), char(app.pars(par)));
                end
                parmat = [parmat; currpars'];
                
                if isfield(new.data.snips, spikevar)
                    spets = [spets; new.data.snips.(spikevar).ts];
                    channels = [channels; new.data.snips.(spikevar).chan];
                end

                
            %read triggered data
                if app.experiment > 1
                    if ~sum(isnan(new.data.epocs.(offvar).data))
                        stimons = [stimons; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data == 1)];
                        frqs = zeros(numel(stimons), 1);
                        stimoffs = [stimoffs; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data == 0)];
                    end
                else
                    if ~sum(isnan(new.data.epocs.(stimvar).onset))
                        frqs = [frqs; new.data.epocs.(stimvar).data];
                    end
                    if ~sum(isnan(new.data.epocs.(offvar).onset))
                        stimons = [stimons; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data==1)];
                        stimoffs = [stimoffs; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data==0)];
                    end
                end
                app.DialogueLabel.Text = ['Streaming Data', newline, datestr(seconds(currtime(end)),'MM:SS'), ' ' num2str(loopc) ' polls, ' num2str(numel(stimons)) ' trials' ];
                
            %running spike rate average
                frate = NaN(nchans,round(max(spets)/binwindow));
                for chan = chanlist'
                    [bincount, bintime] = hist(spets(channels==chan), 0.5*binwindow:binwindow:max(spets));
                    yrange = max(bincount) - min(bincount);
                    frate(chan,:) = (bincount- min(bincount))./(1.1*yrange) - chan;
                end
                plotfrate(app, frate, bintime, stimons, stimoffs)
                 xlim(app.rateAxes, [max([0 currtime(end)-ratewin]), max([1 currtime(end)])]);                

                 if numel(stimons)>0
                    [spetfreq, stimspets, trials] = freqanal(spets, stimons(1:numel(frqs)), frqs);

            
            %tuning curve
                    if app.experiment == 1 & numel(unique(frqs))>1
                        synftc(stimspets, spetfreq, analwin, channels, trials, nchans, frqs, app.FTCAxes)
                    end
                    
                end
            loopc = loopc + 1;
            switch lower(app.ExpDurationRadio.SelectedObject.Text)
                case 'seconds'
                    if currtime(end) > app.ExpDurationEdit.Value
                        loopcon = 0;
                        app.syn.setMode(0);
                        app.IsoStimulusPanel.Visible = 0; app.NBNStimulusPanel.Visible = 0; app.TONEStimulusPanel.Visible = 0;
                        updatemode(app);
                    end
                case 'pulses'
                    if app.experiment == 1
                        scalar = str2double(app.IsoStimNPulsesLabel.Text);
                    else
                        scalar = 1;
                    end
                    if numel(stimoffs) >= app.ExpDurationEdit.Value * scalar
                        loopcon = 0;
                        app.syn.setMode(0);
                        app.IsoStimulusPanel.Visible = 0; app.NBNStimulusPanel.Visible = 0; app.TONEStimulusPanel.Visible = 0;
                        updatemode(app);
                    end

            end
                catch ME
                    ME
                    break
                end
            end %while
            metext = 'Ready.';
            if exist('ME', 'var')
                metext = 'A MATLAB Error was thrown :(';
            end
            app.DialogueLabel.Text = ['Data streaming ended. ' metext];
            if strcmpi(mode, 'Record')
                [changesamp, ~]  = find(diff(parmat));
                changes = sort(unique(changesamp))+1;
                fulltable = array2table([currtime parmat], 'VariableNames', [{'Time'}; app.pars]);
                changearray = [[currtime(1), parmat(1,:)]; [currtime(changes),  parmat(changes,:)]];
                changetable = array2table(changearray, 'VariableNames', [{'Time'}; app.pars]);
                flist = dir(app.syn.getCurrentTank);
                folders = zeros(size(flist));
                dates = zeros(size(flist));
                for file = 3:numel(flist)
                    folders(file) = flist(file).isdir;
                    dates(file) = flist(file).datenum;
                end
                subind= find(folders);
                 [~, i]=max(dates(subind));
                 currfolder = [flist(subind(i)).folder '\' flist(subind(i)).name];
                 save([currfolder '\params.mat'], 'fulltable', 'changetable')
            end
            
            
        end %function livestreamapp
        
        function commongetbutton(app, prefix)
            for par = 1:numel(app.pars)
                mvarname = [prefix char(app.pars(par)) 'Edit'];
                mvarname2 = [prefix char(app.pars(par)) 'Label'];
                app.(mvarname).Value= str2double(app.(mvarname2).Text);
            end

        end
        
        function commonsetbutton(app, prefix)
            for par = 1:numel(app.pars)
                mvarname = [prefix char(app.pars(par)) 'Edit'];
                app.syn.setParameterValue(char(app.gizmos(par)), char(app.pars(par)), app.(mvarname).Value);
            end
            if strcmpi(prefix, 'iso')
                app.syn.setParameterValue('Stimulus', 'GateHiTime', app.IsoStimNPulsesEdit.Value*(app.IsoStimHiTimeEdit.Value + app.IsoStimLoTimeEdit.Value));
            end
            pause(1);
            updatepars(app);

        end
        
        function commonexpbutton(app, expnum)
            if app.syn.getMode > 0
               app.syn.setMode(0);
            end
            app.DialogueLabel.Text = 'Loading Experiment'; pause(.1);
            app.experiment = expnum;
            namefield = ['ExpEdit' num2str(expnum)];
            app.syn.setCurrentExperiment(app.(namefield).Value);
            app.syn.setMode(1);
            updatemode(app); updatepars(app);
            app.DialogueLabel.Text = 'Ready';
        end
        
        function plotfrate(app, frate, bintime, stimons, stimoffs)
                if app.ratePopupCheck.Value
                    figure(1);
                    ax = axes();
                else
                    ax = app.rateAxes;
                end
                plot(ax, 0,0)
                hold(ax, 'on');
                plot(ax, bintime, frate)
                if numel(stimons>0)
                    if numel(stimoffs)<numel(stimons)
                        stimp = [stimons [stimoffs; max(stimons)]];
                    else
                        stimp = [stimons stimoffs];
                    end
                    plot(ax, stimp', [0 0], 'r-', 'LineWidth', 4);
                end
                hold(ax, 'off');
        end
        
        function chanlist = updatechans(app)
            chanvec = zeros(32,1);
            for chan = 1:32
                chanfield = ['CheckBox_' num2str(chan)];
                if app.(chanfield).Value
                    chanvec(chan) = 1;
                end
            end
            chanlist = find(chanvec);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IsoStimulusPanel.Visible = 0;
            app.NBNStimulusPanel.Visible = 0;
            app.TONEStimulusPanel.Visible = 0;
            app.rateAxes.Visible = 0;
            app.FTCAxes.Visible = 0;
            app.syn = SynapseAPI();
            updatemode(app);
            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            livestreamapp(app, 'Preview')
        end

        % Button pushed function: IsoGetButton
        function IsoGetButtonPushed(app, event)
            commongetbutton(app, 'Iso')
        end

        % Button pushed function: IsoSetButton
        function IsoSetButtonPushed(app, event)
           commonsetbutton(app, 'Iso')
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            livestreamapp(app, 'Record')
        end

        % Button pushed function: IdleButton
        function IdleButtonPushed(app, event)
            app.syn.setMode(0);
            app.IsoStimulusPanel.Visible = 0; app.NBNStimulusPanel.Visible = 0; app.TONEStimulusPanel.Visible = 0;
            updatemode(app);
        end

        % Button pushed function: NBNGetButton
        function NBNGetButtonPushed(app, event)
            commongetbutton(app, 'NBN')
        end

        % Button pushed function: NBNSetButton
        function NBNSetButtonPushed(app, event)
            commonsetbutton(app, 'NBN')
        end

        % Button pushed function: TONEGetButton
        function TONEGetButtonPushed(app, event)
            commongetbutton(app, 'TONE')
        end

        % Button pushed function: TONESetButton
        function TONESetButtonPushed(app, event)
            commonsetbutton(app, 'TONE')
        end

        % Button pushed function: ExpButton1
        function ExpButton1Pushed(app, event)
            commonexpbutton(app, 1)
        end

        % Button pushed function: SelectTankButton
        function SelectTankButtonPushed(app, event)
            tankpath = uigetdir('E:\TDT\Synapse\Tanks');
            if tankpath ~= 0
                app.TankEditField.Value = tankpath;
            end
        end

        % Button pushed function: LoadTankButton
        function LoadTankButtonPushed(app, event)
            data=TDTbin2mat(app.TankEditField.Value);
            chanlist = updatechans(app);
            nchans = numel(chanlist);
            spikevar = 'eSpk';
            wavevar = 'Mult';
            stimvar = 'Freq';
            offvar = 'StOn';
            
            spets = data.snips.(spikevar).ts;
            channels = data.snips.(spikevar).chan;
            stimons = data.epocs.(offvar).onset(data.epocs.(offvar).data == 1);
            stimoffs = data.epocs.(offvar).onset(data.epocs.(offvar).data == 0);
            if isfield(data.epocs, stimvar)
                frqs = data.epocs.(stimvar).data;
            else
                frqs = zeros(numel(stimons), 1);
            end
            
            tab1 = tabulate(round(diff(stimons).*1000)./1000);
            [~,i] = sort(tab1(:,2), 'descend');
            j = find(tab1(i, 2)>0);
            
            tabchar = num2str(tab1(i(j), 1:2));
            tabout = '';
            for chrow = 1:size(tabchar, 1)
                tabout = [tabout tabchar(chrow, :) newline];
            end
            app.ISILabel.Text = tabout;
            binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
            psthwindow = app.PSTHBinEdit.Value./1000; 
            analwin = [app.OnsetEdit.Value app.OffsetEdit.Value]./1000; %ftc analysis window;
            [spetfreq, stimspets, trials] = freqanal(spets, stimons, frqs);


            app.rateAxes.Visible = 1;
            app.FTCAxes.Visible = 1;

            %running spike rate average
                frate = NaN(nchans,round(max(spets)/binwindow));
                for chan = chanlist'
                    [bincount, bintime] = hist(spets(channels==chan), 0.5*binwindow:binwindow:max(spets));
                    yrange = max(bincount) - min(bincount);
                    frate(chan,:) = (bincount- min(bincount))./(1.1*yrange) - chan;
                end
                plotfrate(app, frate, bintime, stimons, stimoffs)
            
            %PSTH
            for i = 1:2
                reffield = ['PSTH' num2str(i) 'RefDrop'];
                startfield = ['PSTH' num2str(i) 'StartEdit'];
                endfield = ['PSTH' num2str(i) 'EndEdit'];
                axesfield = ['PSTH' num2str(i) 'Axes'];
                
                
                if strcmpi(app.(reffield).Value, 'onset')
                    ref = stimons;
                else
                    ref = stimoffs;
                end
                
                if isempty(spets)
                    spettable = [];
                else
                    spettable = repmat(spets, 1, numel(stimons)) - (ref + app.(startfield).Value)';
                end
                spettable(spettable<0) = NaN;
                spettable(spettable>(app.(endfield).Value - app.(startfield).Value)) = NaN;
                [PSTHspets, trial] = min(spettable + app.(startfield).Value, [], 2, 'omitnan');
                trial = trial(~isnan(PSTHspets));
                PSTHchans = channels(~isnan(PSTHspets));
                PSTHsubspets = PSTHspets(~isnan(PSTHspets));
                
                binvec = app.(startfield).Value:psthwindow:app.(endfield).Value;
                if app.PSTHPopupCheck.Value
                    figure(2+i); ax = axes();
                else
                    ax = app.(axesfield);
                end
                synpsth(PSTHsubspets, binvec, PSTHchans, chanlist, ax)
                title(ax, [num2str(numel(PSTHsubspets)) ' spikes, ' num2str(numel(stimons)) ' trials'])
            end
            
            %tuning curve
            if numel(unique(frqs))>1
                if app.FTCPopupCheck.Value
                    figure(2); ax = axes();
                else
                    ax = app.FTCAxes;
                end
                    synftc(stimspets, spetfreq, analwin, channels, trials, nchans, frqs, ax)
            end
            
        end

        % Button pushed function: ExpButton2
        function ExpButton2Pushed(app, event)
            commonexpbutton(app, 2)
        end

        % Button pushed function: ExpButton3
        function ExpButton3Pushed(app, event)
            commonexpbutton(app, 3)
        end

        % Value changed function: Chan116Button
        function Chan116ButtonValueChanged(app, event)
            value = app.Chan116Button.Value;
            if value
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan116Button.Text = 'Clear 1-16';
            else
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end                
                app.Chan116Button.Text = 'Check 1-16';
            end
        end

        % Value changed function: Chan1732Button
        function Chan1732ButtonValueChanged(app, event)
            value = app.Chan1732Button.Value;
            if value
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan1732Button.Text = 'Clear 17-32';
            else
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end
                app.Chan1732Button.Text = 'Check 17-32';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.DialogueLabel = uilabel(app.UIFigure);
            app.DialogueLabel.Text = 'Dialogue';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IsoIntensityTab = uitab(app.TabGroup);
            app.IsoIntensityTab.Title = 'Iso Intensity';
            app.IsoStimulusPanel = uipanel(app.IsoIntensityTab);
            app.IsoStimulusPanel.Title = 'Stimulus';
            app.IsoStimHiTimeLabel = uilabel(app.IsoStimulusPanel);
            app.IsoStimHiTimeLabel.Text = 'HiTime';
            app.IsoStimLoTimeLabel = uilabel(app.IsoStimulusPanel);
            app.IsoStimLoTimeLabel.Text = 'LoTime';
            app.SynapseValuesLabel = uilabel(app.IsoStimulusPanel);
            app.SynapseValuesLabel.Text = 'Synapse Values';
            app.IsoStimNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.IsoStimNPulsesLabel.Text = 'Pulses';
            app.IsoGateLoTimeLabel = uilabel(app.IsoStimulusPanel);
            app.IsoGateLoTimeLabel.Text = 'ISI';
            app.IsoGateNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.IsoGateNPulsesLabel.Text = 'Trains';
            app.IsoLevelLabel = uilabel(app.IsoStimulusPanel);
            app.IsoLevelLabel.Text = 'dB';
            app.StimHiTimemsLabel = uilabel(app.IsoStimulusPanel);
            app.StimHiTimemsLabel.Text = 'Stim Hi Time (ms)';
            app.IsoStimHiTimeEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoStimHiTimeEdit.Value = 200;
            app.StimLoTimemsLabel = uilabel(app.IsoStimulusPanel);
            app.StimLoTimemsLabel.Text = 'Stim Lo Time (ms)';
            app.IsoStimLoTimeEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoStimLoTimeEdit.Value = 300;
            app.StimNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.StimNPulsesLabel.Text = 'Stim N Pulses';
            app.IsoStimNPulsesEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoStimNPulsesEdit.Value = 5;
            app.GateLoTimemsLabel_2 = uilabel(app.IsoStimulusPanel);
            app.GateLoTimemsLabel_2.Text = 'Gate Lo Time (ms)';
            app.IsoGateLoTimeEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoGateLoTimeEdit.Value = 500;
            app.GateNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.GateNPulsesLabel.Text = 'Gate N Pulses';
            app.IsoGateNPulsesEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoGateNPulsesEdit.Value = 6;
            app.LeveldBEditFieldLabel = uilabel(app.IsoStimulusPanel);
            app.LeveldBEditFieldLabel.Text = 'Level (dB)';
            app.IsoLevelEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoLevelEdit.Value = 70;
            app.IsoGetButton = uibutton(app.IsoStimulusPanel, 'push');
            app.IsoGetButton.ButtonPushedFcn = createCallbackFcn(app, @IsoGetButtonPushed, true);
            app.IsoGetButton.Text = '<- Get ';
            app.IsoSetButton = uibutton(app.IsoStimulusPanel, 'push');
            app.IsoSetButton.ButtonPushedFcn = createCallbackFcn(app, @IsoSetButtonPushed, true);
            app.IsoSetButton.Text = 'Set ->';
            app.IsoStimAxes = uiaxes(app.IsoStimulusPanel);
            title(app.IsoStimAxes, 'Stim Preview')
            xlabel(app.IsoStimAxes, 'Time')
            ylabel(app.IsoStimAxes, 'Y')
            app.ExperimentNameEditFieldLabel = uilabel(app.IsoIntensityTab);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name';
            app.ExpEdit1 = uieditfield(app.IsoIntensityTab, 'text');
            app.ExpEdit1.Value = 'Iso_Pilot_11-29-2018';
            app.ExpButton1 = uibutton(app.IsoIntensityTab, 'push');
            app.ExpButton1.ButtonPushedFcn = createCallbackFcn(app, @ExpButton1Pushed, true);
            app.ExpButton1.Text = 'Load Experiment';
            app.LDSNoiseTab = uitab(app.TabGroup);
            app.LDSNoiseTab.Title = 'LDS Noise';
            app.NBNStimulusPanel = uipanel(app.LDSNoiseTab);
            app.NBNStimulusPanel.Title = 'Stimulus';
            app.NBNTotLDSPulsesLabel = uilabel(app.NBNStimulusPanel);
            app.NBNTotLDSPulsesLabel.Text = 'TotLDSPulses';
            app.NBNLDSOnsetDelayLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLDSOnsetDelayLabel.Text = 'LDSOnsetDelay';
            app.SynapseValuesLabel_2 = uilabel(app.NBNStimulusPanel);
            app.SynapseValuesLabel_2.Text = 'Synapse Values';
            app.NBNLDSDurationLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLDSDurationLabel.Text = 'LDSDuration';
            app.NBNLDSTimeBtwnPulsesLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLDSTimeBtwnPulsesLabel.Text = 'LDSTimeBtwnPulses';
            app.NBNLevelLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLevelLabel.Text = 'Level';
            app.NBNHPCFLabel = uilabel(app.NBNStimulusPanel);
            app.NBNHPCFLabel.Text = 'HPCF';
            app.TotLDSPulsesLabel = uilabel(app.NBNStimulusPanel);
            app.TotLDSPulsesLabel.Text = 'Tot LDS Pulses';
            app.NBNTotLDSPulsesEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNTotLDSPulsesEdit.Value = 20;
            app.LDSOnsetDelaysecLabel = uilabel(app.NBNStimulusPanel);
            app.LDSOnsetDelaysecLabel.Text = 'LDS Onset Delay (sec)';
            app.NBNLDSOnsetDelayEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLDSOnsetDelayEdit.Value = 20;
            app.LDSDurationsecLabel = uilabel(app.NBNStimulusPanel);
            app.LDSDurationsecLabel.Text = 'LDS Duration (sec)';
            app.NBNLDSDurationEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLDSDurationEdit.Value = 20;
            app.LDSTimeBtwnPulsessecLabel = uilabel(app.NBNStimulusPanel);
            app.LDSTimeBtwnPulsessecLabel.Text = 'LDS Time Btwn Pulses (sec)';
            app.NBNLDSTimeBtwnPulsesEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLDSTimeBtwnPulsesEdit.Value = 30;
            app.LeveldBLabel = uilabel(app.NBNStimulusPanel);
            app.LeveldBLabel.Text = 'Level (dB)';
            app.NBNLevelEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLevelEdit.Value = 70;
            app.HipassHzLabel = uilabel(app.NBNStimulusPanel);
            app.HipassHzLabel.Text = 'Hipass (Hz)';
            app.NBNHPCFEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNHPCFEdit.Value = 6000;
            app.LopassHzEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.LopassHzEditFieldLabel.Text = 'Lopass (Hz)';
            app.NBNLPCFEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLPCFEdit.Value = 12000;
            app.ModulationEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.ModulationEditFieldLabel.Text = 'Modulation %';
            app.NBNModPercentageEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.ModulationFreqHzEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.ModulationFreqHzEditFieldLabel.Text = 'Modulation Freq (Hz)';
            app.NBNModFrequencyEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.SineExponentEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.SineExponentEditFieldLabel.Text = 'Sine Exponent';
            app.NBNSineWaveExponentEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNSineWaveExponentEdit.Value = 1;
            app.NBNLPCFLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLPCFLabel.Text = 'LPCF';
            app.NBNModPercentageLabel = uilabel(app.NBNStimulusPanel);
            app.NBNModPercentageLabel.Text = 'ModPercentage';
            app.NBNModFrequencyLabel = uilabel(app.NBNStimulusPanel);
            app.NBNModFrequencyLabel.Text = 'ModFrequency';
            app.NBNSineWaveExponentLabel = uilabel(app.NBNStimulusPanel);
            app.NBNSineWaveExponentLabel.Text = 'SineWaveExponent';
            app.NBNGetButton = uibutton(app.NBNStimulusPanel, 'push');
            app.NBNGetButton.ButtonPushedFcn = createCallbackFcn(app, @NBNGetButtonPushed, true);
            app.NBNGetButton.Text = '<- Get ';
            app.NBNSetButton = uibutton(app.NBNStimulusPanel, 'push');
            app.NBNSetButton.ButtonPushedFcn = createCallbackFcn(app, @NBNSetButtonPushed, true);
            app.NBNSetButton.Text = 'Set ->';
            app.NBNStimAxes = uiaxes(app.NBNStimulusPanel);
            title(app.NBNStimAxes, 'Stim Preview')
            xlabel(app.NBNStimAxes, 'Time')
            ylabel(app.NBNStimAxes, 'Y')
            app.ExperimentNameEditFieldLabel_2 = uilabel(app.LDSNoiseTab);
            app.ExperimentNameEditFieldLabel_2.Text = 'Experiment Name';
            app.ExpEdit2 = uieditfield(app.LDSNoiseTab, 'text');
            app.ExpEdit2.Value = 'LDS_Pilot_11-29-2018';
            app.ExpButton2 = uibutton(app.LDSNoiseTab, 'push');
            app.ExpButton2.ButtonPushedFcn = createCallbackFcn(app, @ExpButton2Pushed, true);
            app.ExpButton2.Text = 'Load Experiment';
            app.LDSToneTab = uitab(app.TabGroup);
            app.LDSToneTab.Title = 'LDS Tone';
            app.TONEStimulusPanel = uipanel(app.LDSToneTab);
            app.TONEStimulusPanel.Title = 'Stimulus';
            app.TONETotLDSPulsesLabel = uilabel(app.TONEStimulusPanel);
            app.TONETotLDSPulsesLabel.Text = 'TotLDSPulses';
            app.TONELDSOnsetDelayLabel = uilabel(app.TONEStimulusPanel);
            app.TONELDSOnsetDelayLabel.Text = 'LDSOnsetDelay';
            app.TONESynapseValuesLabel = uilabel(app.TONEStimulusPanel);
            app.TONESynapseValuesLabel.Text = 'Synapse Values';
            app.TONELDSDurationLabel = uilabel(app.TONEStimulusPanel);
            app.TONELDSDurationLabel.Text = 'LDSDuration';
            app.TONELDSTimeBtwnPulsesLabel = uilabel(app.TONEStimulusPanel);
            app.TONELDSTimeBtwnPulsesLabel.Text = 'LDSTimeBtwnPulses';
            app.TONELevelLabel = uilabel(app.TONEStimulusPanel);
            app.TONELevelLabel.Text = 'Level';
            app.TONEToneFreqLabel = uilabel(app.TONEStimulusPanel);
            app.TONEToneFreqLabel.Text = 'ToneFreq';
            app.TONEModPercentageLabel = uilabel(app.TONEStimulusPanel);
            app.TONEModPercentageLabel.Text = 'ModPercentage';
            app.TONEModFrequencyLabel = uilabel(app.TONEStimulusPanel);
            app.TONEModFrequencyLabel.Text = 'ModFrequency';
            app.TONESineWaveExponentLabel = uilabel(app.TONEStimulusPanel);
            app.TONESineWaveExponentLabel.Text = 'SineWaveExponent';
            app.TotLDSPulsesLabel_2 = uilabel(app.TONEStimulusPanel);
            app.TotLDSPulsesLabel_2.Text = 'Tot LDS Pulses';
            app.TONETotLDSPulsesEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONETotLDSPulsesEdit.Value = 20;
            app.LDSOnsetDelaysecLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LDSOnsetDelaysecLabel_2.Text = 'LDS Onset Delay (sec)';
            app.TONELDSOnsetDelayEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELDSOnsetDelayEdit.Value = 20;
            app.LDSDurationsecLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LDSDurationsecLabel_2.Text = 'LDS Duration (sec)';
            app.TONELDSDurationEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELDSDurationEdit.Value = 20;
            app.LDSTimeBtwnPulsessecLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LDSTimeBtwnPulsessecLabel_2.Text = 'LDS Time Btwn Pulses (sec)';
            app.TONELDSTimeBtwnPulsesEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELDSTimeBtwnPulsesEdit.Value = 30;
            app.LeveldBLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LeveldBLabel_2.Text = 'Level (dB)';
            app.TONELevelEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELevelEdit.Value = 70;
            app.FrequencyHzLabel = uilabel(app.TONEStimulusPanel);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.TONEToneFreqEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONEToneFreqEdit.Value = 6000;
            app.ModulationEditFieldLabel_2 = uilabel(app.TONEStimulusPanel);
            app.ModulationEditFieldLabel_2.Text = 'Modulation %';
            app.TONEModPercentageEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.ModulationFreqHzEditFieldLabel_2 = uilabel(app.TONEStimulusPanel);
            app.ModulationFreqHzEditFieldLabel_2.Text = 'Modulation Freq (Hz)';
            app.TONEModFrequencyEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.SineExponentEditFieldLabel_2 = uilabel(app.TONEStimulusPanel);
            app.SineExponentEditFieldLabel_2.Text = 'Sine Exponent';
            app.TONESineWaveExponentEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONESineWaveExponentEdit.Value = 1;
            app.TONEGetButton = uibutton(app.TONEStimulusPanel, 'push');
            app.TONEGetButton.ButtonPushedFcn = createCallbackFcn(app, @TONEGetButtonPushed, true);
            app.TONEGetButton.Text = '<- Get ';
            app.TONESetButton = uibutton(app.TONEStimulusPanel, 'push');
            app.TONESetButton.ButtonPushedFcn = createCallbackFcn(app, @TONESetButtonPushed, true);
            app.TONESetButton.Text = 'Set ->';
            app.TONEStimAxes = uiaxes(app.TONEStimulusPanel);
            title(app.TONEStimAxes, 'Stim Preview')
            xlabel(app.TONEStimAxes, 'Time')
            ylabel(app.TONEStimAxes, 'Y')
            app.ExperimentNameEditFieldLabel_3 = uilabel(app.LDSToneTab);
            app.ExperimentNameEditFieldLabel_3.Text = 'Experiment Name';
            app.ExpEdit3 = uieditfield(app.LDSToneTab, 'text');
            app.ExpEdit3.Value = 'LDS_Tone_11-29-2018';
            app.ExpButton3 = uibutton(app.LDSToneTab, 'push');
            app.ExpButton3.ButtonPushedFcn = createCallbackFcn(app, @ExpButton3Pushed, true);
            app.ExpButton3.Text = 'Load Experiment';
            app.ReadTankTab = uitab(app.TabGroup);
            app.ReadTankTab.Title = 'ReadTank';
            app.SelectTankButton = uibutton(app.ReadTankTab, 'push');
            app.SelectTankButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTankButtonPushed, true);
            app.SelectTankButton.Text = 'Select Tank';
            app.LoadTankButton = uibutton(app.ReadTankTab, 'push');
            app.LoadTankButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTankButtonPushed, true);
            app.LoadTankButton.Text = 'Load Tank';
            app.TankEditFieldLabel = uilabel(app.ReadTankTab);
            app.TankEditFieldLabel.Text = 'Tank';
            app.TankEditField = uieditfield(app.ReadTankTab, 'text');
            app.ISILabel = uilabel(app.ReadTankTab);
            app.ISILabel.Text = '';
            app.CurrentModeLabel = uilabel(app.UIFigure);
            app.CurrentModeLabel.Text = 'Current Mode';
            app.IdleButton = uibutton(app.UIFigure, 'push');
            app.IdleButton.ButtonPushedFcn = createCallbackFcn(app, @IdleButtonPushed, true);
            app.IdleButton.Text = 'Idle';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.FiringRateTab = uitab(app.TabGroup2);
            app.FiringRateTab.Title = 'Firing Rate';
            app.rateAxes = uiaxes(app.FiringRateTab);
            title(app.rateAxes, 'Running Spike Rate')
            xlabel(app.rateAxes, 'Time')
            ylabel(app.rateAxes, 'Spike Rate')
            app.BinsizemsLabel_2 = uilabel(app.FiringRateTab);
            app.BinsizemsLabel_2.Text = 'Bin size (ms)';
            app.BinEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.BinEdit.Value = 500;
            app.HistorysecLabel = uilabel(app.FiringRateTab);
            app.HistorysecLabel.Text = 'History (sec)';
            app.RangeEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.RangeEdit.Value = 60;
            app.ratePopupCheck = uicheckbox(app.FiringRateTab);
            app.ratePopupCheck.Text = 'Popup';
            app.FreqTuningTab = uitab(app.TabGroup2);
            app.FreqTuningTab.Title = 'Freq Tuning';
            app.OffsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.OffsetEdit.Value = 100;
            app.EvokedratewindowmsLabel_4 = uilabel(app.FreqTuningTab);
            app.EvokedratewindowmsLabel_4.Text = 'Evoked rate window (ms)';
            app.OnsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.FTCAxes = uiaxes(app.FreqTuningTab);
            title(app.FTCAxes, 'IsoIntensity Function')
            xlabel(app.FTCAxes, 'Frequency')
            ylabel(app.FTCAxes, 'Channel')
            app.FTCPopupCheck = uicheckbox(app.FreqTuningTab);
            app.FTCPopupCheck.Text = 'Popup';
            app.PSTHTab = uitab(app.TabGroup2);
            app.PSTHTab.Title = 'PSTH';
            app.PSTH1Axes = uiaxes(app.PSTHTab);
            title(app.PSTH1Axes, 'PSTH 1')
            xlabel(app.PSTH1Axes, 'Time')
            ylabel(app.PSTH1Axes, 'Spike Rate')
            app.PSTHPopupCheck = uicheckbox(app.PSTHTab);
            app.PSTHPopupCheck.Text = 'Popup';
            app.BinsizemsLabel = uilabel(app.PSTHTab);
            app.BinsizemsLabel.Text = 'Bin size (ms)';
            app.PSTHBinEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTHBinEdit.Value = 500;
            app.PSTH2Axes = uiaxes(app.PSTHTab);
            title(app.PSTH2Axes, 'PSTH 2')
            xlabel(app.PSTH2Axes, 'Time')
            ylabel(app.PSTH2Axes, 'Spike Rate')
            app.PSTH1WindowsecEditFieldLabel = uilabel(app.PSTHTab);
            app.PSTH1WindowsecEditFieldLabel.Text = 'PSTH 1 Window (sec)';
            app.PSTH1StartEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH1EndEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH1EndEdit.Value = 1;
            app.Referencet0DropDownLabel = uilabel(app.PSTHTab);
            app.Referencet0DropDownLabel.Text = 'Reference (t=0)';
            app.PSTH1RefDrop = uidropdown(app.PSTHTab);
            app.PSTH1RefDrop.Items = {'Onset', 'Offset'};
            app.PSTH1RefDrop.Value = 'Onset';
            app.Referencet0DropDown_2Label = uilabel(app.PSTHTab);
            app.Referencet0DropDown_2Label.Text = 'Reference (t=0)';
            app.PSTH2RefDrop = uidropdown(app.PSTHTab);
            app.PSTH2RefDrop.Items = {'Onset', 'Offset'};
            app.PSTH2RefDrop.Value = 'Offset';
            app.PSTH2EndEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH2EndEdit.Value = 1;
            app.PSTH2WindowsecEditFieldLabel = uilabel(app.PSTHTab);
            app.PSTH2WindowsecEditFieldLabel.Text = 'PSTH 2 Window (sec)';
            app.PSTH2StartEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PollingperiodsecEditFieldLabel = uilabel(app.UIFigure);
            app.PollingperiodsecEditFieldLabel.Text = 'Polling period (sec)';
            app.UpdateEdit = uieditfield(app.UIFigure, 'numeric');
            app.UpdateEdit.Value = 1;
            app.ExpDurationRadio = uibuttongroup(app.UIFigure);
            app.ExpDurationRadio.Title = 'Set to Idle After...';
            app.InfiniteButton = uiradiobutton(app.ExpDurationRadio);
            app.InfiniteButton.Text = 'Infinite';
            app.InfiniteButton.Value = true;
            app.SecondsButton = uiradiobutton(app.ExpDurationRadio);
            app.SecondsButton.Text = 'Seconds';
            app.PulsesButton = uiradiobutton(app.ExpDurationRadio);
            app.PulsesButton.Text = 'Pulses';
            app.ExpDurationEdit = uieditfield(app.UIFigure, 'numeric');
            app.ExpDurationEdit.Value = 60;
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.CheckBox_1 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_18 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_19 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_20 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_21 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_22 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_23 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_24 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_25 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_26 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_27 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_28 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_29 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_30 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_31 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_32 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_32.Text = '32';
            app.Chan116Button = uibutton(app.ChannelsPanel, 'state');
            app.Chan116Button.ValueChangedFcn = createCallbackFcn(app, @Chan116ButtonValueChanged, true);
            app.Chan116Button.Text = 'Clear 1-16';
            app.Chan116Button.Value = true;
            app.Chan1732Button = uibutton(app.ChannelsPanel, 'state');
            app.Chan1732Button.ValueChangedFcn = createCallbackFcn(app, @Chan1732ButtonValueChanged, true);
            app.Chan1732Button.Text = 'Check 17-32';
        end
    end

    methods (Access = public)

        % Construct app
        function app = synui_2018_11_29

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end