classdef synui_20190128 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewButton                   matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        DialogueLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        IsoIntensityTab                 matlab.ui.container.Tab
        IsoStimulusPanel                matlab.ui.container.Panel
        IsoStimHiTimeLabel              matlab.ui.control.Label
        IsoStimLoTimeLabel              matlab.ui.control.Label
        SynapseValuesLabel              matlab.ui.control.Label
        IsoStimNPulsesLabel             matlab.ui.control.Label
        IsoGateLoTimeLabel              matlab.ui.control.Label
        IsoGateNPulsesLabel             matlab.ui.control.Label
        IsoLevelLabel                   matlab.ui.control.Label
        StimHiTimemsLabel               matlab.ui.control.Label
        IsoStimHiTimeEdit               matlab.ui.control.NumericEditField
        StimLoTimemsLabel               matlab.ui.control.Label
        IsoStimLoTimeEdit               matlab.ui.control.NumericEditField
        StimNPulsesLabel                matlab.ui.control.Label
        IsoStimNPulsesEdit              matlab.ui.control.NumericEditField
        GateLoTimemsLabel_2             matlab.ui.control.Label
        IsoGateLoTimeEdit               matlab.ui.control.NumericEditField
        GateNPulsesLabel                matlab.ui.control.Label
        IsoGateNPulsesEdit              matlab.ui.control.NumericEditField
        LeveldBEditFieldLabel           matlab.ui.control.Label
        IsoLevelEdit                    matlab.ui.control.NumericEditField
        IsoGetButton                    matlab.ui.control.Button
        IsoSetButton                    matlab.ui.control.Button
        IsoStimAxes                     matlab.ui.control.UIAxes
        FRATab                          matlab.ui.container.Tab
        FRAStimulusPanel                matlab.ui.container.Panel
        FRAGateHiTimeLabel              matlab.ui.control.Label
        FRAGateLoTimeLabel              matlab.ui.control.Label
        SynapseValuesLabel_3            matlab.ui.control.Label
        FRAGateNPulsesLabel             matlab.ui.control.Label
        GateHiTimemsLabel               matlab.ui.control.Label
        FRAGateHiTimeEdit               matlab.ui.control.NumericEditField
        GateLoTimemsLabel_4             matlab.ui.control.Label
        FRAGateLoTimeEdit               matlab.ui.control.NumericEditField
        GateNPulsesLabel_3              matlab.ui.control.Label
        FRAGateNPulsesEdit              matlab.ui.control.NumericEditField
        FRAGetButton                    matlab.ui.control.Button
        FRASetButton                    matlab.ui.control.Button
        FRAStimAxes                     matlab.ui.control.UIAxes
        LDSNoiseTab                     matlab.ui.container.Tab
        NBNStimulusPanel                matlab.ui.container.Panel
        NBNTotLDSPulsesLabel            matlab.ui.control.Label
        NBNLDSOnsetDelayLabel           matlab.ui.control.Label
        SynapseValuesLabel_2            matlab.ui.control.Label
        NBNLDSDurationLabel             matlab.ui.control.Label
        NBNLDSTimeBtwnPulsesLabel       matlab.ui.control.Label
        NBNLevelLabel                   matlab.ui.control.Label
        NBNHPCFLabel                    matlab.ui.control.Label
        TotLDSPulsesLabel               matlab.ui.control.Label
        NBNTotLDSPulsesEdit             matlab.ui.control.NumericEditField
        LDSOnsetDelaysecLabel           matlab.ui.control.Label
        NBNLDSOnsetDelayEdit            matlab.ui.control.NumericEditField
        LDSDurationsecLabel             matlab.ui.control.Label
        NBNLDSDurationEdit              matlab.ui.control.NumericEditField
        LDSTimeBtwnPulsessecLabel       matlab.ui.control.Label
        NBNLDSTimeBtwnPulsesEdit        matlab.ui.control.NumericEditField
        LeveldBLabel                    matlab.ui.control.Label
        NBNLevelEdit                    matlab.ui.control.NumericEditField
        HipassHzLabel                   matlab.ui.control.Label
        NBNHPCFEdit                     matlab.ui.control.NumericEditField
        LopassHzEditFieldLabel          matlab.ui.control.Label
        NBNLPCFEdit                     matlab.ui.control.NumericEditField
        ModulationEditFieldLabel        matlab.ui.control.Label
        NBNModPercentageEdit            matlab.ui.control.NumericEditField
        ModulationFreqHzEditFieldLabel  matlab.ui.control.Label
        NBNModFrequencyEdit             matlab.ui.control.NumericEditField
        SineExponentEditFieldLabel      matlab.ui.control.Label
        NBNSineWaveExponentEdit         matlab.ui.control.NumericEditField
        NBNLPCFLabel                    matlab.ui.control.Label
        NBNModPercentageLabel           matlab.ui.control.Label
        NBNModFrequencyLabel            matlab.ui.control.Label
        NBNSineWaveExponentLabel        matlab.ui.control.Label
        NBNGetButton                    matlab.ui.control.Button
        NBNSetButton                    matlab.ui.control.Button
        NBNStimAxes                     matlab.ui.control.UIAxes
        CenterFreqHzLabel               matlab.ui.control.Label
        NBNCFEdit                       matlab.ui.control.NumericEditField
        BandwidthOctLabel               matlab.ui.control.Label
        NBNBWEdit                       matlab.ui.control.NumericEditField
        LDSToneTab                      matlab.ui.container.Tab
        TONEStimulusPanel               matlab.ui.container.Panel
        TONETotLDSPulsesLabel           matlab.ui.control.Label
        TONELDSOnsetDelayLabel          matlab.ui.control.Label
        TONESynapseValuesLabel          matlab.ui.control.Label
        TONELDSDurationLabel            matlab.ui.control.Label
        TONELDSTimeBtwnPulsesLabel      matlab.ui.control.Label
        TONELevelLabel                  matlab.ui.control.Label
        TONEToneFreqLabel               matlab.ui.control.Label
        TONEModPercentageLabel          matlab.ui.control.Label
        TONEModFrequencyLabel           matlab.ui.control.Label
        TONESineWaveExponentLabel       matlab.ui.control.Label
        TotLDSPulsesLabel_2             matlab.ui.control.Label
        TONETotLDSPulsesEdit            matlab.ui.control.NumericEditField
        LDSOnsetDelaysecLabel_2         matlab.ui.control.Label
        TONELDSOnsetDelayEdit           matlab.ui.control.NumericEditField
        LDSDurationsecLabel_2           matlab.ui.control.Label
        TONELDSDurationEdit             matlab.ui.control.NumericEditField
        LDSTimeBtwnPulsessecLabel_2     matlab.ui.control.Label
        TONELDSTimeBtwnPulsesEdit       matlab.ui.control.NumericEditField
        LeveldBLabel_2                  matlab.ui.control.Label
        TONELevelEdit                   matlab.ui.control.NumericEditField
        FrequencyHzLabel                matlab.ui.control.Label
        TONEToneFreqEdit                matlab.ui.control.NumericEditField
        ModulationEditFieldLabel_2      matlab.ui.control.Label
        TONEModPercentageEdit           matlab.ui.control.NumericEditField
        ModulationFreqHzEditFieldLabel_2  matlab.ui.control.Label
        TONEModFrequencyEdit            matlab.ui.control.NumericEditField
        SineExponentEditFieldLabel_2    matlab.ui.control.Label
        TONESineWaveExponentEdit        matlab.ui.control.NumericEditField
        TONEGetButton                   matlab.ui.control.Button
        TONESetButton                   matlab.ui.control.Button
        TONEStimAxes                    matlab.ui.control.UIAxes
        LDSEvokeTab                     matlab.ui.container.Tab
        EVOStimulusPanel                matlab.ui.container.Panel
        EVOEvokeStimHiLabel             matlab.ui.control.Label
        EVOEvokeStimLoLabel             matlab.ui.control.Label
        EVOEvokeNPulsesLabel            matlab.ui.control.Label
        EVOEvokeFrequencyLabel          matlab.ui.control.Label
        EVOEvokeLevelLabel              matlab.ui.control.Label
        EVOLDSDurationLabel             matlab.ui.control.Label
        EVOLDSFrequencyLabel            matlab.ui.control.Label
        EVOLDSLevelLabel                matlab.ui.control.Label
        EVONCycleRepeatsLabel           matlab.ui.control.Label
        EVOGetButton                    matlab.ui.control.Button
        EVOSetButton                    matlab.ui.control.Button
        EVOStimAxes                     matlab.ui.control.UIAxes
        EvokeStimHimsLabel              matlab.ui.control.Label
        EVOEvokeStimHiEdit              matlab.ui.control.NumericEditField
        EvokeStimLomsLabel              matlab.ui.control.Label
        EVOEvokeStimLoEdit              matlab.ui.control.NumericEditField
        EvokePulsesLabel                matlab.ui.control.Label
        EVOEvokeNPulsesEdit             matlab.ui.control.NumericEditField
        EvokeFrequencyHzLabel           matlab.ui.control.Label
        EVOEvokeFrequencyEdit           matlab.ui.control.NumericEditField
        EvokeLeveldBLabel               matlab.ui.control.Label
        EVOEvokeLevelEdit               matlab.ui.control.NumericEditField
        LDSDurationLabel                matlab.ui.control.Label
        EVOLDSDurationEdit              matlab.ui.control.NumericEditField
        LDSFrequencyLabel               matlab.ui.control.Label
        EVOLDSFrequencyEdit             matlab.ui.control.NumericEditField
        LDSLeveldBLabel                 matlab.ui.control.Label
        EVOLDSLevelEdit                 matlab.ui.control.NumericEditField
        CycleRepeatsLabel               matlab.ui.control.Label
        EVONCycleRepeatsEdit            matlab.ui.control.NumericEditField
        ReadTankTab                     matlab.ui.container.Tab
        SelectTankButton                matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        TankEditFieldLabel              matlab.ui.control.Label
        TankEditField                   matlab.ui.control.EditField
        ParamsLabel                     matlab.ui.control.Label
        ParamsLabel2                    matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        FilenameprefixEditFieldLabel    matlab.ui.control.Label
        PrefixEdit                      matlab.ui.control.EditField
        CurrentModeLabel                matlab.ui.control.Label
        IdleButton                      matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        FiringRateTab                   matlab.ui.container.Tab
        rateAxes                        matlab.ui.control.UIAxes
        BinsizemsLabel_2                matlab.ui.control.Label
        BinEdit                         matlab.ui.control.NumericEditField
        HistorysecLabel                 matlab.ui.control.Label
        RangeEdit                       matlab.ui.control.NumericEditField
        ratePopupCheck                  matlab.ui.control.CheckBox
        fratescaleEdit                  matlab.ui.control.NumericEditField
        yscale0maxobservedLabel         matlab.ui.control.Label
        frateTable                      matlab.ui.control.Table
        FreqTuningTab                   matlab.ui.container.Tab
        OffsetEdit                      matlab.ui.control.NumericEditField
        EvokedratewindowmsLabel_4       matlab.ui.control.Label
        OnsetEdit                       matlab.ui.control.NumericEditField
        FTCAxes                         matlab.ui.control.UIAxes
        FTCPopupCheck                   matlab.ui.control.CheckBox
        PSTHTab                         matlab.ui.container.Tab
        PSTH1Axes                       matlab.ui.control.UIAxes
        PSTHPopupCheck                  matlab.ui.control.CheckBox
        BinsizemsLabel                  matlab.ui.control.Label
        PSTHBinEdit                     matlab.ui.control.NumericEditField
        PSTH2Axes                       matlab.ui.control.UIAxes
        PSTH1WindowsecEditFieldLabel    matlab.ui.control.Label
        PSTH1StartEdit                  matlab.ui.control.NumericEditField
        PSTH1EndEdit                    matlab.ui.control.NumericEditField
        Referencet0DropDownLabel        matlab.ui.control.Label
        PSTH1RefDrop                    matlab.ui.control.DropDown
        Referencet0DropDown_2Label      matlab.ui.control.Label
        PSTH2RefDrop                    matlab.ui.control.DropDown
        PSTH2EndEdit                    matlab.ui.control.NumericEditField
        PSTH2WindowsecEditFieldLabel    matlab.ui.control.Label
        PSTH2StartEdit                  matlab.ui.control.NumericEditField
        PSTHscaleradio                  matlab.ui.container.ButtonGroup
        IndependentButton               matlab.ui.control.RadioButton
        xPSTHsButton                    matlab.ui.control.RadioButton
        xAllButton                      matlab.ui.control.RadioButton
        PSTHscaleEdit                   matlab.ui.control.NumericEditField
        FrequencyDropDownLabel          matlab.ui.control.Label
        FrequencyDrop                   matlab.ui.control.DropDown
        LevelLabel                      matlab.ui.control.Label
        LevelDrop                       matlab.ui.control.DropDown
        PollingperiodsecEditFieldLabel  matlab.ui.control.Label
        UpdateEdit                      matlab.ui.control.NumericEditField
        ChannelsPanel                   matlab.ui.container.Panel
        CheckBox_1                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_21                     matlab.ui.control.CheckBox
        CheckBox_22                     matlab.ui.control.CheckBox
        CheckBox_23                     matlab.ui.control.CheckBox
        CheckBox_24                     matlab.ui.control.CheckBox
        CheckBox_25                     matlab.ui.control.CheckBox
        CheckBox_26                     matlab.ui.control.CheckBox
        CheckBox_27                     matlab.ui.control.CheckBox
        CheckBox_28                     matlab.ui.control.CheckBox
        CheckBox_29                     matlab.ui.control.CheckBox
        CheckBox_30                     matlab.ui.control.CheckBox
        CheckBox_31                     matlab.ui.control.CheckBox
        CheckBox_32                     matlab.ui.control.CheckBox
        Chan116Button                   matlab.ui.control.StateButton
        Chan1732Button                  matlab.ui.control.StateButton
        ClustsCheck                     matlab.ui.control.CheckBox
        ExperimentDrop                  matlab.ui.control.DropDown
        ExpButton1                      matlab.ui.control.Button
        ExpDurationRadio                matlab.ui.container.ButtonGroup
        InfiniteButton                  matlab.ui.control.RadioButton
        LastoffsetnsecButton            matlab.ui.control.RadioButton
        SecondsButton                   matlab.ui.control.RadioButton
        StimDurLabel                    matlab.ui.control.Label
        TotalStimDurLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ExpDurationEdit                 matlab.ui.control.NumericEditField
        LoadThresholdsButton            matlab.ui.control.Button
        BlockEditFieldLabel             matlab.ui.control.Label
        BlockEdit                       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        syn = [];           % links to Synapse
        experiment = [];    % 1 Iso Intensity, 2 LDS Noise, 3 LDS Tone
        pars = [];          % Parameters
        gizmos = [];        % Gizmos
        data = [];
    end

    methods (Access = private)
%Updates the textbox that displays the Synapse Mode
        function updatemode(app) 
            switch app.syn.getMode
                case 0
                    app.CurrentModeLabel.Text = 'Idle';
                case 1
                    app.CurrentModeLabel.Text = 'Standby';
                case 2
                    app.CurrentModeLabel.Text = 'Preview';
                case 3
                    app.CurrentModeLabel.Text = 'Recording';
            end
        end
    
%Reads parameters stored in synapse        
        function updatepars(app) 
            if app.syn.getMode == 0
                app.syn.setMode(1);
            end
            app.IsoStimulusPanel.Visible = 0;
            app.NBNStimulusPanel.Visible = 0;
            app.TONEStimulusPanel.Visible = 0;
            app.FRAStimulusPanel.Visible = 0;
            app.EVOStimulusPanel.Visible = 0;

            switch app.experiment
                case 1
                    app.pars = {'StimHiTime'; 'StimLoTime'; 'StimNPulses'; 'GateLoTime'; 'GateNPulses'; 'Level'};
                    app.gizmos = {'Stimulus'; 'Stimulus'; 'Stimulus'; 'Stimulus'; 'Stimulus'; 'Intensity'};
                    prefix = 'Iso';
                case 2
                    app.pars = {'TotLDSPulses'; 'LDSOnsetDelay'; 'LDSDuration'; 'LDSTimeBtwnPulses'; 'Level'; 'HPCF'; 'LPCF'; ...
                        'ModPercentage'; 'ModFrequency'; 'SineWaveExponent'};
                    app.gizmos = {'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; ...
                        'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'};
                    prefix = 'NBN';
                case 3
                    app.pars = {'TotLDSPulses'; 'LDSOnsetDelay'; 'LDSDuration'; 'LDSTimeBtwnPulses'; 'Level'; 'ToneFreq'; ...
                        'ModPercentage'; 'ModFrequency'; 'SineWaveExponent'};
                    app.gizmos = {'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; ...
                        'LDS_tones'; 'LDS_tones'};
                    prefix = 'TONE';
                case 4
                    app.pars = {'GateHiTime'; 'GateLoTime'; 'GateNPulses'};
                    app.gizmos = {'FRA'; 'FRA'; 'FRA'};
                    prefix = 'FRA';
                case 5
                    app.pars = {'EvokeStimHi'; 'EvokeStimLo'; 'EvokeNPulses'; 'EvokeFrequency'; 'EvokeLevel'; 'LDSDuration'; 'LDSFrequency'; 'LDSLevel'; 'NCycleRepeats'};
                    app.gizmos = {'LDSe'; 'LDSe'; 'LDSe'; 'LDSe'; 'LDSe'; 'LDSe'; 'LDSe'; 'LDSe'; 'LDSe'};
                    prefix = 'EVO';
            end
            for par = 1:numel(app.pars)
                mvarname = [prefix char(app.pars(par)) 'Label'];
                app.(mvarname).Text = num2str(app.syn.getParameterValue(char(app.gizmos(par)), char(app.pars(par))));
            end
            switch app.experiment
                case 1
                    hitime = str2double(app.IsoStimHiTimeLabel.Text);
                    lotime = str2double(app.IsoStimLoTimeLabel.Text);
                    period = hitime + lotime;
                    stimons = 0:period:period*(str2double(app.IsoStimNPulsesLabel.Text)-1);
                    stimoffs = stimons + hitime;
                    trainperiod =  stimoffs(end)+ lotime + str2double(app.IsoGateLoTimeLabel.Text);
                    train = reshape([stimons; stimoffs], 1, numel([stimons; stimoffs]));
                    timemat = repmat(train, str2double(app.IsoGateNPulsesLabel.Text), 1) + (0:trainperiod:trainperiod*(str2double(app.IsoGateNPulsesLabel.Text)-1))';
                    timevec = reshape(timemat', 1, numel(timemat));
                    timevec2 = reshape([timevec; timevec], 1, numel(timevec)*2);
                    yval = repmat([0 1 1 0], 1, numel(timevec2)/4);
                    plot(app.IsoStimAxes, timevec2/1000, yval, 'k-')
                    maxdur = max(timevec2/1000);
                case 2
                    period = str2double(app.NBNLDSDurationLabel.Text) + str2double(app.NBNLDSTimeBtwnPulsesLabel.Text);
                    del = str2double(app.NBNLDSOnsetDelayLabel.Text);
                    stimons = del:period:(del + period*(str2double(app.NBNTotLDSPulsesLabel.Text))-1);
                    stimoffs = stimons + str2double(app.NBNLDSDurationLabel.Text);
                    timevec = reshape([stimons; stimoffs], 1, numel([stimons; stimoffs]));
                    timevec2 = reshape([timevec; timevec], 1, numel(timevec)*2);
                    yval = repmat([0 1 1 0], 1, numel(timevec2)/4);
                    plot(app.NBNStimAxes, timevec2, yval, 'k-')
                    maxdur = max(timevec2);
                case 3
                    period = str2double(app.TONELDSDurationLabel.Text) + str2double(app.TONELDSTimeBtwnPulsesLabel.Text);
                    del = str2double(app.TONELDSOnsetDelayLabel.Text);
                    stimons = del:period:(del + period*(str2double(app.TONETotLDSPulsesLabel.Text))-1);
                    stimoffs = stimons + str2double(app.TONELDSDurationLabel.Text);
                    timevec = reshape([stimons; stimoffs], 1, numel([stimons; stimoffs]));
                    timevec2 = reshape([timevec; timevec], 1, numel(timevec)*2);
                    yval = repmat([0 1 1 0], 1, numel(timevec2)/4);
                    plot(app.TONEStimAxes, timevec2/60, yval, 'k-')
                    maxdur = max(timevec2);
                case 4
                    period = str2double(app.FRAGateHiTimeLabel.Text) + str2double(app.FRAGateLoTimeLabel.Text);
                    stimons = 0:period:(period*(str2double(app.FRAGateNPulsesLabel.Text))-1);
                    stimoffs = stimons + str2double(app.FRAGateHiTimeLabel.Text);
                    timevec = reshape([stimons; stimoffs], 1, numel([stimons; stimoffs]));
                    timevec2 = reshape([timevec; timevec], 1, numel(timevec)*2)./1000;
                    yval = repmat([0 1 1 0], 1, numel(timevec2)/4);
                    plot(app.FRAStimAxes, timevec2/60, yval, 'k-')
                    maxdur = max(timevec2);
                case 5
                    period = str2double(app.EVOEvokeStimHiLabel.Text) + str2double(app.EVOEvokeStimLoLabel.Text);
                    stimons = 0:period:(period*(str2double(app.EVOEvokeNPulsesLabel.Text))-1);
                    stimoffs = stimons + str2double(app.EVOEvokeStimHiLabel.Text);
                    timevec = reshape([stimons; stimoffs], 1, numel([stimons; stimoffs]));
                    t1 = period*str2double(app.EVOEvokeNPulsesLabel.Text)./1000;
                    t2 = str2double(app.EVOLDSDurationLabel.Text);
                    nrepeats = str2double(app.EVONCycleRepeatsLabel.Text);
                    timevec2 = reshape([timevec; timevec], numel(timevec)*2, 1)./1000;
                    timevec3 = repmat([timevec2; t1; t1; t1+t2; t1+t2], 1, nrepeats);
                    cycleperiod = max(max(timevec3));
                    timevec4 = [reshape(timevec3 + cycleperiod .* ((1:nrepeats)-1), 1, numel(timevec3)), timevec2' + cycleperiod*nrepeats];
                    yval = repmat([0 1 1 0], 1, numel(timevec4)/4);
                    plot(app.EVOStimAxes, timevec4, yval, 'k-')
                    maxdur = max(timevec4);
            end
            stimpanel = [prefix 'StimulusPanel'];
            app.(stimpanel).Visible = 1;
            app.ExpDurationRadio.Visible = 1;
            app.StimDurLabel.Text = datestr(seconds(maxdur), 'MM:SS');
        end


%Streams data from synapse to MATLAB during preview and recording
        function livestreamapp(app, mode) 
        app.DialogueLabel.Text = 'Beginning Experiment';
        updatepars(app);
        chanlist = updatechans(app);
        spikevar = 'eSpk';
        wavevar = 'RAW1';
        stimvar = 'Freq';
        offvar = 'StOn';
        
        app.DialogueLabel.Text = 'Connecting to SynapseLive';
        
        if app.syn.getMode() == 0
            c = clock();
            d = [num2str(mod(c(1), 100)) sprintf('%02d', c(2)), sprintf('%02d', c(3))];
            t = [sprintf('%02d', c(4)) sprintf('%02d', c(5))];
            blockname = [sprintf('%03d', app.BlockEdit.Value) '-' app.syn.getCurrentExperiment() '-' d '-' t];
            app.syn.setCurrentBlock(blockname);
        end

        exp = SynapseLive('MODE', mode, 'EXPERIMENT', app.ExperimentDrop.Value);
        updaterate = app.UpdateEdit.Value;
        binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
        updatemode(app);
        if updaterate<2*binwindow; pause(2*binwindow); end
        
        spets = [];
        channels = [];
        stimons = [];
        stimoffs = [];
        frqs = [];
        lvls = [];
        currtime = [];
        parmat = [];
        app.DialogueLabel.Text = 'Streaming Data';
        loopc = 1;
        loopcon = 1;
        while loopcon
            pause(updaterate)

            if app.syn.getMode() < 2 %stops if synapse is set to idle or standby
                break
            end
            new = exp.update;
            try 
            if isempty(new)
                break
            end
            
        %read UI data  
            updaterate = app.UpdateEdit.Value;
            binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
            ratewin = app.RangeEdit.Value;
            analwin = [app.OnsetEdit.Value app.OffsetEdit.Value]./1000; %ftc analysis window;
            chanlist = updatechans(app);
            

        %read continuously updated data
            currtime = [currtime; app.syn.getParameterValue('ReadTime', 'Time')];
            currpars = NaN(size(app.pars));
            for par = 1:numel(app.pars)
                currpars(par) = app.syn.getParameterValue(char(app.gizmos(par)), char(app.pars(par)));
            end
            parmat = [parmat; currpars'];
            
            if isfield(new.data.snips, spikevar)
                spets = [spets; new.data.snips.(spikevar).ts];
                channels = [channels; new.data.snips.(spikevar).chan];
            end

            nthreshs = sum(cell2mat(regexp(app.syn.getParameterNames('Neu1'), 'Threshold')));
            threshlist = zeros(nthreshs,1);
            for i = 1:nthreshs
                threshlist(i,1) = app.syn.getParameterValue('Neu1', ['Threshold' num2str(i)]);
            end

        %read triggered data
            switch app.experiment
                case {2 3 5}
                    if ~sum(isnan(new.data.epocs.(offvar).data))
                        stimons = [stimons; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data == 1)];
                        frqs = zeros(numel(stimons), 1);
                        stimoffs = [stimoffs; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data == 0)];
                    end
                case 1
                    if ~sum(isnan(new.data.epocs.(stimvar).onset))
                        frqs = [frqs; new.data.epocs.(stimvar).data];
                    end
                    if ~sum(isnan(new.data.epocs.(offvar).onset))
                        stimons = [stimons; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data==1)];
                        stimoffs = [stimoffs; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data==0)];
                    end
                case 4
                    if ~sum(isnan(new.data.epocs.(offvar).onset))
                        frqs = [frqs; new.data.epocs.Stim.data];
                        lvls = [lvls; new.data.epocs.Stmb.data];
                        stimons = [stimons; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data==1)];
                        stimoffs = [stimoffs; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data==0)];
                    end
            end
            if ismember('Level', app.pars)
                lvlparind = find(strcmpi(app.pars, 'Level'));
                lvls = [lvls; app.syn.getParameterValue(char(app.gizmos(lvlparind)), 'Level').* ones(size(stimons))];
            end
            if ismember('EvokeLevel', app.pars)
                lvlparind = find(strcmpi(app.pars, 'EvokeLevel'));
                lvls = [lvls; app.syn.getParameterValue(char(app.gizmos(lvlparind)), 'EvokeLevel').* ones(size(stimons))];
            end
            endtext = '';
            if app.LastoffsetnsecButton.Value
                x = app.StimDurLabel.Text;
                y = datestr(seconds(0), 'MM:SS');
                stimdur = seconds(datetime(x, 'InputFormat', 'mm:ss')-datetime(y, 'InputFormat', 'mm:ss'));
                endtime = stimdur + app.ExpDurationEdit.Value;
                endtext = ['(' datestr(seconds(endtime-currtime(end)),'MM:SS') ' remaining) '];
            end
            if app.SecondsButton.Value
                endtime = app.ExpDurationEdit.Value;
                endtext = ['(' datestr(seconds(endtime-currtime(end)),'MM:SS') ' remaining)'];
            end
            
            app.DialogueLabel.Text = ['Streaming Data', newline, datestr(seconds(currtime(end)),'MM:SS'), ' ' ...
                endtext, num2str(loopc) ' polls, ' num2str(numel(stimons)) ' trials' ];
            
        %running spike rate average
            if numel(chanlist)>0
                bincount = NaN(round(currtime(end)/binwindow), max(chanlist));
                for chan = chanlist'
                    [bincount(:,chan), bintime] = hist(spets(channels==chan), 0.5*binwindow:binwindow:currtime(end));
                end
                if app.fratescaleEdit.Value == 0
                    yscale = max(max(bincount));
                else
                    yscale = app.fratescaleEdit.Value*binwindow;
                end
                frate = bincount./yscale - (1:max(chanlist));
            app.frateTable.Data = [(1:max(chanlist))', max(bincount)'./binwindow];
            app.frateTable.ColumnName = {'Channel', 'Max rate'};
            else
                frate = NaN;
                bintime = NaN;
            end
            
            plotfrate(app, frate, bintime, stimons, stimoffs, chanlist)
             xlim(app.rateAxes, [max([0 currtime(end)-ratewin]), max([1 currtime(end)])]);                

        %tuning curve
             if numel(stimons)>0
%                 nstims = min([numel(stimons); numel(frqs)]);
%                 [spetfreq, stimspets, trials] = freqanal(spets, stimons(1:nstims), frqs(1:nstims));
                plottrials = min([numel(stimons) numel(frqs) numel(lvls)]);
                [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(spets, stimons(1:plottrials), frqs(1:plottrials), lvls(1:plottrials));
                if app.experiment == 1 & numel(unique(frqs))>1
                    synftc(stimspets, spetfreq, analwin, channels, trials, max(chanlist), frqs, app.FTCAxes)
                end
                if app.experiment == 4 & numel(unique(frqs))>1
                    plottrials = min([numel(stimons) numel(frqs) numel(lvls)]);
                    [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(spets, stimons(1:plottrials), frqs(1:plottrials), lvls(1:plottrials));
                    multifra(stimspets, spetfreq, spetlevel, analwin, channels, trials, chanlist, frqs(1:plottrials), lvls(1:plottrials), app.FTCAxes)
                end
             end

        loopc = loopc + 1;
        if (~app.InfiniteButton.Value) && (currtime(end) > endtime)
            
                loopcon = 0;
                app.syn.setMode(0);
                app.IsoStimulusPanel.Visible = 0; app.NBNStimulusPanel.Visible = 0; app.TONEStimulusPanel.Visible = 0; 
                app.FRAStimulusPanel.Visible = 0; app.ExpDurationRadio.Visible = 0;
                updatemode(app);
        end
            catch ME
                ME
                ME.stack(1)
                ME.stack(2)
                frqs
            end
        end %while

        metext = 'Ready.';
        if exist('ME', 'var')
            metext = 'A MATLAB Error was thrown :(';
        end
        app.DialogueLabel.Text = ['Data streaming ended. ' metext];
            
        if strcmpi(mode, 'Record')
            [changesamp, ~]  = find(diff(parmat));
            changes = sort(unique(changesamp))+1;
            fulltable = array2table([currtime parmat], 'VariableNames', [{'Time'}; app.pars]);
            changearray = [[currtime(1), parmat(1,:)]; [currtime(changes),  parmat(changes,:)]];
            changetable = array2table(changearray, 'VariableNames', [{'Time'}; app.pars]);
            
            flist = dir(app.syn.getCurrentTank);
            folders = zeros(size(flist));
            dates = zeros(size(flist));
            for file = 3:numel(flist)
                folders(file) = flist(file).isdir;
                dates(file) = flist(file).datenum;
            end
            subind= find(folders);
             [~, i]=max(dates(subind));
             currfolder = [flist(subind(i)).folder '\' flist(subind(i)).name];
             save([currfolder '\params.mat'], 'fulltable', 'changetable', 'threshlist')
             app.BlockEdit.Value = app.BlockEdit.Value + 1;
        end
            
            
        end %function livestreamapp
        
        function commongetbutton(app, prefix)
            for par = 1:numel(app.pars)
                mvarname = [prefix char(app.pars(par)) 'Edit'];
                mvarname2 = [prefix char(app.pars(par)) 'Label'];
                app.(mvarname).Value= str2double(app.(mvarname2).Text);
            end

        end
        
        function commonsetbutton(app, prefix)
            for par = 1:numel(app.pars)
                mvarname = [prefix char(app.pars(par)) 'Edit'];
                app.syn.setParameterValue(char(app.gizmos(par)), char(app.pars(par)), app.(mvarname).Value);
            end
            if strcmpi(prefix, 'iso')
                app.syn.setParameterValue('Stimulus', 'GateHiTime', app.IsoStimNPulsesEdit.Value*(app.IsoStimHiTimeEdit.Value + app.IsoStimLoTimeEdit.Value));
            end
            pause(1);
            updatepars(app);

        end
        
        
        function plotfrate(app, frate, bintime, stimons, stimoffs, chanlist)
                if app.ratePopupCheck.Value
                    figure(1); clf;
                    ax = axes();
                else
                    ax = app.rateAxes;
                end
                if numel(stimons>0)
                    if numel(stimoffs)<numel(stimons)
                        stimp = [stimons [stimoffs; max(stimons)]];
                    else
                        stimp = [stimons stimoffs];
                    end
                end
                plot(ax, 0,0)
                hold(ax, 'on');
                for stim = 1:size(stimp, 1)
                    rectangle(ax, 'Position', [stimp(stim, 1) -100  stimp(stim, 2)-stimp(stim, 1) 100], 'FaceColor', .8*[1 1 1], 'EdgeColor', .8*[1 1 1])
                end
                plot(ax, bintime, frate)
%                     plot(ax, stimp', [0 0], 'r-', 'LineWidth', 4);
                hold(ax, 'off');
                xlabel(ax, 'Time (sec)')
                ylabel(ax, 'Channel')
                ylim(ax, -1.*[chanlist(end)+.5 chanlist(1)-1])
                title(ax, [app.TankEditField.Value newline num2str(app.BinEdit.Value) ' msec bins'])
        end
        
        function chanlist = updatechans(app)
            chanvec = zeros(32,1);
            for chan = 1:32
                chanfield = ['CheckBox_' num2str(chan)];
                if app.(chanfield).Value
                    chanvec(chan) = 1;
                end
            end
            chanlist = find(chanvec);
        end
        
        function out = readtank(app)
            data=TDTbin2mat(app.TankEditField.Value);
            out.chanlist = updatechans(app);
            out.nchans = max(out.chanlist);
            spikevar = 'eSpk';
            wavevar = 'RAW1';
            stimvar = 'Freq';
            stimvar2 = 'Stmb';
            stimvar3 = 'Stim';
            offvar = 'StOn';
            
%           out.spets = data.snips.(spikevar).ts+.3; %FRAs collected before 1/7/2019 skipped the first stimulation
            out.spets = data.snips.(spikevar).ts;
            out.channels = double(data.snips.(spikevar).chan);
            clusters = double(data.snips.(spikevar).sortcode);
            clusterlist = sort(unique(clusters));
            for clusteri = 1:numel(clusterlist)
                clusters(clusters == clusterlist(clusteri)) = clusteri;
            end
            out.clusters = double(clusters);
            out.stimons = data.epocs.(offvar).onset(data.epocs.(offvar).data == 1);
            out.stimoffs = data.epocs.(offvar).onset(data.epocs.(offvar).data == 0);
            out.lvls = zeros(size(out.stimons));

            if isfield(data.epocs, stimvar2) %FRA
                out.lvls = data.epocs.(stimvar2).data;
                out.frqs = data.epocs.(stimvar3).data(2:end);
            else
                if isfield(data.epocs, stimvar) %Iso-intensity
                    out.frqs = data.epocs.(stimvar).data;
                else %LDS
                    out.frqs = zeros(numel(out.stimons), 1);
                end
                try
                    load([app.TankEditField.Value '\params.mat']');
                    if ismember('Level', changetable.Properties.VariableNames)
                        out.lvls = ones(size(out.stimons)).*changetable.Level(1);
                    else
                        out.lvls = ones(size(out.stimons)).*changetable.EvokeLevel(1);
                        out.frqs = ones(size(out.stimons)).*changetable.EvokeFrequency(1);
                    end
                end
            end
        end
        
        function results = plotpsth(app, ax, pst, yrange, averate, sy, sz)
                switch lower(app.PSTHscaleradio.SelectedObject.Text)
                    case 'independent'
                    case 'x psths'
                        yrange = repmat(max(yrange), numel(pst), 1);
                    case 'x all'
                        if app.PSTHscaleEdit.Value == 0
                            yrange = repmat(max(max(yrange)), numel(pst), sy*sz);
                        else
                            yrange = repmat(app.PSTHscaleEdit.Value, numel(pst), sy*sz);
                        end
                end
                for i = 1:numel(pst)
                    plot(ax, i + pst(i).bintime.*0.5./(pst(i).bintime(end) - pst(i).bintime(1)),...
                        (pst(i).bincount./yrange(i,:))- (1:(sy*sz)));
                end
                set(gca, 'XTick', 1:numel(pst))
                hold(ax, 'off'); grid(ax, 'on');
                ylabel(ax, 'Channel');
                title(ax, [app.TankEditField.Value newline 'Level = ', app.LevelDrop.Value ', '  app.PSTHscaleradio.SelectedObject.Text ' normalization']);

        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IsoStimulusPanel.Visible = 0;
            app.NBNStimulusPanel.Visible = 0;
            app.TONEStimulusPanel.Visible = 0;
            app.FRAStimulusPanel.Visible = 0;
            app.ExpDurationRadio.Visible = 0;
            
            app.syn = SynapseAPI();
            updatemode(app);
            app.ExperimentDrop.Items = app.syn.getKnownExperiments;
            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            livestreamapp(app, 'Preview')
        end

        % Button pushed function: IsoGetButton
        function IsoGetButtonPushed(app, event)
            commongetbutton(app, 'Iso')
        end

        % Button pushed function: IsoSetButton
        function IsoSetButtonPushed(app, event)
           commonsetbutton(app, 'Iso')
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            livestreamapp(app, 'Record')
        end

        % Button pushed function: IdleButton
        function IdleButtonPushed(app, event)
            app.syn.setMode(0);
            app.IsoStimulusPanel.Visible = 0; app.NBNStimulusPanel.Visible = 0; app.TONEStimulusPanel.Visible = 0; 
            app.FRAStimulusPanel.Visible = 0; app.ExpDurationRadio.Visible = 0;
            updatemode(app);
        end

        % Button pushed function: NBNGetButton
        function NBNGetButtonPushed(app, event)
            commongetbutton(app, 'NBN')
        end

        % Button pushed function: NBNSetButton
        function NBNSetButtonPushed(app, event)
            commonsetbutton(app, 'NBN')
        end

        % Button pushed function: TONEGetButton
        function TONEGetButtonPushed(app, event)
            commongetbutton(app, 'TONE')
        end

        % Button pushed function: TONESetButton
        function TONESetButtonPushed(app, event)
            commonsetbutton(app, 'TONE')
        end

        % Button pushed function: ExpButton1
        function ExpButton1Pushed(app, event)
            expstr = app.ExperimentDrop.Value;
            nameendi= regexp(expstr, '_') -1;
            switch lower(expstr(1:nameendi))
                case 'iso'
                    app.TabGroup.SelectedTab = app.IsoIntensityTab;
                    app.experiment = 1;
                case 'lds'
                    app.TabGroup.SelectedTab = app.LDSNoiseTab;
                    app.experiment = 2;
                case 'search'
                    app.TabGroup.SelectedTab = app.LDSNoiseTab;
                    app.experiment = 2;
                case 'ldst'
                    app.TabGroup.SelectedTab = app.LDSToneTab;
                    app.experiment = 3;
                case 'frao'
                    app.TabGroup.SelectedTab = app.FRATab;
                    app.experiment = 4;
                case 'fraho'
                    app.TabGroup.SelectedTab = app.FRATab;
                    app.experiment = 4;
                case 'fra'
                    app.TabGroup.SelectedTab = app.FRATab;
                    app.experiment = 4;
                case 'ldse'
                    app.TabGroup.SelectedTab = app.LDSEvokeTab;
                    app.experiment = 5;
            end
            if app.syn.getMode > 0
               app.syn.setMode(0);
            end
            app.DialogueLabel.Text = 'Loading Experiment'; pause(.1);
            app.syn.setCurrentExperiment(app.ExperimentDrop.Value);
            
            c = clock();
            d = [num2str(mod(c(1), 100)) sprintf('%02d', c(2)), sprintf('%02d', c(3))];
            t = [sprintf('%02d', c(4)) sprintf('%02d', c(5))];
            blockname = [sprintf('%03d', app.BlockEdit.Value) '-' app.syn.getCurrentExperiment() '-' d '-' t];
            app.syn.setCurrentBlock(blockname);

            app.syn.setMode(1);
            updatemode(app); updatepars(app);
            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: SelectTankButton
        function SelectTankButtonPushed(app, event)
            [filepath, ~, ~] = fileparts(app.TankEditField.Value);
            if isempty(filepath)
                tankpath = uigetdir('D:\Synapse\Tanks');
            else
                tankpath = uigetdir(filepath);
            end
            if tankpath ~= 0
                app.TankEditField.Value = tankpath;
            end
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            data = readtank(app);
            frqlist = sort(unique(data.frqs));
            app.FrequencyDrop.Items = compose('%d', frqlist);
            app.LevelDrop.Items = {'0'};
            if isfield(data, 'lvls')
                lvllist = sort(unique(data.lvls));
                app.LevelDrop.Items = ['all'; compose('%d', lvllist)];
            end
             try
                load([app.TankEditField.Value '\params.mat']');
                chstr = ''; namestr = '';
                charr = num2str(cell2mat(table2cell(changetable))');
                for row = 1:size(charr, 1)
                    chstr = [chstr, charr(row,:), newline];
                    namestr = [namestr, char(changetable.Properties.VariableNames(row)), newline];
                end
                app.ParamsLabel2.Text = chstr;
                 app.ParamsLabel.Text = namestr;
                 
                if any(strcmp('LDSOnsetDelay',fieldnames(changetable)))
                    app.PSTH1StartEdit.Value = -1*table2array(changetable(1, 'LDSOnsetDelay'));
                    app.PSTH2StartEdit.Value = 0;
                    
                    app.PSTH1EndEdit.Value = 0;
                    app.PSTH2EndEdit.Value = table2array(changetable(1, 'LDSDuration'));
                    app.PSTHBinEdit.Value = table2array(changetable(1, 'LDSDuration'))*10;
                elseif numel(data.stimons) > 1
                    tab1 = tabulate(round(diff(data.stimons).*1000)./1000);
                    [~,i] = sort(tab1(:,2), 'descend');
                    j = find(tab1(i, 2)>0);
                    isis = tab1(i(j), 1:2);
                    app.PSTH1StartEdit.Value = 0;
                    app.PSTH2StartEdit.Value = 0;
                    
                    app.PSTH1EndEdit.Value = isis(1,1);
                    app.PSTH2EndEdit.Value = isis(1,1);
                    app.PSTHBinEdit.Value = isis(1,1)*10;
                end
             end
             app.DialogueLabel.Text = 'Ready';


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            data = readtank(app);
%             [spetfreq, stimspets, trials] = freqanal(data.spets, data.stimons, data.frqs);
            [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(data.spets, data.stimons, data.frqs, data.lvls);
          
            binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
            analwin = [app.OnsetEdit.Value app.OffsetEdit.Value]./1000; %ftc analysis window;
            
%tuning curve
            cla(app.FTCAxes, 'reset');
            if numel(unique(data.frqs))>1
                if app.FTCPopupCheck.Value
                    figure(2); clf; ax = axes();
                else
                    ax = app.FTCAxes;
                end
                if numel(unique(data.lvls)) > 1
                    multifra(stimspets, spetfreq, spetlevel, analwin, data.channels, trials, data.chanlist, data.frqs, data.lvls, ax)
                else
                    synftc(stimspets, spetfreq, analwin, data.channels, trials(1:end), data.nchans, data.frqs, ax)
                end
                title(ax, [app.TankEditField.Value newline num2str(app.OnsetEdit.Value) '-' num2str(app.OffsetEdit.Value) ' ms'])

            end


%running spike rate average
            if ~app.ClustsCheck.Value
                data.clusters = ones(size(stimspets));
                bincount = NaN(round(max(data.spets)/binwindow), 1, data.nchans);
            else
                bincount = NaN(round(max(data.spets)/binwindow), max(data.clusters), data.nchans);
            end
            for chan = data.chanlist'
                for cluster = 1:max(data.clusters)
                    [bincount(:,cluster, chan), bintime] = hist(data.spets(data.channels==chan & data.clusters==cluster), 0.5*binwindow:binwindow:max(data.spets));
                end
            end
            if app.fratescaleEdit.Value == 0
                yscale = max(max(max(bincount)));
            else
                yscale = app.fratescaleEdit.Value*binwindow;
            end
            [sx, sy, sz] = size(bincount);
            bincount2 = reshape(bincount, sx, sy*sz);

            frate = bincount2./sy./yscale - ((1:sy*sz)./sy);
            cla(app.rateAxes, 'reset');
            plotfrate(app, frate, bintime, data.stimons, data.stimoffs, data.chanlist);
            if numel(unique(data.clusters))> 1
                app.frateTable.Data = [reshape(repmat(1:max(data.chanlist), sy, 1), sy*sz, 1), reshape(repmat(1:max(double(data.clusters)), 1, sz), sy*sz, 1), max(bincount2)'./binwindow];
                app.frateTable.ColumnWidth = {50, 50, 50};
                app.frateTable.ColumnName = {'Chan', 'Clust', 'MaxRate'};
            else
                app.frateTable.Data = [(1:max(data.chanlist))', max(bincount2)'./binwindow];
                app.frateTable.ColumnName = {'Chan', 'MaxRate'};
            end

%PSTH
            frqlist = sort(unique(data.frqs));
            app.FrequencyDrop.Items = compose('%d', frqlist);
            yrange = NaN(2, sy*sz);
            averate = NaN(2, sy*sz);
            cla(app.PSTH1Axes, 'reset');
            cla(app.PSTH2Axes, 'reset');
            if numel(unique(data.lvls))> 1

                lvllist = sort(unique(data.lvls));
                targetlevel = -1;
                if ~isnan(str2double(app.LevelDrop.Value))
                    targetlevel = str2double(app.LevelDrop.Value);
                end
                app.LevelDrop.Items = ['all'; compose('%d', lvllist)];

                figure(3); clf; ax = axes(); cla(ax, 'reset'); hold (ax, 'on');
                for freqi = 1:numel(frqlist)
                    [pst(freqi), yrange(freqi,:), averate(freqi,:)] = synpstbin(app, data, 1, spetfreq, frqlist(freqi), spetlevel, targetlevel);
                end
                plotpsth(app, ax, pst, yrange, averate, sy, sz)
                set(gca, 'XTickLabel', compose('%d', frqlist))
                xlabel(ax, 'Frequency');

                figure(4); clf; ax = axes(); cla(ax, 'reset'); hold (ax, 'on');
                for lvli = 1:numel(lvllist)
                    [pst(lvli), yrange(lvli, :), averate(lvli,:)] = synpstbin(app, data, 1, spetfreq, str2double(app.FrequencyDrop.Value), spetlevel, lvllist(lvli));
                end
                plotpsth(app, ax, pst, yrange, averate, sy, sz); 
                set(gca, 'XTickLabel', compose('%d', lvllist))
                xlabel(ax, 'Level');
                    
            else
                for i = 1:2
                    [pst(i), yrange(i,:), averate(i,:)] = synpstbin(app, data, i, spetfreq, str2double(app.FrequencyDrop.Value), [], -1);
                    if app.PSTHPopupCheck.Value
                        figure(2+i); clf; ax(i) = axes();
                    else
                        axesfield = ['PSTH' num2str(i) 'Axes'];
                        ax(i) = app.(axesfield); cla(ax(i), 'reset');
                    end    
                end %for PSTH1 and 2
                
            switch lower(app.PSTHscaleradio.SelectedObject.Text)
                case 'independent'
                case 'x psths'
                    yrange = repmat(max(yrange), 2, 1);
                case 'x all'
                    if app.PSTHscaleEdit.Value == 0
                        yrange = repmat(max(max(yrange)), 2, max(data.clusters)*max(data.chanlist));
                    else
                        yrange = repmat(app.PSTHscaleEdit.Value, 2, max(data.clusters)*max(data.chanlist));
                    end
            end
            psthwindow = app.PSTHBinEdit.Value./1000;
            stimdur = median(data.stimoffs - data.stimons);
            stims = [-1.*strcmp(app.PSTH1RefDrop.Value, 'Offset').*stimdur, stimdur];
            rectangle(ax(1), 'Position', [stims(1) -100  stims(2) 100], 'FaceColor', .8*[1 1 1], 'EdgeColor', .8*[1 1 1])
            hold(ax(1), 'on');
            plot(ax(1), pst(1).bintime, (pst(1).bincount./yrange(1,:))./max(data.clusters)- (1:max(data.clusters)*max(data.chanlist))./max(data.clusters));
            plot(ax(1), [pst(1).bintime(find(~isnan(pst(1).bintime), 1)) pst(1).bintime(end)], ([averate(1,:); averate(1,:)]./yrange(1,:)) - ...
                (1:max(data.clusters)*max(data.chanlist))./max(data.clusters), 'r:');
            plot(ax(1), pst(1).PSTHspets, 1-1*pst(1).PSTHchans - pst(1).PSTHclusters./max(data.clusters), 'k.');
            hold(ax(1), 'off');
            grid(ax(1), 'on');
            xlim(ax(1), [app.PSTH1StartEdit.Value app.PSTH1EndEdit.Value]);
            ylim(ax(1), -1.*[max(data.chanlist)+.5 min(data.chanlist)-1])

            stims = [-1.*strcmp(app.PSTH2RefDrop.Value, 'Offset').*stimdur, stimdur];
            rectangle(ax(2), 'Position', [stims(1) -100  stims(2) 100], 'FaceColor', .8*[1 1 1], 'EdgeColor', .8*[1 1 1])
            hold(ax(2), 'on');
            plot(ax(2), pst(2).bintime, (pst(2).bincount./yrange(2,:))./max(data.clusters)- (1:max(data.clusters)*max(data.chanlist))./max(data.clusters));
            plot(ax(2), [pst(2).bintime(find(~isnan(pst(2).bintime), 1)) pst(2).bintime(end)], ([averate(1,:); averate(1,:)]./yrange(2,:)) - ...
                (1:max(data.clusters)*max(data.chanlist))./max(data.clusters), 'r:');
            plot(ax(2), pst(2).PSTHspets, 1-1*pst(2).PSTHchans - pst(2).PSTHclusters./max(data.clusters), 'k.');
            hold(ax(2), 'off');
            grid(ax(2), 'on');
            xlim(ax(2), [app.PSTH2StartEdit.Value app.PSTH2EndEdit.Value]);
            ylim(ax(2), -1.*[max(data.chanlist)+.5 min(data.chanlist)-1])
            for i = 1:2
                    ylabel(ax(i), 'Channel')
                    readfield = ['PSTH' num2str(i) 'RefDrop'];
                    xlabel(ax(i), ['Time relative to ' app.(readfield).Value ' (sec)'])
                    title(ax(i), [app.TankEditField.Value newline 'Frequency = ' app.FrequencyDrop.Value ' Hz, ' app.PSTHscaleradio.SelectedObject.Text ' normalization' ...
                        newline num2str(numel(pst(i).PSTHspets)) ' spikes, ' num2str(sum(data.frqs==str2double(app.FrequencyDrop.Value))) ' trials'])
            end

            end %if FRA condition
            app.DialogueLabel.Text = 'Ready';


        end

        % Value changed function: Chan116Button
        function Chan116ButtonValueChanged(app, event)
            value = app.Chan116Button.Value;
            if value
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan116Button.Text = 'Clear 1-16';
            else
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end                
                app.Chan116Button.Text = 'Check 1-16';
            end
        end

        % Value changed function: Chan1732Button
        function Chan1732ButtonValueChanged(app, event)
            value = app.Chan1732Button.Value;
            if value
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan1732Button.Text = 'Clear 17-32';
            else
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end
                app.Chan1732Button.Text = 'Check 17-32';
            end
        end

        % Value changed function: NBNBWEdit, NBNCFEdit
        function NBNCFEditValueChanged(app, event)
            app.NBNLPCFEdit.Value = app.NBNCFEdit.Value * 2 ^ (app.NBNBWEdit.Value / 2);
            app.NBNHPCFEdit.Value = app.NBNCFEdit.Value * 2 ^ ((app.NBNBWEdit.Value) / -2 );
        end

        % Value changed function: NBNHPCFEdit, NBNLPCFEdit
        function NBNHPCFEditValueChanged(app, event)
            app.NBNCFEdit.Value = sqrt(app.NBNHPCFEdit.Value * app.NBNLPCFEdit.Value);
            app.NBNBWEdit.Value = log2(app.NBNLPCFEdit.Value / app.NBNHPCFEdit.Value);  
        end

        % Button pushed function: FRAGetButton
        function FRAGetButtonPushed(app, event)
            commongetbutton(app, 'FRA')
        end

        % Button pushed function: FRASetButton
        function FRASetButtonPushed(app, event)
            commonsetbutton(app, 'FRA')
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            savepath = uigetdir('D:\Synapse\ExperimentData\');
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            data = readtank(app);
            if isfield(data, 'lvls')
                [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(data.spets, data.stimons, data.frqs, data.lvls);
                stimtable = table(data.stimons, data.stimoffs, data.frqs, data.lvls, 'VariableNames', {'OnsetTime', 'OffsetTime', 'Frequency', 'Level'});
                spiketable = table(data.spets, data.channels, data.clusters, stimspets, spetfreq, spetlevel, 'VariableNames', {'SpikeTime', 'Channel', 'Cluster', 'PostStimulusTime', 'LastFreq', 'LastLevel'});
            else
                stimtable = table(data.stimons, data.stimoffs, data.frqs, 'VariableNames', {'OnsetTime', 'OffsetTime', 'Frequency'});
                [spetfreq, stimspets, trials] = freqanal(data.spets, data.stimons, data.frqs);
                spiketable = table(data.spets, data.channels, data.clusters, stimspets, spetfreq, 'VariableNames', {'SpikeTime', 'Channel', 'Cluster', 'PostStimulusTime', 'LastFreq'});
            end
            if ~app.ClustsCheck.Value
                data.clusters = ones(size(stimspets));
            end
            binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
            for chan = data.chanlist'
                for cluster = 1:max(data.clusters)
                    [bincount(:,cluster, chan), bintime] = hist(data.spets(data.channels==chan & data.clusters==cluster), 0.5*binwindow:binwindow:max(data.spets));
                end
            end
            [sx, sy, sz] = size(bincount);
            bincount2 = reshape(bincount, sx, sy*sz);
            ratetable = table(bintime (:,1), bincount2, 'VariableNames', {'BinTime', 'BinCount'});
            
            
            for i = 1:2
                [pst(i), ~, averate(i,:)] = synpstbin(app, data, i, spetfreq, str2double(app.FrequencyDrop.Value), [], -1);
            end
                psth1table = table(pst(1).bintime(:,1), pst(1).bincount, 'VariableNames', {'BinTime', 'BinCount'});
                psth2table = table(pst(2).bintime(:,1), pst(2).bincount, 'VariableNames', {'BinTime', 'BinCount'});
            
                paramsfname = [savepath '\' app.PrefixEdit.Value 'params.csv'];
                stimfname = [savepath '\' app.PrefixEdit.Value 'stim.csv'];
                spikefname = [savepath '\' app.PrefixEdit.Value 'spike.csv'];
                ratefname = [savepath '\' app.PrefixEdit.Value 'rate.csv'];
                psth1fname = [savepath '\' app.PrefixEdit.Value 'psth1.csv'];
                psth2fname = [savepath '\' app.PrefixEdit.Value 'psth2.csv'];
            d = datestr(now, 'yymmdd-HHMMSS');
            
            try
                load([app.TankEditField.Value '\params.mat']', 'changetable');
                if exist(paramsfname, 'file')
                    writetable(changetable, [savepath '\' app.PrefixEdit.Value 'params' d '.csv'])
                else
                    writetable(changetable, paramsfname)
                end
            end
            if exist(stimfname, 'file')
                writetable(stimtable, [savepath '\' app.PrefixEdit.Value 'stim' d '.csv'])
            else
                writetable(stimtable, stimfname)
            end
            if exist(spikefname, 'file')
                writetable(spiketable, [savepath '\' app.PrefixEdit.Value 'spike' d '.csv'])
            else
                writetable(spiketable, spikefname)
            end
            if exist(ratefname, 'file')
                writetable(ratetable, [savepath '\' app.PrefixEdit.Value 'rate' d '.csv'])
            else
                writetable(ratetable, ratefname)
            end    
            if exist(psth1fname, 'file')
                writetable(psth1table, [savepath '\' app.PrefixEdit.Value 'psth1' d '.csv'])
            else
                writetable(psth1table, psth1fname)
            end       
            if exist(psth2fname, 'file')
                writetable(psth2table, [savepath '\' app.PrefixEdit.Value 'psth2' d '.csv'])
            else
                writetable(psth2table, psth2fname)
            end
        end

        % Button pushed function: LoadThresholdsButton
        function LoadThresholdsButtonPushed(app, event)
            [threshfile, threshpath, filt] = uigetfile('D:\Synapse\Tanks\');
            if filt
                load([threshpath threshfile]);
                if exist('threshlist', 'var')
                    for i = 1:numel(threshlist)
                        app.syn.setParameterValue('Neu1', ['Threshold' num2str(i)], threshlist(i));
                    end
                end
            end
        end

        % Button pushed function: EVOGetButton
        function EVOGetButtonPushed(app, event)
            commongetbutton(app, 'EVO')            
        end

        % Button pushed function: EVOSetButton
        function EVOSetButtonPushed(app, event)
            commonsetbutton(app, 'EVO')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.DialogueLabel = uilabel(app.UIFigure);
            app.DialogueLabel.Text = 'Dialogue';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IsoIntensityTab = uitab(app.TabGroup);
            app.IsoIntensityTab.Title = 'IsoIntensity';
            app.IsoStimulusPanel = uipanel(app.IsoIntensityTab);
            app.IsoStimulusPanel.Title = 'Stimulus';
            app.IsoStimHiTimeLabel = uilabel(app.IsoStimulusPanel);
            app.IsoStimHiTimeLabel.Text = 'HiTime';
            app.IsoStimLoTimeLabel = uilabel(app.IsoStimulusPanel);
            app.IsoStimLoTimeLabel.Text = 'LoTime';
            app.SynapseValuesLabel = uilabel(app.IsoStimulusPanel);
            app.SynapseValuesLabel.Text = 'Synapse Values';
            app.IsoStimNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.IsoStimNPulsesLabel.Text = 'Pulses';
            app.IsoGateLoTimeLabel = uilabel(app.IsoStimulusPanel);
            app.IsoGateLoTimeLabel.Text = 'ISI';
            app.IsoGateNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.IsoGateNPulsesLabel.Text = 'Trains';
            app.IsoLevelLabel = uilabel(app.IsoStimulusPanel);
            app.IsoLevelLabel.Text = 'dB';
            app.StimHiTimemsLabel = uilabel(app.IsoStimulusPanel);
            app.StimHiTimemsLabel.Text = 'Stim Hi Time (ms)';
            app.IsoStimHiTimeEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoStimHiTimeEdit.Value = 200;
            app.StimLoTimemsLabel = uilabel(app.IsoStimulusPanel);
            app.StimLoTimemsLabel.Text = 'Stim Lo Time (ms)';
            app.IsoStimLoTimeEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoStimLoTimeEdit.Value = 300;
            app.StimNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.StimNPulsesLabel.Text = 'Stim N Pulses';
            app.IsoStimNPulsesEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoStimNPulsesEdit.Value = 5;
            app.GateLoTimemsLabel_2 = uilabel(app.IsoStimulusPanel);
            app.GateLoTimemsLabel_2.Text = 'Gate Lo Time (ms)';
            app.IsoGateLoTimeEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoGateLoTimeEdit.Value = 500;
            app.GateNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.GateNPulsesLabel.Text = 'Gate N Pulses';
            app.IsoGateNPulsesEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoGateNPulsesEdit.Value = 6;
            app.LeveldBEditFieldLabel = uilabel(app.IsoStimulusPanel);
            app.LeveldBEditFieldLabel.Text = 'Level (dB)';
            app.IsoLevelEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoLevelEdit.Value = 70;
            app.IsoGetButton = uibutton(app.IsoStimulusPanel, 'push');
            app.IsoGetButton.ButtonPushedFcn = createCallbackFcn(app, @IsoGetButtonPushed, true);
            app.IsoGetButton.Text = '<- Get ';
            app.IsoSetButton = uibutton(app.IsoStimulusPanel, 'push');
            app.IsoSetButton.ButtonPushedFcn = createCallbackFcn(app, @IsoSetButtonPushed, true);
            app.IsoSetButton.Text = 'Set ->';
            app.IsoStimAxes = uiaxes(app.IsoStimulusPanel);
            title(app.IsoStimAxes, 'Stim Preview')
            xlabel(app.IsoStimAxes, 'Time')
            ylabel(app.IsoStimAxes, 'Y')
            app.FRATab = uitab(app.TabGroup);
            app.FRATab.Title = 'FRA';
            app.FRAStimulusPanel = uipanel(app.FRATab);
            app.FRAStimulusPanel.Title = 'Stimulus';
            app.FRAGateHiTimeLabel = uilabel(app.FRAStimulusPanel);
            app.FRAGateHiTimeLabel.Text = 'HiTime';
            app.FRAGateLoTimeLabel = uilabel(app.FRAStimulusPanel);
            app.FRAGateLoTimeLabel.Text = 'LoTime';
            app.SynapseValuesLabel_3 = uilabel(app.FRAStimulusPanel);
            app.SynapseValuesLabel_3.Text = 'Synapse Values';
            app.FRAGateNPulsesLabel = uilabel(app.FRAStimulusPanel);
            app.FRAGateNPulsesLabel.Text = 'Pulses';
            app.GateHiTimemsLabel = uilabel(app.FRAStimulusPanel);
            app.GateHiTimemsLabel.Text = 'Gate Hi Time (ms)';
            app.FRAGateHiTimeEdit = uieditfield(app.FRAStimulusPanel, 'numeric');
            app.FRAGateHiTimeEdit.Value = 100;
            app.GateLoTimemsLabel_4 = uilabel(app.FRAStimulusPanel);
            app.GateLoTimemsLabel_4.Text = 'GateLo Time (ms)';
            app.FRAGateLoTimeEdit = uieditfield(app.FRAStimulusPanel, 'numeric');
            app.FRAGateLoTimeEdit.Value = 200;
            app.GateNPulsesLabel_3 = uilabel(app.FRAStimulusPanel);
            app.GateNPulsesLabel_3.Text = 'Gate N Pulses';
            app.FRAGateNPulsesEdit = uieditfield(app.FRAStimulusPanel, 'numeric');
            app.FRAGateNPulsesEdit.Value = 225;
            app.FRAGetButton = uibutton(app.FRAStimulusPanel, 'push');
            app.FRAGetButton.ButtonPushedFcn = createCallbackFcn(app, @FRAGetButtonPushed, true);
            app.FRAGetButton.Text = '<- Get ';
            app.FRASetButton = uibutton(app.FRAStimulusPanel, 'push');
            app.FRASetButton.ButtonPushedFcn = createCallbackFcn(app, @FRASetButtonPushed, true);
            app.FRASetButton.Text = 'Set ->';
            app.FRAStimAxes = uiaxes(app.FRAStimulusPanel);
            title(app.FRAStimAxes, 'Stim Preview')
            xlabel(app.FRAStimAxes, 'Time')
            ylabel(app.FRAStimAxes, 'Y')
            app.LDSNoiseTab = uitab(app.TabGroup);
            app.LDSNoiseTab.Title = 'LDS Noise';
            app.NBNStimulusPanel = uipanel(app.LDSNoiseTab);
            app.NBNStimulusPanel.Title = 'Stimulus';
            app.NBNTotLDSPulsesLabel = uilabel(app.NBNStimulusPanel);
            app.NBNTotLDSPulsesLabel.Text = 'TotLDSPulses';
            app.NBNLDSOnsetDelayLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLDSOnsetDelayLabel.Text = 'LDSOnsetDelay';
            app.SynapseValuesLabel_2 = uilabel(app.NBNStimulusPanel);
            app.SynapseValuesLabel_2.Text = 'Synapse Values';
            app.NBNLDSDurationLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLDSDurationLabel.Text = 'LDSDuration';
            app.NBNLDSTimeBtwnPulsesLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLDSTimeBtwnPulsesLabel.Text = 'LDSTimeBtwnPulses';
            app.NBNLevelLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLevelLabel.Text = 'Level';
            app.NBNHPCFLabel = uilabel(app.NBNStimulusPanel);
            app.NBNHPCFLabel.Text = 'HPCF';
            app.TotLDSPulsesLabel = uilabel(app.NBNStimulusPanel);
            app.TotLDSPulsesLabel.Text = 'Tot LDS Pulses';
            app.NBNTotLDSPulsesEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNTotLDSPulsesEdit.Value = 20;
            app.LDSOnsetDelaysecLabel = uilabel(app.NBNStimulusPanel);
            app.LDSOnsetDelaysecLabel.Text = 'LDS Onset Delay (sec)';
            app.NBNLDSOnsetDelayEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLDSOnsetDelayEdit.Value = 20;
            app.LDSDurationsecLabel = uilabel(app.NBNStimulusPanel);
            app.LDSDurationsecLabel.Text = 'LDS Duration (sec)';
            app.NBNLDSDurationEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLDSDurationEdit.Value = 20;
            app.LDSTimeBtwnPulsessecLabel = uilabel(app.NBNStimulusPanel);
            app.LDSTimeBtwnPulsessecLabel.Text = 'LDS Time Btwn Pulses (sec)';
            app.NBNLDSTimeBtwnPulsesEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLDSTimeBtwnPulsesEdit.Value = 30;
            app.LeveldBLabel = uilabel(app.NBNStimulusPanel);
            app.LeveldBLabel.Text = 'Level (dB)';
            app.NBNLevelEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLevelEdit.Value = 70;
            app.HipassHzLabel = uilabel(app.NBNStimulusPanel);
            app.HipassHzLabel.Text = 'Hipass (Hz)';
            app.NBNHPCFEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNHPCFEdit.ValueChangedFcn = createCallbackFcn(app, @NBNHPCFEditValueChanged, true);
            app.NBNHPCFEdit.Value = 6000;
            app.LopassHzEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.LopassHzEditFieldLabel.Text = 'Lopass (Hz)';
            app.NBNLPCFEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLPCFEdit.ValueChangedFcn = createCallbackFcn(app, @NBNHPCFEditValueChanged, true);
            app.NBNLPCFEdit.Value = 12000;
            app.ModulationEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.ModulationEditFieldLabel.Text = 'Modulation %';
            app.NBNModPercentageEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.ModulationFreqHzEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.ModulationFreqHzEditFieldLabel.Text = 'Modulation Freq (Hz)';
            app.NBNModFrequencyEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.SineExponentEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.SineExponentEditFieldLabel.Text = 'Sine Exponent';
            app.NBNSineWaveExponentEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNSineWaveExponentEdit.Value = 1;
            app.NBNLPCFLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLPCFLabel.Text = 'LPCF';
            app.NBNModPercentageLabel = uilabel(app.NBNStimulusPanel);
            app.NBNModPercentageLabel.Text = 'ModPercentage';
            app.NBNModFrequencyLabel = uilabel(app.NBNStimulusPanel);
            app.NBNModFrequencyLabel.Text = 'ModFrequency';
            app.NBNSineWaveExponentLabel = uilabel(app.NBNStimulusPanel);
            app.NBNSineWaveExponentLabel.Text = 'SineWaveExponent';
            app.NBNGetButton = uibutton(app.NBNStimulusPanel, 'push');
            app.NBNGetButton.ButtonPushedFcn = createCallbackFcn(app, @NBNGetButtonPushed, true);
            app.NBNGetButton.Text = '<- Get ';
            app.NBNSetButton = uibutton(app.NBNStimulusPanel, 'push');
            app.NBNSetButton.ButtonPushedFcn = createCallbackFcn(app, @NBNSetButtonPushed, true);
            app.NBNSetButton.Text = 'Set ->';
            app.NBNStimAxes = uiaxes(app.NBNStimulusPanel);
            title(app.NBNStimAxes, 'Stim Preview')
            xlabel(app.NBNStimAxes, 'Time')
            ylabel(app.NBNStimAxes, 'Y')
            app.CenterFreqHzLabel = uilabel(app.NBNStimulusPanel);
            app.CenterFreqHzLabel.Text = 'Center Freq (Hz)';
            app.NBNCFEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNCFEdit.ValueChangedFcn = createCallbackFcn(app, @NBNCFEditValueChanged, true);
            app.BandwidthOctLabel = uilabel(app.NBNStimulusPanel);
            app.BandwidthOctLabel.Text = 'Bandwidth (Oct)';
            app.NBNBWEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNBWEdit.ValueChangedFcn = createCallbackFcn(app, @NBNCFEditValueChanged, true);
            app.LDSToneTab = uitab(app.TabGroup);
            app.LDSToneTab.Title = 'LDS Tone';
            app.TONEStimulusPanel = uipanel(app.LDSToneTab);
            app.TONEStimulusPanel.Title = 'Stimulus';
            app.TONETotLDSPulsesLabel = uilabel(app.TONEStimulusPanel);
            app.TONETotLDSPulsesLabel.Text = 'TotLDSPulses';
            app.TONELDSOnsetDelayLabel = uilabel(app.TONEStimulusPanel);
            app.TONELDSOnsetDelayLabel.Text = 'LDSOnsetDelay';
            app.TONESynapseValuesLabel = uilabel(app.TONEStimulusPanel);
            app.TONESynapseValuesLabel.Text = 'Synapse Values';
            app.TONELDSDurationLabel = uilabel(app.TONEStimulusPanel);
            app.TONELDSDurationLabel.Text = 'LDSDuration';
            app.TONELDSTimeBtwnPulsesLabel = uilabel(app.TONEStimulusPanel);
            app.TONELDSTimeBtwnPulsesLabel.Text = 'LDSTimeBtwnPulses';
            app.TONELevelLabel = uilabel(app.TONEStimulusPanel);
            app.TONELevelLabel.Text = 'Level';
            app.TONEToneFreqLabel = uilabel(app.TONEStimulusPanel);
            app.TONEToneFreqLabel.Text = 'ToneFreq';
            app.TONEModPercentageLabel = uilabel(app.TONEStimulusPanel);
            app.TONEModPercentageLabel.Text = 'ModPercentage';
            app.TONEModFrequencyLabel = uilabel(app.TONEStimulusPanel);
            app.TONEModFrequencyLabel.Text = 'ModFrequency';
            app.TONESineWaveExponentLabel = uilabel(app.TONEStimulusPanel);
            app.TONESineWaveExponentLabel.Text = 'SineWaveExponent';
            app.TotLDSPulsesLabel_2 = uilabel(app.TONEStimulusPanel);
            app.TotLDSPulsesLabel_2.Text = 'Tot LDS Pulses';
            app.TONETotLDSPulsesEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONETotLDSPulsesEdit.Value = 20;
            app.LDSOnsetDelaysecLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LDSOnsetDelaysecLabel_2.Text = 'LDS Onset Delay (sec)';
            app.TONELDSOnsetDelayEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELDSOnsetDelayEdit.Value = 20;
            app.LDSDurationsecLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LDSDurationsecLabel_2.Text = 'LDS Duration (sec)';
            app.TONELDSDurationEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELDSDurationEdit.Value = 20;
            app.LDSTimeBtwnPulsessecLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LDSTimeBtwnPulsessecLabel_2.Text = 'LDS Time Btwn Pulses (sec)';
            app.TONELDSTimeBtwnPulsesEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELDSTimeBtwnPulsesEdit.Value = 30;
            app.LeveldBLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LeveldBLabel_2.Text = 'Level (dB)';
            app.TONELevelEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELevelEdit.Value = 70;
            app.FrequencyHzLabel = uilabel(app.TONEStimulusPanel);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.TONEToneFreqEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONEToneFreqEdit.Value = 6000;
            app.ModulationEditFieldLabel_2 = uilabel(app.TONEStimulusPanel);
            app.ModulationEditFieldLabel_2.Text = 'Modulation %';
            app.TONEModPercentageEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.ModulationFreqHzEditFieldLabel_2 = uilabel(app.TONEStimulusPanel);
            app.ModulationFreqHzEditFieldLabel_2.Text = 'Modulation Freq (Hz)';
            app.TONEModFrequencyEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.SineExponentEditFieldLabel_2 = uilabel(app.TONEStimulusPanel);
            app.SineExponentEditFieldLabel_2.Text = 'Sine Exponent';
            app.TONESineWaveExponentEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONESineWaveExponentEdit.Value = 1;
            app.TONEGetButton = uibutton(app.TONEStimulusPanel, 'push');
            app.TONEGetButton.ButtonPushedFcn = createCallbackFcn(app, @TONEGetButtonPushed, true);
            app.TONEGetButton.Text = '<- Get ';
            app.TONESetButton = uibutton(app.TONEStimulusPanel, 'push');
            app.TONESetButton.ButtonPushedFcn = createCallbackFcn(app, @TONESetButtonPushed, true);
            app.TONESetButton.Text = 'Set ->';
            app.TONEStimAxes = uiaxes(app.TONEStimulusPanel);
            title(app.TONEStimAxes, 'Stim Preview')
            xlabel(app.TONEStimAxes, 'Time')
            ylabel(app.TONEStimAxes, 'Y')
            app.LDSEvokeTab = uitab(app.TabGroup);
            app.LDSEvokeTab.Title = 'LDS + Evoke';
            app.EVOStimulusPanel = uipanel(app.LDSEvokeTab);
            app.EVOStimulusPanel.Title = 'Panel';
            app.EVOEvokeStimHiLabel = uilabel(app.EVOStimulusPanel);
            app.EVOEvokeStimHiLabel.Text = 'TotLDSPulses';
            app.EVOEvokeStimLoLabel = uilabel(app.EVOStimulusPanel);
            app.EVOEvokeStimLoLabel.Text = 'LDSOnsetDelay';
            app.EVOEvokeNPulsesLabel = uilabel(app.EVOStimulusPanel);
            app.EVOEvokeNPulsesLabel.Text = 'LDSDuration';
            app.EVOEvokeFrequencyLabel = uilabel(app.EVOStimulusPanel);
            app.EVOEvokeFrequencyLabel.Text = 'LDSTimeBtwnPulses';
            app.EVOEvokeLevelLabel = uilabel(app.EVOStimulusPanel);
            app.EVOEvokeLevelLabel.Text = 'Level';
            app.EVOLDSDurationLabel = uilabel(app.EVOStimulusPanel);
            app.EVOLDSDurationLabel.Text = 'ToneFreq';
            app.EVOLDSFrequencyLabel = uilabel(app.EVOStimulusPanel);
            app.EVOLDSFrequencyLabel.Text = 'ModPercentage';
            app.EVOLDSLevelLabel = uilabel(app.EVOStimulusPanel);
            app.EVOLDSLevelLabel.Text = 'ModFrequency';
            app.EVONCycleRepeatsLabel = uilabel(app.EVOStimulusPanel);
            app.EVONCycleRepeatsLabel.Text = 'SineWaveExponent';
            app.EVOGetButton = uibutton(app.EVOStimulusPanel, 'push');
            app.EVOGetButton.ButtonPushedFcn = createCallbackFcn(app, @EVOGetButtonPushed, true);
            app.EVOGetButton.Text = '<- Get ';
            app.EVOSetButton = uibutton(app.EVOStimulusPanel, 'push');
            app.EVOSetButton.ButtonPushedFcn = createCallbackFcn(app, @EVOSetButtonPushed, true);
            app.EVOSetButton.Text = 'Set ->';
            app.EVOStimAxes = uiaxes(app.EVOStimulusPanel);
            title(app.EVOStimAxes, 'Stim Preview')
            xlabel(app.EVOStimAxes, 'Time')
            ylabel(app.EVOStimAxes, 'Y')
            app.EvokeStimHimsLabel = uilabel(app.EVOStimulusPanel);
            app.EvokeStimHimsLabel.Text = 'Evoke Stim Hi (ms)';
            app.EVOEvokeStimHiEdit = uieditfield(app.EVOStimulusPanel, 'numeric');
            app.EVOEvokeStimHiEdit.Value = 200;
            app.EvokeStimLomsLabel = uilabel(app.EVOStimulusPanel);
            app.EvokeStimLomsLabel.Text = 'Evoke Stim Lo (ms)';
            app.EVOEvokeStimLoEdit = uieditfield(app.EVOStimulusPanel, 'numeric');
            app.EVOEvokeStimLoEdit.Value = 300;
            app.EvokePulsesLabel = uilabel(app.EVOStimulusPanel);
            app.EvokePulsesLabel.Text = 'Evoke # Pulses';
            app.EVOEvokeNPulsesEdit = uieditfield(app.EVOStimulusPanel, 'numeric');
            app.EVOEvokeNPulsesEdit.Value = 120;
            app.EvokeFrequencyHzLabel = uilabel(app.EVOStimulusPanel);
            app.EvokeFrequencyHzLabel.Text = 'Evoke Frequency (Hz)';
            app.EVOEvokeFrequencyEdit = uieditfield(app.EVOStimulusPanel, 'numeric');
            app.EVOEvokeFrequencyEdit.Value = 12000;
            app.EvokeLeveldBLabel = uilabel(app.EVOStimulusPanel);
            app.EvokeLeveldBLabel.Text = 'Evoke Level (dB)';
            app.EVOEvokeLevelEdit = uieditfield(app.EVOStimulusPanel, 'numeric');
            app.EVOEvokeLevelEdit.Value = 60;
            app.LDSDurationLabel = uilabel(app.EVOStimulusPanel);
            app.LDSDurationLabel.Text = 'LDS Duration';
            app.EVOLDSDurationEdit = uieditfield(app.EVOStimulusPanel, 'numeric');
            app.EVOLDSDurationEdit.Value = 60;
            app.LDSFrequencyLabel = uilabel(app.EVOStimulusPanel);
            app.LDSFrequencyLabel.Text = 'LDS Frequency';
            app.EVOLDSFrequencyEdit = uieditfield(app.EVOStimulusPanel, 'numeric');
            app.EVOLDSFrequencyEdit.Value = 16000;
            app.LDSLeveldBLabel = uilabel(app.EVOStimulusPanel);
            app.LDSLeveldBLabel.Text = 'LDS Level (dB)';
            app.EVOLDSLevelEdit = uieditfield(app.EVOStimulusPanel, 'numeric');
            app.EVOLDSLevelEdit.Value = 60;
            app.CycleRepeatsLabel = uilabel(app.EVOStimulusPanel);
            app.CycleRepeatsLabel.Text = '# Cycle Repeats';
            app.EVONCycleRepeatsEdit = uieditfield(app.EVOStimulusPanel, 'numeric');
            app.EVONCycleRepeatsEdit.Value = 1;
            app.ReadTankTab = uitab(app.TabGroup);
            app.ReadTankTab.Title = 'ReadTank';
            app.SelectTankButton = uibutton(app.ReadTankTab, 'push');
            app.SelectTankButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTankButtonPushed, true);
            app.SelectTankButton.Text = 'Select Tank';
            app.PlotButton = uibutton(app.ReadTankTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TankEditFieldLabel = uilabel(app.ReadTankTab);
            app.TankEditFieldLabel.Text = 'Tank';
            app.TankEditField = uieditfield(app.ReadTankTab, 'text');
            app.ParamsLabel = uilabel(app.ReadTankTab);
            app.ParamsLabel.Text = '';
            app.ParamsLabel2 = uilabel(app.ReadTankTab);
            app.ParamsLabel2.Text = '';
            app.ExportButton = uibutton(app.ReadTankTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export Data';
            app.FilenameprefixEditFieldLabel = uilabel(app.ReadTankTab);
            app.FilenameprefixEditFieldLabel.Text = 'Filename prefix';
            app.PrefixEdit = uieditfield(app.ReadTankTab, 'text');
            app.CurrentModeLabel = uilabel(app.UIFigure);
            app.CurrentModeLabel.Text = 'Current Mode';
            app.IdleButton = uibutton(app.UIFigure, 'push');
            app.IdleButton.ButtonPushedFcn = createCallbackFcn(app, @IdleButtonPushed, true);
            app.IdleButton.Text = 'Idle';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.FiringRateTab = uitab(app.TabGroup2);
            app.FiringRateTab.Title = 'Firing Rate';
            app.rateAxes = uiaxes(app.FiringRateTab);
            title(app.rateAxes, 'Running Spike Rate')
            xlabel(app.rateAxes, 'Time')
            ylabel(app.rateAxes, 'Channel')
            app.BinsizemsLabel_2 = uilabel(app.FiringRateTab);
            app.BinsizemsLabel_2.Text = 'Bin size (ms)';
            app.BinEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.BinEdit.Value = 500;
            app.HistorysecLabel = uilabel(app.FiringRateTab);
            app.HistorysecLabel.Text = 'History (sec)';
            app.RangeEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.RangeEdit.Value = 60;
            app.ratePopupCheck = uicheckbox(app.FiringRateTab);
            app.ratePopupCheck.Text = 'Popup';
            app.fratescaleEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.yscale0maxobservedLabel = uilabel(app.FiringRateTab);
            app.yscale0maxobservedLabel.Text = 'y-scale (0 = max observed)';
            app.frateTable = uitable(app.FiringRateTab);
            app.frateTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.frateTable.RowName = {};
            app.FreqTuningTab = uitab(app.TabGroup2);
            app.FreqTuningTab.Title = 'Freq Tuning';
            app.OffsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.OffsetEdit.Value = 100;
            app.EvokedratewindowmsLabel_4 = uilabel(app.FreqTuningTab);
            app.EvokedratewindowmsLabel_4.Text = 'Evoked rate window (ms)';
            app.OnsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.FTCAxes = uiaxes(app.FreqTuningTab);
            title(app.FTCAxes, 'IsoIntensity Function')
            xlabel(app.FTCAxes, 'Frequency')
            ylabel(app.FTCAxes, 'Channel')
            app.FTCPopupCheck = uicheckbox(app.FreqTuningTab);
            app.FTCPopupCheck.Text = 'Popup';
            app.PSTHTab = uitab(app.TabGroup2);
            app.PSTHTab.Title = 'PSTH';
            app.PSTH1Axes = uiaxes(app.PSTHTab);
            title(app.PSTH1Axes, 'PSTH 1')
            xlabel(app.PSTH1Axes, 'Time')
            ylabel(app.PSTH1Axes, 'Spike Rate')
            app.PSTHPopupCheck = uicheckbox(app.PSTHTab);
            app.PSTHPopupCheck.Text = 'Popup';
            app.BinsizemsLabel = uilabel(app.PSTHTab);
            app.BinsizemsLabel.Text = 'Bin size (ms)';
            app.PSTHBinEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTHBinEdit.Value = 500;
            app.PSTH2Axes = uiaxes(app.PSTHTab);
            title(app.PSTH2Axes, 'PSTH 2')
            xlabel(app.PSTH2Axes, 'Time')
            ylabel(app.PSTH2Axes, 'Spike Rate')
            app.PSTH1WindowsecEditFieldLabel = uilabel(app.PSTHTab);
            app.PSTH1WindowsecEditFieldLabel.Text = 'PSTH 1 Window (sec)';
            app.PSTH1StartEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH1EndEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH1EndEdit.Value = 1;
            app.Referencet0DropDownLabel = uilabel(app.PSTHTab);
            app.Referencet0DropDownLabel.Text = 'Reference (t=0)';
            app.PSTH1RefDrop = uidropdown(app.PSTHTab);
            app.PSTH1RefDrop.Items = {'Onset', 'Offset'};
            app.PSTH1RefDrop.Value = 'Onset';
            app.Referencet0DropDown_2Label = uilabel(app.PSTHTab);
            app.Referencet0DropDown_2Label.Text = 'Reference (t=0)';
            app.PSTH2RefDrop = uidropdown(app.PSTHTab);
            app.PSTH2RefDrop.Items = {'Onset', 'Offset'};
            app.PSTH2RefDrop.Value = 'Offset';
            app.PSTH2EndEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH2EndEdit.Value = 1;
            app.PSTH2WindowsecEditFieldLabel = uilabel(app.PSTHTab);
            app.PSTH2WindowsecEditFieldLabel.Text = 'PSTH 2 Window (sec)';
            app.PSTH2StartEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTHscaleradio = uibuttongroup(app.PSTHTab);
            app.PSTHscaleradio.Title = 'Normalization';
            app.IndependentButton = uiradiobutton(app.PSTHscaleradio);
            app.IndependentButton.Text = 'Independent';
            app.xPSTHsButton = uiradiobutton(app.PSTHscaleradio);
            app.xPSTHsButton.Text = 'x PSTHs';
            app.xAllButton = uiradiobutton(app.PSTHscaleradio);
            app.xAllButton.Text = 'x All';
            app.xAllButton.Value = true;
            app.PSTHscaleEdit = uieditfield(app.PSTHscaleradio, 'numeric');
            app.FrequencyDropDownLabel = uilabel(app.PSTHTab);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDrop = uidropdown(app.PSTHTab);
            app.LevelLabel = uilabel(app.PSTHTab);
            app.LevelLabel.Text = 'Level';
            app.LevelDrop = uidropdown(app.PSTHTab);
            app.PollingperiodsecEditFieldLabel = uilabel(app.UIFigure);
            app.PollingperiodsecEditFieldLabel.Text = 'Polling period (sec)';
            app.UpdateEdit = uieditfield(app.UIFigure, 'numeric');
            app.UpdateEdit.Value = 1;
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.CheckBox_1 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_18 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_19 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_20 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_21 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_22 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_23 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_24 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_25 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_26 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_27 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_28 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_29 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_30 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_31 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_32 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_32.Text = '32';
            app.Chan116Button = uibutton(app.ChannelsPanel, 'state');
            app.Chan116Button.ValueChangedFcn = createCallbackFcn(app, @Chan116ButtonValueChanged, true);
            app.Chan116Button.Text = 'Clear 1-16';
            app.Chan116Button.Value = true;
            app.Chan1732Button = uibutton(app.ChannelsPanel, 'state');
            app.Chan1732Button.ValueChangedFcn = createCallbackFcn(app, @Chan1732ButtonValueChanged, true);
            app.Chan1732Button.Text = 'Check 17-32';
            app.ClustsCheck = uicheckbox(app.UIFigure);
            app.ClustsCheck.Text = 'Show Clusters';
            app.ExperimentDrop = uidropdown(app.UIFigure);
            app.ExpButton1 = uibutton(app.UIFigure, 'push');
            app.ExpButton1.ButtonPushedFcn = createCallbackFcn(app, @ExpButton1Pushed, true);
            app.ExpButton1.Text = 'Load Experiment';
            app.ExpDurationRadio = uibuttongroup(app.UIFigure);
            app.ExpDurationRadio.Title = 'Set to Idle After...';
            app.InfiniteButton = uiradiobutton(app.ExpDurationRadio);
            app.InfiniteButton.Text = 'Infinite';
            app.InfiniteButton.Value = true;
            app.LastoffsetnsecButton = uiradiobutton(app.ExpDurationRadio);
            app.LastoffsetnsecButton.Text = 'Last offset + n sec';
            app.SecondsButton = uiradiobutton(app.ExpDurationRadio);
            app.SecondsButton.Text = 'Seconds';
            app.StimDurLabel = uilabel(app.ExpDurationRadio);
            app.StimDurLabel.Text = '';
            app.TotalStimDurLabel = uilabel(app.ExpDurationRadio);
            app.TotalStimDurLabel.Text = 'Total Stimulus Duration:';
            app.Label = uilabel(app.ExpDurationRadio);
            app.Label.Text = '';
            app.ExpDurationEdit = uieditfield(app.ExpDurationRadio, 'numeric');
            app.ExpDurationEdit.Value = 60;
            app.LoadThresholdsButton = uibutton(app.ExpDurationRadio, 'push');
            app.LoadThresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadThresholdsButtonPushed, true);
            app.LoadThresholdsButton.Text = 'Load Thresholds';
            app.BlockEditFieldLabel = uilabel(app.UIFigure);
            app.BlockEditFieldLabel.Text = 'Block';
            app.BlockEdit = uieditfield(app.UIFigure, 'numeric');
            app.BlockEdit.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = synui_20190128

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end