classdef synui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewButton                   matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        DialogueLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SynapseTab                      matlab.ui.container.Tab
        StimPanel                       matlab.ui.container.Panel
        StimAxes                        matlab.ui.control.UIAxes
        Param1Label                     matlab.ui.control.Label
        Param2Label                     matlab.ui.control.Label
        Param3Label                     matlab.ui.control.Label
        Param4Label                     matlab.ui.control.Label
        Param5Label                     matlab.ui.control.Label
        Param6Label                     matlab.ui.control.Label
        Param7Label                     matlab.ui.control.Label
        Param8Label                     matlab.ui.control.Label
        Param9Label                     matlab.ui.control.Label
        Param10Label                    matlab.ui.control.Label
        Param11Label                    matlab.ui.control.Label
        GetButton                       matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        Par1Label                       matlab.ui.control.Label
        Param1Edit                      matlab.ui.control.NumericEditField
        Par2Label                       matlab.ui.control.Label
        Param2Edit                      matlab.ui.control.NumericEditField
        Par3Label                       matlab.ui.control.Label
        Param3Edit                      matlab.ui.control.NumericEditField
        Par4Label                       matlab.ui.control.Label
        Param4Edit                      matlab.ui.control.NumericEditField
        Par5Label                       matlab.ui.control.Label
        Param5Edit                      matlab.ui.control.NumericEditField
        Par6Label                       matlab.ui.control.Label
        Param6Edit                      matlab.ui.control.NumericEditField
        Par7Label                       matlab.ui.control.Label
        Param7Edit                      matlab.ui.control.NumericEditField
        Par8Label                       matlab.ui.control.Label
        Param8Edit                      matlab.ui.control.NumericEditField
        Par9Label                       matlab.ui.control.Label
        Param9Edit                      matlab.ui.control.NumericEditField
        Par10Label                      matlab.ui.control.Label
        Param10Edit                     matlab.ui.control.NumericEditField
        Par11Label                      matlab.ui.control.Label
        Param11Edit                     matlab.ui.control.NumericEditField
        SynapseValuesLabel_3            matlab.ui.control.Label
        Param12Label                    matlab.ui.control.Label
        Par12Label                      matlab.ui.control.Label
        Param12Edit                     matlab.ui.control.NumericEditField
        CenterFreqEditFieldLabel        matlab.ui.control.Label
        CenterFreqEdit                  matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel         matlab.ui.control.Label
        BandwidthEdit                   matlab.ui.control.NumericEditField
        TargetRMSLabel                  matlab.ui.control.Label
        TRMSEdit                        matlab.ui.control.NumericEditField
        ReadTankTab                     matlab.ui.container.Tab
        SelectTankButton                matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        TankEditFieldLabel              matlab.ui.control.Label
        TankEditField                   matlab.ui.control.EditField
        ParamsLabel                     matlab.ui.control.Label
        ParamsLabel2                    matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        FilenameprefixEditFieldLabel    matlab.ui.control.Label
        PrefixEdit                      matlab.ui.control.EditField
        paramsCheck                     matlab.ui.control.CheckBox
        stimCheck                       matlab.ui.control.CheckBox
        spikeCheck                      matlab.ui.control.CheckBox
        rateCheck                       matlab.ui.control.CheckBox
        psth1Check                      matlab.ui.control.CheckBox
        psth2Check                      matlab.ui.control.CheckBox
        averateCheck                    matlab.ui.control.CheckBox
        CurrentModeLabel                matlab.ui.control.Label
        IdleButton                      matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        FiringRateTab                   matlab.ui.container.Tab
        rateAxes                        matlab.ui.control.UIAxes
        BinsizemsLabel_2                matlab.ui.control.Label
        BinEdit                         matlab.ui.control.NumericEditField
        HistorysecLabel                 matlab.ui.control.Label
        RangeEdit                       matlab.ui.control.NumericEditField
        ratePopupCheck                  matlab.ui.control.CheckBox
        fratescaleEdit                  matlab.ui.control.NumericEditField
        yscale0maxobservedLabel         matlab.ui.control.Label
        frateTable                      matlab.ui.control.Table
        FreqTuningTab                   matlab.ui.container.Tab
        OffsetEdit                      matlab.ui.control.NumericEditField
        EvokedratewindowmsLabel_4       matlab.ui.control.Label
        OnsetEdit                       matlab.ui.control.NumericEditField
        FTCAxes                         matlab.ui.control.UIAxes
        FTCPopupCheck                   matlab.ui.control.CheckBox
        PSTHTab                         matlab.ui.container.Tab
        PSTH1Axes                       matlab.ui.control.UIAxes
        PSTHPopupCheck                  matlab.ui.control.CheckBox
        BinsizemsLabel                  matlab.ui.control.Label
        PSTHBinEdit                     matlab.ui.control.NumericEditField
        PSTH2Axes                       matlab.ui.control.UIAxes
        PSTH1WindowsecEditFieldLabel    matlab.ui.control.Label
        PSTH1StartEdit                  matlab.ui.control.NumericEditField
        PSTH1EndEdit                    matlab.ui.control.NumericEditField
        Referencet0DropDownLabel        matlab.ui.control.Label
        PSTH1RefDrop                    matlab.ui.control.DropDown
        Referencet0DropDown_2Label      matlab.ui.control.Label
        PSTH2RefDrop                    matlab.ui.control.DropDown
        PSTH2EndEdit                    matlab.ui.control.NumericEditField
        PSTH2WindowsecEditFieldLabel    matlab.ui.control.Label
        PSTH2StartEdit                  matlab.ui.control.NumericEditField
        PSTHscaleradio                  matlab.ui.container.ButtonGroup
        IndependentButton               matlab.ui.control.RadioButton
        xPSTHsButton                    matlab.ui.control.RadioButton
        xAllButton                      matlab.ui.control.RadioButton
        PSTHscaleEdit                   matlab.ui.control.NumericEditField
        FrequencyDropDownLabel          matlab.ui.control.Label
        FrequencyDrop                   matlab.ui.control.DropDown
        LevelLabel                      matlab.ui.control.Label
        LevelDrop                       matlab.ui.control.DropDown
        PSTHbaseline                    matlab.ui.container.ButtonGroup
        MeanButton                      matlab.ui.control.RadioButton
        CLButton                        matlab.ui.control.RadioButton
        CLEdit                          matlab.ui.control.NumericEditField
        PSTHCycleCheck                  matlab.ui.control.CheckBox
        LFPTab                          matlab.ui.container.Tab
        LFP1Axes                        matlab.ui.control.UIAxes
        LFP2Axes                        matlab.ui.control.UIAxes
        LFPPopupCheck                   matlab.ui.control.CheckBox
        LFP1EndEdit                     matlab.ui.control.NumericEditField
        LFP2EndEdit                     matlab.ui.control.NumericEditField
        LFP1WindowsecLabel              matlab.ui.control.Label
        LFP1StartEdit                   matlab.ui.control.NumericEditField
        Referencet0DropDownLabel_2      matlab.ui.control.Label
        LFP1RefDrop                     matlab.ui.control.DropDown
        Referencet0DropDown_2Label_2    matlab.ui.control.Label
        LFP2RefDrop                     matlab.ui.control.DropDown
        LFP2WindowsecLabel              matlab.ui.control.Label
        LFP2StartEdit                   matlab.ui.control.NumericEditField
        LFPCycleCheck                   matlab.ui.control.CheckBox
        PollingperiodsecEditFieldLabel  matlab.ui.control.Label
        UpdateEdit                      matlab.ui.control.NumericEditField
        ChannelsPanel                   matlab.ui.container.Panel
        CheckBox_1                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_21                     matlab.ui.control.CheckBox
        CheckBox_22                     matlab.ui.control.CheckBox
        CheckBox_23                     matlab.ui.control.CheckBox
        CheckBox_24                     matlab.ui.control.CheckBox
        CheckBox_25                     matlab.ui.control.CheckBox
        CheckBox_26                     matlab.ui.control.CheckBox
        CheckBox_27                     matlab.ui.control.CheckBox
        CheckBox_28                     matlab.ui.control.CheckBox
        CheckBox_29                     matlab.ui.control.CheckBox
        CheckBox_30                     matlab.ui.control.CheckBox
        CheckBox_31                     matlab.ui.control.CheckBox
        CheckBox_32                     matlab.ui.control.CheckBox
        Chan116Button                   matlab.ui.control.StateButton
        Chan1732Button                  matlab.ui.control.StateButton
        ClustsCheck                     matlab.ui.control.CheckBox
        ExperimentDrop                  matlab.ui.control.DropDown
        ExpButton1                      matlab.ui.control.Button
        ExpDurationRadio                matlab.ui.container.ButtonGroup
        InfiniteButton                  matlab.ui.control.RadioButton
        LastoffsetnsecButton            matlab.ui.control.RadioButton
        SecondsButton                   matlab.ui.control.RadioButton
        StimDurLabel                    matlab.ui.control.Label
        TotalStimDurLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ExpDurationEdit                 matlab.ui.control.NumericEditField
        LoadThresholdsButton            matlab.ui.control.Button
        BlockEditFieldLabel             matlab.ui.control.Label
        BlockEdit                       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
    end

    properties (Access = public)
        syn = [];           % links to Synapse
        experiment = [];    % 1 Iso Intensity, 2 LDS Noise, 3 LDS Tone
        pars = [];          % Parameters
        gizmos = [];        % Gizmos
        data = [];
    end

    methods (Access = private)


        function yrange = plotpsth(app, ax, pst, yrange, nclusts, sy, sz, stims, scalet)
                switch lower(app.PSTHscaleradio.SelectedObject.Text)
                    case 'independent'
                    case 'x psths'
                        yrange = repmat(max(yrange, [], 'omitnan'), numel(pst), 1);
                    case 'x all'
                        if app.PSTHscaleEdit.Value == 0
                            yrange = repmat(max(max(yrange, [], 'omitnan'), [], 'omitnan'), numel(pst), sy*sz);
                        else
                            yrange = repmat(app.PSTHscaleEdit.Value, numel(pst), sy*sz);
                        end
                end
                hold(ax, 'on');
                
                for i = 1:numel(pst)
                    rectangle(ax, 'Position', [i-1+stims(1) -sy*sz  stims(2)./scalet sy*sz], 'FaceColor', .8*[1 1 1], 'EdgeColor', .8*[1 1 1]);
                    bintime1 = pst(i).bintime(find(~isnan(pst(i).bintime), 1));
                    plot(ax, i - 1 + pst(i).bintime./scalet, (pst(i).bincount./yrange(i,:))- (1:(sy*sz)));
                    plot(ax, i-1 + pst(i).PSTHspets./scalet, 1-1*pst(i).PSTHchans - pst(i).PSTHclusters./nclusts, 'k.');
                end

                hold(ax, 'off'); grid(ax, 'on');
                ylabel(ax, 'Channel');
                title(ax, [app.TankEditField.Value newline 'Level = ', app.LevelDrop.Value ', '  app.PSTHscaleradio.SelectedObject.Text ' normalization']);
        end

        function exporttables(app, savepath, ntable, name)
            checkfield = [name 'Check'];
            fname = [savepath '\' app.PrefixEdit.Value, name '.csv'];
            d = datestr(now, 'yymmdd-HHMMSS');
            if app.(checkfield).Value
                if exist(fname, 'file')
                    writetable(ntable, [savepath '\' app.PrefixEdit.Value name d '.csv'])
                else
                    writetable(ntable, fname)
                end
            end
        end

        function results = paramupdate(app, ind)
            LPCFi = find(ismember(app.pars, 'LPCF'));
            HPCFi = find(ismember(app.pars, 'HPCF'));
            LPCFfield = ['Param' num2str(LPCFi) 'Edit'];
            HPCFfield = ['Param' num2str(HPCFi) 'Edit'];

            switch char(app.pars(ind))
                case 'HPCF'
                    app.CenterFreqEdit.Value = sqrt(app.(HPCFfield).Value * app.(LPCFfield).Value);
                    app.BandwidthEdit.Value = log2(app.(LPCFfield).Value / app.(HPCFfield).Value);  
                case 'LPCF'
                    app.CenterFreqEdit.Value = sqrt(app.(HPCFfield).Value * app.(LPCFfield).Value);
                    app.BandwidthEdit.Value = log2(app.(LPCFfield).Value / app.(HPCFfield).Value);  
                case 'ModDepth'
                    rmscorrect(app)
                case 'ModExponent'
                    rmscorrect(app)
            end
        end
        
        function results = rmscorrect(app)
            dbshift = [0, -1.7556, 4.2331, -1.1797, 5.635, -0.9538, 6.445, -0.8287, 7.0514];
            MEi = find(ismember(app.pars, 'ModExponent'));
            MEfield = ['Param' num2str(MEi) 'Edit'];
            MDi = find(ismember(app.pars, 'ModDepth'));
            MDfield = ['Param' num2str(MDi) 'Edit'];
            Leveli = find(ismember(app.pars, 'LDSLevel'));
            Levelfield = ['Param' num2str(Leveli) 'Edit'];
            exp = round(app.(MDfield).Value/100) * app.(MEfield).Value+1;
            app.(Levelfield).Value = dbshift(exp) + app.TRMSEdit.Value;

        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StimPanel.Visible = 0;
%             app.TONEStimulusPanel.Visible = 0;
%             app.FRAStimulusPanel.Visible = 0;
            app.ExpDurationRadio.Visible = 0;
            
            app.syn = SynapseAPI();
            updatemode(app);
            app.ExperimentDrop.Items = app.syn.getKnownExperiments;
            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            livestreamapp2(app, 'Preview')
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            livestreamapp2(app, 'Record')
        end

        % Button pushed function: IdleButton
        function IdleButtonPushed(app, event)
            app.syn.setMode(0);
            app.StimPanel.Visible = 0; app.ExpDurationRadio.Visible = 0;
            updatemode(app);
        end

        % Button pushed function: ExpButton1
        function ExpButton1Pushed(app, event)
            if app.syn.getMode > 0
               app.syn.setMode(0);
            end
            app.DialogueLabel.Text = 'Loading Experiment'; pause(.1);
            app.syn.setCurrentExperiment(app.ExperimentDrop.Value);
            
            c = clock();
            d = [num2str(mod(c(1), 100)) sprintf('%02d', c(2)), sprintf('%02d', c(3))];
            t = [sprintf('%02d', c(4)) sprintf('%02d', c(5))];
            blockname = [sprintf('%03d', app.BlockEdit.Value) '-' app.syn.getCurrentExperiment() '-' d '-' t];
            app.syn.setCurrentBlock(blockname);

            app.syn.setMode(1);
            updatemode(app); updatepars2(app);
            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: SelectTankButton
        function SelectTankButtonPushed(app, event)
            [filepath, ~, ~] = fileparts(app.TankEditField.Value);
            if isempty(filepath)
                tankpath = uigetdir('D:\Synapse\Tanks');
            else
                tankpath = uigetdir(filepath);
            end
            if tankpath ~= 0
                app.TankEditField.Value = tankpath;
            end
            figure(app.UIFigure);
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            data = readtank(app.TankEditField.Value);
            data.chanlist = updatechans(app);
            data.nchans = max(data.chanlist);
            frqlist = sort(unique(data.frqs));
            app.FrequencyDrop.Items = compose('%d', frqlist);
            app.LevelDrop.Items = {'0'};
            if isfield(data, 'lvls')
                lvllist = sort(unique(data.lvls));
                app.LevelDrop.Items = ['all'; compose('%d', lvllist)];
            end
             try
                load([app.TankEditField.Value '\params.mat']');
                chstr = ''; namestr = '';
                charr = num2str(cell2mat(table2cell(changetable))');
                for row = 1:size(charr, 1)
                    chstr = [chstr, charr(row,:), newline];
                    namestr = [namestr, char(changetable.Properties.VariableNames(row)), newline];
                end
                app.ParamsLabel2.Text = chstr;
                 app.ParamsLabel.Text = namestr;
                 
                if any(strcmp('LDSOnsetDelay',fieldnames(changetable)))
                    app.PSTH1StartEdit.Value = -1*table2array(changetable(1, 'LDSOnsetDelay'));
                    app.PSTH2StartEdit.Value = 0;
                    
                    app.PSTH1EndEdit.Value = 0;
                    app.PSTH2EndEdit.Value = table2array(changetable(1, 'LDSDuration'));
                    app.PSTHBinEdit.Value = table2array(changetable(1, 'LDSDuration'))*10;
                elseif numel(data.stimons) > 1
                    tab1 = tabulate(round(diff(data.stimons).*1000)./1000);
                    [~,i] = sort(tab1(:,2), 'descend');
                    j = find(tab1(i, 2)>0);
                    isis = tab1(i(j), 1:2);
                    app.PSTH1StartEdit.Value = 0;
                    app.PSTH2StartEdit.Value = 0;
                    
                    app.PSTH1EndEdit.Value = isis(1,1);
                    app.PSTH2EndEdit.Value = isis(1,1);
                    app.PSTHBinEdit.Value = isis(1,1)*10;
                end
             end
             app.DialogueLabel.Text = 'Ready';
             app.data = data;


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            data = app.data;
            data.chanlist = updatechans(app);
            data.nchans = max(data.chanlist);
            [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(data.spets, data.stimons, data.frqs, data.lvls);
            binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
            analwin = [app.OnsetEdit.Value app.OffsetEdit.Value]./1000; %ftc analysis window;
            
%tuning curve
            ax = app.FTCAxes; cla(app.FTCAxes, 'reset');
            
            if app.FTCPopupCheck.Value
                figure(2); clf; ax = axes();
            end
            if numel(unique(data.frqs))>1 && numel(unique(data.lvls)) > 1
                multifra(stimspets, spetfreq, spetlevel, analwin, data.channels, trials, data.chanlist, data.frqs, data.lvls, ax)
            elseif numel(unique(data.frqs))>1 && numel(unique(data.lvls)) == 1
            trials = reshape(trials, 1, numel(trials));
                synftc(stimspets, spetfreq, analwin, data.channels, trials(1:end), data.chanlist, data.frqs, ax)
                set(ax,'XTickLabel', sort(unique(data.frqs))./1000)
                xlabel(ax, 'Frequency (kHz)')
            elseif numel(unique(data.frqs))==1 && numel(unique(data.lvls)) > 1
                trials = reshape(trials, 1, numel(trials));          
                synftc(stimspets, spetlevel, analwin, data.channels, trials(1:end), data.chanlist, data.lvls, ax)
                set(ax,'XTickLabel', sort(unique(data.lvls)))
                xlabel(ax, 'Level (dB)')
                title(ax, [app.TankEditField.Value newline num2str(app.OnsetEdit.Value) '-' num2str(app.OffsetEdit.Value) ' ms'])
            end


%running spike rate average
            if ~app.ClustsCheck.Value
                data.clusters = ones(size(stimspets));
                bincount = NaN(round(max(data.spets)/binwindow), 1, data.nchans);
            else
                bincount = NaN(round(max(data.spets)/binwindow), max(data.clusters), data.nchans);
            end
            for chan = data.chanlist'
                for cluster = 1:max(data.clusters)
                    [bincount(:,cluster, chan), bintime] = hist(data.spets(data.channels==chan & data.clusters==cluster), 0.5*binwindow:binwindow:max(data.spets));
                end
            end
            if app.fratescaleEdit.Value == 0
                yscale = max(max(max(bincount)));
            else
                yscale = app.fratescaleEdit.Value*binwindow;
            end
            [sx, sy, sz] = size(bincount);
            bincount2 = reshape(bincount, sx, sy*sz);

            frate = bincount2./sy./yscale - ((1:sy*sz)./sy);
            cla(app.rateAxes, 'reset');
            plotfrate(app, frate, bintime, data.stimons, data.stimoffs, data.chanlist);
           app.data.ratetable = table(reshape(bintime, [numel(bintime), 1]), bincount2, 'VariableNames', {'BinTime', 'BinCount'});

            if numel(unique(data.clusters))> 1
                app.frateTable.Data = [reshape(repmat(1:max(data.chanlist), sy, 1), sy*sz, 1), reshape(repmat(1:max(double(data.clusters)), 1, sz), sy*sz, 1), max(bincount2)'./binwindow];
                app.frateTable.ColumnWidth = {50, 50, 50};
                app.frateTable.ColumnName = {'Chan', 'Clust', 'MaxRate'};
            else
                app.frateTable.Data = [(1:max(data.chanlist))', max(bincount2)'./binwindow];
                app.frateTable.ColumnName = {'Chan', 'MaxRate'};
            end

%PSTH
            frqlist = sort(unique(data.frqs));
            app.FrequencyDrop.Items = compose('%d', frqlist);
            yrange = NaN(2, sy*sz);
            averate = NaN(2, sy*sz);
            cla(app.PSTH1Axes, 'reset');
            cla(app.PSTH2Axes, 'reset');
            lvllist = sort(unique(data.lvls));
            app.LevelDrop.Items = ['all'; compose('%d', lvllist)];
            targetlevel = -1;
            if ~isnan(str2double(app.LevelDrop.Value))
                targetlevel = str2double(app.LevelDrop.Value);
            end
            if numel(unique(data.lvls))> 1 && numel(unique(data.frqs))> 1 %FRA
                figure(3); clf; ax = axes(); cla(ax, 'reset');
                for freqi = 1:numel(frqlist)
                    [pst(freqi), yrange(freqi,:), averate(freqi,:), stims] = synpstbin(app, data, 1, spetfreq, frqlist(freqi), spetlevel, targetlevel);
                end
                plotpsth(app, ax, pst, yrange, max(data.clusters), sy, sz, stims, 1.2*(app.PSTH1EndEdit.Value - app.PSTH1StartEdit.Value));
                set(ax, 'XTick', (1:numel(pst)) - 1)
                set(ax, 'XTickLabel', compose('%d', frqlist))
                xlabel(ax, 'Frequency');

                figure(4); clf; ax = axes(); cla(ax, 'reset');
                for lvli = 1:numel(lvllist)
                    [pst(lvli), yrange(lvli, :), averate(lvli,:), stims] = synpstbin(app, data, 1, spetfreq, str2double(app.FrequencyDrop.Value), spetlevel, lvllist(lvli));
                end
                plotpsth(app, ax, pst, yrange, max(data.clusters), sy, sz, stims, 1.2*(app.PSTH1EndEdit.Value - app.PSTH1StartEdit.Value)); 
                set(ax, 'XTick', (1:numel(pst)) - 1)
                set(ax, 'XTickLabel', compose('%d', lvllist))
                xlabel(ax, 'Level');
            elseif isempty(data.evons) || app.PSTHCycleCheck.Value == 0 %LDS silent
                for i = 1:2
                    [pst(i), yrange(i,:), averate(i,:), stims(i,:)] = synpstbin(app, data, i, spetfreq, str2double(app.FrequencyDrop.Value), spetlevel, targetlevel);
                    if app.PSTHPopupCheck.Value
                        figure(2+i); clf; ax(i) = axes(); 
                    else
                        axesfield = ['PSTH' num2str(i) 'Axes'];
                        ax(i) = app.(axesfield); 
                    end
                    cla(ax(i), 'reset'); hold(ax(i), 'on');
                end
                if strcmpi(app.PSTHbaseline.SelectedObject.Text, 'mean')
                    baseline = averate(1,:);
                else
                    baseline = poissinv(app.CLEdit.Value/100, averate(1,:));
                end
                for i = 1:2
                    yrange(i,:) = plotpsth(app, ax(i), pst(i), yrange, max(data.clusters), sy, sz, stims(i,:), 1); 
                    ylim(ax(i), -1.*[max(data.chanlist)+.5 min(data.chanlist)-1])
                    ylabel(ax(i), 'Channel')
                    readfield = ['PSTH' num2str(i) 'RefDrop'];
                    xlabel(ax(i), ['Time relative to ' app.(readfield).Value ' (sec)'])
                    title(ax(i), [app.TankEditField.Value newline 'Frequency = ' app.FrequencyDrop.Value ' Hz, ' app.PSTHscaleradio.SelectedObject.Text ' normalization' ...
                        newline num2str(numel(pst(i).PSTHspets)) ' spikes, ' num2str(sum(data.frqs==str2double(app.FrequencyDrop.Value))) ' trials'])
                    hold(ax(i), 'on');
                    plot(ax(i), [pst(i).bintime(find(~isnan(pst(i).bintime), 1)) pst(i).bintime(end)], ([baseline; baseline]./yrange(i,:)) - ...
                    (1:max(data.clusters)*max(data.chanlist))./max(data.clusters), 'r:');
                    hold(ax(i), 'off');
                    
                %LFP
                    reffield = ['LFP' num2str(i) 'RefDrop'];
                    startfield = ['LFP' num2str(i) 'StartEdit'];
                    endfield = ['LFP' num2str(i) 'EndEdit'];
                    if app.LFPPopupCheck.Value
                        figure(4+i); clf; lax(i) = axes(); 
                    else
                        axesfield = ['LFP' num2str(i) 'Axes'];
                        lax(i) = app.(axesfield); 
                    end
                    cla(lax(i), 'reset');
                    if strcmpi(app.(reffield).Value, 'onset')
                        analwin = [app.(startfield).Value, app.(endfield).Value] + data.stimons(1);
                    else
                        analwin = [app.(startfield).Value, app.(endfield).Value] + data.stimoffs(1);
                    end
                        x = app.(startfield).Value:(1/data.fs):app.(endfield).Value;
                        yi1 = round(analwin(1).*data.fs);
                        yi2 = yi1 + numel(x) - 1;
                        y = data.LFP(yi1:yi2,data.chanlist);
                        plot(lax(i), x, y./range(reshape(y, numel(y), 1)) - data.chanlist');

                end %for PSTH1 and 2
                for chani = data.chanlist'
                    for j = 1:sy
                        text(lax(1), app.PSTH1StartEdit.Value, 1.1 - j/sy - chani, num2str(baseline(1,(chani*sy)+j-1), '%0.2f'));
                    end
                end
                123
                xlim(ax(1), [app.PSTH1StartEdit.Value app.PSTH1EndEdit.Value]);
                456
                xlim(ax(2), [app.PSTH2StartEdit.Value app.PSTH2EndEdit.Value]);
                789

            
            else %LDS evoked
                analwin = [0 20];
                period = mode(diff(data.evons));
                stimdur = mode(data.evoffs - data.evons);
                t = (1:size(data.raw, 1))./data.fs;
                for i = 1:2
                    reffield = ['PSTH' num2str(i) 'RefDrop'];
                    startfield = ['PSTH' num2str(i) 'StartEdit'];
                    endfield = ['PSTH' num2str(i) 'EndEdit'];
                    if strcmpi(app.(reffield).Value, 'onset')
                        ref = data.stimons(1);
                    else
                        ref = data.stimoffs(1);
                    end
                    psthonset = app.(startfield).Value;
                    psthoffset = app.(endfield).Value;
                    selection = (data.spets > (ref + psthonset)) & (data.spets < (ref + psthoffset));
                    selchannels = data.channels(selection);
                    selclusters = data.clusters(selection);
                    
                    [~, ~, PSTHspets, ~] = freqlevelspet(data.spets(selection), data.evons, data.frqs, data.lvls);
                    pst(i).PSTHclusters = selclusters(~isnan(PSTHspets) & ismember(selchannels, data.chanlist));
                    pst(i).PSTHchans = selchannels(~isnan(PSTHspets) & ismember(selchannels, data.chanlist));
                    pst(i).PSTHspets = PSTHspets(~isnan(PSTHspets) & ismember(selchannels, data.chanlist)); %subset of spikes that fall within PSTH window
                    psthwindow = app.PSTHBinEdit.Value./1000; 
                    binvec = 0:psthwindow:period;
                    bincount = NaN(length(binvec), max(data.clusters), max(data.chanlist));
                    bintime = NaN(length(binvec), max(data.clusters), max(data.chanlist));
                    yrange = NaN(max(data.clusters), max(data.chanlist));
                    averate2 = NaN(max(data.clusters), max(data.chanlist));
                    for chan = data.chanlist'
                        for cluster = 1:max(data.clusters)
                            [bincount(:, cluster,chan), bintime(:, cluster,chan)] = hist(pst(i).PSTHspets(pst(i).PSTHchans==chan & pst(i).PSTHclusters==cluster), binvec);
                            yrange(cluster, chan) = max(bincount(:, cluster, chan));
                            averate2(cluster, chan) = sum(bincount(:, cluster, chan)) / (bintime(end, cluster, chan)-bintime(1, cluster, chan));
                        end
                    end
                    [sx, sy, sz] = size(bincount);
                    pst(i).bincount = reshape(bincount, sx, sy*sz);
                    pst(i).bintime = reshape(bintime, sx, sy*sz);
                    if app.PSTHPopupCheck.Value
                        figure(2+i); clf; ax(i) = axes(); 
                    else
                        axesfield = ['PSTH' num2str(i) 'Axes'];
                        ax(i) = app.(axesfield); 
                    end
                    cla(ax(i), 'reset');

                    plotpsth(app, ax(i), pst(i), yrange, max(data.clusters), sy, sz, [0 stimdur], 1); 
                     axis(ax(i), [0 period -sy*sz 1-min(data.chanlist)]);
                    averate(i,:) = reshape(averate2, sy*sz, 1);
            %LFP
                    reffield = ['LFP' num2str(i) 'RefDrop'];
                    startfield = ['LFP' num2str(i) 'StartEdit'];
                    endfield = ['LFP' num2str(i) 'EndEdit'];
                    if app.LFPPopupCheck.Value
                        figure(4+i); clf; ax(i) = axes(); 
                    else
                        axesfield = ['LFP' num2str(i) 'Axes'];
                        ax(i) = app.(axesfield); 
                    end
                    cla(ax(i), 'reset');
                    if strcmpi(app.(reffield).Value, 'onset')
                        analwin = [app.(startfield).Value, app.(endfield).Value] + data.stimons(1);
                    else
                        analwin = [app.(startfield).Value, app.(endfield).Value] + data.stimoffs(1);
                    end
                    cyclet = data.evons(data.evons>analwin(1) & data.evons<analwin(2));
                    cycletsamp = round(cyclet*data.fs);
                    cycleperiod = min(diff(cycletsamp));
                    lfpcycle = NaN(cycleperiod, size(data.LFP, 2), numel(cyclet));
                    for cycle = 1:numel(cyclet)
                        startsamp = 1+cycletsamp(cycle);
                        endsamp = cycletsamp(cycle)+cycleperiod;
                        lfpcycle(:,:,cycle) = data.LFP(startsamp:endsamp,:);
                    end
                        x = (1:cycleperiod)./data.fs;
                        lfpmeans = mean(lfpcycle, 3);
                        lfpmeansplot = lfpmeans(:, data.chanlist);
                        y = lfpmeansplot./range(reshape(lfpmeansplot, numel(lfpmeansplot), 1)) - data.chanlist';
                        plot(ax(i), x, y);
                end
                
            end %if PSTH condition
            app.data.psth1table = table(pst(1).bintime(:,data.chanlist(1) * sy), pst(1).bincount, 'VariableNames', {'BinTime', 'BinCount'});
            app.data.psth2table = table(pst(2).bintime(:,data.chanlist(1) * sy), pst(2).bincount, 'VariableNames', {'BinTime', 'BinCount'});
            psthwindow = app.PSTHBinEdit.Value./1000;
            CI5(1,:) = poissinv(5/100, averate(1,:));
            CI5(2,:) = poissinv(5/100, averate(2,:));
            CI95(1,:) = poissinv(95/100, averate(1,:));
            CI95(2,:) = poissinv(95/100, averate(2,:));
            app.data.statstable = table((1:(sy*sz))', averate(1,:)', averate(2,:)', CI5(1,:)', CI5(2,:)', CI95(1,:)', CI95(2,:)', ...
                'VariableNames', {'Channel', 'PSTH1AveRate', 'PSTH2AveRate', 'PSTH1CI5', 'PSTH2CI5', 'PSTH1CI95', 'PSTH2CI95'});
            app.DialogueLabel.Text = 'Ready';
        end

        % Value changed function: Chan116Button
        function Chan116ButtonValueChanged(app, event)
            value = app.Chan116Button.Value;
            if value
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan116Button.Text = 'Clear 1-16';
            else
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end                
                app.Chan116Button.Text = 'Check 1-16';
            end
        end

        % Value changed function: Chan1732Button
        function Chan1732ButtonValueChanged(app, event)
            value = app.Chan1732Button.Value;
            if value
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan1732Button.Text = 'Clear 17-32';
            else
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end
                app.Chan1732Button.Text = 'Check 17-32';
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            savepath = uigetdir('D:\Synapse\ExperimentData\');
            figure(app.UIFigure);
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            data = app.data;
            data.chanlist = updatechans(app);
            data.nchans = max(data.chanlist);

            [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(data.spets, data.stimons, data.frqs, data.lvls);
            if isempty(data.evons)
                stimtable = table(data.stimons, data.stimoffs, data.frqs, data.lvls, 'VariableNames', {'OnsetTime', 'OffsetTime', 'Frequency', 'Level'});
            else
                stimtable = table(data.evons, data.evoffs, data.frqs, data.lvls, 'VariableNames', {'OnsetTime', 'OffsetTime', 'Frequency', 'Level'});
            end
            spiketable = table(data.spets, data.channels, data.clusters, stimspets, spetfreq, spetlevel, 'VariableNames', {'SpikeTime', 'Channel', 'Cluster', 'PostStimulusTime', 'LastFreq', 'LastLevel'});

            ratetable = app.data.ratetable;
            psth1table = app.data.psth1table;
            psth2table = app.data.psth2table;
            
            statstable = app.data.statstable;
            
            for i = 1:2
                [pst(i), ~, ~, ~] = synpstbin(app, data, i, spetfreq, str2double(app.FrequencyDrop.Value), [], -1);
            end
                try
                    load([app.TankEditField.Value '\params.mat']', 'changetable');
                    exporttables(app, savepath, changetable, 'params');
                end
                exporttables(app, savepath, stimtable, 'stim');
                exporttables(app, savepath, spiketable, 'spike');
                exporttables(app, savepath, ratetable, 'rate');
                exporttables(app, savepath, psth1table, 'psth1');
                exporttables(app, savepath, psth2table, 'psth2');
                exporttables(app, savepath, statstable, 'averate');

        end

        % Button pushed function: LoadThresholdsButton
        function LoadThresholdsButtonPushed(app, event)
            [threshfile, threshpath, filt] = uigetfile('D:\Synapse\Tanks\');
            if filt
                load([threshpath threshfile]);
                if exist('threshlist', 'var')
                    for i = 1:numel(threshlist)
                        app.syn.setParameterValue('Neu1', ['Threshold' num2str(i)], threshlist(i));
                    end
                end
            end
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            for par = 1:numel(app.pars)
                mvarname = ['Param' num2str(par) 'Edit'];
                mvarname2 = ['Param' num2str(par) 'Label'];
                app.(mvarname).Value= str2double(app.(mvarname2).Text);
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            for par = 1:numel(app.pars)
                mvarname = ['Param' num2str(par) 'Edit'];
                app.syn.setParameterValue(char(app.gizmos(par)), char(app.pars(par)), app.(mvarname).Value);
            end
%             if strcmpi(prefix, 'iso')
%                 app.syn.setParameterValue('Stimulus', 'GateHiTime', app.Par3Edit.Value*(app.Param1Edit.Value + app.Par2Edit.Value));
%             end
            pause(1);
            updatepars2(app);

        end

        % Value changed function: BandwidthEdit, CenterFreqEdit
        function CenterFreqEditValueChanged(app, event)
            value = app.CenterFreqEdit.Value;
            LPCFi = find(ismember(app.pars, 'LPCF'));
            HPCFi = find(ismember(app.pars, 'HPCF'));
            LPCFfield = ['Param' num2str(LPCFi) 'Edit'];
            HPCFfield = ['Param' num2str(HPCFi) 'Edit'];
            app.(LPCFfield).Value = app.CenterFreqEdit.Value * 2 ^ (app.BandwidthEdit.Value / 2);
            app.(HPCFfield).Value = app.CenterFreqEdit.Value * 2 ^ ((app.BandwidthEdit.Value) / -2 );
        end

        % Value changed function: TRMSEdit
        function TRMSEditValueChanged(app, event)
            rmscorrect(app)
        end

        % Value changed function: Param1Edit
        function Param1EditValueChanged(app, event)
            paramupdate(app, 1)           
        end

        % Value changed function: Param2Edit
        function Param2EditValueChanged(app, event)
            paramupdate(app, 2)
        end

        % Value changed function: Param3Edit
        function Param3EditValueChanged(app, event)
            paramupdate(app, 3)
        end

        % Value changed function: Param4Edit
        function Param4EditValueChanged(app, event)
            paramupdate(app, 4)
        end

        % Value changed function: Param5Edit
        function Param5EditValueChanged(app, event)
            paramupdate(app, 5)
        end

        % Value changed function: Param6Edit
        function Param6EditValueChanged(app, event)
            paramupdate(app, 6)
        end

        % Value changed function: Param7Edit
        function Param7EditValueChanged(app, event)
            paramupdate(app, 7)
        end

        % Value changed function: Param8Edit
        function Param8EditValueChanged(app, event)
            paramupdate(app, 8)
        end

        % Value changed function: Param9Edit
        function Param9EditValueChanged(app, event)
            paramupdate(app, 9)
        end

        % Value changed function: Param10Edit
        function Param10EditValueChanged(app, event)
            paramupdate(app, 10)
        end

        % Value changed function: Param11Edit
        function Param11EditValueChanged(app, event)
            paramupdate(app, 11)
        end

        % Value changed function: Param12Edit
        function Param12EditValueChanged(app, event)
            paramupdate(app, 12)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.DialogueLabel = uilabel(app.UIFigure);
            app.DialogueLabel.Text = 'Dialogue';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SynapseTab = uitab(app.TabGroup);
            app.SynapseTab.Title = 'Synapse';
            app.StimPanel = uipanel(app.SynapseTab);
            app.StimPanel.Title = 'Stimulus Parameters';
            app.StimAxes = uiaxes(app.StimPanel);
            title(app.StimAxes, 'Stim Preview')
            xlabel(app.StimAxes, 'Time')
            ylabel(app.StimAxes, 'Y')
            app.Param1Label = uilabel(app.StimPanel);
            app.Param1Label.Text = 'Param1';
            app.Param2Label = uilabel(app.StimPanel);
            app.Param2Label.Text = 'Param2';
            app.Param3Label = uilabel(app.StimPanel);
            app.Param3Label.Text = 'Param3';
            app.Param4Label = uilabel(app.StimPanel);
            app.Param4Label.Text = 'Param4';
            app.Param5Label = uilabel(app.StimPanel);
            app.Param5Label.Text = 'Param5';
            app.Param6Label = uilabel(app.StimPanel);
            app.Param6Label.Text = 'Param6';
            app.Param7Label = uilabel(app.StimPanel);
            app.Param7Label.Text = 'Param7';
            app.Param8Label = uilabel(app.StimPanel);
            app.Param8Label.Text = 'Param8';
            app.Param9Label = uilabel(app.StimPanel);
            app.Param9Label.Text = 'Param9';
            app.Param10Label = uilabel(app.StimPanel);
            app.Param10Label.Text = 'Param10';
            app.Param11Label = uilabel(app.StimPanel);
            app.Param11Label.Text = 'Param11';
            app.GetButton = uibutton(app.StimPanel, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = '<- Get ';
            app.SetButton = uibutton(app.StimPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set ->';
            app.Par1Label = uilabel(app.StimPanel);
            app.Par1Label.Text = 'Par1';
            app.Param1Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param1Edit.ValueChangedFcn = createCallbackFcn(app, @Param1EditValueChanged, true);
            app.Par2Label = uilabel(app.StimPanel);
            app.Par2Label.Text = 'Par2';
            app.Param2Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param2Edit.ValueChangedFcn = createCallbackFcn(app, @Param2EditValueChanged, true);
            app.Par3Label = uilabel(app.StimPanel);
            app.Par3Label.Text = 'Par3';
            app.Param3Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param3Edit.ValueChangedFcn = createCallbackFcn(app, @Param3EditValueChanged, true);
            app.Par4Label = uilabel(app.StimPanel);
            app.Par4Label.Text = 'Par4';
            app.Param4Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param4Edit.ValueChangedFcn = createCallbackFcn(app, @Param4EditValueChanged, true);
            app.Par5Label = uilabel(app.StimPanel);
            app.Par5Label.Text = 'Par5';
            app.Param5Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param5Edit.ValueChangedFcn = createCallbackFcn(app, @Param5EditValueChanged, true);
            app.Par6Label = uilabel(app.StimPanel);
            app.Par6Label.Text = 'Par6';
            app.Param6Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param6Edit.ValueChangedFcn = createCallbackFcn(app, @Param6EditValueChanged, true);
            app.Par7Label = uilabel(app.StimPanel);
            app.Par7Label.Text = 'Par7';
            app.Param7Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param7Edit.ValueChangedFcn = createCallbackFcn(app, @Param7EditValueChanged, true);
            app.Par8Label = uilabel(app.StimPanel);
            app.Par8Label.Text = 'Par8';
            app.Param8Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param8Edit.ValueChangedFcn = createCallbackFcn(app, @Param8EditValueChanged, true);
            app.Par9Label = uilabel(app.StimPanel);
            app.Par9Label.Text = 'Par9';
            app.Param9Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param9Edit.ValueChangedFcn = createCallbackFcn(app, @Param9EditValueChanged, true);
            app.Par10Label = uilabel(app.StimPanel);
            app.Par10Label.Text = 'Par10';
            app.Param10Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param10Edit.ValueChangedFcn = createCallbackFcn(app, @Param10EditValueChanged, true);
            app.Par11Label = uilabel(app.StimPanel);
            app.Par11Label.Text = 'Par11';
            app.Param11Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param11Edit.ValueChangedFcn = createCallbackFcn(app, @Param11EditValueChanged, true);
            app.SynapseValuesLabel_3 = uilabel(app.StimPanel);
            app.SynapseValuesLabel_3.Text = 'Synapse Values';
            app.Param12Label = uilabel(app.StimPanel);
            app.Param12Label.Text = 'Param12';
            app.Par12Label = uilabel(app.StimPanel);
            app.Par12Label.Text = 'Par12';
            app.Param12Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param12Edit.ValueChangedFcn = createCallbackFcn(app, @Param12EditValueChanged, true);
            app.CenterFreqEditFieldLabel = uilabel(app.StimPanel);
            app.CenterFreqEditFieldLabel.Text = 'Center Freq';
            app.CenterFreqEdit = uieditfield(app.StimPanel, 'numeric');
            app.CenterFreqEdit.ValueChangedFcn = createCallbackFcn(app, @CenterFreqEditValueChanged, true);
            app.BandwidthEditFieldLabel = uilabel(app.StimPanel);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEdit = uieditfield(app.StimPanel, 'numeric');
            app.BandwidthEdit.ValueChangedFcn = createCallbackFcn(app, @CenterFreqEditValueChanged, true);
            app.TargetRMSLabel = uilabel(app.StimPanel);
            app.TargetRMSLabel.Text = 'Target RMS';
            app.TRMSEdit = uieditfield(app.StimPanel, 'numeric');
            app.TRMSEdit.ValueChangedFcn = createCallbackFcn(app, @TRMSEditValueChanged, true);
            app.ReadTankTab = uitab(app.TabGroup);
            app.ReadTankTab.Title = 'ReadTank';
            app.SelectTankButton = uibutton(app.ReadTankTab, 'push');
            app.SelectTankButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTankButtonPushed, true);
            app.SelectTankButton.Text = 'Select Tank';
            app.PlotButton = uibutton(app.ReadTankTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TankEditFieldLabel = uilabel(app.ReadTankTab);
            app.TankEditFieldLabel.Text = 'Tank';
            app.TankEditField = uieditfield(app.ReadTankTab, 'text');
            app.ParamsLabel = uilabel(app.ReadTankTab);
            app.ParamsLabel.Text = '';
            app.ParamsLabel2 = uilabel(app.ReadTankTab);
            app.ParamsLabel2.Text = '';
            app.ExportButton = uibutton(app.ReadTankTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export Data';
            app.FilenameprefixEditFieldLabel = uilabel(app.ReadTankTab);
            app.FilenameprefixEditFieldLabel.Text = 'Filename prefix';
            app.PrefixEdit = uieditfield(app.ReadTankTab, 'text');
            app.paramsCheck = uicheckbox(app.ReadTankTab);
            app.paramsCheck.Text = 'Parameters';
            app.stimCheck = uicheckbox(app.ReadTankTab);
            app.stimCheck.Text = 'Stimulus Pulses';
            app.spikeCheck = uicheckbox(app.ReadTankTab);
            app.spikeCheck.Text = 'Spike Times';
            app.rateCheck = uicheckbox(app.ReadTankTab);
            app.rateCheck.Text = 'Total Spike Rates';
            app.psth1Check = uicheckbox(app.ReadTankTab);
            app.psth1Check.Text = 'PSTH1 Spike Rates';
            app.psth2Check = uicheckbox(app.ReadTankTab);
            app.psth2Check.Text = 'PSTH2 Spike Rates';
            app.averateCheck = uicheckbox(app.ReadTankTab);
            app.averateCheck.Text = 'Average Rates';
            app.CurrentModeLabel = uilabel(app.UIFigure);
            app.CurrentModeLabel.Text = 'Current Mode';
            app.IdleButton = uibutton(app.UIFigure, 'push');
            app.IdleButton.ButtonPushedFcn = createCallbackFcn(app, @IdleButtonPushed, true);
            app.IdleButton.Text = 'Idle';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.FiringRateTab = uitab(app.TabGroup2);
            app.FiringRateTab.Title = 'Firing Rate';
            app.rateAxes = uiaxes(app.FiringRateTab);
            title(app.rateAxes, 'Running Spike Rate')
            xlabel(app.rateAxes, 'Time')
            ylabel(app.rateAxes, 'Channel')
            app.BinsizemsLabel_2 = uilabel(app.FiringRateTab);
            app.BinsizemsLabel_2.Text = 'Bin size (ms)';
            app.BinEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.BinEdit.Value = 500;
            app.HistorysecLabel = uilabel(app.FiringRateTab);
            app.HistorysecLabel.Text = 'History (sec)';
            app.RangeEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.RangeEdit.Value = 60;
            app.ratePopupCheck = uicheckbox(app.FiringRateTab);
            app.ratePopupCheck.Text = 'Popup';
            app.fratescaleEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.yscale0maxobservedLabel = uilabel(app.FiringRateTab);
            app.yscale0maxobservedLabel.Text = 'y-scale (0 = max observed)';
            app.frateTable = uitable(app.FiringRateTab);
            app.frateTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.frateTable.RowName = {};
            app.FreqTuningTab = uitab(app.TabGroup2);
            app.FreqTuningTab.Title = 'Freq Tuning';
            app.OffsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.OffsetEdit.Value = 100;
            app.EvokedratewindowmsLabel_4 = uilabel(app.FreqTuningTab);
            app.EvokedratewindowmsLabel_4.Text = 'Evoked rate window (ms)';
            app.OnsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.FTCAxes = uiaxes(app.FreqTuningTab);
            title(app.FTCAxes, 'IsoIntensity Function')
            xlabel(app.FTCAxes, 'Frequency')
            ylabel(app.FTCAxes, 'Channel')
            app.FTCPopupCheck = uicheckbox(app.FreqTuningTab);
            app.FTCPopupCheck.Text = 'Popup';
            app.PSTHTab = uitab(app.TabGroup2);
            app.PSTHTab.Title = 'PSTH';
            app.PSTH1Axes = uiaxes(app.PSTHTab);
            title(app.PSTH1Axes, 'PSTH 1')
            xlabel(app.PSTH1Axes, 'Time')
            ylabel(app.PSTH1Axes, 'Spike Rate')
            app.PSTHPopupCheck = uicheckbox(app.PSTHTab);
            app.PSTHPopupCheck.Text = 'Popup';
            app.BinsizemsLabel = uilabel(app.PSTHTab);
            app.BinsizemsLabel.Text = 'Bin size (ms)';
            app.PSTHBinEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTHBinEdit.Value = 500;
            app.PSTH2Axes = uiaxes(app.PSTHTab);
            title(app.PSTH2Axes, 'PSTH 2')
            xlabel(app.PSTH2Axes, 'Time')
            ylabel(app.PSTH2Axes, 'Spike Rate')
            app.PSTH1WindowsecEditFieldLabel = uilabel(app.PSTHTab);
            app.PSTH1WindowsecEditFieldLabel.Text = 'PSTH 1 Window (sec)';
            app.PSTH1StartEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH1EndEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH1EndEdit.Value = 1;
            app.Referencet0DropDownLabel = uilabel(app.PSTHTab);
            app.Referencet0DropDownLabel.Text = 'Reference (t=0)';
            app.PSTH1RefDrop = uidropdown(app.PSTHTab);
            app.PSTH1RefDrop.Items = {'Onset', 'Offset'};
            app.PSTH1RefDrop.Value = 'Onset';
            app.Referencet0DropDown_2Label = uilabel(app.PSTHTab);
            app.Referencet0DropDown_2Label.Text = 'Reference (t=0)';
            app.PSTH2RefDrop = uidropdown(app.PSTHTab);
            app.PSTH2RefDrop.Items = {'Onset', 'Offset'};
            app.PSTH2RefDrop.Value = 'Offset';
            app.PSTH2EndEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH2EndEdit.Value = 1;
            app.PSTH2WindowsecEditFieldLabel = uilabel(app.PSTHTab);
            app.PSTH2WindowsecEditFieldLabel.Text = 'PSTH 2 Window (sec)';
            app.PSTH2StartEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTHscaleradio = uibuttongroup(app.PSTHTab);
            app.PSTHscaleradio.Title = 'Normalization';
            app.IndependentButton = uiradiobutton(app.PSTHscaleradio);
            app.IndependentButton.Text = 'Independent';
            app.xPSTHsButton = uiradiobutton(app.PSTHscaleradio);
            app.xPSTHsButton.Text = 'x PSTHs';
            app.xAllButton = uiradiobutton(app.PSTHscaleradio);
            app.xAllButton.Text = 'x All';
            app.xAllButton.Value = true;
            app.PSTHscaleEdit = uieditfield(app.PSTHscaleradio, 'numeric');
            app.FrequencyDropDownLabel = uilabel(app.PSTHTab);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDrop = uidropdown(app.PSTHTab);
            app.LevelLabel = uilabel(app.PSTHTab);
            app.LevelLabel.Text = 'Level';
            app.LevelDrop = uidropdown(app.PSTHTab);
            app.PSTHbaseline = uibuttongroup(app.PSTHTab);
            app.PSTHbaseline.Title = 'Show Baseline';
            app.MeanButton = uiradiobutton(app.PSTHbaseline);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.CLButton = uiradiobutton(app.PSTHbaseline);
            app.CLButton.Text = 'CL (%)';
            app.CLEdit = uieditfield(app.PSTHbaseline, 'numeric');
            app.CLEdit.Value = 95;
            app.PSTHCycleCheck = uicheckbox(app.PSTHTab);
            app.PSTHCycleCheck.Text = 'Average cycles';
            app.LFPTab = uitab(app.TabGroup2);
            app.LFPTab.Title = 'LFP';
            app.LFP1Axes = uiaxes(app.LFPTab);
            title(app.LFP1Axes, 'LFP 1')
            xlabel(app.LFP1Axes, 'Time')
            ylabel(app.LFP1Axes, 'Spike Rate')
            app.LFP2Axes = uiaxes(app.LFPTab);
            title(app.LFP2Axes, 'LFP 2')
            xlabel(app.LFP2Axes, 'Time')
            ylabel(app.LFP2Axes, 'Spike Rate')
            app.LFPPopupCheck = uicheckbox(app.LFPTab);
            app.LFPPopupCheck.Text = 'Popup';
            app.LFP1EndEdit = uieditfield(app.LFPTab, 'numeric');
            app.LFP1EndEdit.Value = 1;
            app.LFP2EndEdit = uieditfield(app.LFPTab, 'numeric');
            app.LFP2EndEdit.Value = 1;
            app.LFP1WindowsecLabel = uilabel(app.LFPTab);
            app.LFP1WindowsecLabel.Text = 'LFP 1 Window (sec)';
            app.LFP1StartEdit = uieditfield(app.LFPTab, 'numeric');
            app.Referencet0DropDownLabel_2 = uilabel(app.LFPTab);
            app.Referencet0DropDownLabel_2.Text = 'Reference (t=0)';
            app.LFP1RefDrop = uidropdown(app.LFPTab);
            app.LFP1RefDrop.Items = {'Onset', 'Offset'};
            app.LFP1RefDrop.Value = 'Onset';
            app.Referencet0DropDown_2Label_2 = uilabel(app.LFPTab);
            app.Referencet0DropDown_2Label_2.Text = 'Reference (t=0)';
            app.LFP2RefDrop = uidropdown(app.LFPTab);
            app.LFP2RefDrop.Items = {'Onset', 'Offset'};
            app.LFP2RefDrop.Value = 'Offset';
            app.LFP2WindowsecLabel = uilabel(app.LFPTab);
            app.LFP2WindowsecLabel.Text = 'LFP 2 Window (sec)';
            app.LFP2StartEdit = uieditfield(app.LFPTab, 'numeric');
            app.LFPCycleCheck = uicheckbox(app.LFPTab);
            app.LFPCycleCheck.Text = 'Average cycles';
            app.PollingperiodsecEditFieldLabel = uilabel(app.UIFigure);
            app.PollingperiodsecEditFieldLabel.Text = 'Polling period (sec)';
            app.UpdateEdit = uieditfield(app.UIFigure, 'numeric');
            app.UpdateEdit.Value = 1;
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.CheckBox_1 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_18 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_19 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_20 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_21 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_22 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_23 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_24 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_25 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_26 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_27 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_28 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_29 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_30 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_31 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_32 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_32.Text = '32';
            app.Chan116Button = uibutton(app.ChannelsPanel, 'state');
            app.Chan116Button.ValueChangedFcn = createCallbackFcn(app, @Chan116ButtonValueChanged, true);
            app.Chan116Button.Text = 'Clear 1-16';
            app.Chan116Button.Value = true;
            app.Chan1732Button = uibutton(app.ChannelsPanel, 'state');
            app.Chan1732Button.ValueChangedFcn = createCallbackFcn(app, @Chan1732ButtonValueChanged, true);
            app.Chan1732Button.Text = 'Check 17-32';
            app.ClustsCheck = uicheckbox(app.UIFigure);
            app.ClustsCheck.Text = 'Show Clusters';
            app.ExperimentDrop = uidropdown(app.UIFigure);
            app.ExpButton1 = uibutton(app.UIFigure, 'push');
            app.ExpButton1.ButtonPushedFcn = createCallbackFcn(app, @ExpButton1Pushed, true);
            app.ExpButton1.Text = 'Load Experiment';
            app.ExpDurationRadio = uibuttongroup(app.UIFigure);
            app.ExpDurationRadio.Title = 'Set to Idle After...';
            app.InfiniteButton = uiradiobutton(app.ExpDurationRadio);
            app.InfiniteButton.Text = 'Infinite';
            app.InfiniteButton.Value = true;
            app.LastoffsetnsecButton = uiradiobutton(app.ExpDurationRadio);
            app.LastoffsetnsecButton.Text = 'Last offset + n sec';
            app.SecondsButton = uiradiobutton(app.ExpDurationRadio);
            app.SecondsButton.Text = 'Seconds';
            app.StimDurLabel = uilabel(app.ExpDurationRadio);
            app.StimDurLabel.Text = '';
            app.TotalStimDurLabel = uilabel(app.ExpDurationRadio);
            app.TotalStimDurLabel.Text = 'Total Stimulus Duration:';
            app.Label = uilabel(app.ExpDurationRadio);
            app.Label.Text = '';
            app.ExpDurationEdit = uieditfield(app.ExpDurationRadio, 'numeric');
            app.ExpDurationEdit.Value = 60;
            app.LoadThresholdsButton = uibutton(app.ExpDurationRadio, 'push');
            app.LoadThresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadThresholdsButtonPushed, true);
            app.LoadThresholdsButton.Text = 'Load Thresholds';
            app.BlockEditFieldLabel = uilabel(app.UIFigure);
            app.BlockEditFieldLabel.Text = 'Block';
            app.BlockEdit = uieditfield(app.UIFigure, 'numeric');
            app.BlockEdit.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = synui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end