classdef synui_r181120 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewButton                   matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        LoadParamsButton                matlab.ui.control.Button
        SaveParamsButton                matlab.ui.control.Button
        ParamFileEditFieldLabel         matlab.ui.control.Label
        ParamFileEditField              matlab.ui.control.EditField
        DialogueLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        IsoIntensityTab                 matlab.ui.container.Tab
        IsoStimulusPanel                matlab.ui.container.Panel
        IsoStimHiTimeLabel              matlab.ui.control.Label
        IsoStimLoTimeLabel              matlab.ui.control.Label
        SynapseValuesLabel              matlab.ui.control.Label
        IsoStimNPulsesLabel             matlab.ui.control.Label
        IsoGateLoTimeLabel              matlab.ui.control.Label
        IsoGateNPulsesLabel             matlab.ui.control.Label
        IsoLevelLabel                   matlab.ui.control.Label
        GateHiTimemsLabel               matlab.ui.control.Label
        IsoStimHiTimeEdit               matlab.ui.control.NumericEditField
        GateLoTimemsLabel               matlab.ui.control.Label
        IsoStimLoTimeEdit               matlab.ui.control.NumericEditField
        StimNPulsesLabel                matlab.ui.control.Label
        IsoStimNPulsesEdit              matlab.ui.control.NumericEditField
        GateLoTimemsLabel_2             matlab.ui.control.Label
        IsoGateLoTimeEdit               matlab.ui.control.NumericEditField
        GateNPulsesLabel                matlab.ui.control.Label
        IsoGateNPulsesEdit              matlab.ui.control.NumericEditField
        LeveldBEditFieldLabel           matlab.ui.control.Label
        IsoLevelEdit                    matlab.ui.control.NumericEditField
        IsoGetButton                    matlab.ui.control.Button
        IsoSetButton                    matlab.ui.control.Button
        ExperimentNameEditFieldLabel    matlab.ui.control.Label
        ExpEdit1                        matlab.ui.control.EditField
        ExpButton1                      matlab.ui.control.Button
        LDSNoiseTab                     matlab.ui.container.Tab
        NBNStimulusPanel                matlab.ui.container.Panel
        NBNTotLDSPulsesLabel            matlab.ui.control.Label
        NBNLDSOnsetDelayLabel           matlab.ui.control.Label
        SynapseValuesLabel_2            matlab.ui.control.Label
        NBNLDSDurationLabel             matlab.ui.control.Label
        NBNLDSTimeBtwnPulsesLabel       matlab.ui.control.Label
        NBNLevelLabel                   matlab.ui.control.Label
        NBNHPCFLabel                    matlab.ui.control.Label
        TotLDSPulsesLabel               matlab.ui.control.Label
        NBNTotLDSPulsesEdit             matlab.ui.control.NumericEditField
        LDSOnsetDelaysecLabel           matlab.ui.control.Label
        NBNLDSOnsetDelayEdit            matlab.ui.control.NumericEditField
        LDSDurationsecLabel             matlab.ui.control.Label
        NBNLDSDurationEdit              matlab.ui.control.NumericEditField
        LDSTimeBtwnPulsessecLabel       matlab.ui.control.Label
        NBNLDSTimeBtwnPulsesEdit        matlab.ui.control.NumericEditField
        LeveldBLabel                    matlab.ui.control.Label
        NBNLevelEdit                    matlab.ui.control.NumericEditField
        HipassHzLabel                   matlab.ui.control.Label
        NBNHPCFEdit                     matlab.ui.control.NumericEditField
        LopassHzEditFieldLabel          matlab.ui.control.Label
        NBNLPCFEdit                     matlab.ui.control.NumericEditField
        ModulationEditFieldLabel        matlab.ui.control.Label
        NBNModPercentageEdit            matlab.ui.control.NumericEditField
        ModulationFreqHzEditFieldLabel  matlab.ui.control.Label
        NBNModFrequencyEdit             matlab.ui.control.NumericEditField
        SineExponentEditFieldLabel      matlab.ui.control.Label
        NBNSineWaveExponentEdit         matlab.ui.control.NumericEditField
        NBNLPCFLabel                    matlab.ui.control.Label
        NBNModPercentageLabel           matlab.ui.control.Label
        NBNModFrequencyLabel            matlab.ui.control.Label
        NBNSineWaveExponentLabel        matlab.ui.control.Label
        NBNGetButton                    matlab.ui.control.Button
        NBNSetButton                    matlab.ui.control.Button
        ExperimentNameEditFieldLabel_2  matlab.ui.control.Label
        ExpEdit2                        matlab.ui.control.EditField
        ExpButton2                      matlab.ui.control.Button
        LDSToneTab                      matlab.ui.container.Tab
        TONEStimulusPanel               matlab.ui.container.Panel
        TONETotLDSPulsesLabel           matlab.ui.control.Label
        TONELDSOnsetDelayLabel          matlab.ui.control.Label
        TONESynapseValuesLabel          matlab.ui.control.Label
        TONELDSDurationLabel            matlab.ui.control.Label
        TONELDSTimeBtwnPulsesLabel      matlab.ui.control.Label
        TONELevelLabel                  matlab.ui.control.Label
        TONEToneFreqLabel               matlab.ui.control.Label
        TONEModPercentageLabel          matlab.ui.control.Label
        TONEModFrequencyLabel           matlab.ui.control.Label
        TONESineWaveExponentLabel       matlab.ui.control.Label
        TotLDSPulsesLabel_2             matlab.ui.control.Label
        TONETotLDSPulsesEdit            matlab.ui.control.NumericEditField
        LDSOnsetDelaysecLabel_2         matlab.ui.control.Label
        TONELDSOnsetDelayEdit           matlab.ui.control.NumericEditField
        LDSDurationsecLabel_2           matlab.ui.control.Label
        TONELDSDurationEdit             matlab.ui.control.NumericEditField
        LDSTimeBtwnPulsessecLabel_2     matlab.ui.control.Label
        TONELDSTimeBtwnPulsesEdit       matlab.ui.control.NumericEditField
        LeveldBLabel_2                  matlab.ui.control.Label
        TONELevelEdit                   matlab.ui.control.NumericEditField
        FrequencyHzLabel                matlab.ui.control.Label
        TONEToneFreqEdit                matlab.ui.control.NumericEditField
        ModulationEditFieldLabel_2      matlab.ui.control.Label
        TONEModPercentageEdit           matlab.ui.control.NumericEditField
        ModulationFreqHzEditFieldLabel_2  matlab.ui.control.Label
        TONEModFrequencyEdit            matlab.ui.control.NumericEditField
        SineExponentEditFieldLabel_2    matlab.ui.control.Label
        TONESineWaveExponentEdit        matlab.ui.control.NumericEditField
        TONEGetButton                   matlab.ui.control.Button
        TONESetButton                   matlab.ui.control.Button
        ExperimentNameEditFieldLabel_3  matlab.ui.control.Label
        ExpEdit3                        matlab.ui.control.EditField
        ExpButton3                      matlab.ui.control.Button
        ReadTankTab                     matlab.ui.container.Tab
        PeriodsecEditFieldLabel         matlab.ui.control.Label
        PeriodsecEditField              matlab.ui.control.NumericEditField
        PSTHbinsecEditFieldLabel        matlab.ui.control.Label
        PSTHbinsecEditField             matlab.ui.control.NumericEditField
        ResponseonsetEditFieldLabel     matlab.ui.control.Label
        ResponseonsetEditField          matlab.ui.control.EditField
        ResponseoffsetEditFieldLabel    matlab.ui.control.Label
        ResponseoffsetEditField         matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        SelectTankButton                matlab.ui.control.Button
        LoadTankButton                  matlab.ui.control.Button
        TankEditFieldLabel              matlab.ui.control.Label
        TankEditField                   matlab.ui.control.EditField
        CurrentModeLabel                matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        AnalysisPanel                   matlab.ui.container.Panel
        PollingperiodsecEditFieldLabel  matlab.ui.control.Label
        UpdateEdit                      matlab.ui.control.NumericEditField
        RatebinningwindowmsLabel        matlab.ui.control.Label
        BinEdit                         matlab.ui.control.NumericEditField
        RatehistorysecEditFieldLabel    matlab.ui.control.Label
        RangeEdit                       matlab.ui.control.NumericEditField
        EvokedratewindowmsLabel         matlab.ui.control.Label
        OnsetEdit                       matlab.ui.control.NumericEditField
        OffsetEdit                      matlab.ui.control.NumericEditField
        rateAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        syn = [];           % links to Synapse
        experiment = [];    % 1 Iso Intensity, 2 LDS Noise, 3 LDS Tone
        pars = [];          % Parameters
        gizmos = [];        % Gizmos
    end

    methods (Access = private)
%Updates the textbox that displays the Synapse Mode
        function updatemode(app) 
            switch app.syn.getMode
                case 0
                    app.CurrentModeLabel.Text = 'Idle';
                case 1
                    app.CurrentModeLabel.Text = 'Standby';
                case 2
                    app.CurrentModeLabel.Text = 'Preview';
                case 3
                    app.CurrentModeLabel.Text = 'Recording';
            end
        end
    
%Reads parameters stored in synapse        
        function updatepars(app) 
            if app.syn.getMode == 0
                app.syn.setMode(1);
            end
            app.IsoStimulusPanel.Visible = 0;
            app.NBNStimulusPanel.Visible = 0;
            app.TONEStimulusPanel.Visible = 0;

            switch app.experiment
                case 1
                    app.pars = {'StimHiTime'; 'StimLoTime'; 'StimNPulses'; 'GateLoTime'; 'GateNPulses'; 'Level'};
                    app.gizmos = {'Stimulus'; 'Stimulus'; 'Stimulus'; 'Stimulus'; 'Stimulus'; 'Intensity'};
                    prefix = 'Iso';
                case 2
                    app.pars = {'TotLDSPulses'; 'LDSOnsetDelay'; 'LDSDuration'; 'LDSTimeBtwnPulses'; 'Level'; 'HPCF'; 'LPCF'; ...
                        'ModPercentage'; 'ModFrequency'; 'SineWaveExponent'};
                    app.gizmos = {'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'; ...
                        'LDS_gauss'; 'LDS_gauss'; 'LDS_gauss'};
                    prefix = 'NBN';
                case 3
                    app.pars = {'TotLDSPulses'; 'LDSOnsetDelay'; 'LDSDuration'; 'LDSTimeBtwnPulses'; 'Level'; 'ToneFreq'; ...
                        'ModPercentage'; 'ModFrequency'; 'SineWaveExponent'};
                    app.gizmos = {'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; 'LDS_tones'; ...
                        'LDS_tones'; 'LDS_tones'};
                    prefix = 'TONE';
            end
            for par = 1:numel(app.pars)
                mvarname = [prefix char(app.pars(par)) 'Label'];
                app.(mvarname).Text = num2str(app.syn.getParameterValue(char(app.gizmos(par)), char(app.pars(par))));
            end
            stimpanel = [prefix 'StimulusPanel'];
            app.(stimpanel).Visible = 1;
            app.AnalysisPanel.Visible = 1;
        end
        
%Streams data from synapse to MATLAB during preview and recording
        function livestreamapp(app, mode) 

        updaterate = app.UpdateEdit.Value;
        analwin = [app.OnsetEdit.Value app.OffsetEdit.Value]./1000; %ftc analysis window;
        binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
        ratewin = app.RangeEdit.Value;
        period = app.IsoStimHiTimeEdit.Value + app.IsoStimLoTimeEdit.Value; %stimulus period
        nchans = 16;
        spikevar = 'eSpk';
        wavevar = 'Mult';
        stimvar = 'Freq';
        offvar = 'Puls';
        
        namefield = ['ExpEdit' num2str(app.experiment)];
        exp = SynapseLive('MODE', mode, 'EXPERIMENT', app.(namefield).Value);
            
        if updaterate<2*binwindow; pause(2*binwindow); end
        
        spets = [];
        channels = [];
        stimons = [];
        stimoffs = [];
        frqs = [];
        currtime = [];
        parmat = [];
        
            while 1
                pause(updaterate)
                if app.syn.getMode() < 2 %stops if synapse is set to idle or standby
                    break
                end
                try %stops if synapse is set to idle or standby
                    new = exp.update;
                catch
                    break
                end
                if isempty(new)
                    break
                end
              
            %read continuously updated data
                currtime = [currtime; app.syn.getParameterValue('ReadTime', 'Time')];
                currpars = NaN(size(app.pars));
                for par = 1:numel(app.pars)
                    currpars(par) = app.syn.getParameterValue(char(app.gizmos(par)), char(app.pars(par)));
                end
                parmat = [parmat; currpars'];
                
                if isfield(new.data.snips, spikevar)
                    spets = [spets; new.data.snips.(spikevar).ts];
                    channels = [channels; new.data.snips.(spikevar).chan];
                end
                
                
            %read triggered data
                if app.experiment > 1
                    if ~sum(isnan(new.data.epocs.(offvar).data))
                        stimons = [stimons; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data == 1)];
                        frqs = zeros(numel(stimons), 1);
                        stimoffs = [stimoffs; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data == 0)];
                    end
                else
                    if ~sum(isnan(new.data.epocs.(stimvar).onset))
                        frqs = [frqs; new.data.epocs.(stimvar).data];
                    end
                    if ~sum(isnan(new.data.epocs.(offvar).onset))
                        stimons = [stimons; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data==1)];
                        stimoffs = [stimoffs; new.data.epocs.(offvar).onset(new.data.epocs.(offvar).data==0)];
                    end
                end
                
            %running spike rate average
                
                figure(1);
                synfrate(spets, binwindow, stimons, stimoffs, channels, nchans, ratewin)
                
                if numel(stimons)>0
                    [spetfreq, stimspets, trials] = freqanal(spets, stimons(1:numel(frqs)), frqs);
                    if max(stimspets)>period
                        break
                    end
    %         %PSTH
    %                 nbins = round(period/binwindow);
    %                 figure(2);
    %                 synpsth(stimspets(stimspets<period), nbins, channels(stimspets<period), nchans)
    %                 title(2, [num2str(numel(spets)) ' spikes, ' num2str(numel(stimons)) ' trials'])
            
            %tuning curve
                    if app.experiment == 1 & numel(unique(frqs))>1
                        figure(3);
                        synftc(stimspets(stimspets<period), spetfreq(stimspets<period), analwin, channels(stimspets<period), trials, nchans, frqs)
                    end
                    
                end 
            end %while
            disp('Data streaming finished')
            if strcmpi(mode, 'Record')
                [changesamp, ~]  = find(diff(parmat));
                changes = sort(unique(changesamp))+1;
                fulltable = array2table([currtime parmat], 'VariableNames', [{'Time'}; app.pars]);
                changearray = [[currtime(1), parmat(1,:)]; [currtime(changes),  parmat(changes,:)]];
                changetable = array2table(changearray, 'VariableNames', [{'Time'}; app.pars]);
                flist = dir(app.syn.getCurrentTank);
                folders = zeros(size(flist));
                dates = zeros(size(flist));
                for file = 3:numel(flist)
                    folders(file) = flist(file).isdir;
                    dates(file) = flist(file).datenum;
                end
                subind= find(folders);
                 [~, i]=max(dates(subind));
                 currfolder = [flist(subind(i)).folder '\' flist(subind(i)).name];
                 [currfolder '\params.mat']
                 save([currfolder '\params.mat'], 'fulltable', 'changetable')
            end
            
            
        end %function livestreamapp
        
        function commongetbutton(app, prefix)
            for par = 1:numel(app.pars)
                mvarname = [prefix char(app.pars(par)) 'Edit'];
                mvarname2 = [prefix char(app.pars(par)) 'Label'];
                app.(mvarname).Value= str2double(app.(mvarname2).Text);
            end

        end
        
        function commonsetbutton(app, prefix)
            for par = 1:numel(app.pars)
                mvarname = [prefix char(app.pars(par)) 'Edit'];
                app.syn.setParameterValue(char(app.gizmos(par)), char(app.pars(par)), app.(mvarname).Value);
            end
            if strcmpi(prefix, 'iso')
                app.syn.setParameterValue('Stimulus', 'GateHiTime', app.IsoStimNPulsesEdit.Value*(app.IsoStimHiTimeEdit.Value + app.IsoStimLoTimeEdit.Value));
            end
            pause(1);
            updatepars(app);
        end
        
        function commonexpbutton(app, expnum)
            app.experiment = expnum;
            namefield = ['ExpEdit' num2str(expnum)];
            app.syn.setCurrentExperiment(app.(namefield).Value);
            app.syn.setMode(1);
            updatemode(app); updatepars(app);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IsoStimulusPanel.Visible = 0;
            app.NBNStimulusPanel.Visible = 0;
            app.TONEStimulusPanel.Visible = 0;
            app.AnalysisPanel.Visible = 0;
            app.syn = SynapseAPI();
            updatemode(app)
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            livestreamapp(app, 'Preview')
        end

        % Button pushed function: SelectTankButton
        function SelectTankButtonPushed(app, event)
            tankpath = uigetdir('E:\TDT\Synapse\Tanks');
            app.TankEditField.Value = tankpath;
        end

        % Button pushed function: LoadTankButton
        function LoadTankButtonPushed(app, event)
            data=TDTbin2mat(app.TankEditField.Value);
            
            spikefield = 'eNe1';
            stimfield = 'Fre1';
            
            spets = data.snips.(spikefield).ts;
            channels = data.snips.(spikefield).chan;
            stimons = data.epocs.(stimfield).onset;
            frqs = data.epocs.(stimfield).data;
            nchans = size(data.streams.pNe1.data, 1);
            set(app.DialogueLabel, 'Text', spikefield);


            period = app.PeriodsecEditField.Value;
            binwindow = app.PSTHbinsecEditField.Value; %PSTH bin size (sec)
            analwin = [0 2000]./1000; %ftc analysis window;
            [spetfreq, stimspets, trials] = freqanal(spets, stimons, frqs);
            
            %PSTH
            nbins = round(period/binwindow);
            figure(1); synpsth(stimspets(stimspets<period), nbins, channels(stimspets<period), nchans)
            title([num2str(numel(spets)) ' spikes, ' num2str(numel(stimons)) ' trials'])
            
            %tuning curve
            figure(2); synftc(stimspets(stimspets<period), spetfreq(stimspets<period), analwin, channels(stimspets<period), trials, nchans, frqs)

        end

        % Button pushed function: ExpButton3
        function ExpButton3Pushed(app, event)
            commonexpbutton(app, 3)
        end

        % Button pushed function: IsoGetButton, LoadParamsButton
        function IsoGetButtonPushed(app, event)
            commongetbutton(app, 'Iso')
        end

        % Button pushed function: IsoSetButton
        function IsoSetButtonPushed(app, event)
           commonsetbutton(app, 'Iso')
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            livestreamapp(app, 'Record')
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.syn.setMode(0)
            updatemode(app);
        end

        % Button pushed function: NBNGetButton
        function NBNGetButtonPushed(app, event)
            commongetbutton(app, 'NBN')
        end

        % Button pushed function: NBNSetButton
        function NBNSetButtonPushed(app, event)
            commonsetbutton(app, 'NBN')
        end

        % Button pushed function: TONEGetButton
        function TONEGetButtonPushed(app, event)
            commongetbutton(app, 'TONE')
        end

        % Button pushed function: TONESetButton
        function TONESetButtonPushed(app, event)
            commonsetbutton(app, 'TONE')
        end

        % Button pushed function: ExpButton1
        function ExpButton1Pushed(app, event)
            commonexpbutton(app, 1)
        end

        % Button pushed function: ExpButton2
        function ExpButton2Pushed(app, event)
            commonexpbutton(app, 2)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.LoadParamsButton = uibutton(app.UIFigure, 'push');
            app.LoadParamsButton.ButtonPushedFcn = createCallbackFcn(app, @IsoGetButtonPushed, true);
            app.LoadParamsButton.Text = 'Load Params';
            app.SaveParamsButton = uibutton(app.UIFigure, 'push');
            app.SaveParamsButton.Text = 'Save Params';
            app.ParamFileEditFieldLabel = uilabel(app.UIFigure);
            app.ParamFileEditFieldLabel.Text = 'Param File';
            app.ParamFileEditField = uieditfield(app.UIFigure, 'text');
            app.DialogueLabel = uilabel(app.UIFigure);
            app.DialogueLabel.Text = 'Dialogue';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IsoIntensityTab = uitab(app.TabGroup);
            app.IsoIntensityTab.Title = 'Iso Intensity';
            app.IsoStimulusPanel = uipanel(app.IsoIntensityTab);
            app.IsoStimulusPanel.Title = 'Stimulus';
            app.IsoStimHiTimeLabel = uilabel(app.IsoStimulusPanel);
            app.IsoStimHiTimeLabel.Text = 'HiTime';
            app.IsoStimLoTimeLabel = uilabel(app.IsoStimulusPanel);
            app.IsoStimLoTimeLabel.Text = 'LoTime';
            app.SynapseValuesLabel = uilabel(app.IsoStimulusPanel);
            app.SynapseValuesLabel.Text = 'Synapse Values';
            app.IsoStimNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.IsoStimNPulsesLabel.Text = 'Pulses';
            app.IsoGateLoTimeLabel = uilabel(app.IsoStimulusPanel);
            app.IsoGateLoTimeLabel.Text = 'ISI';
            app.IsoGateNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.IsoGateNPulsesLabel.Text = 'Trains';
            app.IsoLevelLabel = uilabel(app.IsoStimulusPanel);
            app.IsoLevelLabel.Text = 'dB';
            app.GateHiTimemsLabel = uilabel(app.IsoStimulusPanel);
            app.GateHiTimemsLabel.Text = 'Gate Hi Time (ms)';
            app.IsoStimHiTimeEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoStimHiTimeEdit.Value = 200;
            app.GateLoTimemsLabel = uilabel(app.IsoStimulusPanel);
            app.GateLoTimemsLabel.Text = 'Gate Lo Time (ms)';
            app.IsoStimLoTimeEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoStimLoTimeEdit.Value = 300;
            app.StimNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.StimNPulsesLabel.Text = 'Stim N Pulses';
            app.IsoStimNPulsesEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoStimNPulsesEdit.Value = 5;
            app.GateLoTimemsLabel_2 = uilabel(app.IsoStimulusPanel);
            app.GateLoTimemsLabel_2.Text = 'Gate Lo Time (ms)';
            app.IsoGateLoTimeEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoGateLoTimeEdit.Value = 500;
            app.GateNPulsesLabel = uilabel(app.IsoStimulusPanel);
            app.GateNPulsesLabel.Text = 'Gate N Pulses';
            app.IsoGateNPulsesEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoGateNPulsesEdit.Value = 6;
            app.LeveldBEditFieldLabel = uilabel(app.IsoStimulusPanel);
            app.LeveldBEditFieldLabel.Text = 'Level (dB)';
            app.IsoLevelEdit = uieditfield(app.IsoStimulusPanel, 'numeric');
            app.IsoLevelEdit.Value = 90;
            app.IsoGetButton = uibutton(app.IsoStimulusPanel, 'push');
            app.IsoGetButton.ButtonPushedFcn = createCallbackFcn(app, @IsoGetButtonPushed, true);
            app.IsoGetButton.Text = '<- Get ';
            app.IsoSetButton = uibutton(app.IsoStimulusPanel, 'push');
            app.IsoSetButton.ButtonPushedFcn = createCallbackFcn(app, @IsoSetButtonPushed, true);
            app.IsoSetButton.Text = 'Set ->';
            app.ExperimentNameEditFieldLabel = uilabel(app.IsoIntensityTab);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name';
            app.ExpEdit1 = uieditfield(app.IsoIntensityTab, 'text');
            app.ExpEdit1.Value = 'Iso_Pilot';
            app.ExpButton1 = uibutton(app.IsoIntensityTab, 'push');
            app.ExpButton1.ButtonPushedFcn = createCallbackFcn(app, @ExpButton1Pushed, true);
            app.ExpButton1.Text = 'Load Experiment';
            app.LDSNoiseTab = uitab(app.TabGroup);
            app.LDSNoiseTab.Title = 'LDS Noise';
            app.NBNStimulusPanel = uipanel(app.LDSNoiseTab);
            app.NBNStimulusPanel.Title = 'Stimulus';
            app.NBNTotLDSPulsesLabel = uilabel(app.NBNStimulusPanel);
            app.NBNTotLDSPulsesLabel.Text = 'TotLDSPulses';
            app.NBNLDSOnsetDelayLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLDSOnsetDelayLabel.Text = 'LDSOnsetDelay';
            app.SynapseValuesLabel_2 = uilabel(app.NBNStimulusPanel);
            app.SynapseValuesLabel_2.Text = 'Synapse Values';
            app.NBNLDSDurationLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLDSDurationLabel.Text = 'LDSDuration';
            app.NBNLDSTimeBtwnPulsesLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLDSTimeBtwnPulsesLabel.Text = 'LDSTimeBtwnPulses';
            app.NBNLevelLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLevelLabel.Text = 'Level';
            app.NBNHPCFLabel = uilabel(app.NBNStimulusPanel);
            app.NBNHPCFLabel.Text = 'HPCF';
            app.TotLDSPulsesLabel = uilabel(app.NBNStimulusPanel);
            app.TotLDSPulsesLabel.Text = 'Tot LDS Pulses';
            app.NBNTotLDSPulsesEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNTotLDSPulsesEdit.Value = 20;
            app.LDSOnsetDelaysecLabel = uilabel(app.NBNStimulusPanel);
            app.LDSOnsetDelaysecLabel.Text = 'LDS Onset Delay (sec)';
            app.NBNLDSOnsetDelayEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLDSOnsetDelayEdit.Value = 20;
            app.LDSDurationsecLabel = uilabel(app.NBNStimulusPanel);
            app.LDSDurationsecLabel.Text = 'LDS Duration (sec)';
            app.NBNLDSDurationEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLDSDurationEdit.Value = 20;
            app.LDSTimeBtwnPulsessecLabel = uilabel(app.NBNStimulusPanel);
            app.LDSTimeBtwnPulsessecLabel.Text = 'LDS Time Btwn Pulses (sec)';
            app.NBNLDSTimeBtwnPulsesEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLDSTimeBtwnPulsesEdit.Value = 30;
            app.LeveldBLabel = uilabel(app.NBNStimulusPanel);
            app.LeveldBLabel.Text = 'Level (dB)';
            app.NBNLevelEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLevelEdit.Value = 70;
            app.HipassHzLabel = uilabel(app.NBNStimulusPanel);
            app.HipassHzLabel.Text = 'Hipass (Hz)';
            app.NBNHPCFEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNHPCFEdit.Value = 6000;
            app.LopassHzEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.LopassHzEditFieldLabel.Text = 'Lopass (Hz)';
            app.NBNLPCFEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNLPCFEdit.Value = 12000;
            app.ModulationEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.ModulationEditFieldLabel.Text = 'Modulation %';
            app.NBNModPercentageEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.ModulationFreqHzEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.ModulationFreqHzEditFieldLabel.Text = 'Modulation Freq (Hz)';
            app.NBNModFrequencyEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.SineExponentEditFieldLabel = uilabel(app.NBNStimulusPanel);
            app.SineExponentEditFieldLabel.Text = 'Sine Exponent';
            app.NBNSineWaveExponentEdit = uieditfield(app.NBNStimulusPanel, 'numeric');
            app.NBNSineWaveExponentEdit.Value = 1;
            app.NBNLPCFLabel = uilabel(app.NBNStimulusPanel);
            app.NBNLPCFLabel.Text = 'LPCF';
            app.NBNModPercentageLabel = uilabel(app.NBNStimulusPanel);
            app.NBNModPercentageLabel.Text = 'ModPercentage';
            app.NBNModFrequencyLabel = uilabel(app.NBNStimulusPanel);
            app.NBNModFrequencyLabel.Text = 'ModFrequency';
            app.NBNSineWaveExponentLabel = uilabel(app.NBNStimulusPanel);
            app.NBNSineWaveExponentLabel.Text = 'SineWaveExponent';
            app.NBNGetButton = uibutton(app.NBNStimulusPanel, 'push');
            app.NBNGetButton.ButtonPushedFcn = createCallbackFcn(app, @NBNGetButtonPushed, true);
            app.NBNGetButton.Text = '<- Get ';
            app.NBNSetButton = uibutton(app.NBNStimulusPanel, 'push');
            app.NBNSetButton.ButtonPushedFcn = createCallbackFcn(app, @NBNSetButtonPushed, true);
            app.NBNSetButton.Text = 'Set ->';
            app.ExperimentNameEditFieldLabel_2 = uilabel(app.LDSNoiseTab);
            app.ExperimentNameEditFieldLabel_2.Text = 'Experiment Name';
            app.ExpEdit2 = uieditfield(app.LDSNoiseTab, 'text');
            app.ExpEdit2.Value = 'LDS_Pilot';
            app.ExpButton2 = uibutton(app.LDSNoiseTab, 'push');
            app.ExpButton2.ButtonPushedFcn = createCallbackFcn(app, @ExpButton2Pushed, true);
            app.ExpButton2.Text = 'Load Experiment';
            app.LDSToneTab = uitab(app.TabGroup);
            app.LDSToneTab.Title = 'LDS Tone';
            app.TONEStimulusPanel = uipanel(app.LDSToneTab);
            app.TONEStimulusPanel.Title = 'Stimulus';
            app.TONETotLDSPulsesLabel = uilabel(app.TONEStimulusPanel);
            app.TONETotLDSPulsesLabel.Text = 'TotLDSPulses';
            app.TONELDSOnsetDelayLabel = uilabel(app.TONEStimulusPanel);
            app.TONELDSOnsetDelayLabel.Text = 'LDSOnsetDelay';
            app.TONESynapseValuesLabel = uilabel(app.TONEStimulusPanel);
            app.TONESynapseValuesLabel.Text = 'Synapse Values';
            app.TONELDSDurationLabel = uilabel(app.TONEStimulusPanel);
            app.TONELDSDurationLabel.Text = 'LDSDuration';
            app.TONELDSTimeBtwnPulsesLabel = uilabel(app.TONEStimulusPanel);
            app.TONELDSTimeBtwnPulsesLabel.Text = 'LDSTimeBtwnPulses';
            app.TONELevelLabel = uilabel(app.TONEStimulusPanel);
            app.TONELevelLabel.Text = 'Level';
            app.TONEToneFreqLabel = uilabel(app.TONEStimulusPanel);
            app.TONEToneFreqLabel.Text = 'ToneFreq';
            app.TONEModPercentageLabel = uilabel(app.TONEStimulusPanel);
            app.TONEModPercentageLabel.Text = 'ModPercentage';
            app.TONEModFrequencyLabel = uilabel(app.TONEStimulusPanel);
            app.TONEModFrequencyLabel.Text = 'ModFrequency';
            app.TONESineWaveExponentLabel = uilabel(app.TONEStimulusPanel);
            app.TONESineWaveExponentLabel.Text = 'SineWaveExponent';
            app.TotLDSPulsesLabel_2 = uilabel(app.TONEStimulusPanel);
            app.TotLDSPulsesLabel_2.Text = 'Tot LDS Pulses';
            app.TONETotLDSPulsesEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONETotLDSPulsesEdit.Value = 20;
            app.LDSOnsetDelaysecLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LDSOnsetDelaysecLabel_2.Text = 'LDS Onset Delay (sec)';
            app.TONELDSOnsetDelayEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELDSOnsetDelayEdit.Value = 20;
            app.LDSDurationsecLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LDSDurationsecLabel_2.Text = 'LDS Duration (sec)';
            app.TONELDSDurationEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELDSDurationEdit.Value = 20;
            app.LDSTimeBtwnPulsessecLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LDSTimeBtwnPulsessecLabel_2.Text = 'LDS Time Btwn Pulses (sec)';
            app.TONELDSTimeBtwnPulsesEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELDSTimeBtwnPulsesEdit.Value = 30;
            app.LeveldBLabel_2 = uilabel(app.TONEStimulusPanel);
            app.LeveldBLabel_2.Text = 'Level (dB)';
            app.TONELevelEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONELevelEdit.Value = 70;
            app.FrequencyHzLabel = uilabel(app.TONEStimulusPanel);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.TONEToneFreqEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONEToneFreqEdit.Value = 6000;
            app.ModulationEditFieldLabel_2 = uilabel(app.TONEStimulusPanel);
            app.ModulationEditFieldLabel_2.Text = 'Modulation %';
            app.TONEModPercentageEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.ModulationFreqHzEditFieldLabel_2 = uilabel(app.TONEStimulusPanel);
            app.ModulationFreqHzEditFieldLabel_2.Text = 'Modulation Freq (Hz)';
            app.TONEModFrequencyEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.SineExponentEditFieldLabel_2 = uilabel(app.TONEStimulusPanel);
            app.SineExponentEditFieldLabel_2.Text = 'Sine Exponent';
            app.TONESineWaveExponentEdit = uieditfield(app.TONEStimulusPanel, 'numeric');
            app.TONESineWaveExponentEdit.Value = 1;
            app.TONEGetButton = uibutton(app.TONEStimulusPanel, 'push');
            app.TONEGetButton.ButtonPushedFcn = createCallbackFcn(app, @TONEGetButtonPushed, true);
            app.TONEGetButton.Text = '<- Get ';
            app.TONESetButton = uibutton(app.TONEStimulusPanel, 'push');
            app.TONESetButton.ButtonPushedFcn = createCallbackFcn(app, @TONESetButtonPushed, true);
            app.TONESetButton.Text = 'Set ->';
            app.ExperimentNameEditFieldLabel_3 = uilabel(app.LDSToneTab);
            app.ExperimentNameEditFieldLabel_3.Text = 'Experiment Name';
            app.ExpEdit3 = uieditfield(app.LDSToneTab, 'text');
            app.ExpEdit3.Value = 'LDS_Tone_Pilot';
            app.ExpButton3 = uibutton(app.LDSToneTab, 'push');
            app.ExpButton3.ButtonPushedFcn = createCallbackFcn(app, @ExpButton3Pushed, true);
            app.ExpButton3.Text = 'Load Experiment';
            app.ReadTankTab = uitab(app.TabGroup);
            app.ReadTankTab.Title = 'ReadTank';
            app.PeriodsecEditFieldLabel = uilabel(app.ReadTankTab);
            app.PeriodsecEditFieldLabel.Text = 'Period (sec)';
            app.PeriodsecEditField = uieditfield(app.ReadTankTab, 'numeric');
            app.PeriodsecEditField.Value = 2;
            app.PSTHbinsecEditFieldLabel = uilabel(app.ReadTankTab);
            app.PSTHbinsecEditFieldLabel.Text = 'PSTH bin (sec)';
            app.PSTHbinsecEditField = uieditfield(app.ReadTankTab, 'numeric');
            app.PSTHbinsecEditField.Value = 0.05;
            app.ResponseonsetEditFieldLabel = uilabel(app.ReadTankTab);
            app.ResponseonsetEditFieldLabel.Text = 'Response onset';
            app.ResponseonsetEditField = uieditfield(app.ReadTankTab, 'text');
            app.ResponseonsetEditField.Value = '0';
            app.ResponseoffsetEditFieldLabel = uilabel(app.ReadTankTab);
            app.ResponseoffsetEditFieldLabel.Text = 'Response offset';
            app.ResponseoffsetEditField = uieditfield(app.ReadTankTab, 'text');
            app.ResponseoffsetEditField.Value = '2000';
            app.RunButton = uibutton(app.ReadTankTab, 'push');
            app.RunButton.Text = 'Run';
            app.SelectTankButton = uibutton(app.ReadTankTab, 'push');
            app.SelectTankButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTankButtonPushed, true);
            app.SelectTankButton.Text = 'Select Tank';
            app.LoadTankButton = uibutton(app.ReadTankTab, 'push');
            app.LoadTankButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTankButtonPushed, true);
            app.LoadTankButton.Text = 'Load Tank';
            app.TankEditFieldLabel = uilabel(app.ReadTankTab);
            app.TankEditFieldLabel.Text = 'Tank';
            app.TankEditField = uieditfield(app.ReadTankTab, 'text');
            app.CurrentModeLabel = uilabel(app.UIFigure);
            app.CurrentModeLabel.Text = 'Current Mode';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.PollingperiodsecEditFieldLabel = uilabel(app.AnalysisPanel);
            app.PollingperiodsecEditFieldLabel.Text = 'Polling period (sec)';
            app.UpdateEdit = uieditfield(app.AnalysisPanel, 'numeric');
            app.UpdateEdit.Value = 1;
            app.RatebinningwindowmsLabel = uilabel(app.AnalysisPanel);
            app.RatebinningwindowmsLabel.Text = 'Rate binning window (ms)';
            app.BinEdit = uieditfield(app.AnalysisPanel, 'numeric');
            app.BinEdit.Value = 500;
            app.RatehistorysecEditFieldLabel = uilabel(app.AnalysisPanel);
            app.RatehistorysecEditFieldLabel.Text = 'Rate history (sec)';
            app.RangeEdit = uieditfield(app.AnalysisPanel, 'numeric');
            app.RangeEdit.Value = 60;
            app.EvokedratewindowmsLabel = uilabel(app.AnalysisPanel);
            app.EvokedratewindowmsLabel.Text = 'Evoked rate window (ms)';
            app.OnsetEdit = uieditfield(app.AnalysisPanel, 'numeric');
            app.OffsetEdit = uieditfield(app.AnalysisPanel, 'numeric');
            app.OffsetEdit.Value = 100;
            app.rateAxes = uiaxes(app.AnalysisPanel);
            title(app.rateAxes, 'Running Spike Rate')
            xlabel(app.rateAxes, 'Time')
            ylabel(app.rateAxes, 'Spike Rate')
        end
    end

    methods (Access = public)

        % Construct app
        function app = synui_r181120

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end