classdef synabr_v104 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TankButton                    matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        TankEdit                      matlab.ui.control.EditField
        SingleCyclesCheckBox          matlab.ui.control.CheckBox
        DialogueLabel                 matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        PeakPickerTab                 matlab.ui.container.Tab
        Cycle1Axes                    matlab.ui.control.UIAxes
        TS1Table                      matlab.ui.control.Table
        Cycle1TimestampRadio          matlab.ui.container.ButtonGroup
        C1TS1Button                   matlab.ui.control.RadioButton
        C1TS2Button                   matlab.ui.control.RadioButton
        C1TS3Button                   matlab.ui.control.RadioButton
        C1TS4Button                   matlab.ui.control.RadioButton
        C1TS5Button                   matlab.ui.control.RadioButton
        C1TS6Button                   matlab.ui.control.RadioButton
        C1TS7Button                   matlab.ui.control.RadioButton
        C1TS8Button                   matlab.ui.control.RadioButton
        C1TS9Button                   matlab.ui.control.RadioButton
        C1TS10Button                  matlab.ui.control.RadioButton
        C1TS11Button                  matlab.ui.control.RadioButton
        C1TS12Button                  matlab.ui.control.RadioButton
        Cycle2Axes                    matlab.ui.control.UIAxes
        TS2Table                      matlab.ui.control.Table
        Cycle2TimestampRadio          matlab.ui.container.ButtonGroup
        C2TS1Button                   matlab.ui.control.RadioButton
        C2TS2Button                   matlab.ui.control.RadioButton
        C2TS3Button                   matlab.ui.control.RadioButton
        C2TS4Button                   matlab.ui.control.RadioButton
        C2TS5Button                   matlab.ui.control.RadioButton
        C2TS6Button                   matlab.ui.control.RadioButton
        C2TS7Button                   matlab.ui.control.RadioButton
        C2TS8Button                   matlab.ui.control.RadioButton
        C2TS9Button                   matlab.ui.control.RadioButton
        C2TS10Button                  matlab.ui.control.RadioButton
        C2TS11Button                  matlab.ui.control.RadioButton
        C2TS12Button                  matlab.ui.control.RadioButton
        SnaptoButtonGroup             matlab.ui.container.ButtonGroup
        NothingButton                 matlab.ui.control.RadioButton
        PeakButton                    matlab.ui.control.RadioButton
        TroughButton                  matlab.ui.control.RadioButton
        ComparisonTab                 matlab.ui.container.Tab
        AnalysisAxes                  matlab.ui.control.UIAxes
        TSDTable                      matlab.ui.control.Table
        BootstrapTab                  matlab.ui.container.Tab
        BootAxes                      matlab.ui.control.UIAxes
        BootHistAxes                  matlab.ui.control.UIAxes
        AnalysiswindowmsLabel_2       matlab.ui.control.Label
        BootAnal1Edit                 matlab.ui.control.NumericEditField
        BootAnal2Edit                 matlab.ui.control.NumericEditField
        BootstrapsLabel               matlab.ui.control.Label
        NBootsEdit                    matlab.ui.control.NumericEditField
        CyclesButtonGroup             matlab.ui.container.ButtonGroup
        MaxButton                     matlab.ui.control.RadioButton
        NCyclesButton                 matlab.ui.control.RadioButton
        NCyclesEdit                   matlab.ui.control.NumericEditField
        MaxCyclesLabel                matlab.ui.control.Label
        BootDiagLabel                 matlab.ui.control.Label
        seed0shuffleLabel             matlab.ui.control.Label
        BootSeedEdit                  matlab.ui.control.NumericEditField
        XCorrAxes                     matlab.ui.control.UIAxes
        SpectraTab                    matlab.ui.container.Tab
        Spectrum1Axes                 matlab.ui.control.UIAxes
        Spectrum2Axes                 matlab.ui.control.UIAxes
        TrainDurationsecLabel         matlab.ui.control.Label
        TrainDurationEdit             matlab.ui.control.NumericEditField
        PeakReliabilityTab            matlab.ui.container.Tab
        Map1Axes                      matlab.ui.control.UIAxes
        Map2Axes                      matlab.ui.control.UIAxes
        TimeCourseAxes                matlab.ui.control.UIAxes
        StimParamsLabel               matlab.ui.control.Label
        StimParamsLabel_2             matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        FilenameprefixEditFieldLabel  matlab.ui.control.Label
        PrefixEdit                    matlab.ui.control.EditField
        Window1Referencet0Label       matlab.ui.control.Label
        W1RefDrop                     matlab.ui.control.DropDown
        Window2Referencet0Label       matlab.ui.control.Label
        W2RefDrop                     matlab.ui.control.DropDown
        SpectrumPanel                 matlab.ui.container.Panel
        FilterCheck                   matlab.ui.control.CheckBox
        SpectrumCheck                 matlab.ui.control.CheckBox
        SpectrumAxes                  matlab.ui.control.UIAxes
        HipassHzEditFieldLabel        matlab.ui.control.Label
        HPCFEdit                      matlab.ui.control.NumericEditField
        LopassHzEditFieldLabel        matlab.ui.control.Label
        LPCFEdit                      matlab.ui.control.NumericEditField
        OrderLabel                    matlab.ui.control.Label
        FilterOrderEdit               matlab.ui.control.NumericEditField
        ArtifactsPanel                matlab.ui.container.Panel
        ArtRejectCheck                matlab.ui.control.CheckBox
        ArtifactAxes                  matlab.ui.control.UIAxes
        ThresholduVLabel              matlab.ui.control.Label
        ArtThreshEdit                 matlab.ui.control.NumericEditField
        ChannelLabel                  matlab.ui.control.Label
        ChannelEdit                   matlab.ui.control.NumericEditField
        HeadstagePanel                matlab.ui.container.Panel
        DelaymsDropDownLabel          matlab.ui.control.Label
        DelayDrop                     matlab.ui.control.DropDown
        GainLabel                     matlab.ui.control.Label
        DelayEdit                     matlab.ui.control.NumericEditField
        GainEdit                      matlab.ui.control.NumericEditField
        HeadstageDialogue             matlab.ui.control.Label
        Cycle1StartEdit               matlab.ui.control.NumericEditField
        Cycle1EndEdit                 matlab.ui.control.NumericEditField
        Cycle2StartEdit               matlab.ui.control.NumericEditField
        Cycle2EndEdit                 matlab.ui.control.NumericEditField
        StartLabel                    matlab.ui.control.Label
        T1Edit                        matlab.ui.control.NumericEditField
        EndLabel                      matlab.ui.control.Label
        T2Edit                        matlab.ui.control.NumericEditField
        YScaleLabel                   matlab.ui.control.Label
        Y2Edit                        matlab.ui.control.NumericEditField
        ShiftLabel                    matlab.ui.control.Label
        Y1Edit                        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        data = []; % Description
    end
    
    methods (Access = private)
        function readdelay(app)
            switch lower(app.DelayDrop.Value)
                case 'mouse' %RZ6 @ 100kHz, RA4 @ 25kHz
                    app.DelayEdit.Value = 2.05;
                    app.GainEdit.Value = 20;
                    app.HeadstageDialogue.Text = 'RZ6 at 100 kHz, RA4 at 25 kHz, observed delay of 2.05 ms';
                case 'human ra4' %RZ6 @ 50kHz, RA4 @ 25kHz
                    app.DelayEdit.Value = 5;
                    app.GainEdit.Value = 20;
                    app.HeadstageDialogue.Text = 'RZ6 at 50 kHz, RA4 at 25 kHz, observed delay of 5 ms';
                case 'human medusa 25k' %RZ6 @ 50kHz, Med4z @ 25kHz
                    app.DelayEdit.Value = 1.97;
                    app.GainEdit.Value = 1; %medusa has a 100x gain, but it is accounted for in synapse.
                    app.HeadstageDialogue.Text = 'RZ6 at 50 kHz, Medusa at 25 kHz, observed delay of 1.97 ms';
                case 'human medusa 12k' %RZ6 @ 50kHz, Med4z @ 12kHz
                    app.DelayEdit.Value = 2.62;
                    app.GainEdit.Value = 1; %medusa has a 100x gain, but it is accounted for in synapse.
                    app.HeadstageDialogue.Text = 'RZ6 at 50 kHz, Medusa at 12 kHz, observed delay of 2.62 ms';
                case 'human medusa 6k' %RZ6 @ 50kHz, Med4z @ 6kHz
                    app.DelayEdit.Value = 4.1;
                    app.GainEdit.Value = 1; %medusa has a 100x gain, but it is accounted for in synapse.
                    app.HeadstageDialogue.Text = 'RZ6 at 50 kHz, Medusa at 6 kHz, observed delay of 4.1 ms';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            readdelay(app)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            evons = app.data.evons + app.DelayEdit.Value/1E3;
            stimons = app.data.stimons + app.DelayEdit.Value/1E3;
            stimoffs = app.data.stimoffs + app.DelayEdit.Value/1E3;
            if isempty(stimoffs)
                stimoffs = stimons;
            end
            usechan = app.ChannelEdit.Value;
            raw = 1E6.*app.data.raw(:,usechan)./app.GainEdit.Value;
            fs = app.data.fs;
            app.data.currentaxes = 0;
            evons = evons(evons<(numel(raw)/fs));
%filter and plot spectrum
            signals = raw;
            if app.FilterCheck.Value
                app.DialogueLabel.Text = 'Filtering';
                pause(.1);
                fny = fs/2; 
                fcl = app.HPCFEdit.Value;
                fcu = app.LPCFEdit.Value;
                forder = app.FilterOrderEdit.Value;
                [b,a] = butter(forder, [fcl/fny fcu/fny]);
                signals = squeeze(filter(b,a, raw))';
                if sum(sum(~isfinite(signals))) > 0
                    app.DialogueLabel.Text = 'You broke it. Try a lower Filter Order.';
                    return
                end
            end
            if app.SpectrumCheck.Value
                app.DialogueLabel.Text = 'FFT = Fourier Fun Times';
                pause(.1);
                [p,f] = pspectrum(raw, fs); loglog(app.SpectrumAxes, f, p(:,1)', 'Color', [0.5 0.5 0.5]);
                hold(app.SpectrumAxes, 'on');
                [p,f] = pspectrum(signals, fs); loglog(app.SpectrumAxes, f, p(:,1)', 'g');
                hold(app.SpectrumAxes, 'off');
            end
            app.DialogueLabel.Text = 'Plotting';
            pause(.1);
%plot timecourse
            offsetw1 = 0;
            switch app.W1RefDrop.Value
                case 'Onset'
                    offsetw1 = stimons(1);
                case 'Offset'
                    offsetw1 = stimoffs(1);
            end
            w1 = [app.Cycle1StartEdit.Value + offsetw1, -1, app.Cycle1EndEdit.Value-app.Cycle1StartEdit.Value, 2];
            offsetw2 = 0;
            switch app.W2RefDrop.Value
                case 'Onset'
                    offsetw2 = stimons(1);
                case 'Offset'
                    offsetw2 = stimoffs(1);
            end
            w2 = [app.Cycle2StartEdit.Value + offsetw2, -1, app.Cycle2EndEdit.Value-app.Cycle2StartEdit.Value, 2];
            plot(app.TimeCourseAxes, 0, 0);
            hold(app.TimeCourseAxes, 'on');
            rectangle(app.TimeCourseAxes, 'Position', w1, 'FaceColor', [0.6 0.6 1], 'EdgeColor', [0.6 0.6 1]);
            rectangle(app.TimeCourseAxes, 'Position', w2, 'FaceColor', [1 0.6 0.6], 'EdgeColor', [1 0.6 0.6]);
            plot(app.TimeCourseAxes, evons, 0, 'k.');
            plot(app.TimeCourseAxes, [stimons stimoffs]', [0 0], 'g-', 'LineWidth', 2)
            hold(app.TimeCourseAxes, 'off');

%average cycles and histogram cycle maxima
            startsamp = round(evons*fs) + round(app.T1Edit.Value/1000*fs);
            endsamp = round(evons*fs) + round(app.T2Edit.Value/1000*fs);
            while size(raw, 1)<endsamp(end)
                startsamp = startsamp(1:(end-1));
                endsamp = endsamp(1:(end-1));
            end
            sttrace = NaN(numel(startsamp), round(app.T2Edit.Value/1000*fs)); 
             for cycle = 1:numel(startsamp)
                sttrace(cycle,:) = signals(startsamp(cycle):(endsamp(cycle)-1));
             end
            stpeaks = max(abs(sttrace'));
            [y,edges] = histcounts(stpeaks, round(numel(startsamp)/10));
            x = (edges(1:(end-1)) + edges(2:end))./2;
            plot(app.ArtifactAxes, x, y)
%plot ABR
            goodcycles = 1:numel(startsamp);
            if app.ArtRejectCheck.Value
                goodcycles = find(stpeaks<app.ArtThreshEdit.Value);
                if numel(goodcycles) < 1
                    app.DialogueLabel.Text = 'No good cycles found. Try increasing Artifact Threshold.';
                    return
                end
            end
            sttracegood = sttrace(goodcycles,:);
            cycle1starti = find(evons(goodcycles) >= app.Cycle1StartEdit.Value + offsetw1, 1);
            cycle1endi = find(evons(goodcycles) <= app.Cycle1EndEdit.Value + offsetw1, 1, 'last');
            cycle1range = cycle1starti:cycle1endi;
            cycle2starti = find(evons(goodcycles) >= app.Cycle2StartEdit.Value + offsetw2, 1);
            cycle2endi = find(evons(goodcycles) <= app.Cycle2EndEdit.Value + offsetw2, 1, 'last');
            cycle2range = cycle2starti:cycle2endi;
            app.data.meansig1 = mean(sttracegood(cycle1range, :))';
            app.data.meansig2 = mean(sttracegood(cycle2range, :))';
            app.data.errsig1 = std(sttracegood(cycle1range, :))'./sqrt(numel(cycle1range));
            app.data.errsig2 = std(sttracegood(cycle2range, :))'./sqrt(numel(cycle2range));
            plotabr(app)
            app.DialogueLabel.Text = 'Ready';
%plot response spectra            
            if app.SpectrumCheck.Value
%                 app.DialogueLabel.Text = 'FFT = Fourier Fun Times';
%                 pause(.1);
                isis = diff(evons);
                period = mode(isis);
                gaps = find(isis>(period*1.01));
                trainstarts = [evons(1); evons(gaps+1)];
                trainends = [evons(gaps); evons(end-1)]+period;
                w1trainstarti = find(trainstarts>app.Cycle1StartEdit.Value + offsetw1, 1);
                w1trainendi = find(trainstarts<app.Cycle1EndEdit.Value + offsetw1, 1, 'last');
                poststartind = find(trainstarts>stimons(1), 1);
                for i = 1:numel(trainstarts)
                    respsegment = signals(round(trainstarts(i)*fs):round(trainends(i)*fs));
                    [rp(:,i),f] = pspectrum(respsegment, fs); 
                end
                if isempty(poststartind)
                    semilogx(app.Spectrum1Axes, f, rp./max(rp(:))+(1:size(rp,2)));
                else
                    semilogx(app.Spectrum1Axes, f, rp(:,1:(poststartind-1))./max(rp(:))+(1:(poststartind-1)), 'b');
                    hold(app.Spectrum1Axes, 'on')
                    semilogx(app.Spectrum1Axes, f, rp(:,poststartind:end)./max(rp(:))+(1:(poststartind-1)), 'r');
                    hold(app.Spectrum1Axes, 'off')
                end
            end
%plot waveform heatmap
            binvec = linspace(app.Y1Edit.Value - app.Y2Edit.Value, app.Y1Edit.Value + app.Y2Edit.Value, 60);
            for samp = (round(app.T1Edit.Value*fs/1E3 + 1):round(app.T2Edit.Value*fs/1E3)) 
                abr1map(:,samp) = hist(sttracegood(cycle1range,samp), binvec);
                abr2map(:,samp) = hist(sttracegood(cycle2range,samp), binvec);
            end
            t = (1:size(sttracegood, 2)).*1E3./fs;
%             plot(app.Map1Axes, t, sttracegood(cycle1range,:))
%             plot(app.Map2Axes, t, sttracegood(cycle2range,:))
            mapxlims = [app.T1Edit.Value app.T2Edit.Value];
            mapylims =  [app.Y1Edit.Value - app.Y2Edit.Value, app.Y1Edit.Value + app.Y2Edit.Value];
            imagesc(app.Map1Axes, mapxlims, -mapylims, 1-abr1map./max(abr1map(:)))
            hold(app.Map1Axes, 'on')
            plot(app.Map1Axes, t, -app.data.meansig1./app.data.errsig1./10, 'g-')
            hold(app.Map1Axes, 'off')
            axis(app.Map1Axes, [mapxlims, mapylims])
            colormap(app.Map1Axes, gray)
            imagesc(app.Map2Axes, mapxlims, -mapylims, 1-abr2map./max(abr2map(:)))
            hold(app.Map2Axes, 'on')
            plot(app.Map2Axes, t, -app.data.meansig2./app.data.errsig2./10, 'g-')
            hold(app.Map2Axes, 'off')
            axis(app.Map2Axes, [mapxlims, mapylims])
            colormap(app.Map2Axes, gray)
%bootstrap
            win1traces = sttracegood(cycle1range, :); % all pre cycles
            win2traces = sttracegood(cycle2range, :); % all post cycles
            maxcycles = min([floor(numel(cycle1range)/2), numel(cycle2range)]);
            app.MaxCyclesLabel.Text = num2str(maxcycles);
            nboots = app.NBootsEdit.Value;
            if app.MaxButton.Value
                ncycles = maxcycles;
            elseif app.NCyclesEdit.Value > maxcycles
                    app.MaxButton.Value = 1;
                    app.NCyclesButton.Value = 0;
                    ncycles = maxcycles;
            else
                ncycles = app.NCyclesEdit.Value;
            end
            analsamp1 = 1+floor(app.BootAnal1Edit.Value.*fs/1000);
            analsamp2 = 1+floor(app.BootAnal2Edit.Value.*fs/1000);
            bootma = NaN(nboots, size(win1traces, 2));
            bootmb = NaN(nboots, size(win2traces, 2));
            for i = 1:nboots
                if app.BootSeedEdit.Value > 0
                    rng(app.BootSeedEdit.Value+i)
                end
                iord = randperm(numel(cycle1range)); % randomized permutation of cycles
                iord2 = randperm(numel(cycle2range));
                sampa = iord(1:ncycles); % randomly selected half of cycles
                sampb = iord(ncycles+(1:ncycles)); % other half of cycles
                sampc = win2traces(iord2(1:ncycles), :); % post cycles
%                 sampc = win2traces(1:ncycles, :); % post cycles
                ncycs = [numel(sampa); numel(sampb); size(sampc, 1)];
                bootma(i,:) = mean(win1traces(sampa, :)); % mean of first bootstrapped half
                bootmb(i,:) = mean(win1traces(sampb, :)); % mean of second bootstrapped half
                bootmc(i,:) = mean(sampc); % mean of window 2 bootstrap
                r1(i,1) = corr(bootma(i,analsamp1:analsamp2)', bootmb(i,analsamp1:analsamp2)');
                r2(i,1) = corr(bootma(i,analsamp1:analsamp2)', bootmc(i,analsamp1:analsamp2)');
                xr1(i,:) = xcorr(bootma(i,analsamp1:analsamp2)', bootmb(i,analsamp1:analsamp2)', 50)./(1+analsamp2-analsamp1);
                xr2(i,:) = xcorr(bootma(i,analsamp1:analsamp2)', bootmc(i,analsamp1:analsamp2)', 50)./(1+analsamp2-analsamp1);
            end
            [xr1m, xr1l] = max(mean(xr1));
            [xr2m, xr2l] = max(mean(xr2));
            t = (1:size(sttracegood, 2))'.*1000./fs;
            cla(app.BootAxes);
            plot(app.BootAxes, 0, 0);
            hold(app.BootAxes, 'on')
            rectangle(app.BootAxes, 'Position', [app.BootAnal1Edit.Value, app.Y1Edit.Value-app.Y2Edit.Value, app.BootAnal2Edit.Value - app.BootAnal1Edit.Value, 2*app.Y2Edit.Value], 'FaceColor', .8*[.8 1 .8])
            plot(app.BootAxes, t, bootma', 'k:')
            plot(app.BootAxes, t, bootmb', 'b:')
            plot(app.BootAxes, t, bootmc', 'r:')
            hold(app.BootAxes, 'off')
            xlim(app.BootAxes, [app.T1Edit.Value app.T2Edit.Value])
            ylim(app.BootAxes, [app.Y1Edit.Value-app.Y2Edit.Value app.Y1Edit.Value+app.Y2Edit.Value])

            [h, p] = ttest2(r1, r2);
            [y1, x1] = hist(r1, ceil(nboots/10));
            [y2, x2] = hist(r2, ceil(nboots/10));
            cla(app.BootHistAxes);
            plot(app.BootHistAxes, x1', y1', 'k');
            hold(app.BootHistAxes, 'on');
            plot(app.BootHistAxes, x2', y2', 'r');
            plot(app.BootHistAxes, r1, -1.*ones(numel(r1), 1), 'k.');
            plot(app.BootHistAxes, r2, -2.*ones(numel(r2), 1), 'r.');
            plot(app.BootHistAxes, mean(r1), -3, 'k+');
            plot(app.BootHistAxes, [mean(r1)-std(r1)./sqrt(numel(r1)) mean(r1)+std(r1)./sqrt(numel(r1))] , [-3 -3], 'k-');
            plot(app.BootHistAxes, mean(r2), -4, 'r+');
            plot(app.BootHistAxes, [mean(r2)-std(r2)./sqrt(numel(r2)) mean(r2)+std(r2)./sqrt(numel(r2))] , [-4 -4], 'r-');
            text(app.BootHistAxes, mean(r2), -5, num2str(p, '%0.2g'), 'Color', 'r')
            hold(app.BootHistAxes, 'off');
            
            cla(app.XCorrAxes);
            xr1lms = (xr1l-51).*1000./fs;
            xr2lms = (xr2l-51).*1000./fs;
            plot(app.XCorrAxes, (-50:50).*1000./fs, xr1, ':', 'Color', .5*[1 1 1])
            hold(app.XCorrAxes, 'on');
            plot(app.XCorrAxes, (-50:50).*1000./fs, xr2, ':', 'Color', .5*[1 0 0])
            plot(app.XCorrAxes, (-50:50).*1000./fs, mean(xr1), 'k', 'LineWidth', 2)
            plot(app.XCorrAxes, (-50:50).*1000./fs, mean(xr2), 'r', 'LineWidth', 2)
            plot(app.XCorrAxes, xr1lms, xr1m, 'c+', 'LineWidth', 2)
            plot(app.XCorrAxes, xr2lms, xr2m, 'y+', 'LineWidth', 2)
            
            hold(app.XCorrAxes, 'off');
            
            app.data.r1 = r1;
            app.data.r2 = r2;
            app.data.r1m = mean(r1);
            app.data.r2m = mean(r2);
            app.data.r1std = std(r1);
            app.data.r2std = std(r2);
            app.data.xr1 = mean(xr1);
            app.data.xr2 = mean(xr2);
            app.data.xr1lms = xr1lms;
            app.data.xr2lms = xr2lms;
            app.data.xr1m = xr1m;
            app.data.xr2m = xr2m;
            app.BootDiagLabel.Text = ['Analyzed signal from sample ' num2str(analsamp1) ' to ' num2str(analsamp2) ...
                newline 'Pre cycles: ' num2str(numel(sampa)) '/' num2str(numel(sampb)) ...
                newline 'Post cycles: '  num2str(size(sampc, 1))];

        end

        % Button pushed function: TankButton
        function TankButtonPushed(app, event)
            [filepath, ~, ~] = fileparts(app.TankEdit.Value);
            if isempty(filepath)
                tankpath = uigetdir('D:\Synapse\Tanks');
            else
                tankpath = uigetdir(filepath);
            end
            if tankpath ~= 0
                app.TankEdit.Value = tankpath;
            end
            figure(app.UIFigure);
            cla(app.SpectrumAxes); cla(app.ArtifactAxes); cla(app.Cycle1Axes); cla(app.Cycle2Axes);
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
        %Load the tank and update gui
            app.data = readtank_v2(app.TankEdit.Value);
            app.TS1Table.Data = NaN(12, 2);
            app.TS2Table.Data = NaN(12, 2);
            load([app.TankEdit.Value '\params.mat']');
            if exist('changetable', 'var')
                datastr = '';
                for row = 1:size(changetable, 2)
                   datastr = [datastr, num2str(table2array(changetable(1, row))), newline];
                end
                app.StimParamsLabel.Text = changetable.Properties.VariableNames';
                app.StimParamsLabel_2.Text = datastr;
            end
            if exist('stimtable', 'var')
                if numel(stimtable) >0
                    datastr = '';
                    for row = 1:size(stimtable, 1)
                       datastr = [datastr, num2str(table2array(stimtable(row,2))), newline];
                    end
                    app.StimParamsLabel.Text = table2array(stimtable(:,1));
                    app.StimParamsLabel_2.Text = datastr;
                end
            end
            app.DialogueLabel.Text = 'Ready';
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if strcmpi(app.TabGroup.SelectedTab.Title, 'PeakPicker')
                ax = [app.Cycle1Axes, app.Cycle2Axes];
                fs = app.data.fs;   
                tsrow = [str2double(app.Cycle1TimestampRadio.SelectedObject.Text), str2double(app.Cycle2TimestampRadio.SelectedObject.Text)];
                y = [app.data.meansig1, app.data.meansig2];
                for i = 1:2
                    currpoint = get(ax(i), 'CurrentPoint');
                    if currpoint(1,2) < (app.Y1Edit.Value + app.Y2Edit.Value) && currpoint(1,2) > (app.Y1Edit.Value - app.Y2Edit.Value)
                        t = (1:size(y, 1)).*1000./fs;
                        if app.NothingButton.Value
                            peaktime = round(currpoint(1,1)/1000*fs)/fs*1000;
                        else
                            [u,d] = findcross2(diff(y(:,i)));
                            if app.PeakButton.Value
                                peaktimes = t(d+1);
                            else
                                peaktimes = t(u+1);
                            end
                            [~,nearestpeaki] = min(abs(peaktimes-currpoint(1,1)));
                            peaktime = peaktimes(nearestpeaki);
                        end
                        tablename = ['TS' num2str(i) 'Table'];
                        tstable = app.(tablename).Data;
                        tstable(tsrow(i),1) = peaktime;
                        app.(tablename).Data = tstable;
                        app.data.currentaxes = i;
                    end
                end
            end
        plotabr(app);
        end

        % Cell edit callback: TS1Table
        function TS1TableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            tstable = app.TS1Table.Data;
            for ts = 1:10
                if ~isnan(tstable(ts, 1))
                    tstable(ts, 2) = app.data.meansig1(round(tstable(ts, 1)/1000*app.data.fs));
                end
            end
            app.TS1Table.Data = tstable;
            plotabr(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            valchange = 0;
            if strcmp(key, 'rightarrow')
                valchange = 1;
            end
            if strcmp(key, 'leftarrow')
                valchange = -1;
            end
            if app.data.currentaxes > 0
                currentbox = ['Cycle' num2str(app.data.currentaxes) 'TimestampRadio'];
                currentpeak = str2double(app.(currentbox).SelectedObject.Text);
                newpeak = currentpeak;
                if currentpeak + valchange > 0 && currentpeak + valchange < 13
                    newpeak = currentpeak + valchange;
                end
                newbutton = ['C' num2str(app.data.currentaxes) 'TS' num2str(newpeak) 'Button'];
                app.(newbutton).Value = 1;
                plotabr(app);
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            ABR1 = app.data.meansig1;
            ABR2 = app.data.meansig2;
            Time = (1:size(ABR1, 1))'.*1000./app.data.fs;
            abrtable = table(Time, ABR1, ABR2);
            TS1 = app.TS1Table.Data;
            TS2 = app.TS2Table.Data;
            TSD = app.TSDTable.Data;
            Latency1 = TS1(:,1);
            Amp1 = TS1(:,2);
            Latency2 = TS2(:,1);
            Amp2 = TS2(:,2);
            Peak = TSD(:,1);
            LatencyD = TSD(:,2);
            AmpD = TSD(:,3);
            peaktable = table(Peak, Latency1, Latency2, LatencyD, Amp1, Amp2, AmpD);
            
            savepath = uigetdir('D:\Synapse\ExperimentData\');
            figure(app.UIFigure);
            
            fnames = fieldnames(app);
            efi = [];
            parvals = [];
            c = 1;
            for i = 1:numel(fnames)
                fi = char(fnames(i));
                if isprop(app.(fi), 'Value')
                    efi = [efi;i];
                    parvals{c,1} = app.(fi).Value;
                    c = c +1;
                end
            end
            parfields = fnames(efi);
             partable = [{'Parameter', 'Value'}; parfields, parvals];
            name = 'peaks';
            ntable = [{'Peak', 'Latency1', 'Latency2', 'LatencyD', 'Amp1', 'Amp2', 'AmpD'}; ...
                num2cell([Peak, Latency1, Latency2, LatencyD, Amp1, Amp2, AmpD])];
            fname = [savepath '\' app.PrefixEdit.Value, name '.xlsx'];
            d = datestr(now, 'yymmdd-HHMMSS');
            if exist(fname, 'file')
                xlswrite([savepath '\' app.PrefixEdit.Value name d '.xlsx'], ntable, 1)
                xlswrite([savepath '\' app.PrefixEdit.Value name d '.xlsx'], partable, 2)
            elseif exist(savepath, 'dir')
                xlswrite(fname, ntable, 1)
                xlswrite(fname, partable, 2)
            else
                mkdir(savepath)
                xlswrite(fname, ntable, 1)
                xlswrite(fname, partable, 2)
            end
            
            name = 'ABR';
            ntable = abrtable;
            fname = [savepath '\' app.PrefixEdit.Value, name '.csv'];
            d = datestr(now, 'yymmdd-HHMMSS');
            if exist(fname, 'file')
                writetable(ntable, [savepath '\' app.PrefixEdit.Value name d '.csv'])
            elseif exist(savepath, 'dir')
                writetable(ntable, fname)
            else
                mkdir(savepath)
                writetable(ntable, fname)
            end
            
            name = 'correlations';
            corrtable = [{'Window 1-Window 1', 'Window 1-Window2'}; num2cell([app.data.r1, app.data.r2])];
            rstatstable = [{'R1 Mean', 'R1 Std', 'R2 mean', 'R2 Std', 'XR1 Peak', 'XR1 Latency', 'XR2 Peak', 'XR2 Latency'}; ...
                num2cell([app.data.r1m, app.data.r1std, app.data.r2m, app.data.r2std, app.data.xr1m, app.data.xr1lms, app.data.xr2m, app.data.xr2lms])];
            fname = [savepath '\' app.PrefixEdit.Value, name '.xlsx'];
            d = datestr(now, 'yymmdd-HHMMSS');
            if exist(fname, 'file')
                xlswrite([savepath '\' app.PrefixEdit.Value name d '.xlsx'], rstatstable, 1)
                xlswrite([savepath '\' app.PrefixEdit.Value name d '.xlsx'], corrtable, 2)
            elseif exist(savepath, 'dir')
                xlswrite(fname, rstatstable, 1)
                xlswrite(fname, corrtable, 2)
            else
                mkdir(savepath)
                xlswrite(fname, rstatstable, 1)
                xlswrite(fname, corrtable, 2)
            end

        end

        % Cell edit callback: TS2Table
        function TS2TableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            tstable = app.TS2Table.Data;
            for ts = 1:10
                if ~isnan(tstable(ts, 1))
                    tstable(ts, 2) = app.data.meansig2(round(tstable(ts, 1)/1000*app.data.fs));
                end
            end
            app.TS2Table.Data = tstable;
            plotabr(app);
        end

        % Value changed function: DelayDrop
        function DelayDropValueChanged(app, event)
            readdelay(app)                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TankButton = uibutton(app.UIFigure, 'push');
            app.TankButton.ButtonPushedFcn = createCallbackFcn(app, @TankButtonPushed, true);
            app.TankButton.Text = 'Tank';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TankEdit = uieditfield(app.UIFigure, 'text');
            app.SingleCyclesCheckBox = uicheckbox(app.UIFigure);
            app.SingleCyclesCheckBox.Text = 'Single Cycles';
            app.DialogueLabel = uilabel(app.UIFigure);
            app.DialogueLabel.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PeakPickerTab = uitab(app.TabGroup);
            app.PeakPickerTab.Title = 'PeakPicker';
            app.Cycle1Axes = uiaxes(app.PeakPickerTab);
            title(app.Cycle1Axes, 'Window 1')
            xlabel(app.Cycle1Axes, 'Time (ms)')
            ylabel(app.Cycle1Axes, 'ABR (uV)')
            app.TS1Table = uitable(app.PeakPickerTab);
            app.TS1Table.ColumnName = {'Time'; 'Amp'};
            app.TS1Table.RowName = {''};
            app.TS1Table.ColumnEditable = [true false];
            app.TS1Table.CellEditCallback = createCallbackFcn(app, @TS1TableCellEdit, true);
            app.Cycle1TimestampRadio = uibuttongroup(app.PeakPickerTab);
            app.Cycle1TimestampRadio.Title = 'Timestamp';
            app.C1TS1Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS1Button.Text = '1';
            app.C1TS1Button.Value = true;
            app.C1TS2Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS2Button.Text = '2';
            app.C1TS3Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS3Button.Text = '3';
            app.C1TS4Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS4Button.Text = '4';
            app.C1TS5Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS5Button.Text = '5';
            app.C1TS6Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS6Button.Text = '6';
            app.C1TS7Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS7Button.Text = '7';
            app.C1TS8Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS8Button.Text = '8';
            app.C1TS9Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS9Button.Text = '9';
            app.C1TS10Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS10Button.Text = '10';
            app.C1TS11Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS11Button.Text = '11';
            app.C1TS12Button = uiradiobutton(app.Cycle1TimestampRadio);
            app.C1TS12Button.Text = '12';
            app.Cycle2Axes = uiaxes(app.PeakPickerTab);
            title(app.Cycle2Axes, 'Window 2')
            xlabel(app.Cycle2Axes, 'Time (ms)')
            ylabel(app.Cycle2Axes, 'ABR (uV)')
            app.TS2Table = uitable(app.PeakPickerTab);
            app.TS2Table.ColumnName = {'Time'; 'Amp'};
            app.TS2Table.RowName = {''};
            app.TS2Table.ColumnEditable = [true false];
            app.TS2Table.CellEditCallback = createCallbackFcn(app, @TS2TableCellEdit, true);
            app.Cycle2TimestampRadio = uibuttongroup(app.PeakPickerTab);
            app.Cycle2TimestampRadio.Title = 'Timestamp';
            app.C2TS1Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS1Button.Text = '1';
            app.C2TS1Button.Value = true;
            app.C2TS2Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS2Button.Text = '2';
            app.C2TS3Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS3Button.Text = '3';
            app.C2TS4Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS4Button.Text = '4';
            app.C2TS5Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS5Button.Text = '5';
            app.C2TS6Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS6Button.Text = '6';
            app.C2TS7Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS7Button.Text = '7';
            app.C2TS8Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS8Button.Text = '8';
            app.C2TS9Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS9Button.Text = '9';
            app.C2TS10Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS10Button.Text = '10';
            app.C2TS11Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS11Button.Text = '11';
            app.C2TS12Button = uiradiobutton(app.Cycle2TimestampRadio);
            app.C2TS12Button.Text = '12';
            app.SnaptoButtonGroup = uibuttongroup(app.PeakPickerTab);
            app.SnaptoButtonGroup.Title = 'Snap to';
            app.NothingButton = uiradiobutton(app.SnaptoButtonGroup);
            app.NothingButton.Text = 'Nothing';
            app.PeakButton = uiradiobutton(app.SnaptoButtonGroup);
            app.PeakButton.Text = 'Peak';
            app.PeakButton.Value = true;
            app.TroughButton = uiradiobutton(app.SnaptoButtonGroup);
            app.TroughButton.Text = 'Trough';
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.AnalysisAxes = uiaxes(app.ComparisonTab);
            title(app.AnalysisAxes, 'ABR')
            xlabel(app.AnalysisAxes, 'Time (ms)')
            ylabel(app.AnalysisAxes, 'ABR (uV)')
            app.TSDTable = uitable(app.ComparisonTab);
            app.TSDTable.ColumnName = {'Peak'; 'Time'; 'Amp'};
            app.TSDTable.RowName = {''};
            app.TSDTable.ColumnEditable = [false true false];
            app.BootstrapTab = uitab(app.TabGroup);
            app.BootstrapTab.Title = 'Bootstrap';
            app.BootAxes = uiaxes(app.BootstrapTab);
            title(app.BootAxes, 'Bootstrapped ABRs')
            xlabel(app.BootAxes, 'Time (ms)')
            ylabel(app.BootAxes, 'ABR (uV)')
            app.BootHistAxes = uiaxes(app.BootstrapTab);
            title(app.BootHistAxes, 'Corr Coefficients')
            xlabel(app.BootHistAxes, 'Mean R')
            ylabel(app.BootHistAxes, '')
            app.AnalysiswindowmsLabel_2 = uilabel(app.BootstrapTab);
            app.AnalysiswindowmsLabel_2.Text = 'Analysis window (ms)';
            app.BootAnal1Edit = uieditfield(app.BootstrapTab, 'numeric');
            app.BootAnal2Edit = uieditfield(app.BootstrapTab, 'numeric');
            app.BootAnal2Edit.Value = 10;
            app.BootstrapsLabel = uilabel(app.BootstrapTab);
            app.BootstrapsLabel.Text = '#Bootstraps';
            app.NBootsEdit = uieditfield(app.BootstrapTab, 'numeric');
            app.NBootsEdit.Value = 100;
            app.CyclesButtonGroup = uibuttongroup(app.BootstrapTab);
            app.CyclesButtonGroup.Title = '# Cycles';
            app.MaxButton = uiradiobutton(app.CyclesButtonGroup);
            app.MaxButton.Text = 'Max';
            app.MaxButton.Value = true;
            app.NCyclesButton = uiradiobutton(app.CyclesButtonGroup);
            app.NCyclesButton.Text = 'n = ';
            app.NCyclesEdit = uieditfield(app.CyclesButtonGroup, 'numeric');
            app.MaxCyclesLabel = uilabel(app.CyclesButtonGroup);
            app.MaxCyclesLabel.Text = '';
            app.BootDiagLabel = uilabel(app.BootstrapTab);
            app.BootDiagLabel.Text = '';
            app.seed0shuffleLabel = uilabel(app.BootstrapTab);
            app.seed0shuffleLabel.Text = 'seed (0 = shuffle)';
            app.BootSeedEdit = uieditfield(app.BootstrapTab, 'numeric');
            app.BootSeedEdit.Value = 1;
            app.XCorrAxes = uiaxes(app.BootstrapTab);
            title(app.XCorrAxes, 'Cross Correlograms')
            xlabel(app.XCorrAxes, 'Mean R')
            ylabel(app.XCorrAxes, '')
            app.SpectraTab = uitab(app.TabGroup);
            app.SpectraTab.Title = 'Spectra';
            app.Spectrum1Axes = uiaxes(app.SpectraTab);
            title(app.Spectrum1Axes, 'Spectrum')
            xlabel(app.Spectrum1Axes, 'Frequency (Hz)')
            ylabel(app.Spectrum1Axes, '')
            app.Spectrum2Axes = uiaxes(app.SpectraTab);
            title(app.Spectrum2Axes, 'Spectrum')
            xlabel(app.Spectrum2Axes, 'Frequency (Hz)')
            ylabel(app.Spectrum2Axes, '')
            app.TrainDurationsecLabel = uilabel(app.SpectraTab);
            app.TrainDurationsecLabel.Text = 'Train Duration (sec)';
            app.TrainDurationEdit = uieditfield(app.SpectraTab, 'numeric');
            app.TrainDurationEdit.Value = 5;
            app.PeakReliabilityTab = uitab(app.TabGroup);
            app.PeakReliabilityTab.Title = 'Peak Reliability';
            app.Map1Axes = uiaxes(app.PeakReliabilityTab);
            title(app.Map1Axes, 'Window 1')
            xlabel(app.Map1Axes, 'Time (ms)')
            ylabel(app.Map1Axes, 'ABR (uV)')
            app.Map2Axes = uiaxes(app.PeakReliabilityTab);
            title(app.Map2Axes, 'Window 2')
            xlabel(app.Map2Axes, 'Time (ms)')
            ylabel(app.Map2Axes, 'ABR (uV)')
            app.TimeCourseAxes = uiaxes(app.UIFigure);
            title(app.TimeCourseAxes, 'Recording Time Course')
            xlabel(app.TimeCourseAxes, 'Time (sec)')
            ylabel(app.TimeCourseAxes, '')
            app.TimeCourseAxes.YTick = [];
            app.StimParamsLabel = uilabel(app.UIFigure);
            app.StimParamsLabel.Text = '';
            app.StimParamsLabel_2 = uilabel(app.UIFigure);
            app.StimParamsLabel_2.Text = '';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FilenameprefixEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameprefixEditFieldLabel.Text = 'Filename prefix';
            app.PrefixEdit = uieditfield(app.UIFigure, 'text');
            app.Window1Referencet0Label = uilabel(app.UIFigure);
            app.Window1Referencet0Label.Text = 'Window 1 Reference (t=0)';
            app.W1RefDrop = uidropdown(app.UIFigure);
            app.W1RefDrop.Items = {'Onset', 'Offset', '0'};
            app.W1RefDrop.Value = '0';
            app.Window2Referencet0Label = uilabel(app.UIFigure);
            app.Window2Referencet0Label.Text = 'Window 2 Reference (t=0)';
            app.W2RefDrop = uidropdown(app.UIFigure);
            app.W2RefDrop.Items = {'Onset', 'Offset', '0'};
            app.W2RefDrop.Value = 'Offset';
            app.SpectrumPanel = uipanel(app.UIFigure);
            app.SpectrumPanel.Title = 'Spectrum';
            app.FilterCheck = uicheckbox(app.SpectrumPanel);
            app.FilterCheck.Text = 'Post Bandpass';
            app.FilterCheck.Value = true;
            app.SpectrumCheck = uicheckbox(app.SpectrumPanel);
            app.SpectrumCheck.Text = 'Show Spectrum';
            app.SpectrumAxes = uiaxes(app.SpectrumPanel);
            title(app.SpectrumAxes, 'Spectrum')
            xlabel(app.SpectrumAxes, 'Frequency (Hz)')
            ylabel(app.SpectrumAxes, '')
            app.HipassHzEditFieldLabel = uilabel(app.SpectrumPanel);
            app.HipassHzEditFieldLabel.Text = 'Hipass (Hz)';
            app.HPCFEdit = uieditfield(app.SpectrumPanel, 'numeric');
            app.HPCFEdit.Value = 300;
            app.LopassHzEditFieldLabel = uilabel(app.SpectrumPanel);
            app.LopassHzEditFieldLabel.Text = 'Lopass (Hz)';
            app.LPCFEdit = uieditfield(app.SpectrumPanel, 'numeric');
            app.LPCFEdit.Value = 3000;
            app.OrderLabel = uilabel(app.SpectrumPanel);
            app.OrderLabel.Text = 'Order';
            app.FilterOrderEdit = uieditfield(app.SpectrumPanel, 'numeric');
            app.FilterOrderEdit.Value = 2;
            app.ArtifactsPanel = uipanel(app.UIFigure);
            app.ArtifactsPanel.Title = 'Artifacts';
            app.ArtRejectCheck = uicheckbox(app.ArtifactsPanel);
            app.ArtRejectCheck.Text = 'Artifact Rejection';
            app.ArtRejectCheck.Value = true;
            app.ArtifactAxes = uiaxes(app.ArtifactsPanel);
            title(app.ArtifactAxes, 'Bandpass maxima')
            xlabel(app.ArtifactAxes, 'maxima')
            ylabel(app.ArtifactAxes, '')
            app.ThresholduVLabel = uilabel(app.ArtifactsPanel);
            app.ThresholduVLabel.Text = 'Threshold (uV)';
            app.ArtThreshEdit = uieditfield(app.ArtifactsPanel, 'numeric');
            app.ArtThreshEdit.Value = 20;
            app.ChannelLabel = uilabel(app.UIFigure);
            app.ChannelLabel.Text = 'Channel';
            app.ChannelEdit = uieditfield(app.UIFigure, 'numeric');
            app.ChannelEdit.Value = 1;
            app.HeadstagePanel = uipanel(app.UIFigure);
            app.HeadstagePanel.Title = 'Headstage';
            app.DelaymsDropDownLabel = uilabel(app.HeadstagePanel);
            app.DelaymsDropDownLabel.Text = 'Delay (ms)';
            app.DelayDrop = uidropdown(app.HeadstagePanel);
            app.DelayDrop.Items = {'Mouse', 'Human RA4', 'Human Medusa 25K', 'Human Medusa 12K', 'Human Medusa 6K'};
            app.DelayDrop.ValueChangedFcn = createCallbackFcn(app, @DelayDropValueChanged, true);
            app.DelayDrop.Value = 'Mouse';
            app.GainLabel = uilabel(app.HeadstagePanel);
            app.GainLabel.Text = 'Gain';
            app.DelayEdit = uieditfield(app.HeadstagePanel, 'numeric');
            app.GainEdit = uieditfield(app.HeadstagePanel, 'numeric');
            app.GainEdit.Value = 1;
            app.HeadstageDialogue = uilabel(app.HeadstagePanel);
            app.HeadstageDialogue.Text = '';
            app.Cycle1StartEdit = uieditfield(app.UIFigure, 'numeric');
            app.Cycle1EndEdit = uieditfield(app.UIFigure, 'numeric');
            app.Cycle1EndEdit.Value = 20;
            app.Cycle2StartEdit = uieditfield(app.UIFigure, 'numeric');
            app.Cycle2EndEdit = uieditfield(app.UIFigure, 'numeric');
            app.Cycle2EndEdit.Value = 20;
            app.StartLabel = uilabel(app.UIFigure);
            app.StartLabel.Text = 'Start';
            app.T1Edit = uieditfield(app.UIFigure, 'numeric');
            app.EndLabel = uilabel(app.UIFigure);
            app.EndLabel.Text = 'End';
            app.T2Edit = uieditfield(app.UIFigure, 'numeric');
            app.T2Edit.Value = 20;
            app.YScaleLabel = uilabel(app.UIFigure);
            app.YScaleLabel.Text = 'YScale';
            app.Y2Edit = uieditfield(app.UIFigure, 'numeric');
            app.Y2Edit.Value = 1;
            app.ShiftLabel = uilabel(app.UIFigure);
            app.ShiftLabel.Text = 'Shift';
            app.Y1Edit = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synabr_v104

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end