classdef synfra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TankButton                      matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        TankEdit                        matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        PickerTab                       matlab.ui.container.Tab
        IsoIAxes                        matlab.ui.control.UIAxes
        FRAAxes                         matlab.ui.control.UIAxes
        RLFAxes                         matlab.ui.control.UIAxes
        PSTHAxes                        matlab.ui.control.UIAxes
        ResponseWindowmsEditFieldLabel  matlab.ui.control.Label
        AnalStartEdit                   matlab.ui.control.NumericEditField
        AnalEndEdit                     matlab.ui.control.NumericEditField
        SpontEndEdit                    matlab.ui.control.NumericEditField
        SpontaneousWindowmsEditFieldLabel  matlab.ui.control.Label
        SpontStartEdit                  matlab.ui.control.NumericEditField
        PickerButtonGroup               matlab.ui.container.ButtonGroup
        BFBLButton                      matlab.ui.control.RadioButton
        CFThresholdButton               matlab.ui.control.RadioButton
        SmoothingLabel                  matlab.ui.control.Label
        SmoothDrop                      matlab.ui.control.DropDown
        XChannelMapsTab                 matlab.ui.container.Tab
        MultiFRAAxes                    matlab.ui.control.UIAxes
        TuningTable                     matlab.ui.control.Table
        DialogueLabel                   matlab.ui.control.Label
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDrop                     matlab.ui.control.DropDown
        ExportButton                    matlab.ui.control.Button
        FilenameprefixEditFieldLabel    matlab.ui.control.Label
        PrefixEdit                      matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Property % Description
        data
    end
    
    methods (Access = private)
        
        function results = updateplots(app)
            %fra
            channel = str2double(app.ChannelDrop.Value);
            ax = app.FRAAxes;
            fra = squeeze(app.data.fra(channel, :,:));
            switch app.SmoothDrop.Value
                case 'Raw'
                    smoothfra = fra;
                case '2Dweak'
                    h = fspecial('gaussian',3, .5);
                    z = sum(h);
                    sidecoef = sum(z)./(z(1)+z(2));            
                    smoothfra = conv2(fra, h, 'same');
                    smoothfra(1,:) = smoothfra(1,:).*sidecoef;
                    smoothfra(end,:) = smoothfra(end,:).*sidecoef;
                    smoothfra(:,1) = smoothfra(:,1).*sidecoef;
                    smoothfra(:,end) = smoothfra(:,end).*sidecoef;
                case '2Dstrong'
                    h = fspecial('gaussian',3, 1);
                    z = sum(h);
                    sidecoef = sum(z)./(z(1)+z(2));            
                    smoothfra = conv2(fra, h, 'same');
                    smoothfra(1,:) = smoothfra(1,:).*sidecoef;
                    smoothfra(end,:) = smoothfra(end,:).*sidecoef;
                    smoothfra(:,1) = smoothfra(:,1).*sidecoef;
                    smoothfra(:,end) = smoothfra(:,end).*sidecoef;
                case 'Freqweak'
                    g = normpdf(-1:1, 0, .5);
                    g = g./sum(g);
                    for flevel = 1:size(fra, 1)
                        smoothfra(flevel,:) = conv(fra(flevel,:), g, 'same');
                    end
                    sidecoef = sum(g)./(g(1)+g(2));
                    smoothfra(:,1) = smoothfra(:,1).*sidecoef;
                    smoothfra(:,end) = smoothfra(:,end).*sidecoef;
                case 'Freqstrong'
                    g = normpdf(-1:1, 0, 1);
                    g = g./sum(g);
                    for flevel = 1:size(fra, 1)
                        smoothfra(flevel,:) = conv(fra(flevel,:), g, 'same');
                    end
                    sidecoef = sum(g)./(g(1)+g(2));
                    smoothfra(:,1) = smoothfra(:,1).*sidecoef;
                    smoothfra(:,end) = smoothfra(:,end).*sidecoef;

            end
            threshfra = smoothfra;
            app.data.smoothfra = smoothfra;
            coloscale = max(max(threshfra));
            threshfra(threshfra<app.data.spontsig(channel)) = -coloscale/250;
            imagesc(ax, flipud(threshfra));
            ax.Colormap(1, :) = [1,1,1];

            frqlist = round(sort(unique(app.data.frqs)));
            lvllist = round(sort(unique(app.data.lvls)));

            BF = app.TuningTable.Data(channel, 2);
            CF = app.TuningTable.Data(channel, 3);
            BL = app.TuningTable.Data(channel, 4);
            Thresh = app.TuningTable.Data(channel, 5);
            BFi = find(frqlist >= BF, 1);
            CFi = find(frqlist >= CF, 1);
            BLi = find(lvllist >= BL, 1);
            Threshi = find(lvllist >= Thresh, 1);
            hold(ax, 'on');
            plot(ax, BFi, numel(lvllist)+1-BLi, 'k*');
            plot(ax, CFi, numel(lvllist)+1-Threshi, 'k*');
            text(ax, BFi-.4, numel(lvllist)+.7-BLi, num2str(smoothfra(BLi, BFi), '%0.4g'), 'Color', 'k')
            text(ax, CFi-.4, numel(lvllist)+.7-Threshi, num2str(smoothfra(Threshi, CFi), '%0.4g'), 'Color', 'k')
            hold(ax, 'off');
%RLF
            ax = app.RLFAxes; cla(ax, 'reset');
            vvec = [1:-1/16:0 0:1/16:1 1:-1/16:0 0:1/16:1]';
            vv = 1:32;
            colmat = [vvec(vv+32) vvec(vv+22) vvec(vv+12)];
            colmat = [colmat; 0 0 0];
            hold (ax, 'on');
            for frqi = 1:numel(frqlist)
                plot(ax, lvllist, fra(:,frqi), 'Color', colmat(2*frqi-1, :))
            end
            fill(ax, [min(app.data.lvls); max(app.data.lvls); max(app.data.lvls); min(app.data.lvls)],...
                [app.data.spontrates([channel; channel]); app.data.spontsig([channel; channel])], [1 .4 .4], 'FaceAlpha', .6, 'EdgeAlpha', 0);
            if ~isempty(fra(Threshi, CFi))
                plot(ax, Thresh, fra(Threshi, CFi), 'r*');
            end
          
            hold (ax, 'off');
            xlabel(ax, 'Level (dBSPL)')
            ylabel(ax, 'Spike Rate (Hz)')

            legend(ax, sprintfc('%0.1f',frqlist./1000), 'Location', 'northwestoutside')
            
            ax = app.IsoIAxes; cla(ax, 'reset');
            for lvli = 1:numel(lvllist)
                semilogx(ax, frqlist, fra(lvli,:), 'Color', colmat(3*lvli-2, :))
                if lvli ==1
                    hold (ax, 'on');
                end
            end
            if ~isempty(fra(BLi, BFi))
                plot(ax, BF, fra(BLi, BFi), 'r*');
            end

            fill(ax, [min(app.data.frqs); max(app.data.frqs); max(app.data.frqs); min(app.data.frqs)],...
                [app.data.spontrates([channel; channel]); app.data.spontsig([channel; channel])], [1 .4 .4], 'FaceAlpha', .6, 'EdgeAlpha', 0);
            hold (ax, 'off');
            set(ax, 'XTick', frqlist(1:2:end))
            xlabel(ax, 'Frequency (Hz)')
            ylabel(ax, 'Spike Rate (Hz)')
            legend(ax, sprintfc('%d',lvllist), 'Location', 'northeastoutside');
        end
        
        function results = replot(app)
            
            app.DialogueLabel.Text = 'Math...';
            pause(.1)
            frqlist = round(sort(unique(app.data.frqs)));
            lvllist = round(sort(unique(app.data.lvls)));
            [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(app.data.spets, app.data.stimons, app.data.frqs, app.data.lvls);
            app.data.spetfreq = spetfreq;
            app.data.spetlevel = spetlevel;
            app.data.stimspets = stimspets;
            app.data.trials = trials;
%             period = mode(diff(data.stimons));
            %spike times aligned to stimulus onsets are stored in stimspets
            %The frequency and level of the last stimulus preceding each spike is stored in spetfreq and spetlevel
            analwin = [app.AnalStartEdit.Value app.AnalEndEdit.Value]'./1000; %ftc analysis window;
            spontwin = [app.SpontStartEdit.Value app.SpontEndEdit.Value]'./1000; %spontaneous analysis window;
%psth
            ax = app.PSTHAxes; cla(ax, 'reset');
            targetlevel = -1;
            targetfreq = -1;
            binsize = 5E-3;
            psthwindow = [-0.2 0.3];
            channel = str2double(app.ChannelDrop.Value);
            [pst, yrange, averate, stims] = synpstbin2(app.data, spetfreq, targetfreq, spetlevel, targetlevel, binsize, psthwindow, app.data.stimons);
            spontrates = findspontrate(app.data.spets, app.data.stimons, app.data.channels, app.data.chanlist, spontwin');
            totaltime = (spontwin(2)-spontwin(1))*numel(app.data.stimons);
            spontsig = poissinv(0.95, spontrates.*binsize/(spontwin(2)-spontwin(1))); %estimate 95% CL of spikes per bin
            spontsig = spontsig./(binsize*numel(app.data.stimons));
            spontrates = spontrates./totaltime;
            rectangle(ax, 'Position', [stims(1) 0  stims(2) max(pst.bincount(:,channel))], 'FaceColor', .8*[1 1 1], 'EdgeColor', .8*[1 1 1]);
            hold(ax, 'on');
            plot(ax, pst.bintime(:,channel), pst.bincount(:,channel), 'Color', [.4 .4 1], 'LineWidth', 1);
            fill(ax, spontwin([1 2 2 1]), [spontrates([channel; channel]); spontsig([channel; channel])], [1 .4 .4], 'FaceAlpha', .3, 'EdgeAlpha', 0);
            plot(ax, analwin, [0 0], 'Color', [.4 1 .4], 'LineWidth', 1);
            hold(ax, 'off');
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Cycle Average Spike Rate (Hz)')
            app.data.spontrates = spontrates;
            app.data.spontsig = spontsig;
            ax = app.FRAAxes; cla(ax, 'reset');
            analwin = [app.AnalStartEdit.Value app.AnalEndEdit.Value]'./1000; %ftc analysis window;
            frqlist = round(sort(unique(app.data.frqs)));
            lvllist = round(sort(unique(app.data.lvls)));
            app.data.fra = multifra(app.data.stimspets, app.data.spetfreq, app.data.spetlevel, analwin, app.data.channels, app.data.trials, app.data.chanlist, app.data.frqs, app.data.lvls, ax);
            tuningtable = app.TuningTable.Data;
            tuningtable(:,8) = spontsig;
            tuningtable(:,9) = spontrates;
            app.TuningTable.Data = tuningtable;

            updateplots(app);
            app.DialogueLabel.Text = 'Ready';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TankButton
        function TankButtonPushed(app, event)
             [filepath, ~, ~] = fileparts(app.TankEdit.Value);
            if isempty(filepath)
                tankpath = uigetdir('D:\Synapse\Tanks');
            else
                tankpath = uigetdir(filepath);
            end
            if tankpath ~= 0
                app.TankEdit.Value = tankpath;
            end
            figure(app.UIFigure);
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            cla(app.IsoIAxes); cla(app.FRAAxes); cla(app.RLFAxes); cla(app.PSTHAxes);
            
        %Load the tank and update gui
            app.data = readtank_v2(app.TankEdit.Value);
            app.data.chanlist = (1:size(app.data.raw, 2))';
            app.ChannelDrop.Items = [compose('%d', app.data.chanlist)];
            frqlist = round(sort(unique(app.data.frqs)));
            lvllist = round(sort(unique(app.data.lvls)));
            app.TuningTable.Data = NaN(size(app.data.raw, 2), 10);
            app.TuningTable.Data(:,1) = app.data.chanlist;
            app.DialogueLabel.Text = 'Ready';
        end

        % Callback function: ChannelDrop, PlotButton
        function PlotButtonPushed(app, event)
            replot(app);
            %             hold(ax, 'on')
%             plot(ax, 1:numel(unique(data.frqs)), 1+numel(unique(data.lvls)) - ftcstats(:,:,1)./10, 'wo-', 'LineWidth', 4)
%             hold(ax, 'off')
            
%             labeledfra = [];
%             fratable = table([]);
%             ftctable = table([]);
%             for chan = 1:size(fra,1)
%                 chanfra = squeeze(fra(chan,:,:));
%                 [BLi(chan, 1), BFi(chan, 1)] = ind2sub(size(chanfra), find(chanfra == max(chanfra(:)), 1));
%                 peakrate(chan,1) = max(chanfra(:));
%             end
%             for freq = 1:size(fra, 3)
%                 ftcstats(:,freq,:) = ftcthreshold(fra(:,:,freq), sort(unique(data.lvls)), spontrates, spontsig);
%             end
%             realfra = permute(fra, [2 3 1]);
%             Rate = realfra(:);
%             frachannel = repmat(data.chanlist', numel(unique(data.frqs))*numel(unique(data.lvls)), 1);
%             Channel = frachannel(:);
%             frqlist = sort(unique(data.frqs));
%             frafreq = repmat(frqlist', numel(unique(data.lvls)), numel(data.chanlist));
%             Frequency = frafreq(:);
%             lvllist = sort(unique(data.lvls));
%             Level = repmat(lvllist, numel(unique(data.frqs)) * numel(data.chanlist), 1);
%             fratable = table(Channel, Frequency, Level, Rate);
%             for chan = 1:size(fra, 1)
%                 labeledfra(chan,:,:) = [[chan; lvllist], [frqlist'; squeeze(fra(chan,:,:))]];
%             end
%             [~,CFi] = min(ftcstats(:,:,1), [], 2);
%             frqlist = sort(unique(data.frqs));
%             lvllist = sort(unique(data.lvls));
%             BL = lvllist(BLi);
%             BF = frqlist(BFi);
%             CF = frqlist(CFi);
%             allthresh = ftcstats(:,:,1);
%             thresh = diag(squeeze(ftcstats(:,CFi,1)));
%             if app.ClustsCheck.Value
%                 ftctable = table(data.channelsortorder(data.chanlist), peakrate, BF./1000, BL, CF./1000, thresh, allthresh, ...
%                     'VariableNames', {'Cluster', 'PeakRate_Hz', 'BestFreq_kHz', 'BestLevel_dB', 'CharFreq_kHz', 'Thresh_CF_dB', 'AllThresh'});
%             else
%                 ftctable = table(data.chanlist, peakrate, BF./1000, BL, CF./1000, thresh, allthresh, ...
%                     'VariableNames', {'Channel', 'PeakRate_Hz', 'BestFreq_kHz', 'BestLevel_dB', 'CharFreq_kHz', 'Thresh_CF_dB', 'AllThresh'});
%             end
%             for i = 1:numel(frqlist)
%                 threshnames{i} = ['Thresh_' num2str(round(frqlist(i)./1000)), 'kHz_dB'];
%             end
%             ftctable= splitvars(ftctable, 'AllThresh', 'NewVariableNames', threshnames);
            app.DialogueLabel.Text = 'Ready';
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            frqlist = round(sort(unique(app.data.frqs)));
            lvllist = round(sort(unique(app.data.lvls)));
            channel = str2double(app.ChannelDrop.Value);
            if strcmpi(app.TabGroup.SelectedTab.Title, 'Picker')
                ax = app.FRAAxes;
                currpoint = round(get(ax, 'CurrentPoint'));
                tuningtable = app.TuningTable.Data;
                if (currpoint(1,1) > 0) && (currpoint(1,2) > 0) && currpoint(1,1) <= numel(frqlist) && currpoint(1,2) <= numel(lvllist)
                    uFreq = frqlist(currpoint(1,1));
                    uLvl = lvllist(numel(lvllist)+1-currpoint(1,2));
                    if app.BFBLButton.Value
                        tuningtable(channel,2) = uFreq;
                        tuningtable(channel,4) = uLvl;
                        tuningtable(channel,6) = app.data.fra(channel, numel(lvllist)+1-currpoint(1,2), currpoint(1,1));
                    else
                        tuningtable(channel,3) = uFreq;
                        tuningtable(channel,5) = uLvl;                        
                        tuningtable(channel,7) = app.data.fra(channel, numel(lvllist)+1-currpoint(1,2), currpoint(1,1));
                    end
                elseif (currpoint(1,1) > 0) && (currpoint(1,2) == numel(lvllist)+1) && currpoint(1,1) <= numel(frqlist)
                    if app.BFBLButton.Value
                        tuningtable(channel,2) = NaN;
                        tuningtable(channel,4) = NaN;
                        tuningtable(channel,6) = NaN;
                    else
                        tuningtable(channel,3) = NaN;
                        tuningtable(channel,5) = NaN;                        
                        tuningtable(channel,7) = NaN;
                    end
                end                    
                    app.TuningTable.Data = tuningtable;
                    app.DialogueLabel.Text = 'Math...';
                    pause(.1)
                    updateplots(app)
                    app.DialogueLabel.Text = 'Ready';

            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            valchange = 0;
            switch key
                case 'rightarrow'
                    app.CFThresholdButton.Value = 1;
                case 'leftarrow'
                    app.BFBLButton.Value = 1;
                case 'uparrow'
                    if str2double(app.ChannelDrop.Value) > 1
                        app.ChannelDrop.Value = app.ChannelDrop.Value - 1;
                        app.BFBLButton.Value = 1;
                        replot(app);
                    end
                case 'downarrow'
                    if str2double(app.ChannelDrop.Value) < numel(app.ChannelDrop.Items)
                        app.ChannelDrop.Value = app.ChannelDrop.Value + 1;
                        app.BFBLButton.Value = 1;
                        replot(app);
                    end                    
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            Channel = app.TuningTable.Data(:,1);
            BF = app.TuningTable.Data(:,2);
            CF = app.TuningTable.Data(:,3);
            BL = app.TuningTable.Data(:,4);
            Thresh = app.TuningTable.Data(:,5);
            PeakRate = app.TuningTable.Data(:,6);
            ThreshRate = app.TuningTable.Data(:,7);
            SpontUpperCI = app.TuningTable.Data(:,8);
            SpontAveRate = app.TuningTable.Data(:,9);
            Notes = app.TuningTable.Data(:,10);
            
            tuningtable = table(Channel, BF, CF, BL, Thresh, PeakRate, ThreshRate, SpontUpperCI, SpontAveRate, Notes);
            frqlist = round(sort(unique(app.data.frqs)));
            lvllist = round(sort(unique(app.data.lvls)));
            
            savepath = uigetdir('D:\Synapse\ExperimentData\');
            figure(app.UIFigure);
            
            name = 'FRAstatstable';
            ntable = tuningtable;
            fname = [savepath '\' app.PrefixEdit.Value, name '.csv'];
            d = datestr(now, 'yymmdd-HHMMSS');
            if exist(fname, 'file')
                writetable(ntable, [savepath '\' app.PrefixEdit.Value name d '.csv'])
            elseif exist(savepath, 'dir')
                writetable(ntable, fname)
            else
                mkdir(savepath)
                writetable(ntable, fname)
            end
            
            fra = app.data.fra;
            for chan = 1:size(fra, 1)
                labeledfra(chan,:,:) = [[chan; lvllist], [frqlist'; squeeze(fra(chan,:,:))]];
            end
            if exist(fname, 'file')
                for i = 1:size(labeledfra, 1)
                    xlswrite([savepath '\' app.PrefixEdit.Value 'fra' d '.xlsx'], squeeze(labeledfra(i,:,:)), i)
                end
            elseif exist(savepath, 'dir')
                for i = 1:size(labeledfra, 1)
                    xlswrite(fname, squeeze(labeledfra(i,:,:)), i)
                end
            else
                mkdir(savepath)
                for i = 1:size(labeledfra, 1)
                    xlswrite(fname, squeeze(labeledfra(i,:,:)), i)
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.TankButton = uibutton(app.UIFigure, 'push');
            app.TankButton.ButtonPushedFcn = createCallbackFcn(app, @TankButtonPushed, true);
            app.TankButton.Text = 'Tank';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TankEdit = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PickerTab = uitab(app.TabGroup);
            app.PickerTab.Title = 'Picker';
            app.IsoIAxes = uiaxes(app.PickerTab);
            title(app.IsoIAxes, 'Title')
            xlabel(app.IsoIAxes, 'X')
            ylabel(app.IsoIAxes, 'Y')
            app.FRAAxes = uiaxes(app.PickerTab);
            title(app.FRAAxes, 'Title')
            xlabel(app.FRAAxes, 'X')
            ylabel(app.FRAAxes, 'Y')
            app.RLFAxes = uiaxes(app.PickerTab);
            title(app.RLFAxes, 'Title')
            xlabel(app.RLFAxes, 'X')
            ylabel(app.RLFAxes, 'Y')
            app.PSTHAxes = uiaxes(app.PickerTab);
            title(app.PSTHAxes, 'Title')
            xlabel(app.PSTHAxes, 'X')
            ylabel(app.PSTHAxes, 'Y')
            app.ResponseWindowmsEditFieldLabel = uilabel(app.PickerTab);
            app.ResponseWindowmsEditFieldLabel.Text = 'Response Window (ms)';
            app.AnalStartEdit = uieditfield(app.PickerTab, 'numeric');
            app.AnalEndEdit = uieditfield(app.PickerTab, 'numeric');
            app.AnalEndEdit.Value = 100;
            app.SpontEndEdit = uieditfield(app.PickerTab, 'numeric');
            app.SpontaneousWindowmsEditFieldLabel = uilabel(app.PickerTab);
            app.SpontaneousWindowmsEditFieldLabel.Text = 'Spontaneous Window (ms)';
            app.SpontStartEdit = uieditfield(app.PickerTab, 'numeric');
            app.SpontStartEdit.Value = -200;
            app.PickerButtonGroup = uibuttongroup(app.PickerTab);
            app.PickerButtonGroup.Title = 'Picker';
            app.BFBLButton = uiradiobutton(app.PickerButtonGroup);
            app.BFBLButton.Text = 'BF/BL';
            app.BFBLButton.Value = true;
            app.CFThresholdButton = uiradiobutton(app.PickerButtonGroup);
            app.CFThresholdButton.Text = 'CF/Threshold';
            app.SmoothingLabel = uilabel(app.PickerTab);
            app.SmoothingLabel.Text = 'Smoothing';
            app.SmoothDrop = uidropdown(app.PickerTab);
            app.SmoothDrop.Items = {'Raw', '2Dweak', '2Dstrong', 'Freqweak', 'Freqstrong'};
            app.SmoothDrop.Value = 'Raw';
            app.XChannelMapsTab = uitab(app.TabGroup);
            app.XChannelMapsTab.Title = 'X-Channel Maps';
            app.MultiFRAAxes = uiaxes(app.XChannelMapsTab);
            title(app.MultiFRAAxes, 'Title')
            xlabel(app.MultiFRAAxes, 'X')
            ylabel(app.MultiFRAAxes, 'Y')
            app.TuningTable = uitable(app.UIFigure);
            app.TuningTable.ColumnName = {'Channel'; 'BF'; 'CF'; 'BL'; 'Thresh'; 'Peak Rate'; 'Thresh Rate'; 'Spont Upper CI'; 'Spont Ave Rate'; 'notes'};
            app.TuningTable.RowName = {};
            app.TuningTable.ColumnEditable = [false false false false false false false false false true];
            app.DialogueLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDrop = uidropdown(app.UIFigure);
            app.ChannelDrop.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FilenameprefixEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameprefixEditFieldLabel.Text = 'Filename prefix';
            app.PrefixEdit = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synfra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end