classdef synui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewButton                   matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        DialogueLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SynapseTab                      matlab.ui.container.Tab
        StimPanel                       matlab.ui.container.Panel
        StimAxes                        matlab.ui.control.UIAxes
        Param1Label                     matlab.ui.control.Label
        Param2Label                     matlab.ui.control.Label
        Param3Label                     matlab.ui.control.Label
        Param4Label                     matlab.ui.control.Label
        Param5Label                     matlab.ui.control.Label
        Param6Label                     matlab.ui.control.Label
        Param7Label                     matlab.ui.control.Label
        Param8Label                     matlab.ui.control.Label
        Param9Label                     matlab.ui.control.Label
        Param10Label                    matlab.ui.control.Label
        Param11Label                    matlab.ui.control.Label
        GetButton                       matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        Par1Label                       matlab.ui.control.Label
        Param1Edit                      matlab.ui.control.NumericEditField
        Par2Label                       matlab.ui.control.Label
        Param2Edit                      matlab.ui.control.NumericEditField
        Par3Label                       matlab.ui.control.Label
        Param3Edit                      matlab.ui.control.NumericEditField
        Par4Label                       matlab.ui.control.Label
        Param4Edit                      matlab.ui.control.NumericEditField
        Par5Label                       matlab.ui.control.Label
        Param5Edit                      matlab.ui.control.NumericEditField
        Par6Label                       matlab.ui.control.Label
        Param6Edit                      matlab.ui.control.NumericEditField
        Par7Label                       matlab.ui.control.Label
        Param7Edit                      matlab.ui.control.NumericEditField
        Par8Label                       matlab.ui.control.Label
        Param8Edit                      matlab.ui.control.NumericEditField
        Par9Label                       matlab.ui.control.Label
        Param9Edit                      matlab.ui.control.NumericEditField
        Par10Label                      matlab.ui.control.Label
        Param10Edit                     matlab.ui.control.NumericEditField
        Par11Label                      matlab.ui.control.Label
        Param11Edit                     matlab.ui.control.NumericEditField
        SynapseValuesLabel_3            matlab.ui.control.Label
        Param12Label                    matlab.ui.control.Label
        Par12Label                      matlab.ui.control.Label
        Param12Edit                     matlab.ui.control.NumericEditField
        CenterFreqEditFieldLabel        matlab.ui.control.Label
        CenterFreqEdit                  matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel         matlab.ui.control.Label
        BandwidthEdit                   matlab.ui.control.NumericEditField
        TargetRMSLabel                  matlab.ui.control.Label
        TRMSEdit                        matlab.ui.control.NumericEditField
        ReadTankTab                     matlab.ui.container.Tab
        SelectTankButton                matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        TankEditField                   matlab.ui.control.EditField
        ParamsLabel                     matlab.ui.control.Label
        ParamsLabel2                    matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        FilenameprefixEditFieldLabel    matlab.ui.control.Label
        PrefixEdit                      matlab.ui.control.EditField
        ClustersButton                  matlab.ui.control.Button
        ClustEditField                  matlab.ui.control.EditField
        ExperimentDataPanel             matlab.ui.container.Panel
        paramsCheck                     matlab.ui.control.CheckBox
        stimCheck                       matlab.ui.control.CheckBox
        ResponseDataPanel               matlab.ui.container.Panel
        spikeCheck                      matlab.ui.control.CheckBox
        rateCheck                       matlab.ui.control.CheckBox
        psth1Check                      matlab.ui.control.CheckBox
        psth2Check                      matlab.ui.control.CheckBox
        lfp1Check                       matlab.ui.control.CheckBox
        lfp2Check                       matlab.ui.control.CheckBox
        statsCheck                      matlab.ui.control.CheckBox
        tuningCheck                     matlab.ui.control.CheckBox
        CurrentModeLabel                matlab.ui.control.Label
        IdleButton                      matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        FiringRateTab                   matlab.ui.container.Tab
        rateAxes                        matlab.ui.control.UIAxes
        BinsizemsLabel_2                matlab.ui.control.Label
        BinEdit                         matlab.ui.control.NumericEditField
        HistorysecLabel                 matlab.ui.control.Label
        RangeEdit                       matlab.ui.control.NumericEditField
        ratePopupCheck                  matlab.ui.control.CheckBox
        fratescaleEdit                  matlab.ui.control.NumericEditField
        yscale0maxobservedLabel         matlab.ui.control.Label
        frateTable                      matlab.ui.control.Table
        FreqTuningTab                   matlab.ui.container.Tab
        OffsetEdit                      matlab.ui.control.NumericEditField
        EvokedratewindowmsLabel_4       matlab.ui.control.Label
        OnsetEdit                       matlab.ui.control.NumericEditField
        FTCAxes                         matlab.ui.control.UIAxes
        FTCPopupCheck                   matlab.ui.control.CheckBox
        PSTHTab                         matlab.ui.container.Tab
        PSTH1Axes                       matlab.ui.control.UIAxes
        PSTHPopupCheck                  matlab.ui.control.CheckBox
        BinsizemsLabel                  matlab.ui.control.Label
        PSTHBinEdit                     matlab.ui.control.NumericEditField
        PSTH2Axes                       matlab.ui.control.UIAxes
        PSTH1WindowsecEditFieldLabel    matlab.ui.control.Label
        PSTH1StartEdit                  matlab.ui.control.NumericEditField
        PSTH1EndEdit                    matlab.ui.control.NumericEditField
        Referencet0DropDownLabel        matlab.ui.control.Label
        PSTH1RefDrop                    matlab.ui.control.DropDown
        Referencet0DropDown_2Label      matlab.ui.control.Label
        PSTH2RefDrop                    matlab.ui.control.DropDown
        PSTH2EndEdit                    matlab.ui.control.NumericEditField
        PSTH2WindowsecEditFieldLabel    matlab.ui.control.Label
        PSTH2StartEdit                  matlab.ui.control.NumericEditField
        PSTHscaleradio                  matlab.ui.container.ButtonGroup
        IndependentButton               matlab.ui.control.RadioButton
        xPSTHsButton                    matlab.ui.control.RadioButton
        xAllButton                      matlab.ui.control.RadioButton
        PSTHscaleEdit                   matlab.ui.control.NumericEditField
        FrequencyDropDownLabel          matlab.ui.control.Label
        FrequencyDrop                   matlab.ui.control.DropDown
        LevelLabel                      matlab.ui.control.Label
        LevelDrop                       matlab.ui.control.DropDown
        PSTHbaseline                    matlab.ui.container.ButtonGroup
        MeanButton                      matlab.ui.control.RadioButton
        CLButton                        matlab.ui.control.RadioButton
        CLEdit                          matlab.ui.control.NumericEditField
        PSTHCycleCheck                  matlab.ui.control.CheckBox
        LFPTab                          matlab.ui.container.Tab
        LFP1Axes                        matlab.ui.control.UIAxes
        LFP2Axes                        matlab.ui.control.UIAxes
        LFPPopupCheck                   matlab.ui.control.CheckBox
        LFP1Label                       matlab.ui.control.Label
        LFP2Label                       matlab.ui.control.Label
        HPCFEditFieldLabel              matlab.ui.control.Label
        HPCFEdit                        matlab.ui.control.NumericEditField
        LPCFEditFieldLabel              matlab.ui.control.Label
        LPCFEdit                        matlab.ui.control.NumericEditField
        ClusterWaveformsTab             matlab.ui.container.Tab
        Clust1Axes                      matlab.ui.control.UIAxes
        Clust2Axes                      matlab.ui.control.UIAxes
        ClustPopupCheck                 matlab.ui.control.CheckBox
        PollingperiodsecEditFieldLabel  matlab.ui.control.Label
        UpdateEdit                      matlab.ui.control.NumericEditField
        ClustsCheck                     matlab.ui.control.CheckBox
        ExperimentDrop                  matlab.ui.control.DropDown
        ExpButton1                      matlab.ui.control.Button
        ExpDurationRadio                matlab.ui.container.ButtonGroup
        InfiniteButton                  matlab.ui.control.RadioButton
        LastoffsetnsecButton            matlab.ui.control.RadioButton
        SecondsButton                   matlab.ui.control.RadioButton
        StimDurLabel                    matlab.ui.control.Label
        TotalStimDurLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ExpDurationEdit                 matlab.ui.control.NumericEditField
        LoadThresholdsButton            matlab.ui.control.Button
        BlockEditFieldLabel             matlab.ui.control.Label
        BlockEdit                       matlab.ui.control.NumericEditField
        TabGroup3                       matlab.ui.container.TabGroup
        ChannelsTab                     matlab.ui.container.Tab
        CheckBox_1                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_21                     matlab.ui.control.CheckBox
        CheckBox_22                     matlab.ui.control.CheckBox
        CheckBox_23                     matlab.ui.control.CheckBox
        CheckBox_24                     matlab.ui.control.CheckBox
        CheckBox_25                     matlab.ui.control.CheckBox
        CheckBox_26                     matlab.ui.control.CheckBox
        CheckBox_27                     matlab.ui.control.CheckBox
        CheckBox_28                     matlab.ui.control.CheckBox
        CheckBox_29                     matlab.ui.control.CheckBox
        CheckBox_30                     matlab.ui.control.CheckBox
        CheckBox_31                     matlab.ui.control.CheckBox
        CheckBox_32                     matlab.ui.control.CheckBox
        Chan116Button                   matlab.ui.control.StateButton
        Chan1732Button                  matlab.ui.control.StateButton
        ClustersTab                     matlab.ui.container.Tab
        SortOrderDropDownLabel          matlab.ui.control.Label
        SortOrderDropDown               matlab.ui.control.DropDown
        SelectClustersButtonGroup       matlab.ui.container.ButtonGroup
        AllRadio                        matlab.ui.control.RadioButton
        TopRadio                        matlab.ui.control.RadioButton
        BottomRadio                     matlab.ui.control.RadioButton
        ClustRadio                      matlab.ui.control.RadioButton
        ClustTopEdit                    matlab.ui.control.EditField
        ClustBottomEdit                 matlab.ui.control.EditField
        ClustClustEdit                  matlab.ui.control.EditField
    end


    properties (Access = private)
    end

    properties (Access = public)
        syn = [];           % links to Synapse
        experiment = [];    % 1 Iso Intensity, 2 LDS Noise, 3 LDS Tone
        pars = [];          % Parameters
        gizmos = [];        % Gizmos
        data = [];
    end

    methods (Access = private)


        function exporttables(app, savepath, ntable, name)
            checkfield = [name 'Check'];
            fname = [savepath '\' app.PrefixEdit.Value, name '.csv'];
            d = datestr(now, 'yymmdd-HHMMSS');
            if app.(checkfield).Value
                if exist(fname, 'file')
                    writetable(ntable, [savepath '\' app.PrefixEdit.Value name d '.csv'])
                else
                    writetable(ntable, fname)
                end
            end
        end

        function results = paramupdate(app, ind)
            LPCFi = find(ismember(app.pars, 'LPCF'));
            HPCFi = find(ismember(app.pars, 'HPCF'));
            LPCFfield = ['Param' num2str(LPCFi) 'Edit'];
            HPCFfield = ['Param' num2str(HPCFi) 'Edit'];

            switch char(app.pars(ind))
                case 'HPCF'
                    app.CenterFreqEdit.Value = sqrt(app.(HPCFfield).Value * app.(LPCFfield).Value);
                    app.BandwidthEdit.Value = log2(app.(LPCFfield).Value / app.(HPCFfield).Value);  
                case 'LPCF'
                    app.CenterFreqEdit.Value = sqrt(app.(HPCFfield).Value * app.(LPCFfield).Value);
                    app.BandwidthEdit.Value = log2(app.(LPCFfield).Value / app.(HPCFfield).Value);  
                case 'ModDepth'
                    rmscorrect(app)
                case 'ModExponent'
                    rmscorrect(app)
            end
        end
        
        function results = rmscorrect(app)
            dbshift = [0, -1.7556, 4.2331, -1.1797, 5.635, -0.9538, 6.445, -0.8287, 7.0514];
            MEi = find(ismember(app.pars, 'ModExponent'));
            MEfield = ['Param' num2str(MEi) 'Edit'];
            MDi = find(ismember(app.pars, 'ModDepth'));
            MDfield = ['Param' num2str(MDi) 'Edit'];
            Leveli = find(ismember(app.pars, 'LDSLevel'));
            Levelfield = ['Param' num2str(Leveli) 'Edit'];
            exp = round(app.(MDfield).Value/100) * app.(MEfield).Value+1;
            app.(Levelfield).Value = dbshift(exp) + app.TRMSEdit.Value;

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StimPanel.Visible = 0;
%             app.TONEStimulusPanel.Visible = 0;
%             app.FRAStimulusPanel.Visible = 0;
            app.ExpDurationRadio.Visible = 0;
            
            app.syn = SynapseAPI();
            updatemode(app);
            app.ExperimentDrop.Items = app.syn.getKnownExperiments;
            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            livestreamapp2(app, 'Preview')
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            livestreamapp2(app, 'Record')
        end

        % Button pushed function: IdleButton
        function IdleButtonPushed(app, event)
            app.syn.setMode(0);
            app.StimPanel.Visible = 0; app.ExpDurationRadio.Visible = 0;
            updatemode(app);
        end

        % Button pushed function: ExpButton1
        function ExpButton1Pushed(app, event)
            if app.syn.getMode > 0
               app.syn.setMode(0);
            end
            app.DialogueLabel.Text = 'Loading Experiment'; pause(.1);
            app.syn.setCurrentExperiment(app.ExperimentDrop.Value);
            
            c = clock();
            d = [num2str(mod(c(1), 100)) sprintf('%02d', c(2)), sprintf('%02d', c(3))];
            t = [sprintf('%02d', c(4)) sprintf('%02d', c(5))];
            blockname = [sprintf('%03d', app.BlockEdit.Value) '-' app.syn.getCurrentExperiment() '-' d '-' t];
            app.syn.setCurrentBlock(blockname);

            app.syn.setMode(1);
            updatemode(app); updatepars2(app);
            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: SelectTankButton
        function SelectTankButtonPushed(app, event)
            [filepath, ~, ~] = fileparts(app.TankEditField.Value);
            if isempty(filepath)
                tankpath = uigetdir('D:\Synapse\Tanks');
            else
                tankpath = uigetdir(filepath);
            end
            if tankpath ~= 0
                app.TankEditField.Value = tankpath;
            end
            figure(app.UIFigure);
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            data = readtank(app.TankEditField.Value);
            app.ClustsCheck.Value = 0;
            data.chanlist = updatechans(app);
            data.nchans = max(data.chanlist);
            app.ClustEditField.Value = '';
            frqlist = sort(unique(data.frqs));
            app.FrequencyDrop.Items = ['all'; compose('%d', frqlist)];
            app.LevelDrop.Items = {'0'};
            if app.LPCFEdit.Value > data.fs/2
                app.LPCFEdit.Value = data.fs/2;
            end
            if isfield(data, 'lvls')
                lvllist = sort(unique(data.lvls));
                app.LevelDrop.Items = ['all'; compose('%d', lvllist)];
            end
             try
                load([app.TankEditField.Value '\params.mat']');
                chstr = ''; namestr = '';
                charr = num2str(cell2mat(table2cell(changetable))');
                for row = 1:size(charr, 1)
                    chstr = [chstr, charr(row,:), newline];
                    namestr = [namestr, char(changetable.Properties.VariableNames(row)), newline];
                end
                app.ParamsLabel2.Text = chstr;
                 app.ParamsLabel.Text = namestr;
                 
                if any(strcmp('LDSOnsetDelay',fieldnames(changetable)))
                    app.PSTH1StartEdit.Value = -1*table2array(changetable(1, 'LDSOnsetDelay'));
                    app.PSTH2StartEdit.Value = 0;
                    app.PSTH1EndEdit.Value = 0;
                    app.PSTH2EndEdit.Value = table2array(changetable(1, 'LDSDuration'));
                    app.PSTHBinEdit.Value = table2array(changetable(1, 'LDSDuration'))*10;
                elseif numel(data.stimons) > 1
                    tab1 = tabulate(round(diff(data.stimons).*1000)./1000);
                    [~,i] = sort(tab1(:,2), 'descend');
                    j = find(tab1(i, 2)>0);
                    isis = tab1(i(j), 1:2);
                    app.PSTH1StartEdit.Value = 0;
                    app.PSTH2StartEdit.Value = 0;
                    
                    app.PSTH1EndEdit.Value = isis(1,1);
                    app.PSTH2EndEdit.Value = isis(1,1);
                    app.PSTHBinEdit.Value = isis(1,1)*10;
                end
             end
             app.DialogueLabel.Text = 'Ready';

             app.data = data;


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            data = app.data;
            if app.ClustsCheck.Value
                [data.chanlist, channelsortorder] = updatechans(app);
                data.channelsortorder = channelsortorder;
                [~, sorti] = sort(channelsortorder);
                data.channels = sorti(data.clusters); 
                %cluster of each spike is stored in data.clusters and data.channels
                %data.clusters contains the original cluster code generated in Kilosort
                %data.channels contains a new cluster code after sorting the clusters.
                %data.chanlist contains the channel indices selected for plotting
                %in ascending order
                %data.channelsortorder(data.chanlist) contains the actual
                %channel numbers selected for plotting
                %I am putting the sorted cluster code in data.channels because most of our 
                %plots groups data by data.channel, and I am too lazy to rewrite all that.
                %However, the LFP raster requires the original cluster code.
            else
                data.chanlist = updatechans(app);
            end
             app.DialogueLabel.Text = 'Math...';
            pause(.1)
            data.nchans = max(data.chanlist);
            [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(data.spets, data.stimons, data.frqs, data.lvls);
            binwindow = app.BinEdit.Value./1000; %PSTH bin size (sec)
            analwin = [app.OnsetEdit.Value app.OffsetEdit.Value]./1000; %ftc analysis window;
%tuning curve
            ax = app.FTCAxes; cla(app.FTCAxes, 'reset');
            if numel(unique(data.frqs))>1 || numel(unique(data.lvls)) > 1 %FRA
                spontrates = findspontrate(data.spets, data.stimons, data.channels, data.chanlist, [-0.1 0]);
                spontrates = spontrates(data.chanlist);
            end
            if app.FTCPopupCheck.Value
                figure(2); clf; ax = axes();
            end
            ftctable = table([]);
            if numel(unique(data.frqs))>1 && numel(unique(data.lvls)) > 1 %FRA
                fra = multifra(stimspets, spetfreq, spetlevel, analwin, data.channels, trials, data.chanlist, data.frqs, data.lvls, ax);
            elseif numel(unique(data.frqs))>1 && numel(unique(data.lvls)) == 1 %ISO-I
                trials = reshape(trials, 1, numel(trials));
                ftc = synftc(stimspets, spetfreq, analwin, data.channels, trials(1:end), data.chanlist, data.frqs, ax);
                [oprate, obf] = max(ftc, [], 2);
                frqlist = sort(unique(data.frqs))./1000;
                ftcstats = ftcthreshold(ftc, sort(unique(data.frqs)), poissinv(0.95, spontrates), 10);
                if app.ClustsCheck.Value
                    ftctable = table(data.channelsortorder(data.chanlist), oprate, frqlist(obf), ftcstats, 'VariableNames', {'Cluster', 'PeakRate_Obs_Hz', 'BestFrequency_Obs_kHz', 'ftcstats'});
                else
                    ftctable = table(data.chanlist, ftcstats, 'VariableNames', {'Channel', 'ftcstats'});
                end
                ftctable = splitvars(ftctable, 'ftcstats', 'NewVariableNames', {'PeakRate_Model_Hz', 'BestFrequency_Model_kHz', 'Bandwidth2STD_Octaves', 'LowerCutoff_kHz', 'UpperCutoff_kHz'});
                set(ax,'XTickLabel', sort(unique(data.frqs))./1000)
                xlabel(ax, 'Frequency (kHz)')
            elseif numel(unique(data.frqs))==1 && numel(unique(data.lvls)) > 1 %ISO-F
                trials = reshape(trials, 1, numel(trials));          
                ftc = synftc(stimspets, spetlevel, analwin, data.channels, trials(1:end), data.chanlist, data.lvls, ax);
                ftcstats = ftcthreshold(ftc, sort(unique(data.lvls)), poissinv(0.95, spontrates), 10);
                if app.ClustsCheck.Value
                    ftctable = table(data.channelsortorder(data.chanlist), ftcstats(:,4), 'VariableNames', {'Cluster', 'Threshold_dBSPL'});
                else
                    ftctable = table(data.chanlist, ftcstats(:,4), 'VariableNames', {'Channel', 'Threshold_dBSPL'});
                end
                set(ax,'XTickLabel', sort(unique(data.lvls)))
                xlabel(ax, 'Level (dB)')
                title(ax, [app.TankEditField.Value newline num2str(app.OnsetEdit.Value) '-' num2str(app.OffsetEdit.Value) ' ms'])
            end
            if app.ClustsCheck.Value                
                set(ax, 'YTickLabel', channelsortorder);
            end

%running spike rate average
            bincount = NaN(round(max(data.spets)/binwindow), data.nchans);
            for chan = data.chanlist'
                [bincount(:, chan), bintime] = hist(data.spets(data.channels==chan), 0.5*binwindow:binwindow:max(data.spets));
            end
            if app.fratescaleEdit.Value == 0
                yscale = max(bincount(:));
            else
                yscale = app.fratescaleEdit.Value*binwindow;
            end
            [sx, sz] = size(bincount);
            frate = bincount./yscale - (1:sz);
            cla(app.rateAxes, 'reset');
            plotfrate(app, frate, bintime, data.stimons, data.stimoffs, data.chanlist);
            if app.ClustsCheck.Value
                set(app.rateAxes, 'YTick', -numel(app.data.cluster):-1);
                set(app.rateAxes, 'YTickLabel', flipud(channelsortorder));
            end
            data.ratetable = table(reshape(bintime, [numel(bintime), 1]), bincount, 'VariableNames', {'BinTime', 'BinCount'});
            app.frateTable.Data = [(1:max(data.chanlist))', max(bincount)'./binwindow];
            app.frateTable.ColumnName = {'Chan', 'MaxRate'};
%PSTH and LFPs
                psthdata = psthlfpplots(app, data);
%Cluster Waveforms
            if app.ClustsCheck.Value
                cluster = app.data.cluster;
                if app.ClustPopupCheck.Value
                    figure(2); clf; ax = axes();
                else
                    ax = app.Clust1Axes; cla(app.Clust1Axes, 'reset');
                end
                hold(ax, 'on');
                vvec = [1:-1/8:0 0:1/8:1 1:-1/8:0 0:1/8:1]';
                vv = 1:16;
                colmat = [vvec(vv+16) vvec(vv+11) vvec(vv+6)];
    
                clustampmat = NaN(numel(cluster), 1);
                for i = 1:numel(cluster)
                    clustampmat(i,:) = cluster(i).peakChannel2(1);
                end
                peakChannel2 = clustampmat(channelsortorder); %1xn vector of nearest channel
                lfptemp = [zeros(26, 32); data.LFP; zeros(26, 32)];
                msnip = NaN(numel(cluster), 51, 16);
                for i = 1:numel(cluster)
                    snippets = NaN(numel(cluster(channelsortorder(i)).spikes), 51, 16);
                    for spike = 1:numel(cluster(channelsortorder(i)).spikes)
                        spikewin = round(cluster(channelsortorder(i)).spikes(spike) * data.fs) + (1:51);
                        snippets(spike,:,:) = lfptemp(spikewin, 1:16);
                    end
                        msnip(i,:,:) = squeeze(mean(snippets, 1));
                        plot(ax,((1:16) + repmat((-25:25)', 1,16)./60), squeeze(msnip(i,:,:))./range(msnip(:)) - i, 'k');
                    plot(ax,(cluster(channelsortorder(i)).peakChannel2(1) + (-25:25)./60), ...
                       msnip(i,:,cluster(channelsortorder(i)).peakChannel2(1))./range(msnip(:)) - i, 'Color', colmat(peakChannel2(i),:));
                end
                hold(ax, 'off')
                set(ax, 'YTick', -numel(cluster):-1);
                set(ax, 'YTickLabel', flipud(channelsortorder));
                ylabel(ax, 'Cluster'); xlabel(ax, 'Channel');
    
                if app.ClustPopupCheck.Value
                    figure(3); clf; ax = axes();
                else
                    ax = app.Clust2Axes; cla(app.Clust2Axes, 'reset');
                end
                hold(ax, 'on');
                nrows = ceil(sqrt(numel(cluster)));
                    for i = 1:numel(cluster)
                        j = i-1;
                        snippets = NaN(max([1 numel(cluster(channelsortorder(i)).spikes)]), 51);
                        for spike = 1:numel(cluster(channelsortorder(i)).spikes)
                            spikewin = round(cluster(channelsortorder(i)).spikes(spike) * data.fs) + (1:51);
                            snippets(spike,:) = lfptemp(spikewin, peakChannel2(i));
                        end
                        xoff = floor(j/nrows);
                        yoff = nrows - mod(j, nrows);
                        if numel(cluster(channelsortorder(i)).spikes) > 20
                            snipdens = NaN(60, 51);
                            yrange = max(max(abs(snippets)));
                            binvec = linspace(-yrange, yrange, 60);
                            for samp = 1:51
                                snipdens(:,samp) = hist(snippets(:,samp), binvec);
                            end
                            imagesc(ax, xoff + [0 1], yoff + [-1 0], 1-snipdens./max(snipdens(:)))
                            colormap(ax, gray)
                        else
                            yrange = max(max(abs(snippets)));
                            plot(ax, xoff + (1:size(snippets, 2))./size(snippets, 2), yoff -.5 + snippets./yrange./2, 'k')
                        end

                            plot(ax, xoff + (1:size(msnip, 2))./size(msnip, 2), yoff -.5 + msnip(i,:,peakChannel2(i))./yrange./2, 'r:')
                            text(ax, xoff, yoff, num2str(channelsortorder(i)));
                    end
                axis(ax, [0 nrows 0 nrows])
                hold(ax, 'off');
                set(ax, 'YTick', []); set(ax, 'XTick', []);
            end %clustcheck
            app.data = data;

            app.DialogueLabel.Text = 'Ready';
            app.data.psth1table = psthdata.psth1table;
            app.data.psth2table = psthdata.psth2table;
            app.data.lfp1table = psthdata.lfp1table;
            app.data.lfp2table = psthdata.lfp2table;
            app.data.statstable = psthdata.statstable;
            app.data.ftctable = ftctable;
        end

        % Value changed function: Chan116Button
        function Chan116ButtonValueChanged(app, event)
            value = app.Chan116Button.Value;
            if value
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan116Button.Text = 'Clear 1-16';
            else
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end                
                app.Chan116Button.Text = 'Check 1-16';
            end
        end

        % Value changed function: Chan1732Button
        function Chan1732ButtonValueChanged(app, event)
            value = app.Chan1732Button.Value;
            if value
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan1732Button.Text = 'Clear 17-32';
            else
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end
                app.Chan1732Button.Text = 'Check 17-32';
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            savepath = uigetdir('D:\Synapse\ExperimentData\');
            figure(app.UIFigure);
%             app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            data = app.data;
            data.chanlist = updatechans(app);
            data.nchans = max(data.chanlist);

            [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(data.spets, data.stimons, data.frqs, data.lvls);
            if isempty(data.evons)
                stimtable = table(data.stimons, data.stimoffs, data.frqs, data.lvls, 'VariableNames', {'OnsetTime', 'OffsetTime', 'Frequency', 'Level'});
            else
                stimtable = table(data.evons, data.evoffs, data.frqs, data.lvls, 'VariableNames', {'OnsetTime', 'OffsetTime', 'Frequency', 'Level'});
            end
            spiketable = table(data.spets, data.channels, stimspets, spetfreq, spetlevel, 'VariableNames', {'SpikeTime', 'Channel', 'PostStimulusTime', 'LastFreq', 'LastLevel'});

            ratetable = app.data.ratetable;
            psth1table = app.data.psth1table;
            psth2table = app.data.psth2table;
            lfp1table = app.data.lfp1table;
            lfp2table = app.data.lfp2table;
            statstable = app.data.statstable;
            ftctable = app.data.ftctable;
            
            for i = 1:2
                [pst(i), ~, ~, ~] = synpstbin(app, data, i, spetfreq, str2double(app.FrequencyDrop.Value), -1*ones(size(spetfreq)), -1);
            end
                try
                    load([app.TankEditField.Value '\params.mat']', 'changetable');
                    exporttables(app, savepath, changetable, 'params');
                end
                exporttables(app, savepath, stimtable, 'stim');
                exporttables(app, savepath, spiketable, 'spike');
                exporttables(app, savepath, ratetable, 'rate');
                exporttables(app, savepath, psth1table, 'psth1');
                exporttables(app, savepath, psth2table, 'psth2');
                exporttables(app, savepath, lfp1table, 'lfp1');
                exporttables(app, savepath, lfp2table, 'lfp2');
                exporttables(app, savepath, statstable, 'stats');
                exporttables(app, savepath, ftctable, 'tuning');

        end

        % Button pushed function: LoadThresholdsButton
        function LoadThresholdsButtonPushed(app, event)
            [threshfile, threshpath, filt] = uigetfile('D:\Synapse\Tanks\');
            if filt
                load([threshpath threshfile]);
                if exist('threshlist', 'var')
                    for i = 1:numel(threshlist)
                        app.syn.setParameterValue('Neu1', ['Threshold' num2str(i)], threshlist(i));
                    end
                end
            end
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            for par = 1:numel(app.pars)
                mvarname = ['Param' num2str(par) 'Edit'];
                mvarname2 = ['Param' num2str(par) 'Label'];
                app.(mvarname).Value= str2double(app.(mvarname2).Text);
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            for par = 1:numel(app.pars)
                mvarname = ['Param' num2str(par) 'Edit'];
                app.syn.setParameterValue(char(app.gizmos(par)), char(app.pars(par)), app.(mvarname).Value);
            end
%             if strcmpi(prefix, 'iso')
%                 app.syn.setParameterValue('Stimulus', 'GateHiTime', app.Par3Edit.Value*(app.Param1Edit.Value + app.Par2Edit.Value));
%             end
            pause(1);
            updatepars2(app);

        end

        % Value changed function: BandwidthEdit, CenterFreqEdit
        function CenterFreqEditValueChanged(app, event)
            value = app.CenterFreqEdit.Value;
            LPCFi = find(ismember(app.pars, 'LPCF'));
            HPCFi = find(ismember(app.pars, 'HPCF'));
            LPCFfield = ['Param' num2str(LPCFi) 'Edit'];
            HPCFfield = ['Param' num2str(HPCFi) 'Edit'];
            app.(LPCFfield).Value = app.CenterFreqEdit.Value * 2 ^ (app.BandwidthEdit.Value / 2);
            app.(HPCFfield).Value = app.CenterFreqEdit.Value * 2 ^ ((app.BandwidthEdit.Value) / -2 );
        end

        % Value changed function: TRMSEdit
        function TRMSEditValueChanged(app, event)
            rmscorrect(app)
        end

        % Value changed function: Param1Edit
        function Param1EditValueChanged(app, event)
            paramupdate(app, 1)           
        end

        % Value changed function: Param2Edit
        function Param2EditValueChanged(app, event)
            paramupdate(app, 2)
        end

        % Value changed function: Param3Edit
        function Param3EditValueChanged(app, event)
            paramupdate(app, 3)
        end

        % Value changed function: Param4Edit
        function Param4EditValueChanged(app, event)
            paramupdate(app, 4)
        end

        % Value changed function: Param5Edit
        function Param5EditValueChanged(app, event)
            paramupdate(app, 5)
        end

        % Value changed function: Param6Edit
        function Param6EditValueChanged(app, event)
            paramupdate(app, 6)
        end

        % Value changed function: Param7Edit
        function Param7EditValueChanged(app, event)
            paramupdate(app, 7)
        end

        % Value changed function: Param8Edit
        function Param8EditValueChanged(app, event)
            paramupdate(app, 8)
        end

        % Value changed function: Param9Edit
        function Param9EditValueChanged(app, event)
            paramupdate(app, 9)
        end

        % Value changed function: Param10Edit
        function Param10EditValueChanged(app, event)
            paramupdate(app, 10)
        end

        % Value changed function: Param11Edit
        function Param11EditValueChanged(app, event)
            paramupdate(app, 11)
        end

        % Value changed function: Param12Edit
        function Param12EditValueChanged(app, event)
            paramupdate(app, 12)
        end

        % Button pushed function: ClustersButton
        function ClustersButtonPushed(app, event)
            file = app.ClustEditField.Value;
            if isempty(file)
                [filename pathname]= uigetfile('D:\Spikes\');
            else
                [filename pathname] = uigetfile(file);
            end
            figure(app.UIFigure);
            if filename ~= 0
                app.ClustEditField.Value = [pathname filename];
                temp = load(app.ClustEditField.Value);
                app.data.spets = temp.spikes;
                app.data.clusters = temp.clusters;
                app.data.cluster = temp.cluster;
                app.data.peakChannel = temp.peakChannel;
                app.ClustsCheck.Value = 1;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.DialogueLabel = uilabel(app.UIFigure);
            app.DialogueLabel.Text = 'Dialogue';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SynapseTab = uitab(app.TabGroup);
            app.SynapseTab.Title = 'Synapse';
            app.StimPanel = uipanel(app.SynapseTab);
            app.StimPanel.Title = 'Stimulus Parameters';
            app.StimAxes = uiaxes(app.StimPanel);
            title(app.StimAxes, 'Stim Preview')
            xlabel(app.StimAxes, 'Time')
            ylabel(app.StimAxes, 'Y')
            app.Param1Label = uilabel(app.StimPanel);
            app.Param1Label.Text = 'Param1';
            app.Param2Label = uilabel(app.StimPanel);
            app.Param2Label.Text = 'Param2';
            app.Param3Label = uilabel(app.StimPanel);
            app.Param3Label.Text = 'Param3';
            app.Param4Label = uilabel(app.StimPanel);
            app.Param4Label.Text = 'Param4';
            app.Param5Label = uilabel(app.StimPanel);
            app.Param5Label.Text = 'Param5';
            app.Param6Label = uilabel(app.StimPanel);
            app.Param6Label.Text = 'Param6';
            app.Param7Label = uilabel(app.StimPanel);
            app.Param7Label.Text = 'Param7';
            app.Param8Label = uilabel(app.StimPanel);
            app.Param8Label.Text = 'Param8';
            app.Param9Label = uilabel(app.StimPanel);
            app.Param9Label.Text = 'Param9';
            app.Param10Label = uilabel(app.StimPanel);
            app.Param10Label.Text = 'Param10';
            app.Param11Label = uilabel(app.StimPanel);
            app.Param11Label.Text = 'Param11';
            app.GetButton = uibutton(app.StimPanel, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = '<- Get ';
            app.SetButton = uibutton(app.StimPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set ->';
            app.Par1Label = uilabel(app.StimPanel);
            app.Par1Label.Text = 'Par1';
            app.Param1Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param1Edit.ValueChangedFcn = createCallbackFcn(app, @Param1EditValueChanged, true);
            app.Par2Label = uilabel(app.StimPanel);
            app.Par2Label.Text = 'Par2';
            app.Param2Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param2Edit.ValueChangedFcn = createCallbackFcn(app, @Param2EditValueChanged, true);
            app.Par3Label = uilabel(app.StimPanel);
            app.Par3Label.Text = 'Par3';
            app.Param3Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param3Edit.ValueChangedFcn = createCallbackFcn(app, @Param3EditValueChanged, true);
            app.Par4Label = uilabel(app.StimPanel);
            app.Par4Label.Text = 'Par4';
            app.Param4Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param4Edit.ValueChangedFcn = createCallbackFcn(app, @Param4EditValueChanged, true);
            app.Par5Label = uilabel(app.StimPanel);
            app.Par5Label.Text = 'Par5';
            app.Param5Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param5Edit.ValueChangedFcn = createCallbackFcn(app, @Param5EditValueChanged, true);
            app.Par6Label = uilabel(app.StimPanel);
            app.Par6Label.Text = 'Par6';
            app.Param6Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param6Edit.ValueChangedFcn = createCallbackFcn(app, @Param6EditValueChanged, true);
            app.Par7Label = uilabel(app.StimPanel);
            app.Par7Label.Text = 'Par7';
            app.Param7Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param7Edit.ValueChangedFcn = createCallbackFcn(app, @Param7EditValueChanged, true);
            app.Par8Label = uilabel(app.StimPanel);
            app.Par8Label.Text = 'Par8';
            app.Param8Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param8Edit.ValueChangedFcn = createCallbackFcn(app, @Param8EditValueChanged, true);
            app.Par9Label = uilabel(app.StimPanel);
            app.Par9Label.Text = 'Par9';
            app.Param9Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param9Edit.ValueChangedFcn = createCallbackFcn(app, @Param9EditValueChanged, true);
            app.Par10Label = uilabel(app.StimPanel);
            app.Par10Label.Text = 'Par10';
            app.Param10Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param10Edit.ValueChangedFcn = createCallbackFcn(app, @Param10EditValueChanged, true);
            app.Par11Label = uilabel(app.StimPanel);
            app.Par11Label.Text = 'Par11';
            app.Param11Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param11Edit.ValueChangedFcn = createCallbackFcn(app, @Param11EditValueChanged, true);
            app.SynapseValuesLabel_3 = uilabel(app.StimPanel);
            app.SynapseValuesLabel_3.Text = 'Synapse Values';
            app.Param12Label = uilabel(app.StimPanel);
            app.Param12Label.Text = 'Param12';
            app.Par12Label = uilabel(app.StimPanel);
            app.Par12Label.Text = 'Par12';
            app.Param12Edit = uieditfield(app.StimPanel, 'numeric');
            app.Param12Edit.ValueChangedFcn = createCallbackFcn(app, @Param12EditValueChanged, true);
            app.CenterFreqEditFieldLabel = uilabel(app.StimPanel);
            app.CenterFreqEditFieldLabel.Text = 'Center Freq';
            app.CenterFreqEdit = uieditfield(app.StimPanel, 'numeric');
            app.CenterFreqEdit.ValueChangedFcn = createCallbackFcn(app, @CenterFreqEditValueChanged, true);
            app.BandwidthEditFieldLabel = uilabel(app.StimPanel);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEdit = uieditfield(app.StimPanel, 'numeric');
            app.BandwidthEdit.ValueChangedFcn = createCallbackFcn(app, @CenterFreqEditValueChanged, true);
            app.TargetRMSLabel = uilabel(app.StimPanel);
            app.TargetRMSLabel.Text = 'Target RMS';
            app.TRMSEdit = uieditfield(app.StimPanel, 'numeric');
            app.TRMSEdit.ValueChangedFcn = createCallbackFcn(app, @TRMSEditValueChanged, true);
            app.ReadTankTab = uitab(app.TabGroup);
            app.ReadTankTab.Title = 'ReadTank';
            app.SelectTankButton = uibutton(app.ReadTankTab, 'push');
            app.SelectTankButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTankButtonPushed, true);
            app.SelectTankButton.Text = 'Select Tank';
            app.PlotButton = uibutton(app.ReadTankTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TankEditField = uieditfield(app.ReadTankTab, 'text');
            app.ParamsLabel = uilabel(app.ReadTankTab);
            app.ParamsLabel.Text = '';
            app.ParamsLabel2 = uilabel(app.ReadTankTab);
            app.ParamsLabel2.Text = '';
            app.ExportButton = uibutton(app.ReadTankTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export Data';
            app.FilenameprefixEditFieldLabel = uilabel(app.ReadTankTab);
            app.FilenameprefixEditFieldLabel.Text = 'Filename prefix';
            app.PrefixEdit = uieditfield(app.ReadTankTab, 'text');
            app.ClustersButton = uibutton(app.ReadTankTab, 'push');
            app.ClustersButton.ButtonPushedFcn = createCallbackFcn(app, @ClustersButtonPushed, true);
            app.ClustersButton.Text = 'Clusters';
            app.ClustEditField = uieditfield(app.ReadTankTab, 'text');
            app.ExperimentDataPanel = uipanel(app.ReadTankTab);
            app.ExperimentDataPanel.Title = 'Experiment Data';
            app.paramsCheck = uicheckbox(app.ExperimentDataPanel);
            app.paramsCheck.Text = 'Parameters';
            app.stimCheck = uicheckbox(app.ExperimentDataPanel);
            app.stimCheck.Text = 'Stimulus Pulses';
            app.ResponseDataPanel = uipanel(app.ReadTankTab);
            app.ResponseDataPanel.Title = 'Response Data';
            app.spikeCheck = uicheckbox(app.ResponseDataPanel);
            app.spikeCheck.Text = 'Spike Times';
            app.rateCheck = uicheckbox(app.ResponseDataPanel);
            app.rateCheck.Text = 'Total Spike Rates';
            app.psth1Check = uicheckbox(app.ResponseDataPanel);
            app.psth1Check.Text = 'PSTH1';
            app.psth2Check = uicheckbox(app.ResponseDataPanel);
            app.psth2Check.Text = 'PSTH2';
            app.lfp1Check = uicheckbox(app.ResponseDataPanel);
            app.lfp1Check.Text = 'LFP1';
            app.lfp2Check = uicheckbox(app.ResponseDataPanel);
            app.lfp2Check.Text = 'LFP2';
            app.statsCheck = uicheckbox(app.ResponseDataPanel);
            app.statsCheck.Text = 'Pre/Post Statistics';
            app.tuningCheck = uicheckbox(app.ResponseDataPanel);
            app.tuningCheck.Text = 'Stimulus Tuning Stats';
            app.CurrentModeLabel = uilabel(app.UIFigure);
            app.CurrentModeLabel.Text = 'Current Mode';
            app.IdleButton = uibutton(app.UIFigure, 'push');
            app.IdleButton.ButtonPushedFcn = createCallbackFcn(app, @IdleButtonPushed, true);
            app.IdleButton.Text = 'Idle';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.FiringRateTab = uitab(app.TabGroup2);
            app.FiringRateTab.Title = 'Firing Rate';
            app.rateAxes = uiaxes(app.FiringRateTab);
            title(app.rateAxes, 'Running Spike Rate')
            xlabel(app.rateAxes, 'Time')
            ylabel(app.rateAxes, 'Channel')
            app.BinsizemsLabel_2 = uilabel(app.FiringRateTab);
            app.BinsizemsLabel_2.Text = 'Bin size (ms)';
            app.BinEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.BinEdit.Value = 500;
            app.HistorysecLabel = uilabel(app.FiringRateTab);
            app.HistorysecLabel.Text = 'History (sec)';
            app.RangeEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.RangeEdit.Value = 60;
            app.ratePopupCheck = uicheckbox(app.FiringRateTab);
            app.ratePopupCheck.Text = 'Popup';
            app.fratescaleEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.yscale0maxobservedLabel = uilabel(app.FiringRateTab);
            app.yscale0maxobservedLabel.Text = 'y-scale (0 = max observed)';
            app.frateTable = uitable(app.FiringRateTab);
            app.frateTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.frateTable.RowName = {};
            app.FreqTuningTab = uitab(app.TabGroup2);
            app.FreqTuningTab.Title = 'Freq Tuning';
            app.OffsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.OffsetEdit.Value = 100;
            app.EvokedratewindowmsLabel_4 = uilabel(app.FreqTuningTab);
            app.EvokedratewindowmsLabel_4.Text = 'Evoked rate window (ms)';
            app.OnsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.FTCAxes = uiaxes(app.FreqTuningTab);
            title(app.FTCAxes, 'IsoIntensity Function')
            xlabel(app.FTCAxes, 'Frequency')
            ylabel(app.FTCAxes, 'Channel')
            app.FTCPopupCheck = uicheckbox(app.FreqTuningTab);
            app.FTCPopupCheck.Text = 'Popup';
            app.PSTHTab = uitab(app.TabGroup2);
            app.PSTHTab.Title = 'PSTH';
            app.PSTH1Axes = uiaxes(app.PSTHTab);
            title(app.PSTH1Axes, 'PSTH 1')
            xlabel(app.PSTH1Axes, 'Time')
            ylabel(app.PSTH1Axes, 'Spike Rate')
            app.PSTHPopupCheck = uicheckbox(app.PSTHTab);
            app.PSTHPopupCheck.Text = 'Popup';
            app.BinsizemsLabel = uilabel(app.PSTHTab);
            app.BinsizemsLabel.Text = 'Bin size (ms)';
            app.PSTHBinEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTHBinEdit.Value = 500;
            app.PSTH2Axes = uiaxes(app.PSTHTab);
            title(app.PSTH2Axes, 'PSTH 2')
            xlabel(app.PSTH2Axes, 'Time')
            ylabel(app.PSTH2Axes, 'Spike Rate')
            app.PSTH1WindowsecEditFieldLabel = uilabel(app.PSTHTab);
            app.PSTH1WindowsecEditFieldLabel.Text = 'PSTH 1 Window (sec)';
            app.PSTH1StartEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH1EndEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH1EndEdit.Value = 1;
            app.Referencet0DropDownLabel = uilabel(app.PSTHTab);
            app.Referencet0DropDownLabel.Text = 'Reference (t=0)';
            app.PSTH1RefDrop = uidropdown(app.PSTHTab);
            app.PSTH1RefDrop.Items = {'Onset', 'Offset'};
            app.PSTH1RefDrop.Value = 'Onset';
            app.Referencet0DropDown_2Label = uilabel(app.PSTHTab);
            app.Referencet0DropDown_2Label.Text = 'Reference (t=0)';
            app.PSTH2RefDrop = uidropdown(app.PSTHTab);
            app.PSTH2RefDrop.Items = {'Onset', 'Offset'};
            app.PSTH2RefDrop.Value = 'Offset';
            app.PSTH2EndEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTH2EndEdit.Value = 1;
            app.PSTH2WindowsecEditFieldLabel = uilabel(app.PSTHTab);
            app.PSTH2WindowsecEditFieldLabel.Text = 'PSTH 2 Window (sec)';
            app.PSTH2StartEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTHscaleradio = uibuttongroup(app.PSTHTab);
            app.PSTHscaleradio.Title = 'Normalization';
            app.IndependentButton = uiradiobutton(app.PSTHscaleradio);
            app.IndependentButton.Text = 'Independent';
            app.xPSTHsButton = uiradiobutton(app.PSTHscaleradio);
            app.xPSTHsButton.Text = 'x PSTHs';
            app.xAllButton = uiradiobutton(app.PSTHscaleradio);
            app.xAllButton.Text = 'x All';
            app.xAllButton.Value = true;
            app.PSTHscaleEdit = uieditfield(app.PSTHscaleradio, 'numeric');
            app.FrequencyDropDownLabel = uilabel(app.PSTHTab);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDrop = uidropdown(app.PSTHTab);
            app.LevelLabel = uilabel(app.PSTHTab);
            app.LevelLabel.Text = 'Level';
            app.LevelDrop = uidropdown(app.PSTHTab);
            app.PSTHbaseline = uibuttongroup(app.PSTHTab);
            app.PSTHbaseline.Title = 'Show Baseline';
            app.MeanButton = uiradiobutton(app.PSTHbaseline);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.CLButton = uiradiobutton(app.PSTHbaseline);
            app.CLButton.Text = 'CL (%)';
            app.CLEdit = uieditfield(app.PSTHbaseline, 'numeric');
            app.CLEdit.Value = 95;
            app.PSTHCycleCheck = uicheckbox(app.PSTHTab);
            app.PSTHCycleCheck.Text = 'Average cycles';
            app.LFPTab = uitab(app.TabGroup2);
            app.LFPTab.Title = 'LFP';
            app.LFP1Axes = uiaxes(app.LFPTab);
            title(app.LFP1Axes, 'LFP 1')
            xlabel(app.LFP1Axes, 'Time')
            ylabel(app.LFP1Axes, 'Channel')
            app.LFP2Axes = uiaxes(app.LFPTab);
            title(app.LFP2Axes, 'LFP 2')
            xlabel(app.LFP2Axes, 'Time')
            ylabel(app.LFP2Axes, 'Channel')
            app.LFPPopupCheck = uicheckbox(app.LFPTab);
            app.LFPPopupCheck.Text = 'Popup';
            app.LFP1Label = uilabel(app.LFPTab);
            app.LFP2Label = uilabel(app.LFPTab);
            app.HPCFEditFieldLabel = uilabel(app.LFPTab);
            app.HPCFEditFieldLabel.Text = 'HPCF';
            app.HPCFEdit = uieditfield(app.LFPTab, 'numeric');
            app.HPCFEdit.Value = 30;
            app.LPCFEditFieldLabel = uilabel(app.LFPTab);
            app.LPCFEditFieldLabel.Text = 'LPCF';
            app.LPCFEdit = uieditfield(app.LFPTab, 'numeric');
            app.LPCFEdit.Value = 300;
            app.ClusterWaveformsTab = uitab(app.TabGroup2);
            app.ClusterWaveformsTab.Title = 'Cluster Waveforms';
            app.Clust1Axes = uiaxes(app.ClusterWaveformsTab);
            title(app.Clust1Axes, '')
            xlabel(app.Clust1Axes, 'Channel')
            ylabel(app.Clust1Axes, 'Cluster')
            app.Clust2Axes = uiaxes(app.ClusterWaveformsTab);
            title(app.Clust2Axes, {''; ''})
            xlabel(app.Clust2Axes, '')
            ylabel(app.Clust2Axes, '')
            app.ClustPopupCheck = uicheckbox(app.ClusterWaveformsTab);
            app.ClustPopupCheck.Text = 'Popup';
            app.PollingperiodsecEditFieldLabel = uilabel(app.UIFigure);
            app.PollingperiodsecEditFieldLabel.Text = 'Polling period (sec)';
            app.UpdateEdit = uieditfield(app.UIFigure, 'numeric');
            app.UpdateEdit.Value = 1;
            app.ClustsCheck = uicheckbox(app.UIFigure);
            app.ClustsCheck.Text = 'Show Clusters';
            app.ExperimentDrop = uidropdown(app.UIFigure);
            app.ExpButton1 = uibutton(app.UIFigure, 'push');
            app.ExpButton1.ButtonPushedFcn = createCallbackFcn(app, @ExpButton1Pushed, true);
            app.ExpButton1.Text = 'Load Experiment';
            app.ExpDurationRadio = uibuttongroup(app.UIFigure);
            app.ExpDurationRadio.Title = 'Set to Idle After...';
            app.InfiniteButton = uiradiobutton(app.ExpDurationRadio);
            app.InfiniteButton.Text = 'Infinite';
            app.InfiniteButton.Value = true;
            app.LastoffsetnsecButton = uiradiobutton(app.ExpDurationRadio);
            app.LastoffsetnsecButton.Text = 'Last offset + n sec';
            app.SecondsButton = uiradiobutton(app.ExpDurationRadio);
            app.SecondsButton.Text = 'Seconds';
            app.StimDurLabel = uilabel(app.ExpDurationRadio);
            app.StimDurLabel.Text = '';
            app.TotalStimDurLabel = uilabel(app.ExpDurationRadio);
            app.TotalStimDurLabel.Text = 'Total Stimulus Duration:';
            app.Label = uilabel(app.ExpDurationRadio);
            app.Label.Text = '';
            app.ExpDurationEdit = uieditfield(app.ExpDurationRadio, 'numeric');
            app.ExpDurationEdit.Value = 60;
            app.LoadThresholdsButton = uibutton(app.ExpDurationRadio, 'push');
            app.LoadThresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadThresholdsButtonPushed, true);
            app.LoadThresholdsButton.Text = 'Load Thresholds';
            app.BlockEditFieldLabel = uilabel(app.UIFigure);
            app.BlockEditFieldLabel.Text = 'Block';
            app.BlockEdit = uieditfield(app.UIFigure, 'numeric');
            app.BlockEdit.Value = 1;
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.ChannelsTab = uitab(app.TabGroup3);
            app.ChannelsTab.Title = 'Channels';
            app.CheckBox_1 = uicheckbox(app.ChannelsTab);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.ChannelsTab);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.ChannelsTab);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.ChannelsTab);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.ChannelsTab);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.ChannelsTab);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.ChannelsTab);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.ChannelsTab);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.ChannelsTab);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.ChannelsTab);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.ChannelsTab);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.ChannelsTab);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.ChannelsTab);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.ChannelsTab);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.ChannelsTab);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.ChannelsTab);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.ChannelsTab);
            app.CheckBox_17.Text = '17';
            app.CheckBox_18 = uicheckbox(app.ChannelsTab);
            app.CheckBox_18.Text = '18';
            app.CheckBox_19 = uicheckbox(app.ChannelsTab);
            app.CheckBox_19.Text = '19';
            app.CheckBox_20 = uicheckbox(app.ChannelsTab);
            app.CheckBox_20.Text = '20';
            app.CheckBox_21 = uicheckbox(app.ChannelsTab);
            app.CheckBox_21.Text = '21';
            app.CheckBox_22 = uicheckbox(app.ChannelsTab);
            app.CheckBox_22.Text = '22';
            app.CheckBox_23 = uicheckbox(app.ChannelsTab);
            app.CheckBox_23.Text = '23';
            app.CheckBox_24 = uicheckbox(app.ChannelsTab);
            app.CheckBox_24.Text = '24';
            app.CheckBox_25 = uicheckbox(app.ChannelsTab);
            app.CheckBox_25.Text = '25';
            app.CheckBox_26 = uicheckbox(app.ChannelsTab);
            app.CheckBox_26.Text = '26';
            app.CheckBox_27 = uicheckbox(app.ChannelsTab);
            app.CheckBox_27.Text = '27';
            app.CheckBox_28 = uicheckbox(app.ChannelsTab);
            app.CheckBox_28.Text = '28';
            app.CheckBox_29 = uicheckbox(app.ChannelsTab);
            app.CheckBox_29.Text = '29';
            app.CheckBox_30 = uicheckbox(app.ChannelsTab);
            app.CheckBox_30.Text = '30';
            app.CheckBox_31 = uicheckbox(app.ChannelsTab);
            app.CheckBox_31.Text = '31';
            app.CheckBox_32 = uicheckbox(app.ChannelsTab);
            app.CheckBox_32.Text = '32';
            app.Chan116Button = uibutton(app.ChannelsTab, 'state');
            app.Chan116Button.ValueChangedFcn = createCallbackFcn(app, @Chan116ButtonValueChanged, true);
            app.Chan116Button.Text = 'Clear 1-16';
            app.Chan116Button.Value = true;
            app.Chan1732Button = uibutton(app.ChannelsTab, 'state');
            app.Chan1732Button.ValueChangedFcn = createCallbackFcn(app, @Chan1732ButtonValueChanged, true);
            app.Chan1732Button.Text = 'Check 17-32';
            app.ClustersTab = uitab(app.TabGroup3);
            app.ClustersTab.Title = 'Clusters';
            app.SortOrderDropDownLabel = uilabel(app.ClustersTab);
            app.SortOrderDropDownLabel.Text = 'Sort Order';
            app.SortOrderDropDown = uidropdown(app.ClustersTab);
            app.SortOrderDropDown.Items = {'Original', 'Nearest Channel', 'Spike Count', 'AD'};
            app.SortOrderDropDown.Value = 'Original';
            app.SelectClustersButtonGroup = uibuttongroup(app.ClustersTab);
            app.SelectClustersButtonGroup.Title = 'Select Clusters';
            app.AllRadio = uiradiobutton(app.SelectClustersButtonGroup);
            app.AllRadio.Text = 'All';
            app.AllRadio.Value = true;
            app.TopRadio = uiradiobutton(app.SelectClustersButtonGroup);
            app.TopRadio.Text = 'Top';
            app.BottomRadio = uiradiobutton(app.SelectClustersButtonGroup);
            app.BottomRadio.Text = 'Bottom';
            app.ClustRadio = uiradiobutton(app.SelectClustersButtonGroup);
            app.ClustRadio.Text = 'Clusters';
            app.ClustTopEdit = uieditfield(app.SelectClustersButtonGroup, 'text');
            app.ClustBottomEdit = uieditfield(app.SelectClustersButtonGroup, 'text');
            app.ClustClustEdit = uieditfield(app.SelectClustersButtonGroup, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end