classdef synui_v122 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DialogueLabel                 matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SynapseTab                    matlab.ui.container.Tab
        ExpDurationRadio              matlab.ui.container.ButtonGroup
        InfiniteButton                matlab.ui.control.RadioButton
        LastoffsetnsecButton          matlab.ui.control.RadioButton
        SecondsButton                 matlab.ui.control.RadioButton
        nLabel                        matlab.ui.control.Label
        ExpDurationEdit               matlab.ui.control.NumericEditField
        TotalStimDurLabel             matlab.ui.control.Label
        StimDurLabel                  matlab.ui.control.Label
        StimAxes                      matlab.ui.control.UIAxes
        IdleButton                    matlab.ui.control.Button
        PreviewButton                 matlab.ui.control.Button
        RecordButton                  matlab.ui.control.Button
        CurrentModeLabel              matlab.ui.control.Label
        ExperimentDrop                matlab.ui.control.DropDown
        ExpButton1                    matlab.ui.control.Button
        BlockEditFieldLabel           matlab.ui.control.Label
        BlockEdit                     matlab.ui.control.NumericEditField
        PollingsecLabel               matlab.ui.control.Label
        UpdateEdit                    matlab.ui.control.NumericEditField
        ConnectButton                 matlab.ui.control.Button
        StimPanel                     matlab.ui.container.TabGroup
        SoundTab                      matlab.ui.container.Tab
        StimulusPanel                 matlab.ui.container.Panel
        PulseDurationmsLabel          matlab.ui.control.Label
        PulseDurEdit                  matlab.ui.control.NumericEditField
        PulserateHzLabel              matlab.ui.control.Label
        PulseRateEdit                 matlab.ui.control.NumericEditField
        TrainOnLabel                  matlab.ui.control.Label
        TrainOnEdit                   matlab.ui.control.NumericEditField
        TrainOffLabel                 matlab.ui.control.Label
        TrainOffEdit                  matlab.ui.control.NumericEditField
        TrainRepeatsLabel             matlab.ui.control.Label
        TrainRepeatsEdit              matlab.ui.control.NumericEditField
        StimFreqLabel                 matlab.ui.control.Label
        StimFreqEdit                  matlab.ui.control.NumericEditField
        StimBWLabel                   matlab.ui.control.Label
        StimBWEdit                    matlab.ui.control.NumericEditField
        StimLevelLabel                matlab.ui.control.Label
        StimLevelEdit                 matlab.ui.control.NumericEditField
        StimModFreqLabel              matlab.ui.control.Label
        StimModFreqEdit               matlab.ui.control.NumericEditField
        Stim2FreqLabel                matlab.ui.control.Label
        Stim2FreqEdit                 matlab.ui.control.NumericEditField
        Stim2LevelLabel               matlab.ui.control.Label
        Stim2LevelEdit                matlab.ui.control.NumericEditField
        RiseFallmsLabel               matlab.ui.control.Label
        RampEdit                      matlab.ui.control.NumericEditField
        LDSPanel                      matlab.ui.container.Panel
        LDSPreLabel                   matlab.ui.control.Label
        LDSPreEdit                    matlab.ui.control.NumericEditField
        LDSDurLabel                   matlab.ui.control.Label
        LDSDurEdit                    matlab.ui.control.NumericEditField
        LDSISILabel                   matlab.ui.control.Label
        LDSISIEdit                    matlab.ui.control.NumericEditField
        LDSRepeatsLabel               matlab.ui.control.Label
        LDSRepeatsEdit                matlab.ui.control.NumericEditField
        LDSPostGapLabel               matlab.ui.control.Label
        LDSPostGapEdit                matlab.ui.control.NumericEditField
        LDSCFreqLabel                 matlab.ui.control.Label
        LDSCFreqEdit                  matlab.ui.control.NumericEditField
        LDSBWLabel                    matlab.ui.control.Label
        LDSBWEdit                     matlab.ui.control.NumericEditField
        LDSLevelLabel                 matlab.ui.control.Label
        LDSLevelEdit                  matlab.ui.control.NumericEditField
        LDSModDepthLabel              matlab.ui.control.Label
        LDSModDepthEdit               matlab.ui.control.NumericEditField
        LDSModFreqLabel               matlab.ui.control.Label
        LDSModFreqEdit                matlab.ui.control.NumericEditField
        LDSModExpLabel                matlab.ui.control.Label
        LDSModExpEdit                 matlab.ui.control.NumericEditField
        SynapseTab_2                  matlab.ui.container.Tab
        Param1Label                   matlab.ui.control.Label
        Param2Label                   matlab.ui.control.Label
        Param3Label                   matlab.ui.control.Label
        Param4Label                   matlab.ui.control.Label
        Param5Label                   matlab.ui.control.Label
        Param6Label                   matlab.ui.control.Label
        Param7Label                   matlab.ui.control.Label
        Param8Label                   matlab.ui.control.Label
        Param9Label                   matlab.ui.control.Label
        Param10Label                  matlab.ui.control.Label
        Param11Label                  matlab.ui.control.Label
        Param12Label                  matlab.ui.control.Label
        Param13Label                  matlab.ui.control.Label
        Param14Label                  matlab.ui.control.Label
        Param15Label                  matlab.ui.control.Label
        Par1Label                     matlab.ui.control.Label
        Param1Edit                    matlab.ui.control.NumericEditField
        Par2Label                     matlab.ui.control.Label
        Param2Edit                    matlab.ui.control.NumericEditField
        Par3Label                     matlab.ui.control.Label
        Param3Edit                    matlab.ui.control.NumericEditField
        Par4Label                     matlab.ui.control.Label
        Param4Edit                    matlab.ui.control.NumericEditField
        Par5Label                     matlab.ui.control.Label
        Param5Edit                    matlab.ui.control.NumericEditField
        Par6Label                     matlab.ui.control.Label
        Param6Edit                    matlab.ui.control.NumericEditField
        Par7Label                     matlab.ui.control.Label
        Param7Edit                    matlab.ui.control.NumericEditField
        Par8Label                     matlab.ui.control.Label
        Param8Edit                    matlab.ui.control.NumericEditField
        Par9Label                     matlab.ui.control.Label
        Param9Edit                    matlab.ui.control.NumericEditField
        Par10Label                    matlab.ui.control.Label
        Param10Edit                   matlab.ui.control.NumericEditField
        Par11Label                    matlab.ui.control.Label
        Param11Edit                   matlab.ui.control.NumericEditField
        Par12Label                    matlab.ui.control.Label
        Param12Edit                   matlab.ui.control.NumericEditField
        Par13Label                    matlab.ui.control.Label
        Param13Edit                   matlab.ui.control.NumericEditField
        Par14Label                    matlab.ui.control.Label
        Param14Edit                   matlab.ui.control.NumericEditField
        Par15Label                    matlab.ui.control.Label
        Param15Edit                   matlab.ui.control.NumericEditField
        Param16Label                  matlab.ui.control.Label
        Par16Label                    matlab.ui.control.Label
        Param16Edit                   matlab.ui.control.NumericEditField
        Param17Label                  matlab.ui.control.Label
        Par17Label                    matlab.ui.control.Label
        Param17Edit                   matlab.ui.control.NumericEditField
        Param18Label                  matlab.ui.control.Label
        Par18Label                    matlab.ui.control.Label
        Param18Edit                   matlab.ui.control.NumericEditField
        SynUILabel                    matlab.ui.control.Label
        SynapseLabel                  matlab.ui.control.Label
        LoadThresholdsButton          matlab.ui.control.Button
        GetButton                     matlab.ui.control.Button
        SetButton                     matlab.ui.control.Button
        ReadTankTab                   matlab.ui.container.Tab
        TankButton                    matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        ParamsLabel                   matlab.ui.control.Label
        ParamsLabel2                  matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        ClustersButton                matlab.ui.control.Button
        ExperimentDataPanel           matlab.ui.container.Panel
        paramsCheck                   matlab.ui.control.CheckBox
        stimCheck                     matlab.ui.control.CheckBox
        stimsCheck                    matlab.ui.control.CheckBox
        ResponseDataPanel             matlab.ui.container.Panel
        spikeCheck                    matlab.ui.control.CheckBox
        rateCheck                     matlab.ui.control.CheckBox
        psth1Check                    matlab.ui.control.CheckBox
        psth2Check                    matlab.ui.control.CheckBox
        lfp1Check                     matlab.ui.control.CheckBox
        lfp2Check                     matlab.ui.control.CheckBox
        statsCheck                    matlab.ui.control.CheckBox
        tuningCheck                   matlab.ui.control.CheckBox
        lfpstatsCheck                 matlab.ui.control.CheckBox
        psth3Check                    matlab.ui.control.CheckBox
        fraCheck                      matlab.ui.control.CheckBox
        BatchButton                   matlab.ui.control.Button
        StimParamsLabel               matlab.ui.control.Label
        StimParamsLabel2              matlab.ui.control.Label
        AnalysisDataPanel             matlab.ui.container.Panel
        ExpParCheck                   matlab.ui.control.CheckBox
        BatchEdit                     matlab.ui.control.EditField
        TankEdit                      matlab.ui.control.EditField
        ClustEdit                     matlab.ui.control.EditField
        FilenameprefixEditFieldLabel  matlab.ui.control.Label
        PrefixEdit                    matlab.ui.control.EditField
        SkipLFPCheck                  matlab.ui.control.CheckBox
        TabGroup2                     matlab.ui.container.TabGroup
        FiringRateTab                 matlab.ui.container.Tab
        rateAxes                      matlab.ui.control.UIAxes
        BinsizemsLabel_2              matlab.ui.control.Label
        BinEdit                       matlab.ui.control.NumericEditField
        HistorysecLabel               matlab.ui.control.Label
        RangeEdit                     matlab.ui.control.NumericEditField
        fratescaleEdit                matlab.ui.control.NumericEditField
        yscale0maxLabel               matlab.ui.control.Label
        frateTable                    matlab.ui.control.Table
        ratePopupCheck                matlab.ui.control.CheckBox
        FreqTuningTab                 matlab.ui.container.Tab
        AnalysiswindowmsLabel         matlab.ui.control.Label
        OnsetEdit                     matlab.ui.control.NumericEditField
        FTCAxes                       matlab.ui.control.UIAxes
        FTCPopupCheck                 matlab.ui.control.CheckBox
        OffsetEdit                    matlab.ui.control.NumericEditField
        PSTHTab                       matlab.ui.container.Tab
        PSTH1Axes                     matlab.ui.control.UIAxes
        PSTH2Axes                     matlab.ui.control.UIAxes
        PSTHPopupCheck                matlab.ui.control.CheckBox
        PSTHbaseline                  matlab.ui.container.ButtonGroup
        MeanButton                    matlab.ui.control.RadioButton
        CLButton                      matlab.ui.control.RadioButton
        CLEdit                        matlab.ui.control.NumericEditField
        PSTHButton                    matlab.ui.control.RadioButton
        SubtractionCheck              matlab.ui.control.CheckBox
        PSTHscaleradio                matlab.ui.container.ButtonGroup
        IndependentButton             matlab.ui.control.RadioButton
        xPSTHsButton                  matlab.ui.control.RadioButton
        xAllButton                    matlab.ui.control.RadioButton
        PSTHscaleEdit                 matlab.ui.control.NumericEditField
        CyclePSTHPanel                matlab.ui.container.Panel
        ResponseDurationCheck         matlab.ui.control.CheckBox
        SpectraCheck                  matlab.ui.control.CheckBox
        PSTHWindowText                matlab.ui.control.Label
        OnsetOffsetLabel              matlab.ui.control.Label
        PSTHOnsetEdit                 matlab.ui.control.NumericEditField
        PSTHOffsetEdit                matlab.ui.control.NumericEditField
        BaselinemsLabel               matlab.ui.control.Label
        BaselineStartEdit             matlab.ui.control.NumericEditField
        BaselineEndEdit               matlab.ui.control.NumericEditField
        PSTHCycleCheck                matlab.ui.control.CheckBox
        FrequencyDropDownLabel        matlab.ui.control.Label
        FrequencyDrop                 matlab.ui.control.DropDown
        PSTHBinEdit                   matlab.ui.control.NumericEditField
        BinsizemsLabel_3              matlab.ui.control.Label
        LevelDrop                     matlab.ui.control.DropDown
        LevelLabel                    matlab.ui.control.Label
        LFPTab                        matlab.ui.container.Tab
        LFP1Axes                      matlab.ui.control.UIAxes
        LFP2Axes                      matlab.ui.control.UIAxes
        LFPPopupCheck                 matlab.ui.control.CheckBox
        LFP1Label                     matlab.ui.control.Label
        LFP2Label                     matlab.ui.control.Label
        HPCFEditFieldLabel            matlab.ui.control.Label
        HPCFEdit                      matlab.ui.control.NumericEditField
        LPCFEditFieldLabel            matlab.ui.control.Label
        LPCFEdit                      matlab.ui.control.NumericEditField
        RMSWindowmsLabel              matlab.ui.control.Label
        RMSonEdit                     matlab.ui.control.NumericEditField
        RMSoffEdit                    matlab.ui.control.NumericEditField
        ClusterWaveformsTab           matlab.ui.container.Tab
        Clust1Axes                    matlab.ui.control.UIAxes
        Clust2Axes                    matlab.ui.control.UIAxes
        ClustPopupCheck               matlab.ui.control.CheckBox
        TabGroup3                     matlab.ui.container.TabGroup
        ChannelsTab                   matlab.ui.container.Tab
        CheckBox_1                    matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_4                    matlab.ui.control.CheckBox
        CheckBox_5                    matlab.ui.control.CheckBox
        CheckBox_6                    matlab.ui.control.CheckBox
        CheckBox_7                    matlab.ui.control.CheckBox
        CheckBox_8                    matlab.ui.control.CheckBox
        CheckBox_9                    matlab.ui.control.CheckBox
        CheckBox_10                   matlab.ui.control.CheckBox
        CheckBox_11                   matlab.ui.control.CheckBox
        CheckBox_12                   matlab.ui.control.CheckBox
        CheckBox_13                   matlab.ui.control.CheckBox
        CheckBox_14                   matlab.ui.control.CheckBox
        CheckBox_15                   matlab.ui.control.CheckBox
        CheckBox_16                   matlab.ui.control.CheckBox
        CheckBox_17                   matlab.ui.control.CheckBox
        CheckBox_18                   matlab.ui.control.CheckBox
        CheckBox_19                   matlab.ui.control.CheckBox
        CheckBox_20                   matlab.ui.control.CheckBox
        CheckBox_21                   matlab.ui.control.CheckBox
        CheckBox_22                   matlab.ui.control.CheckBox
        CheckBox_23                   matlab.ui.control.CheckBox
        CheckBox_24                   matlab.ui.control.CheckBox
        CheckBox_25                   matlab.ui.control.CheckBox
        CheckBox_26                   matlab.ui.control.CheckBox
        CheckBox_27                   matlab.ui.control.CheckBox
        CheckBox_28                   matlab.ui.control.CheckBox
        CheckBox_29                   matlab.ui.control.CheckBox
        CheckBox_30                   matlab.ui.control.CheckBox
        CheckBox_31                   matlab.ui.control.CheckBox
        CheckBox_32                   matlab.ui.control.CheckBox
        Chan116Button                 matlab.ui.control.StateButton
        Chan1732Button                matlab.ui.control.StateButton
        ClustersTab                   matlab.ui.container.Tab
        SortOrderDropDownLabel        matlab.ui.control.Label
        SortOrderDropDown             matlab.ui.control.DropDown
        SelectClustersButtonGroup     matlab.ui.container.ButtonGroup
        AllRadio                      matlab.ui.control.RadioButton
        TopRadio                      matlab.ui.control.RadioButton
        BottomRadio                   matlab.ui.control.RadioButton
        ClustRadio                    matlab.ui.control.RadioButton
        ClustTopEdit                  matlab.ui.control.EditField
        ClustBottomEdit               matlab.ui.control.EditField
        ClustClustEdit                matlab.ui.control.EditField
        ClustsCheck                   matlab.ui.control.CheckBox
        LoadParButton                 matlab.ui.control.Button
        LoadParEdit                   matlab.ui.control.EditField
        ExpParButton                  matlab.ui.control.Button
        Referencet0DropDownLabel      matlab.ui.control.Label
        PSTH1RefDrop                  matlab.ui.control.DropDown
        Referencet0DropDown_2Label    matlab.ui.control.Label
        PSTH2RefDrop                  matlab.ui.control.DropDown
        PSTH1WindowsecEditFieldLabel  matlab.ui.control.Label
        PSTH1StartEdit                matlab.ui.control.NumericEditField
        PSTH1EndEdit                  matlab.ui.control.NumericEditField
        PSTH2WindowsecEditFieldLabel  matlab.ui.control.Label
        PSTH2StartEdit                matlab.ui.control.NumericEditField
        PSTH2EndEdit                  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
    end

    properties (Access = public)
        syn = [];           % links to Synapse
%         experiment = [];    % 1 Iso Intensity, 2 LDS Noise, 3 LDS Tone
        pars = [];          % Parameters
        gizmos = [];        % Gizmos
        fid = [];           % file to read
        breakflag = 0;      % set synapse to idle and stop data streaming
        data = [];
    end

    methods (Access = private)


        function exporttables(app, savepath, ntable, name)
            checkfield = [name 'Check'];
            fname = [savepath '/' app.PrefixEdit.Value, name '.csv'];
            d = datestr(now, 'yymmdd-HHMMSS');
            if app.(checkfield).Value
                if exist(fname, 'file')
                    writetable(ntable, [savepath '/' app.PrefixEdit.Value name d '.csv'])
                elseif exist(savepath, 'dir')
                    writetable(ntable, fname)
                else
                    mkdir(savepath)
                    writetable(ntable, fname)
                end
            end
        end


        function loadtank(app)
            if app.SkipLFPCheck.Value
                data = readtankfast(app.TankEdit.Value);
            else
                data = readtank_v3(app.TankEdit.Value);
            end
%Tanks do not contain spikes sorted by kilosort, but instead spikes sorted
%by Synapse's PCA spike sorting/detection gizmo.
            app.ClustsCheck.Value = 0;
            data.chanlist = updatechans(app);
            data.nchans = max(data.chanlist);
            app.ClustEdit.Value = '';
%Populate the frequency and level dropdowns
            frqlist = round(sort(unique(data.frqs)));
            app.FrequencyDrop.Items = ['all'; compose('%d', frqlist)];
            app.LevelDrop.Items = {'0'};
            if app.LPCFEdit.Value > data.fs/2
                app.LPCFEdit.Value = data.fs/2;
            end
            if isfield(data, 'lvls')
                lvllist = round(sort(unique(data.lvls)));
                app.LevelDrop.Items = ['all'; compose('%d', lvllist)];
            end
%Display Synapse gizmo parameters, automated field updates
             try
                load([app.TankEdit.Value '/params.mat']');
                chstr = ''; namestr = '';
                charr = num2str(cell2mat(table2cell(changetable))');
                for row = 1:size(charr, 1)
                    chstr = [chstr, charr(row,:), newline];
                    namestr = [namestr, char(changetable.Properties.VariableNames(row)), newline];
                end
                app.ParamsLabel2.Text = chstr;
                 app.ParamsLabel.Text = namestr;
                 if exist('stimtable', 'var')
                     datastr = '';
                     for row = 1:size(stimtable, 1)
                        datastr = [datastr, num2str(table2array(stimtable(row,2))), newline];
                     end

                    app.StimParamsLabel.Text = table2array(stimtable(:,1));
                    app.StimParamsLabel2.Text = datastr();
                 else
                    app.StimParamsLabel.Text = '';
                    app.StimParamsLabel2.Text = '';                     
                 end
                 
                if any(strcmp('LDSOnsetDelay',fieldnames(changetable)))
                    app.PSTH1StartEdit.Value = -1*table2array(changetable(1, 'LDSOnsetDelay'));
                    app.PSTH2StartEdit.Value = 0;
                    app.PSTH1EndEdit.Value = 0;
                    app.PSTH2EndEdit.Value = table2array(changetable(1, 'LDSDuration'));
                    app.PSTHBinEdit.Value = table2array(changetable(1, 'LDSDuration'))*10;
                elseif numel(data.stimons) > 1
                    tab1 = tabulate(round(diff(data.stimons).*1000)./1000);
                    [~,i] = sort(tab1(:,2), 'descend');
                    j = find(tab1(i, 2)>0);
                    isis = tab1(i(j), 1:2);
                    app.PSTH1StartEdit.Value = 0;
                    app.PSTH2StartEdit.Value = 0;
                    
                    app.PSTH1EndEdit.Value = isis(1,1);
                    app.PSTH2EndEdit.Value = isis(1,1);
                    app.PSTHBinEdit.Value = isis(1,1)*10;
                end
             catch ME
                    app.StimParamsLabel.Text = '';
                    app.StimParamsLabel2.Text = '';                     
                    app.ParamsLabel2.Text = '';
                    app.ParamsLabel.Text = '';

                    ME
             end
             app.data = data;
        end
        
        function updatesynuipars(app)
            load(app.LoadParEdit.Value);
            faili = [];
            for i = 1:size(parmat, 1)
                fieldname = char(parmat(i,1));
                fieldvalue = cell2mat(parmat(i,2));
                try
                    if ~strcmpi(fieldname, 'TankEdit') && ~strcmpi(fieldname, 'ClustEdit') && ~strcmpi(fieldname, 'LoadParEdit') &&...
                            ~strcmpi(fieldname, 'BatchEdit') && ~strcmpi(fieldname, 'ExperimentDrop') && ~strcmpi(fieldname, 'BlockEdit') && ...
                            ~strcmpi(fieldname, 'PrefixEdit') 
                        app.(fieldname).Value = fieldvalue;
                    end
                catch
                    faili = [faili; i];
                end
            end
            if ~isempty(faili)
                disp('The following parameters failed to load:');
                disp(char(parmat(faili,1)));
            end
            if app.PSTHCycleCheck.Value
                app.PSTHWindowText.Visible = 1;
                app.PSTHOnsetEdit.Visible = 1;
                app.PSTHOffsetEdit.Visible = 1;
                app.PSTHButton.Visible = 1;
                app.SubtractionCheck.Visible = 1;
                app.ResponseDurationCheck.Visible = 1;
                app.SpectraCheck.Visible = 1;
                app.OnsetOffsetLabel.Visible = 1;
            else
                app.PSTHWindowText.Visible = 0;
                app.PSTHOnsetEdit.Visible = 0;
                app.PSTHOffsetEdit.Visible = 0;
                app.PSTHButton.Visible = 0;
                app.SubtractionCheck.Visible = 0;
                app.ResponseDurationCheck.Visible = 0;
                app.SpectraCheck.Visible = 0;
            end
        end
        
            function makeandexport(app, savepath)
                    data = app.data;
                    data.chanlist = updatechans(app);
                    data.nchans = max(data.chanlist);
        
                    [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(data.spets, data.stimons, data.frqs, data.lvls);
                    if isempty(data.evons)
                        stimptable = table(data.stimons, data.stimoffs, data.frqs, data.lvls, 'VariableNames', {'OnsetTime', 'OffsetTime', 'Frequency', 'Level'});
                    else
                        stimptable = table(data.evons, data.evoffs, data.frqs, data.lvls, 'VariableNames', {'OnsetTime', 'OffsetTime', 'Frequency', 'Level'});
                    end
                    spiketable = table(data.spets, data.channels, stimspets, spetfreq, spetlevel, 'VariableNames', {'SpikeTime', 'Channel', 'PostStimulusTime', 'LastFreq', 'LastLevel'});
        
                    ratetable = data.ratetable;
                    psth1table = data.psth1table;
                    psth2table = data.psth2table;
                    psth3table = data.psth3table;
                    lfp1table = data.lfp1table;
                    lfp2table = data.lfp2table;
                    statstable = data.statstable;
                    lfpstatstable = data.lfpstatstable;
                    ftctable = data.ftctable;
                     try
                        load([app.TankEdit.Value '/params.mat']', 'changetable', 'stimtable');
                        [fpath, fname, ~] = fileparts(app.TankEdit.Value);
                        BlockName = {fname};
                        stimtable2 = table('Size', [1 size(stimtable, 1)], 'VariableTypes', repmat({'double'}, 1, size(stimtable, 1)));
                        stimtable2.Properties.VariableNames = table2cell(stimtable(:,1))';
                        stimtable2(1,:) = table2cell(stimtable(:,2))';
                        if sum(strcmpi('trmsedit', stimtable2.Properties.VariableNames))
                            stimtable2.Properties.VariableNames(find(strcmpi('trmsedit', stimtable2.Properties.VariableNames))) = {'LDSLevel'};
                        end 
                        partable = stimtable2;
                        partable = addvars(partable, BlockName);
                        exporttables(app, savepath, partable, 'params');
                     end
                    exporttables(app, savepath, stimptable, 'stim');
                    exporttables(app, savepath, spiketable, 'spike');
                    exporttables(app, savepath, ratetable, 'rate');
                    exporttables(app, savepath, psth1table, 'psth1');
                    exporttables(app, savepath, psth2table, 'psth2');
                    exporttables(app, savepath, psth3table, 'psth3');
                    exporttables(app, savepath, lfp1table, 'lfp1');
                    exporttables(app, savepath, lfp2table, 'lfp2');
                    exporttables(app, savepath, statstable, 'stats');
                    exporttables(app, savepath, lfpstatstable, 'lfpstats');
                    exporttables(app, savepath, ftctable, 'tuning');
                    exporttables(app, savepath, data.fratable, 'fra');
                    
            fname = [savepath '/' app.PrefixEdit.Value, 'fra.xlsx'];
            d = datestr(now, 'yymmdd-HHMMSS');
            if app.fraCheck.Value
                fra = app.data.fra;
                if exist(fname, 'file')
                    for i = 1:size(fra, 1)
                        xlswrite([savepath '/' app.PrefixEdit.Value 'fra' d '.xlsx'], squeeze(fra(i,:,:)), i)
                    end
                elseif exist(savepath, 'dir')
                    for i = 1:size(fra, 1)
                        xlswrite(fname, squeeze(fra(i,:,:)), i)
                    end
                else
                    mkdir(savepath)
                    for i = 1:size(fra, 1)
                        xlswrite(fname, squeeze(fra(i,:,:)), i)
                    end
                end
            end
                    
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StimPanel.Visible = 0;
            app.CyclePSTHPanel.Visible = 0;
            app.ExpDurationRadio.Visible = 0;
            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.syn = SynapseAPI();
            updatemode(app);
            app.ExperimentDrop.Items = sort(app.syn.getKnownExperiments);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            livestreamapp7(app, 'Preview')
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            livestreamapp7(app, 'Record')
        end

        % Button pushed function: IdleButton
        function IdleButtonPushed(app, event)
            app.syn.setMode(0);
            app.StimPanel.Visible = 0; app.ExpDurationRadio.Visible = 0;
            updatemode(app);
        end

        % Button pushed function: ExpButton1
        function ExpButton1Pushed(app, event)
            if app.syn.getMode > 0
               app.syn.setMode(0);
            end
            app.DialogueLabel.Text = 'Loading Experiment'; pause(.1);
            app.syn.setCurrentExperiment(app.ExperimentDrop.Value);
            
            c = clock();
            d = [num2str(mod(c(1), 100)) sprintf('%02d', c(2)), sprintf('%02d', c(3))];
            t = [sprintf('%02d', c(4)) sprintf('%02d', c(5))];
            blockname = [sprintf('%03d', app.BlockEdit.Value) '-' app.syn.getCurrentExperiment() '-' d '-' t];
            if length(blockname)>31
                app.DialogueLabel.Text = 'Yo blockname is too long (max 31 characters)';
                error('Yo blockname is too long (max 31 characters)')
            end
            app.syn.setCurrentBlock(blockname);

            app.syn.setMode(1);
            try
                app.syn.setParameterValue('xDMR_audio', 'setflag', 0);
            end
            updatemode(app); readsynpars(app);
            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: TankButton
        function TankButtonPushed(app, event)
        %User chooses a tank to load
            [filepath, ~, ~] = fileparts(app.TankEdit.Value);
            if isempty(filepath)
                tankpath = uigetdir();
            else
                tankpath = uigetdir(filepath);
            end
            if tankpath ~= 0
                app.TankEdit.Value = tankpath;
            end
            figure(app.UIFigure);
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
        %Load the tank and update gui
            loadtank(app)
             app.DialogueLabel.Text = 'Ready';


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
                synuiplotting(app)
                checkstim(app)
        end

        % Value changed function: Chan116Button
        function Chan116ButtonValueChanged(app, event)
            value = app.Chan116Button.Value;
            if value
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan116Button.Text = 'Clear 1-16';
            else
                for chan = 1:16
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end                
                app.Chan116Button.Text = 'Check 1-16';
            end
        end

        % Value changed function: Chan1732Button
        function Chan1732ButtonValueChanged(app, event)
            value = app.Chan1732Button.Value;
            if value
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 1;
                end
                app.Chan1732Button.Text = 'Clear 17-32';
            else
                for chan = 17:32
                    chanfield = ['CheckBox_' num2str(chan)];
                    app.(chanfield).Value = 0;
                end
                app.Chan1732Button.Text = 'Check 17-32';
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            savepath = uigetdir();
            figure(app.UIFigure);
            makeandexport(app, savepath)
            if app.stimsCheck.Value
                fname = [savepath '/' app.PrefixEdit.Value, 'stims'];
                d = datestr(now, 'yymmdd-HHMMSS');
                stimstab = app.data.stimstable;
                figh = figure(1); clf(figh);
                plot((1:numel(app.data.stimsig))./app.data.stimfs, app.data.stimsig, 'k-');
                if exist([fname '.csv'], 'file')
                    writetable(app.data.stimstable, [fname d '.csv'])
                    print(figh, [fname d], '-dpng')
                elseif exist(savepath, 'dir')
                    writetable(app.data.stimstable, [fname '.csv'])
                    print(figh, fname, '-dpng')
                else
                    mkdir(savepath)
                    writetable(app.data.stimstable, [fname '.csv'])
                    print(figh, fname, '-dpng')
                end
            end
            if app.ExpParCheck.Value
                fnames = fieldnames(app);
                efi = [];
                parvals = [];
                c = 1;
                for i = 1:numel(fnames)
                    fi = char(fnames(i));
                    if isprop(app.(fi), 'Value')
                        efi = [efi;i];
                        parvals{c,1} = app.(fi).Value;
                        c = c +1;
                    end
                end
                parfields = fnames(efi);
                parmat = [parfields parvals];
                
                fname = [savepath '/' app.PrefixEdit.Value, 'params.mat'];
                d = datestr(now, 'yymmdd-HHMMSS');
                if exist(fname, 'file')
                    save([savepath '/' app.PrefixEdit.Value 'params' d '.mat'], 'parmat')
                elseif exist(savepath, 'dir')
                    save(fname, 'parmat')
                else
                    mkdir(savepath)
                    save(fname, 'parmat')
                end
            end
            figure(app.UIFigure);

        end

        % Button pushed function: LoadThresholdsButton
        function LoadThresholdsButtonPushed(app, event)
            [threshfile, threshpath, filt] = uigetfile();
            if filt
                load([threshpath threshfile]);
                if exist('threshlist', 'var')
                    for i = 1:numel(threshlist)
                        app.syn.setParameterValue('Neu1', ['Threshold' num2str(i)], threshlist(i));
                    end
                end
            end
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            for par = 1:numel(app.pars)
                mvarname = ['Param' num2str(par) 'Edit'];
                mvarname2 = ['Param' num2str(par) 'Label'];
                app.(mvarname).Value= str2double(app.(mvarname2).Text);
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            for par = 1:numel(app.pars)
                mvarname = ['Param' num2str(par) 'Edit'];
                app.syn.setParameterValue(char(app.gizmos(par)), char(app.pars(par)), app.(mvarname).Value);
            end
%             if strcmpi(prefix, 'iso')
%                 app.syn.setParameterValue('Stimulus', 'GateHiTime', app.Par3Edit.Value*(app.Param1Edit.Value + app.Par2Edit.Value));
%             end
            pause(1);
            readsynpars(app);
        end

        % Value changed function: LDSBWEdit, LDSCFreqEdit
        function CenterFreqEditValueChanged(app, event)
            value = app.LDSCFreqEdit.Value;
            LPCFi = find(ismember(app.pars, 'LPCF'));
            HPCFi = find(ismember(app.pars, 'HPCF'));
            LPCFfield = ['Param' num2str(LPCFi) 'Edit'];
            HPCFfield = ['Param' num2str(HPCFi) 'Edit'];
            app.(LPCFfield).Value = 1000 * app.LDSCFreqEdit.Value * 2 ^ (app.LDSBWEdit.Value / 2);
            app.(HPCFfield).Value = 1000 * app.LDSCFreqEdit.Value * 2 ^ ((app.LDSBWEdit.Value) / -2 );
            rms2amp2(app);
        end

        % Value changed function: LDSLevelEdit
        function LDSLevelEditValueChanged(app, event)
            rms2amp2(app)
        end

        % Value changed function: Param1Edit
        function Param1EditValueChanged(app, event)
            make_pars_userfriendly(app, 1)           
        end

        % Value changed function: Param2Edit
        function Param2EditValueChanged(app, event)
            make_pars_userfriendly(app, 2)
        end

        % Value changed function: Param3Edit
        function Param3EditValueChanged(app, event)
            make_pars_userfriendly(app, 3)
        end

        % Value changed function: Param4Edit
        function Param4EditValueChanged(app, event)
            make_pars_userfriendly(app, 4)
        end

        % Value changed function: Param5Edit
        function Param5EditValueChanged(app, event)
            make_pars_userfriendly(app, 5)
        end

        % Value changed function: Param6Edit
        function Param6EditValueChanged(app, event)
            make_pars_userfriendly(app, 6)
        end

        % Value changed function: Param7Edit
        function Param7EditValueChanged(app, event)
            make_pars_userfriendly(app, 7)
        end

        % Value changed function: Param8Edit
        function Param8EditValueChanged(app, event)
            make_pars_userfriendly(app, 8)
        end

        % Value changed function: Param9Edit
        function Param9EditValueChanged(app, event)
            make_pars_userfriendly(app, 9)
        end

        % Value changed function: Param10Edit
        function Param10EditValueChanged(app, event)
            make_pars_userfriendly(app, 10)
        end

        % Value changed function: Param11Edit
        function Param11EditValueChanged(app, event)
            make_pars_userfriendly(app, 11)
        end

        % Value changed function: Param12Edit
        function Param12EditValueChanged(app, event)
            make_pars_userfriendly(app, 12)
        end

        % Button pushed function: ClustersButton
        function ClustersButtonPushed(app, event)
            file = app.ClustEdit.Value;
            if isempty(file)
                [filename pathname]= uigetfile('');
            else
                [filename pathname] = uigetfile(file);
            end
            figure(app.UIFigure);
            if filename ~= 0
                app.ClustEdit.Value = [pathname filename];
                temp = load(app.ClustEdit.Value);
                app.data.spets = temp.spikes;
                app.data.clusters = temp.clusters;
                app.data.cluster = temp.cluster;
                app.data.peakChannel = temp.peakChannel;
                app.ClustsCheck.Value = 1;
            end

        end

        % Button pushed function: ExpParButton
        function ExpParButtonPushed(app, event)
            
            fnames = fieldnames(app);
            efi = [];
            parvals = [];
            c = 1;
            for i = 1:numel(fnames)
                fi = char(fnames(i));
                if isprop(app.(fi), 'Value')
                    efi = [efi;i];
                    parvals{c,1} = app.(fi).Value;
                    c = c +1;
                end
            end
            parfields = fnames(efi);
            parmat = [parfields parvals];
            [savefile, savepath] = uiputfile('.mat', 'Save Params as...');
            if savefile ~= 0
                save([savepath, savefile], 'parmat');
            end
            figure(app.UIFigure);

        end

        % Button pushed function: LoadParButton
        function LoadParButtonPushed(app, event)
            file = app.LoadParEdit.Value;
            if isempty(file)
                [filename, pathname]= uigetfile('');
            else
                [filename, pathname] = uigetfile(file);
            end
            figure(app.UIFigure);
            if filename ~= 0
                app.LoadParEdit.Value = [pathname filename];
                updatesynuipars(app)
            end
        end

        % Button pushed function: BatchButton
        function BatchButtonPushed(app, event)
            file = app.BatchEdit.Value;
            if isempty(file)
                [filename pathname]= uigetfile('*.xlsx', 'Choose Batch');
            else
                [filename pathname] = uigetfile('*.xlsx', 'Choose Batch', file);
            end
            app.BatchEdit.Value = [pathname filename];
            [~,batchlist,~] = xlsread(app.BatchEdit.Value);
            figure(app.UIFigure);
            for i = 2:size(batchlist, 1)
                disp(['file ' num2str(i-1) '/' num2str(size(batchlist, 1)-1)])
                app.DialogueLabel.Text = 'Loading Data';
                pause(.1)
                app.TankEdit.Value = char(batchlist(i,1));
                loadtank(app)
                app.ClustEdit.Value = char(batchlist(i,2));
                if ~isempty(char(batchlist(i,2)))
                    temp = load(app.ClustEdit.Value);
                    app.data.spets = temp.spikes;
                    app.data.clusters = temp.clusters;
                    app.data.cluster = temp.cluster;
                    app.data.peakChannel = temp.peakChannel;
                    app.ClustsCheck.Value = 1;
                end
                app.LoadParEdit.Value = char(batchlist(i,3));
                updatesynuipars(app)
                close all;
                synuiplotting(app)
                [savepath,fname,~] = fileparts(char(batchlist(i,4)));
                app.PrefixEdit.Value = fname;
                makeandexport(app, savepath)
                
                if app.ExpParCheck.Value
                    fnames = fieldnames(app);
                    efi = [];
                    parvals = [];
                    c = 1;
                    for i = 1:numel(fnames)
                        fi = char(fnames(i));
                        if isprop(app.(fi), 'Value')
                            efi = [efi;i];
                            parvals{c,1} = app.(fi).Value;
                            c = c +1;
                        end
                    end
                    parfields = fnames(efi);
                    parmat = [parfields parvals];
                    
                    fname = [savepath '/' app.PrefixEdit.Value, 'params.mat'];
                    d = datestr(now, 'yymmdd-HHMMSS');
                    if exist(fname, 'file')
                        save([savepath '/' app.PrefixEdit.Value 'params' d '.mat'], 'parmat')
                    elseif exist(savepath, 'dir')
                        save(fname, 'parmat')
                    else
                        mkdir(savepath)
                        save(fname, 'parmat')
                    end
                end
                
                figHandles = findobj('Type', 'figure');
                for figi = 1:numel(figHandles)
                    print(figHandles(figi), [char(batchlist(i,4)) '-fig', num2str(figHandles(figi).Number)], '-dpng')
                end
            end
        end

        % Value changed function: ExpDurationEdit
        function ExpDurationEditValueChanged(app, event)
            for par = 1:numel(app.pars)
                mvarname = ['Param' num2str(par) 'Edit'];
                app.syn.setParameterValue(char(app.gizmos(par)), char(app.pars(par)), app.(mvarname).Value);
            end
            pause(1);  %delay needed to for synapse API communication
            readsynpars(app);
        end

        % Selection changed function: ExpDurationRadio
        function ExpDurationRadioSelectionChanged(app, event)
            for par = 1:numel(app.pars)
                mvarname = ['Param' num2str(par) 'Edit'];
                app.syn.setParameterValue(char(app.gizmos(par)), char(app.pars(par)), app.(mvarname).Value);
            end
            pause(1);  %delay needed to for synapse API communication
            readsynpars(app);
        end

        % Value changed function: PSTHCycleCheck
        function PSTHCycleCheckValueChanged(app, event)
            value = app.PSTHCycleCheck.Value;
            if value
                app.CyclePSTHPanel.Visible = 1;
                app.PSTHButton.Visible = 1;
                app.SubtractionCheck.Visible = 1;
            else
                app.CyclePSTHPanel.Visible = 0;
                app.PSTHButton.Visible = 0;
                app.SubtractionCheck.Visible = 0;
            end
        end

        % Value changed function: Param13Edit
        function Param13EditValueChanged(app, event)
            make_pars_userfriendly(app, 13)
        end

        % Value changed function: Param14Edit
        function Param14EditValueChanged(app, event)
            make_pars_userfriendly(app, 14)
        end

        % Value changed function: Param15Edit
        function Param15EditValueChanged(app, event)
            make_pars_userfriendly(app, 15)            
        end

        % Value changed function: Param16Edit
        function Param16EditValueChanged(app, event)
            make_pars_userfriendly(app, 16)
            
        end

        % Value changed function: Param17Edit
        function Param17EditValueChanged(app, event)
            make_pars_userfriendly(app, 17)
            
        end

        % Value changed function: Param18Edit
        function Param18EditValueChanged(app, event)
            make_pars_userfriendly(app, 18)
        end

        % Value changed function: PulseDurEdit
        function PulseDurEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'StimHiMS'))) 'Edit'];
            app.(editfield).Value = app.PulseDurEdit.Value - app.RampEdit.Value * 1.25;
            editfield = ['Param' num2str(find(ismember(app.pars, 'StimLoMS'))) 'Edit'];
            app.(editfield).Value = 1000/app.PulseRateEdit.Value - app.PulseDurEdit.Value + app.RampEdit.Value * 1.25;
        end

        % Value changed function: PulseRateEdit
        function PulseRateEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'StimLoMS'))) 'Edit'];
            app.(editfield).Value = 1000/app.PulseRateEdit.Value - app.PulseDurEdit.Value + app.RampEdit.Value * 1.25;
        end

        % Value changed function: RampEdit
        function RampEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'StimHiMS'))) 'Edit'];
            app.(editfield).Value = app.PulseDurEdit.Value - app.RampEdit.Value * 1.25;
            editfield = ['Param' num2str(find(ismember(app.pars, 'StimLoMS'))) 'Edit'];
            app.(editfield).Value = 1000/app.PulseRateEdit.Value - app.PulseDurEdit.Value + app.RampEdit.Value * 1.25;            
            editfield = ['Param' num2str(find(ismember(app.pars, 'RiseFallMS'))) 'Edit'];
            app.(editfield).Value = app.RampEdit.Value;            

        end

        % Value changed function: TrainOnEdit
        function TrainOnEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'TrainDurationMS'))) 'Edit'];
            app.(editfield).Value = app.TrainOnEdit.Value*1000;
        end

        % Value changed function: TrainOffEdit
        function TrainOffEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'TrainGapMS'))) 'Edit'];
            app.(editfield).Value = app.TrainOffEdit.Value*1000;
        end

        % Value changed function: TrainRepeatsEdit
        function TrainRepeatsEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'TrainRepeats'))) 'Edit'];
            app.(editfield).Value = app.TrainRepeatsEdit.Value;
        end

        % Value changed function: StimFreqEdit
        function StimFreqEditValueChanged(app, event)
            if sum(ismember(app.pars, 'StimHPCF'))
                StimLPCFi = find(ismember(app.pars, 'StimLPCF'));
                StimHPCFi = find(ismember(app.pars, 'StimHPCF'));
                StimLPCFfield = ['Param' num2str(StimLPCFi) 'Edit'];
                StimHPCFfield = ['Param' num2str(StimHPCFi) 'Edit'];
                app.(StimLPCFfield).Value = 1000 * app.StimFreqEdit.Value * 2 ^ (app.StimBWEdit.Value / 2);
                app.(StimHPCFfield).Value = 1000 * app.StimFreqEdit.Value * 2 ^ ((app.StimBWEdit.Value) / -2 );
            elseif sum(ismember(app.pars, 'StimOctave1'))
                Oct1i = find(ismember(app.pars, 'StimOctave1'));
                Oct2i = find(ismember(app.pars, 'StimOctave2'));
                Oct1field = ['Param' num2str(Oct1i) 'Edit'];
                Oct2field = ['Param' num2str(Oct2i) 'Edit'];
                app.(Oct2field).Value = log2(app.StimFreqEdit.Value*1E3) + (app.StimBWEdit.Value / 2);
                app.(Oct1field).Value = log2(app.StimFreqEdit.Value*1E3) - (app.StimBWEdit.Value / 2);
            else
                editfield = ['Param' num2str(find(ismember(app.pars, 'StimFrequencyHz'))) 'Edit'];
                app.(editfield).Value = app.StimFreqEdit.Value*1000;
            end
            rms2amp2(app);
        end

        % Value changed function: StimBWEdit
        function StimBWEditValueChanged(app, event)
            if sum(ismember(app.pars, 'StimHPCF'))
                StimLPCFi = find(ismember(app.pars, 'StimLPCF'));
                StimHPCFi = find(ismember(app.pars, 'StimHPCF'));
                StimLPCFfield = ['Param' num2str(StimLPCFi) 'Edit'];
                StimHPCFfield = ['Param' num2str(StimHPCFi) 'Edit'];
                app.(StimLPCFfield).Value = 1000 * app.StimFreqEdit.Value * 2 ^ (app.StimBWEdit.Value / 2);
                app.(StimHPCFfield).Value = 1000 * app.StimFreqEdit.Value * 2 ^ ((app.StimBWEdit.Value) / -2 );
            elseif sum(ismember(app.pars, 'StimOctave1'))
                Oct1i = find(ismember(app.pars, 'StimOctave1'));
                Oct2i = find(ismember(app.pars, 'StimOctave2'));
                Oct1field = ['Param' num2str(Oct1i) 'Edit'];
                Oct2field = ['Param' num2str(Oct2i) 'Edit'];
                app.(Oct2field).Value = log2(app.StimFreqEdit.Value*1E3) + (app.StimBWEdit.Value / 2);
                app.(Oct1field).Value = log2(app.StimFreqEdit.Value*1E3) - (app.StimBWEdit.Value / 2);
                sweepspeed = 1.5; % oct/ms
                editfield = ['Param' num2str(find(ismember(app.pars, 'StimHiMS'))) 'Edit'];
                app.(editfield).Value = app.StimBWEdit.Value * sweepspeed - app.RampEdit.Value * 1.25;
                editfield = ['Param' num2str(find(ismember(app.pars, 'StimLoMS'))) 'Edit'];
                app.(editfield).Value = 1000/app.PulseRateEdit.Value - app.StimBWEdit.Value * sweepspeed  + app.RampEdit.Value * 1.25;
                
                
            end
            rms2amp2(app);

        end

        % Value changed function: StimLevelEdit
        function StimLevelEditValueChanged(app, event)
%             editfield = ['Param' num2str(find(ismember(app.pars, 'StimAmplitude'))) 'Edit'];
%             app.(editfield).Value = 10^(app.StimLevelEdit.Value/20);
            rms2amp2(app);
        end

        % Value changed function: LDSDurEdit
        function LDSDurEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'LDSDurationMS'))) 'Edit'];
            app.(editfield).Value = app.LDSDurEdit.Value*1000;
        end

        % Value changed function: LDSPostGapEdit
        function LDSPostGapEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'PostLDSGapMS'))) 'Edit'];
            app.(editfield).Value = app.LDSPostGapEdit.Value*1000;
        end

        % Value changed function: LDSModDepthEdit
        function LDSModDepthEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'ModDepth'))) 'Edit'];
            app.(editfield).Value = app.LDSModDepthEdit.Value/100;
            rms2amp2(app);
        end

        % Value changed function: LDSModFreqEdit
        function LDSModFreqEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'ModFrequencyHz'))) 'Edit'];
            app.(editfield).Value = app.LDSModFreqEdit.Value; 
        end

        % Value changed function: LDSModExpEdit
        function LDSModExpEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'ModExponent'))) 'Edit'];
            app.(editfield).Value = app.LDSModExpEdit.Value;
            rms2amp2(app);
        end

        % Value changed function: LDSPreEdit
        function LDSPreEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'LDSPreMS'))) 'Edit'];
            app.(editfield).Value = app.LDSPreEdit.Value*1000; 
        end

        % Value changed function: LDSISIEdit
        function LDSISIEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'LDSISIMS'))) 'Edit'];
            app.(editfield).Value = app.LDSISIEdit.Value*1000; 
        end

        % Value changed function: LDSRepeatsEdit
        function LDSRepeatsEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'LDSRepeats'))) 'Edit'];
            app.(editfield).Value = app.LDSRepeatsEdit.Value; 
        end

        % Value changed function: Stim2FreqEdit
        function Stim2FreqEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'Stim2FrequencyHz'))) 'Edit'];
            app.(editfield).Value = app.Stim2FreqEdit.Value*1000;
        end

        % Value changed function: Stim2LevelEdit
        function Stim2LevelEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'Stim2Amplitude'))) 'Edit'];
            app.(editfield).Value = 10^(app.Stim2LevelEdit.Value/20);
        end

        % Value changed function: StimModFreqEdit
        function StimModFreqEditValueChanged(app, event)
            editfield = ['Param' num2str(find(ismember(app.pars, 'StimModFrequencyHz'))) 'Edit'];
            app.(editfield).Value = app.StimModFreqEdit.Value; 
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(key, 'divide')
                app.breakflag = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.DialogueLabel = uilabel(app.UIFigure);
            app.DialogueLabel.Text = 'Dialogue';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SynapseTab = uitab(app.TabGroup);
            app.SynapseTab.Title = 'Synapse';
            app.ExpDurationRadio = uibuttongroup(app.SynapseTab);
            app.ExpDurationRadio.SelectionChangedFcn = createCallbackFcn(app, @ExpDurationRadioSelectionChanged, true);
            app.ExpDurationRadio.Title = 'Set to Idle After...';
            app.InfiniteButton = uiradiobutton(app.ExpDurationRadio);
            app.InfiniteButton.Text = 'Infinite';
            app.InfiniteButton.Value = true;
            app.LastoffsetnsecButton = uiradiobutton(app.ExpDurationRadio);
            app.LastoffsetnsecButton.Text = 'Last offset + n sec';
            app.SecondsButton = uiradiobutton(app.ExpDurationRadio);
            app.SecondsButton.Text = 'n Seconds';
            app.nLabel = uilabel(app.ExpDurationRadio);
            app.nLabel.Text = 'n =';
            app.ExpDurationEdit = uieditfield(app.ExpDurationRadio, 'numeric');
            app.ExpDurationEdit.ValueChangedFcn = createCallbackFcn(app, @ExpDurationEditValueChanged, true);
            app.ExpDurationEdit.Value = 60;
            app.TotalStimDurLabel = uilabel(app.ExpDurationRadio);
            app.TotalStimDurLabel.Text = 'Duration:';
            app.StimDurLabel = uilabel(app.ExpDurationRadio);
            app.StimDurLabel.Text = '';
            app.StimAxes = uiaxes(app.ExpDurationRadio);
            title(app.StimAxes, 'Stim Preview')
            xlabel(app.StimAxes, 'Time (seconds)')
            ylabel(app.StimAxes, 'Y')
            app.IdleButton = uibutton(app.SynapseTab, 'push');
            app.IdleButton.ButtonPushedFcn = createCallbackFcn(app, @IdleButtonPushed, true);
            app.IdleButton.Text = 'Idle';
            app.PreviewButton = uibutton(app.SynapseTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.RecordButton = uibutton(app.SynapseTab, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.CurrentModeLabel = uilabel(app.SynapseTab);
            app.CurrentModeLabel.Text = 'Current Mode';
            app.ExperimentDrop = uidropdown(app.SynapseTab);
            app.ExpButton1 = uibutton(app.SynapseTab, 'push');
            app.ExpButton1.ButtonPushedFcn = createCallbackFcn(app, @ExpButton1Pushed, true);
            app.ExpButton1.Text = 'Load';
            app.BlockEditFieldLabel = uilabel(app.SynapseTab);
            app.BlockEditFieldLabel.Text = 'Block';
            app.BlockEdit = uieditfield(app.SynapseTab, 'numeric');
            app.BlockEdit.Value = 1;
            app.PollingsecLabel = uilabel(app.SynapseTab);
            app.PollingsecLabel.Text = 'Polling (sec)';
            app.UpdateEdit = uieditfield(app.SynapseTab, 'numeric');
            app.UpdateEdit.Value = 1;
            app.ConnectButton = uibutton(app.SynapseTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StimPanel = uitabgroup(app.SynapseTab);
            app.SoundTab = uitab(app.StimPanel);
            app.SoundTab.Title = 'Sound';
            app.StimulusPanel = uipanel(app.SoundTab);
            app.StimulusPanel.Title = 'Stimulus';
            app.PulseDurationmsLabel = uilabel(app.StimulusPanel);
            app.PulseDurationmsLabel.Text = 'Pulse Duration (ms)';
            app.PulseDurEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.PulseDurEdit.ValueChangedFcn = createCallbackFcn(app, @PulseDurEditValueChanged, true);
            app.PulserateHzLabel = uilabel(app.StimulusPanel);
            app.PulserateHzLabel.Text = 'Pulse rate (Hz)';
            app.PulseRateEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.PulseRateEdit.ValueChangedFcn = createCallbackFcn(app, @PulseRateEditValueChanged, true);
            app.TrainOnLabel = uilabel(app.StimulusPanel);
            app.TrainOnLabel.Text = 'Train on (sec)';
            app.TrainOnEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.TrainOnEdit.ValueChangedFcn = createCallbackFcn(app, @TrainOnEditValueChanged, true);
            app.TrainOffLabel = uilabel(app.StimulusPanel);
            app.TrainOffLabel.Text = 'Train off (sec)';
            app.TrainOffEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.TrainOffEdit.ValueChangedFcn = createCallbackFcn(app, @TrainOffEditValueChanged, true);
            app.TrainRepeatsLabel = uilabel(app.StimulusPanel);
            app.TrainRepeatsLabel.Text = 'Number of Trains';
            app.TrainRepeatsEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.TrainRepeatsEdit.ValueChangedFcn = createCallbackFcn(app, @TrainRepeatsEditValueChanged, true);
            app.StimFreqLabel = uilabel(app.StimulusPanel);
            app.StimFreqLabel.Text = 'Frequency (kHz)';
            app.StimFreqEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.StimFreqEdit.ValueChangedFcn = createCallbackFcn(app, @StimFreqEditValueChanged, true);
            app.StimBWLabel = uilabel(app.StimulusPanel);
            app.StimBWLabel.Text = 'Bandwidth (8ves)';
            app.StimBWEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.StimBWEdit.ValueChangedFcn = createCallbackFcn(app, @StimBWEditValueChanged, true);
            app.StimLevelLabel = uilabel(app.StimulusPanel);
            app.StimLevelLabel.Text = ' Level (rms dB)';
            app.StimLevelEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.StimLevelEdit.ValueChangedFcn = createCallbackFcn(app, @StimLevelEditValueChanged, true);
            app.StimModFreqLabel = uilabel(app.StimulusPanel);
            app.StimModFreqLabel.Text = 'Mod Freq (Hz)';
            app.StimModFreqEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.StimModFreqEdit.ValueChangedFcn = createCallbackFcn(app, @StimModFreqEditValueChanged, true);
            app.Stim2FreqLabel = uilabel(app.StimulusPanel);
            app.Stim2FreqLabel.Text = 'Frequency 2 (kHz)';
            app.Stim2FreqEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.Stim2FreqEdit.ValueChangedFcn = createCallbackFcn(app, @Stim2FreqEditValueChanged, true);
            app.Stim2LevelLabel = uilabel(app.StimulusPanel);
            app.Stim2LevelLabel.Text = ' Level 2 (rms dB)';
            app.Stim2LevelEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.Stim2LevelEdit.ValueChangedFcn = createCallbackFcn(app, @Stim2LevelEditValueChanged, true);
            app.RiseFallmsLabel = uilabel(app.StimulusPanel);
            app.RiseFallmsLabel.Text = 'Rise/Fall (ms)';
            app.RampEdit = uieditfield(app.StimulusPanel, 'numeric');
            app.RampEdit.ValueChangedFcn = createCallbackFcn(app, @RampEditValueChanged, true);
            app.LDSPanel = uipanel(app.SoundTab);
            app.LDSPanel.Title = 'LDS';
            app.LDSPreLabel = uilabel(app.LDSPanel);
            app.LDSPreLabel.Text = 'Pre-LDS (sec)';
            app.LDSPreEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSPreEdit.ValueChangedFcn = createCallbackFcn(app, @LDSPreEditValueChanged, true);
            app.LDSDurLabel = uilabel(app.LDSPanel);
            app.LDSDurLabel.Text = 'Duration (sec)';
            app.LDSDurEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSDurEdit.ValueChangedFcn = createCallbackFcn(app, @LDSDurEditValueChanged, true);
            app.LDSISILabel = uilabel(app.LDSPanel);
            app.LDSISILabel.Text = 'ISI (sec)';
            app.LDSISIEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSISIEdit.ValueChangedFcn = createCallbackFcn(app, @LDSISIEditValueChanged, true);
            app.LDSRepeatsLabel = uilabel(app.LDSPanel);
            app.LDSRepeatsLabel.Text = 'Repeats';
            app.LDSRepeatsEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSRepeatsEdit.ValueChangedFcn = createCallbackFcn(app, @LDSRepeatsEditValueChanged, true);
            app.LDSPostGapLabel = uilabel(app.LDSPanel);
            app.LDSPostGapLabel.Text = 'Post-LDS Gap(sec)';
            app.LDSPostGapEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSPostGapEdit.ValueChangedFcn = createCallbackFcn(app, @LDSPostGapEditValueChanged, true);
            app.LDSCFreqLabel = uilabel(app.LDSPanel);
            app.LDSCFreqLabel.Text = 'Center Freq (kHz)';
            app.LDSCFreqEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSCFreqEdit.ValueChangedFcn = createCallbackFcn(app, @CenterFreqEditValueChanged, true);
            app.LDSBWLabel = uilabel(app.LDSPanel);
            app.LDSBWLabel.Text = 'Bandwidth (8ves)';
            app.LDSBWEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSBWEdit.ValueChangedFcn = createCallbackFcn(app, @CenterFreqEditValueChanged, true);
            app.LDSLevelLabel = uilabel(app.LDSPanel);
            app.LDSLevelLabel.Text = 'Level (rms dB)';
            app.LDSLevelEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSLevelEdit.ValueChangedFcn = createCallbackFcn(app, @LDSLevelEditValueChanged, true);
            app.LDSModDepthLabel = uilabel(app.LDSPanel);
            app.LDSModDepthLabel.Text = 'Mod Depth (%)';
            app.LDSModDepthEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSModDepthEdit.ValueChangedFcn = createCallbackFcn(app, @LDSModDepthEditValueChanged, true);
            app.LDSModFreqLabel = uilabel(app.LDSPanel);
            app.LDSModFreqLabel.Text = 'Mod Freq (Hz)';
            app.LDSModFreqEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSModFreqEdit.ValueChangedFcn = createCallbackFcn(app, @LDSModFreqEditValueChanged, true);
            app.LDSModExpLabel = uilabel(app.LDSPanel);
            app.LDSModExpLabel.Text = 'Mod Exponent';
            app.LDSModExpEdit = uieditfield(app.LDSPanel, 'numeric');
            app.LDSModExpEdit.ValueChangedFcn = createCallbackFcn(app, @LDSModExpEditValueChanged, true);
            app.SynapseTab_2 = uitab(app.StimPanel);
            app.SynapseTab_2.Title = 'Synapse';
            app.Param1Label = uilabel(app.SynapseTab_2);
            app.Param1Label.Text = 'Param1';
            app.Param2Label = uilabel(app.SynapseTab_2);
            app.Param2Label.Text = 'Param2';
            app.Param3Label = uilabel(app.SynapseTab_2);
            app.Param3Label.Text = 'Param3';
            app.Param4Label = uilabel(app.SynapseTab_2);
            app.Param4Label.Text = 'Param4';
            app.Param5Label = uilabel(app.SynapseTab_2);
            app.Param5Label.Text = 'Param5';
            app.Param6Label = uilabel(app.SynapseTab_2);
            app.Param6Label.Text = 'Param6';
            app.Param7Label = uilabel(app.SynapseTab_2);
            app.Param7Label.Text = 'Param7';
            app.Param8Label = uilabel(app.SynapseTab_2);
            app.Param8Label.Text = 'Param8';
            app.Param9Label = uilabel(app.SynapseTab_2);
            app.Param9Label.Text = 'Param9';
            app.Param10Label = uilabel(app.SynapseTab_2);
            app.Param10Label.Text = 'Param10';
            app.Param11Label = uilabel(app.SynapseTab_2);
            app.Param11Label.Text = 'Param11';
            app.Param12Label = uilabel(app.SynapseTab_2);
            app.Param12Label.Text = 'Param12';
            app.Param13Label = uilabel(app.SynapseTab_2);
            app.Param13Label.Text = 'Param13';
            app.Param14Label = uilabel(app.SynapseTab_2);
            app.Param14Label.Text = 'Param14';
            app.Param15Label = uilabel(app.SynapseTab_2);
            app.Param15Label.Text = 'Param15';
            app.Par1Label = uilabel(app.SynapseTab_2);
            app.Par1Label.Text = 'Par1';
            app.Param1Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param1Edit.ValueChangedFcn = createCallbackFcn(app, @Param1EditValueChanged, true);
            app.Par2Label = uilabel(app.SynapseTab_2);
            app.Par2Label.Text = 'Par2';
            app.Param2Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param2Edit.ValueChangedFcn = createCallbackFcn(app, @Param2EditValueChanged, true);
            app.Par3Label = uilabel(app.SynapseTab_2);
            app.Par3Label.Text = 'Par3';
            app.Param3Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param3Edit.ValueChangedFcn = createCallbackFcn(app, @Param3EditValueChanged, true);
            app.Par4Label = uilabel(app.SynapseTab_2);
            app.Par4Label.Text = 'Par4';
            app.Param4Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param4Edit.ValueChangedFcn = createCallbackFcn(app, @Param4EditValueChanged, true);
            app.Par5Label = uilabel(app.SynapseTab_2);
            app.Par5Label.Text = 'Par5';
            app.Param5Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param5Edit.ValueChangedFcn = createCallbackFcn(app, @Param5EditValueChanged, true);
            app.Par6Label = uilabel(app.SynapseTab_2);
            app.Par6Label.Text = 'Par6';
            app.Param6Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param6Edit.ValueChangedFcn = createCallbackFcn(app, @Param6EditValueChanged, true);
            app.Par7Label = uilabel(app.SynapseTab_2);
            app.Par7Label.Text = 'Par7';
            app.Param7Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param7Edit.ValueChangedFcn = createCallbackFcn(app, @Param7EditValueChanged, true);
            app.Par8Label = uilabel(app.SynapseTab_2);
            app.Par8Label.Text = 'Par8';
            app.Param8Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param8Edit.ValueChangedFcn = createCallbackFcn(app, @Param8EditValueChanged, true);
            app.Par9Label = uilabel(app.SynapseTab_2);
            app.Par9Label.Text = 'Par9';
            app.Param9Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param9Edit.ValueChangedFcn = createCallbackFcn(app, @Param9EditValueChanged, true);
            app.Par10Label = uilabel(app.SynapseTab_2);
            app.Par10Label.Text = 'Par10';
            app.Param10Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param10Edit.ValueChangedFcn = createCallbackFcn(app, @Param10EditValueChanged, true);
            app.Par11Label = uilabel(app.SynapseTab_2);
            app.Par11Label.Text = 'Par11';
            app.Param11Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param11Edit.ValueChangedFcn = createCallbackFcn(app, @Param11EditValueChanged, true);
            app.Par12Label = uilabel(app.SynapseTab_2);
            app.Par12Label.Text = 'Par12';
            app.Param12Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param12Edit.ValueChangedFcn = createCallbackFcn(app, @Param12EditValueChanged, true);
            app.Par13Label = uilabel(app.SynapseTab_2);
            app.Par13Label.Text = 'Par13';
            app.Param13Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param13Edit.ValueChangedFcn = createCallbackFcn(app, @Param13EditValueChanged, true);
            app.Par14Label = uilabel(app.SynapseTab_2);
            app.Par14Label.Text = 'Par14';
            app.Param14Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param14Edit.ValueChangedFcn = createCallbackFcn(app, @Param14EditValueChanged, true);
            app.Par15Label = uilabel(app.SynapseTab_2);
            app.Par15Label.Text = 'Par15';
            app.Param15Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param15Edit.ValueChangedFcn = createCallbackFcn(app, @Param15EditValueChanged, true);
            app.Param16Label = uilabel(app.SynapseTab_2);
            app.Param16Label.Text = 'Param16';
            app.Par16Label = uilabel(app.SynapseTab_2);
            app.Par16Label.Text = 'Par16';
            app.Param16Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param16Edit.ValueChangedFcn = createCallbackFcn(app, @Param16EditValueChanged, true);
            app.Param17Label = uilabel(app.SynapseTab_2);
            app.Param17Label.Text = 'Param17';
            app.Par17Label = uilabel(app.SynapseTab_2);
            app.Par17Label.Text = 'Par17';
            app.Param17Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param17Edit.ValueChangedFcn = createCallbackFcn(app, @Param17EditValueChanged, true);
            app.Param18Label = uilabel(app.SynapseTab_2);
            app.Param18Label.Text = 'Param18';
            app.Par18Label = uilabel(app.SynapseTab_2);
            app.Par18Label.Text = 'Par18';
            app.Param18Edit = uieditfield(app.SynapseTab_2, 'numeric');
            app.Param18Edit.ValueChangedFcn = createCallbackFcn(app, @Param18EditValueChanged, true);
            app.SynUILabel = uilabel(app.SynapseTab_2);
            app.SynUILabel.Text = 'SynUI';
            app.SynapseLabel = uilabel(app.SynapseTab_2);
            app.SynapseLabel.Text = 'Synapse';
            app.LoadThresholdsButton = uibutton(app.SynapseTab, 'push');
            app.LoadThresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadThresholdsButtonPushed, true);
            app.LoadThresholdsButton.Text = 'Load Thresholds';
            app.GetButton = uibutton(app.SynapseTab, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = '<- Get ';
            app.SetButton = uibutton(app.SynapseTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set ->';
            app.ReadTankTab = uitab(app.TabGroup);
            app.ReadTankTab.Title = 'ReadTank';
            app.TankButton = uibutton(app.ReadTankTab, 'push');
            app.TankButton.ButtonPushedFcn = createCallbackFcn(app, @TankButtonPushed, true);
            app.TankButton.Text = 'Tank';
            app.PlotButton = uibutton(app.ReadTankTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ParamsLabel = uilabel(app.ReadTankTab);
            app.ParamsLabel.Text = '';
            app.ParamsLabel2 = uilabel(app.ReadTankTab);
            app.ParamsLabel2.Text = '';
            app.ExportButton = uibutton(app.ReadTankTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export Data';
            app.ClustersButton = uibutton(app.ReadTankTab, 'push');
            app.ClustersButton.ButtonPushedFcn = createCallbackFcn(app, @ClustersButtonPushed, true);
            app.ClustersButton.Text = 'Clusters';
            app.ExperimentDataPanel = uipanel(app.ReadTankTab);
            app.ExperimentDataPanel.Title = 'Experiment Data';
            app.paramsCheck = uicheckbox(app.ExperimentDataPanel);
            app.paramsCheck.Text = 'Parameters';
            app.stimCheck = uicheckbox(app.ExperimentDataPanel);
            app.stimCheck.Text = 'Stimulus Pulses';
            app.stimsCheck = uicheckbox(app.ExperimentDataPanel);
            app.stimsCheck.Text = 'Stimulus Stats';
            app.ResponseDataPanel = uipanel(app.ReadTankTab);
            app.ResponseDataPanel.Title = 'Response Data';
            app.spikeCheck = uicheckbox(app.ResponseDataPanel);
            app.spikeCheck.Text = 'Spike Times';
            app.rateCheck = uicheckbox(app.ResponseDataPanel);
            app.rateCheck.Text = 'Total Spike Rates';
            app.psth1Check = uicheckbox(app.ResponseDataPanel);
            app.psth1Check.Text = 'PSTH1';
            app.psth2Check = uicheckbox(app.ResponseDataPanel);
            app.psth2Check.Text = 'PSTH2';
            app.lfp1Check = uicheckbox(app.ResponseDataPanel);
            app.lfp1Check.Text = 'LFP1';
            app.lfp2Check = uicheckbox(app.ResponseDataPanel);
            app.lfp2Check.Text = 'LFP2';
            app.statsCheck = uicheckbox(app.ResponseDataPanel);
            app.statsCheck.Text = 'Pre/Post Statistics';
            app.tuningCheck = uicheckbox(app.ResponseDataPanel);
            app.tuningCheck.Text = 'Stimulus Tuning Stats';
            app.lfpstatsCheck = uicheckbox(app.ResponseDataPanel);
            app.lfpstatsCheck.Text = 'LFP Stats';
            app.psth3Check = uicheckbox(app.ResponseDataPanel);
            app.psth3Check.Text = 'PSTH2-1';
            app.fraCheck = uicheckbox(app.ResponseDataPanel);
            app.fraCheck.Text = 'FRA Table';
            app.BatchButton = uibutton(app.ReadTankTab, 'push');
            app.BatchButton.ButtonPushedFcn = createCallbackFcn(app, @BatchButtonPushed, true);
            app.BatchButton.Text = 'Batch';
            app.StimParamsLabel = uilabel(app.ReadTankTab);
            app.StimParamsLabel.Text = '';
            app.StimParamsLabel2 = uilabel(app.ReadTankTab);
            app.StimParamsLabel2.Text = '';
            app.AnalysisDataPanel = uipanel(app.ReadTankTab);
            app.AnalysisDataPanel.Title = 'Analysis Data';
            app.ExpParCheck = uicheckbox(app.AnalysisDataPanel);
            app.ExpParCheck.Text = 'Parameters';
            app.BatchEdit = uieditfield(app.ReadTankTab, 'text');
            app.TankEdit = uieditfield(app.ReadTankTab, 'text');
            app.ClustEdit = uieditfield(app.ReadTankTab, 'text');
            app.FilenameprefixEditFieldLabel = uilabel(app.ReadTankTab);
            app.FilenameprefixEditFieldLabel.Text = 'Filename prefix';
            app.PrefixEdit = uieditfield(app.ReadTankTab, 'text');
            app.SkipLFPCheck = uicheckbox(app.ReadTankTab);
            app.SkipLFPCheck.Text = 'Skip LFP (faster)';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.FiringRateTab = uitab(app.TabGroup2);
            app.FiringRateTab.Title = 'Firing Rate';
            app.rateAxes = uiaxes(app.FiringRateTab);
            title(app.rateAxes, 'Running Spike Rate')
            xlabel(app.rateAxes, 'Time')
            ylabel(app.rateAxes, 'Channel')
            app.BinsizemsLabel_2 = uilabel(app.FiringRateTab);
            app.BinsizemsLabel_2.Text = 'Bin size (ms)';
            app.BinEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.BinEdit.Value = 500;
            app.HistorysecLabel = uilabel(app.FiringRateTab);
            app.HistorysecLabel.Text = 'History (sec)';
            app.RangeEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.RangeEdit.Value = 60;
            app.fratescaleEdit = uieditfield(app.FiringRateTab, 'numeric');
            app.yscale0maxLabel = uilabel(app.FiringRateTab);
            app.yscale0maxLabel.Text = 'y-scale (0 = max)';
            app.frateTable = uitable(app.FiringRateTab);
            app.frateTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.frateTable.RowName = {};
            app.ratePopupCheck = uicheckbox(app.FiringRateTab);
            app.ratePopupCheck.Text = 'Popup';
            app.FreqTuningTab = uitab(app.TabGroup2);
            app.FreqTuningTab.Title = 'Freq Tuning';
            app.AnalysiswindowmsLabel = uilabel(app.FreqTuningTab);
            app.AnalysiswindowmsLabel.Text = 'Analysis window (ms)';
            app.OnsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.FTCAxes = uiaxes(app.FreqTuningTab);
            title(app.FTCAxes, 'IsoIntensity Function')
            xlabel(app.FTCAxes, 'Frequency')
            ylabel(app.FTCAxes, 'Channel')
            app.FTCPopupCheck = uicheckbox(app.FreqTuningTab);
            app.FTCPopupCheck.Text = 'Popup';
            app.OffsetEdit = uieditfield(app.FreqTuningTab, 'numeric');
            app.OffsetEdit.Value = 100;
            app.PSTHTab = uitab(app.TabGroup2);
            app.PSTHTab.Title = 'PSTH';
            app.PSTH1Axes = uiaxes(app.PSTHTab);
            title(app.PSTH1Axes, 'PSTH 1')
            xlabel(app.PSTH1Axes, 'Time')
            ylabel(app.PSTH1Axes, 'Spike Rate')
            app.PSTH2Axes = uiaxes(app.PSTHTab);
            title(app.PSTH2Axes, 'PSTH 2')
            xlabel(app.PSTH2Axes, 'Time')
            ylabel(app.PSTH2Axes, 'Spike Rate')
            app.PSTHPopupCheck = uicheckbox(app.PSTHTab);
            app.PSTHPopupCheck.Text = 'Popup';
            app.PSTHbaseline = uibuttongroup(app.PSTHTab);
            app.PSTHbaseline.Title = 'Show Baseline';
            app.MeanButton = uiradiobutton(app.PSTHbaseline);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.CLButton = uiradiobutton(app.PSTHbaseline);
            app.CLButton.Text = 'CL (%)';
            app.CLEdit = uieditfield(app.PSTHbaseline, 'numeric');
            app.CLEdit.Value = 95;
            app.PSTHButton = uiradiobutton(app.PSTHbaseline);
            app.PSTHButton.Visible = 'off';
            app.PSTHButton.Text = 'PSTH 1';
            app.SubtractionCheck = uicheckbox(app.PSTHbaseline);
            app.SubtractionCheck.Visible = 'off';
            app.SubtractionCheck.Text = 'PSTH2-1';
            app.PSTHscaleradio = uibuttongroup(app.PSTHTab);
            app.PSTHscaleradio.Title = 'Normalization';
            app.IndependentButton = uiradiobutton(app.PSTHscaleradio);
            app.IndependentButton.Text = 'Independent';
            app.xPSTHsButton = uiradiobutton(app.PSTHscaleradio);
            app.xPSTHsButton.Text = 'x PSTHs';
            app.xAllButton = uiradiobutton(app.PSTHscaleradio);
            app.xAllButton.Text = 'x All';
            app.xAllButton.Value = true;
            app.PSTHscaleEdit = uieditfield(app.PSTHscaleradio, 'numeric');
            app.CyclePSTHPanel = uipanel(app.PSTHTab);
            app.CyclePSTHPanel.Title = 'CyclePSTH';
            app.ResponseDurationCheck = uicheckbox(app.CyclePSTHPanel);
            app.ResponseDurationCheck.Text = 'Response Duration';
            app.SpectraCheck = uicheckbox(app.CyclePSTHPanel);
            app.SpectraCheck.Text = 'Spectra';
            app.PSTHWindowText = uilabel(app.CyclePSTHPanel);
            app.PSTHWindowText.Text = 'Analysis window (ms)';
            app.OnsetOffsetLabel = uilabel(app.CyclePSTHPanel);
            app.OnsetOffsetLabel.Text = 'Onset       Offset';
            app.PSTHOnsetEdit = uieditfield(app.CyclePSTHPanel, 'numeric');
            app.PSTHOffsetEdit = uieditfield(app.CyclePSTHPanel, 'numeric');
            app.BaselinemsLabel = uilabel(app.CyclePSTHPanel);
            app.BaselinemsLabel.Text = 'Baseline (ms)';
            app.BaselineStartEdit = uieditfield(app.CyclePSTHPanel, 'numeric');
            app.BaselineEndEdit = uieditfield(app.CyclePSTHPanel, 'numeric');
            app.BaselineEndEdit.Value = 7;
            app.PSTHCycleCheck = uicheckbox(app.PSTHTab);
            app.PSTHCycleCheck.ValueChangedFcn = createCallbackFcn(app, @PSTHCycleCheckValueChanged, true);
            app.PSTHCycleCheck.Text = 'Average cycles';
            app.FrequencyDropDownLabel = uilabel(app.PSTHTab);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDrop = uidropdown(app.PSTHTab);
            app.PSTHBinEdit = uieditfield(app.PSTHTab, 'numeric');
            app.PSTHBinEdit.Value = 500;
            app.BinsizemsLabel_3 = uilabel(app.PSTHTab);
            app.BinsizemsLabel_3.Text = 'Bin size (ms)';
            app.LevelDrop = uidropdown(app.PSTHTab);
            app.LevelLabel = uilabel(app.PSTHTab);
            app.LevelLabel.Text = 'Level';
            app.LFPTab = uitab(app.TabGroup2);
            app.LFPTab.Title = 'LFP';
            app.LFP1Axes = uiaxes(app.LFPTab);
            title(app.LFP1Axes, 'LFP 1')
            xlabel(app.LFP1Axes, 'Time')
            ylabel(app.LFP1Axes, 'Channel')
            app.LFP2Axes = uiaxes(app.LFPTab);
            title(app.LFP2Axes, 'LFP 2')
            xlabel(app.LFP2Axes, 'Time')
            ylabel(app.LFP2Axes, 'Channel')
            app.LFPPopupCheck = uicheckbox(app.LFPTab);
            app.LFPPopupCheck.Text = 'Popup';
            app.LFP1Label = uilabel(app.LFPTab);
            app.LFP2Label = uilabel(app.LFPTab);
            app.HPCFEditFieldLabel = uilabel(app.LFPTab);
            app.HPCFEditFieldLabel.Text = 'HPCF';
            app.HPCFEdit = uieditfield(app.LFPTab, 'numeric');
            app.HPCFEdit.Value = 30;
            app.LPCFEditFieldLabel = uilabel(app.LFPTab);
            app.LPCFEditFieldLabel.Text = 'LPCF';
            app.LPCFEdit = uieditfield(app.LFPTab, 'numeric');
            app.LPCFEdit.Value = 300;
            app.RMSWindowmsLabel = uilabel(app.LFPTab);
            app.RMSWindowmsLabel.Text = 'RMS Window (ms)';
            app.RMSonEdit = uieditfield(app.LFPTab, 'numeric');
            app.RMSoffEdit = uieditfield(app.LFPTab, 'numeric');
            app.ClusterWaveformsTab = uitab(app.TabGroup2);
            app.ClusterWaveformsTab.Title = 'Cluster Waveforms';
            app.Clust1Axes = uiaxes(app.ClusterWaveformsTab);
            title(app.Clust1Axes, '')
            xlabel(app.Clust1Axes, 'Channel')
            ylabel(app.Clust1Axes, 'Cluster')
            app.Clust2Axes = uiaxes(app.ClusterWaveformsTab);
            title(app.Clust2Axes, {''; ''})
            xlabel(app.Clust2Axes, '')
            ylabel(app.Clust2Axes, '')
            app.ClustPopupCheck = uicheckbox(app.ClusterWaveformsTab);
            app.ClustPopupCheck.Text = 'Popup';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.ChannelsTab = uitab(app.TabGroup3);
            app.ChannelsTab.Title = 'Channels';
            app.CheckBox_1 = uicheckbox(app.ChannelsTab);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.ChannelsTab);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.ChannelsTab);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.ChannelsTab);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.ChannelsTab);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.ChannelsTab);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.ChannelsTab);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.ChannelsTab);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.ChannelsTab);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.ChannelsTab);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.ChannelsTab);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.ChannelsTab);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.ChannelsTab);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.ChannelsTab);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.ChannelsTab);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.ChannelsTab);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.ChannelsTab);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.ChannelsTab);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.ChannelsTab);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.ChannelsTab);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.ChannelsTab);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.ChannelsTab);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.ChannelsTab);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.ChannelsTab);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.ChannelsTab);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.ChannelsTab);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.ChannelsTab);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.ChannelsTab);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.ChannelsTab);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.ChannelsTab);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.ChannelsTab);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.ChannelsTab);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.Chan116Button = uibutton(app.ChannelsTab, 'state');
            app.Chan116Button.ValueChangedFcn = createCallbackFcn(app, @Chan116ButtonValueChanged, true);
            app.Chan116Button.Text = 'Clear 1-16';
            app.Chan116Button.Value = true;
            app.Chan1732Button = uibutton(app.ChannelsTab, 'state');
            app.Chan1732Button.ValueChangedFcn = createCallbackFcn(app, @Chan1732ButtonValueChanged, true);
            app.Chan1732Button.Text = 'Check 17-32';
            app.Chan1732Button.Value = true;
            app.ClustersTab = uitab(app.TabGroup3);
            app.ClustersTab.Title = 'Clusters';
            app.SortOrderDropDownLabel = uilabel(app.ClustersTab);
            app.SortOrderDropDownLabel.Text = 'Sort Order';
            app.SortOrderDropDown = uidropdown(app.ClustersTab);
            app.SortOrderDropDown.Items = {'Original', 'Nearest Channel', 'Spike Count', 'AD'};
            app.SortOrderDropDown.Value = 'Original';
            app.SelectClustersButtonGroup = uibuttongroup(app.ClustersTab);
            app.SelectClustersButtonGroup.Title = 'Select Clusters';
            app.AllRadio = uiradiobutton(app.SelectClustersButtonGroup);
            app.AllRadio.Text = 'All';
            app.AllRadio.Value = true;
            app.TopRadio = uiradiobutton(app.SelectClustersButtonGroup);
            app.TopRadio.Text = 'Top';
            app.BottomRadio = uiradiobutton(app.SelectClustersButtonGroup);
            app.BottomRadio.Text = 'Bottom';
            app.ClustRadio = uiradiobutton(app.SelectClustersButtonGroup);
            app.ClustRadio.Text = 'Clusters';
            app.ClustTopEdit = uieditfield(app.SelectClustersButtonGroup, 'text');
            app.ClustBottomEdit = uieditfield(app.SelectClustersButtonGroup, 'text');
            app.ClustClustEdit = uieditfield(app.SelectClustersButtonGroup, 'text');
            app.ClustsCheck = uicheckbox(app.ClustersTab);
            app.ClustsCheck.Text = 'Show Clusters';
            app.LoadParButton = uibutton(app.UIFigure, 'push');
            app.LoadParButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParButtonPushed, true);
            app.LoadParButton.Text = 'Load Params';
            app.LoadParEdit = uieditfield(app.UIFigure, 'text');
            app.ExpParButton = uibutton(app.UIFigure, 'push');
            app.ExpParButton.ButtonPushedFcn = createCallbackFcn(app, @ExpParButtonPushed, true);
            app.ExpParButton.Text = 'Export Params';
            app.Referencet0DropDownLabel = uilabel(app.UIFigure);
            app.Referencet0DropDownLabel.Text = 'Reference (t=0)';
            app.PSTH1RefDrop = uidropdown(app.UIFigure);
            app.PSTH1RefDrop.Items = {'Onset', 'Offset', '0'};
            app.PSTH1RefDrop.Value = 'Onset';
            app.Referencet0DropDown_2Label = uilabel(app.UIFigure);
            app.Referencet0DropDown_2Label.Text = 'Reference (t=0)';
            app.PSTH2RefDrop = uidropdown(app.UIFigure);
            app.PSTH2RefDrop.Items = {'Onset', 'Offset', '0'};
            app.PSTH2RefDrop.Value = 'Offset';
            app.PSTH1WindowsecEditFieldLabel = uilabel(app.UIFigure);
            app.PSTH1WindowsecEditFieldLabel.Text = 'PSTH 1 Window (sec)';
            app.PSTH1StartEdit = uieditfield(app.UIFigure, 'numeric');
            app.PSTH1EndEdit = uieditfield(app.UIFigure, 'numeric');
            app.PSTH1EndEdit.Value = 1;
            app.PSTH2WindowsecEditFieldLabel = uilabel(app.UIFigure);
            app.PSTH2WindowsecEditFieldLabel.Text = 'PSTH 2 Window (sec)';
            app.PSTH2StartEdit = uieditfield(app.UIFigure, 'numeric');
            app.PSTH2EndEdit = uieditfield(app.UIFigure, 'numeric');
            app.PSTH2EndEdit.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synui_v122

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end